classdef OrganoSeg2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        OpenMenu                        matlab.ui.container.Menu
        NewImageSetMenu                 matlab.ui.container.Menu
        PreviosulySegmentedImagesMenu   matlab.ui.container.Menu
        SaveMenu                        matlab.ui.container.Menu
        CurrentImagesMenu               matlab.ui.container.Menu
        RawOverlayColoredImagesMenu     matlab.ui.container.Menu
        CroppedSpheroidMenu             matlab.ui.container.Menu
        ImageSetMenu                    matlab.ui.container.Menu
        UndoMenu                        matlab.ui.container.Menu
        UndoCurrentMenu                 matlab.ui.container.Menu
        UndoAllMenu                     matlab.ui.container.Menu
        PreferencesMenu                 matlab.ui.container.Menu
        MetricsMenu                     matlab.ui.container.Menu
        CalibrationMenu                 matlab.ui.container.Menu
        BrightnessMenu                  matlab.ui.container.Menu
        ResizeImagesMenu                matlab.ui.container.Menu
        CroppingMenu                    matlab.ui.container.Menu
        EdgeCorrectionMenu              matlab.ui.container.Menu
        AdditionalParametersMenu        matlab.ui.container.Menu
        AdditionalAnalysisMenu          matlab.ui.container.Menu
        FluorescenceMenu                matlab.ui.container.Menu
        TrackingMenu                    matlab.ui.container.Menu
        TrackSingleBatchMenu            matlab.ui.container.Menu
        TrackInBatchesMenu              matlab.ui.container.Menu
        TotalGrid                       matlab.ui.container.GridLayout
        RightPanel                      matlab.ui.container.Panel
        RightGrid                       matlab.ui.container.GridLayout
        BottomRightPanel                matlab.ui.container.Panel
        BottomGrid                      matlab.ui.container.GridLayout
        TabGroup2                       matlab.ui.container.TabGroup
        SegmentOptionsTab               matlab.ui.container.Tab
        SegmentationOptionsPanel        matlab.ui.container.Panel
        SegmentationOptionsGrid         matlab.ui.container.GridLayout
        EdgeIntensityEditField          matlab.ui.control.NumericEditField
        EdgeIntensity01Label            matlab.ui.control.Label
        EdgeCorrectionCheckBox          matlab.ui.control.CheckBox
        ContaminantIntensityEditField   matlab.ui.control.NumericEditField
        ContaminantIntesity0255Label    matlab.ui.control.Label
        MinimumCircularityEditField     matlab.ui.control.NumericEditField
        MinimumCircularity01Label       matlab.ui.control.Label
        SizeThresholdEditField          matlab.ui.control.NumericEditField
        SizeThreshold105000Label        matlab.ui.control.Label
        WindowSizeEditField             matlab.ui.control.NumericEditField
        WindowSize20500Label            matlab.ui.control.Label
        IntensityThresholdEditField     matlab.ui.control.NumericEditField
        IntensityThreshold01Label       matlab.ui.control.Label
        SplitWholeImageCheckBox         matlab.ui.control.CheckBox
        DICCorrectionCheckBox           matlab.ui.control.CheckBox
        OutofFocusCorrectionCheckBox    matlab.ui.control.CheckBox
        GroupingOptionsTab              matlab.ui.container.Tab
        GroupingOptionsGrid             matlab.ui.container.GridLayout
        RegexEditField                  matlab.ui.control.EditField
        RegexEditFieldLabel             matlab.ui.control.Label
        GroupingOptionsTable            matlab.ui.control.Table
        SpheroidZoomPanel               matlab.ui.container.Panel
        SpheroidZoomGrid                matlab.ui.container.GridLayout
        SpheroidListBox                 matlab.ui.control.ListBox
        SpheroidZoomAxes                matlab.ui.control.UIAxes
        ImagesPanel                     matlab.ui.container.Panel
        ImagesGrid                      matlab.ui.container.GridLayout
        MaximizeImageButton             matlab.ui.control.Button
        ToolsPanel                      matlab.ui.container.Panel
        ToolsGrid                       matlab.ui.container.GridLayout
        IndividualEdgeCorrectPreference  matlab.ui.control.DropDown
        CombineCloseSizeEditField       matlab.ui.control.NumericEditField
        SplitSelectedSpheroidtCheckBox  matlab.ui.control.CheckBox
        IndivSplitBlurringEditField     matlab.ui.control.NumericEditField
        IndividualEdgeIntensityEditField  matlab.ui.control.NumericEditField
        IndividualEdgeCorrectiCheckBox  matlab.ui.control.CheckBox
        ClassificationDropDown          matlab.ui.control.DropDown
        CombinecCheckBox                matlab.ui.control.CheckBox
        RemoveCheckBox                  matlab.ui.control.CheckBox
        ColoredSegmentationPanel        matlab.ui.container.Panel
        ColoredSegmentationGrid         matlab.ui.container.GridLayout
        ColoredSegmentationLabelGrid    matlab.ui.container.GridLayout
        ShowIDsSwitch                   matlab.ui.control.Switch
        ShowIDsSwitchLabel              matlab.ui.control.Label
        ColoredSegmentationLabel        matlab.ui.control.Label
        ColoredSegmentationAxes         matlab.ui.control.UIAxes
        RawGrayscaleImagePanel          matlab.ui.container.Panel
        RawImageGrid                    matlab.ui.container.GridLayout
        CropGrid                        matlab.ui.container.GridLayout
        CropDimensionsGridLayout        matlab.ui.container.GridLayout
        CropDimensionsLabel             matlab.ui.control.Label
        UseLastCropDimensionsCheckBox   matlab.ui.control.CheckBox
        RotationAngleEditField          matlab.ui.control.NumericEditField
        RotationAngleCounterClockwiseEditFieldLabel  matlab.ui.control.Label
        MaximizeRawImage                matlab.ui.control.Button
        RotateButton                    matlab.ui.control.Button
        BrightnessGrid                  matlab.ui.container.GridLayout
        FirstAdjustmentGrid             matlab.ui.container.GridLayout
        SecondBrightParam               matlab.ui.control.NumericEditField
        Parameter2Label                 matlab.ui.control.Label
        FirstBrightParam                matlab.ui.control.NumericEditField
        Parameter1Label                 matlab.ui.control.Label
        BrightnessAdjustment            matlab.ui.control.DropDown
        AdjustmentStyleLabel            matlab.ui.control.Label
        Change_Contrast_All             matlab.ui.control.Button
        Decrease                        matlab.ui.control.Button
        Increase                        matlab.ui.control.Button
        Change_Contrast                 matlab.ui.control.Button
        BrightnessButton                matlab.ui.control.Button
        RevertButton                    matlab.ui.control.Button
        Crop                            matlab.ui.control.Button
        RawGrayscaleImageLabel          matlab.ui.control.Label
        RawImageAxes                    matlab.ui.control.UIAxes
        LeftPanel                       matlab.ui.container.Panel
        LeftGrid                        matlab.ui.container.GridLayout
        LeftTitleGrid                   matlab.ui.container.GridLayout
        CollapseLeftButton              matlab.ui.control.Button
        ManageImagesLabel               matlab.ui.control.Label
        ImageSelectionPanel             matlab.ui.container.Panel
        ImageSelectGrid                 matlab.ui.container.GridLayout
        DeleteImageButton               matlab.ui.control.Button
        ClearWorkspaceButton            matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        ImagesTab                       matlab.ui.container.Tab
        ImageSelectList                 matlab.ui.control.ListBox
        GroupsTab                       matlab.ui.container.Tab
        GroupsTable                     matlab.ui.control.Table
        FluorescenceButtonsGrid         matlab.ui.container.GridLayout
        LoadSavedFileButton             matlab.ui.control.Button
        EscButton                       matlab.ui.control.Button
        BeginAnalysisButton             matlab.ui.control.Button
        SegmentPanel                    matlab.ui.container.Panel
        SegmentGrid                     matlab.ui.container.GridLayout
        NumWorkersEditField             matlab.ui.control.NumericEditField
        NumWorkersLabel                 matlab.ui.control.Label
        QuitSegmentationButton          matlab.ui.control.Button
        SelectImagestoSegmentButton     matlab.ui.control.Button
        ApplytoAllButton                matlab.ui.control.Button
        SegmentCurrentButton            matlab.ui.control.Button
        ExportPanel                     matlab.ui.container.Panel
        ExportGrid                      matlab.ui.container.GridLayout
        ExportingroupsButton            matlab.ui.control.Button
        AddClassificationSwitch         matlab.ui.control.Switch
        SelectImagestoExportButton      matlab.ui.control.Button
        WhichMetricsSwitch              matlab.ui.control.Switch
        ExportSetButton                 matlab.ui.control.Button
        ExportCurrentButton             matlab.ui.control.Button
    end


    properties (Access = public)
        MetricsSelected = struct('Area', 1, 'Perimeter', 1, 'PerimeterToAreaRatio', 0, 'Circularity', 0, 'EquivDiameter', 0, ...
            'Eccentricity', 1, 'MajorAxisLength', 0, 'MinorAxisLength', 0, 'ConvexArea', 0, 'Extent', 0, 'Orientation', 0, ...
            'Solidity', 0, 'Z', 0, 'A', 0, 'Phi', 0, 'MeanPixelIntensity', 0, 'StdPixelIntensity', 0, 'CVPixelIntensity', 0, ...
            'Skewness', 0, 'Kurtosis', 0, 'Contrast', 1, 'Correlation', 1, 'Energy', 1, 'Homogeneity', 1);
        % transfers between main app and metric selection app which metrics


    end

    properties (Access = private)

        %%%%%% Components %%%%%%%%
       

        %%%Resizing Panels%%%%
        ImagesMaximized = false;  %Booleans describing state of scene
        LeftPanelCollapsed = false;
        BottomPanelCollapsed = false;
        RawImageMaximized = false;


        %%%% File Open %%%%
        Filename = {} %string filename of each image
        Basename = {} %string basename for each image
       
        BulkI = {};    %Cell array storing all images, operated on for segmentation


        %%% Segment Parameters %%%%
        % parameters specified for each image
        WindowSize = {}; %used in adaptive thresholding
        SizeThresh = {}; %used to filter out small noise
        IntensityThresh = {}; %used for adaptive thresholding
        MinimumCircularity = {}; %used to identify non-circular objects (no affect on segmentation)
        ContaminantIntensity = {}; %used to identify possible contaminant, dark objects (no affect on segmentation), 
        % represented in pixel value from 0-255
        CheckWater = {}; %says whether watershed splitting is used on image
        CheckOOF = {};%says whether out of focus correction is used on image
        CheckDIC = {}; %says whether DIC is used on image
        
        CheckEdge = {}; %says whether edge correction is used on image
        EdgeIntensity = {}; %specifies the minimum pixel intesnity (0-255) that will register as a spheroid edge


        %%%% Segment Products %%%%%%%
       
        Bw_nowater = {}; %Black white image before watershed, with noise removed and holes filled
        Bwfinal = {}; %Final black white image used for CC generation
        CC = {}; %Connected components, struct containing data on each segmented spheroid (pixels included)
     
        Centers = {}; %Centers of each segmented spheroid
        Spheroids_order = {}; %gives order of spheroids, keeping clusters
        % (spheroids from same original spheroid before split) together,
        % only relevant if split
        ColoredLabels = {}; %rgb mask of spheroids, displayed on colored segmentation axes
        ImgMasked = {}; %image displayed after segmentation with perimeter overlay
        BoundingBox = {}; %describes rectangle surrounding each spheroid, 
        % does not include removed spheroids
        NonCircularCC = {}; % copy of CC but only including spheroids marked as non circular
        %used for perimeter display and reclassification
        CircularBoolean = {}; %binary array, 1 for circular, 0 for non-circular
        ContaminantCC = {}; % copy of CC but only including spheroids marked as contaminant
        %used for perimeter display and reclassification
        NonContaminantBoolean = {}; %binary array, 1 for non-contaminant, 0 for contaminant
        %AxesChild 


        %%%%% Deletions and Stuff %%%%%%
        CentersDeleted = {};  %stores centers of the spheroids that have been deleted (can be reinstated)
        CcDeleted = {}; %stores pixel idx list of deleted spheroids, can be reinstated


        %%%%%%%%% Using tools %%%%%%%%%
        FirstRemoveCombineSelected = false;   %says if first corner for remove was selected
        SecondRemoveCombineSelected = false;
        FirstSelectedCoords = NaN %coordinates of the first selected corner, will not change unless escape
        SecondSelectedCoords = NaN %coordinates of second selected corner, can change by clicking again
        Marker = 0  %marker for first corner
        RectangleRemoveCombineMarker = 0  %marker identifying rectangulr region to remove from

        FirstCombine = NaN;  %index of first spheroid selected for combine


        SpheroidsToSplit = []; %stores indicies of spheroids selected for splitting
        SplitSpheroidsSelected = 0; %number of spheroids that were selected to split
        BeenSplit = {};  %stores if image has been split manually to account for spheroid ordering later

        SpheroidsToCorrect = []; %stores indicies of spheroids selected for edge correction
        EdgeSpheroidsSelected = 0; %number of spheroids that were selected to be corrected


        CroppingImage = false; %says if app is in state of cropping image
        FirstCropCorner = NaN %coordinates of first corner if rectangular crop
        SecondCropCorner = NaN
        CropPreference = 'Rectangle' 
        CornerMarker = 0  %Markers for cropping interactions
        RectangleMarker = 0
        CircleMarker = 0;
        CenterMarker = 0;

        CropCenter = NaN; %coordinates of crop center if circular crop
        
        CropRadius = NaN; %calculated radius of crop if circular crop

        SpheroidsToClassify = [];  %indices of spheroids to be reclassified (as contaminant or non-circular)
        ClassifySpheroidsSelected = 0; % number of spheroids to be reclassified
        ChangingClassification = false; %used to determine result of pressing enter/ return, will change spheroid classification
        BulkRemove = false; %used to determine result of pressing enter/ return, will remove all spheroids of certain classification

        BrightnessScene  %app for brightness preference testing


        ToolReadyToUse = false; %declared by centralized function that resets tool-related variables, ensures requirementes are met for using tool
        Saved_states %when tool usage disables other features, stores which features were enabled/ disabled previously
        ThingsDisenabled = false; %says if things are disenabled so that they can be reenabled
        RawImageAxesEnabled = true; 
        ColoredSegmentationAxesEnabled = true;


        AdditionalParameters = {}; %Additional parameters that are not present on main scene
        WatershedFilterSigma = 5; %St dev for gaussian filter in watershed splitting
        NumberWatershedIterations = 1; %Number of iterations of watershed splitting
        ImageClosingDegree = 3;  %Nhood size for image close of segmentation
        ImageReconstructionRadius = 5; %Nhood size for pre segmentation image reconstruction
        WindowSizeJump = 10; %Step size for iterative adaptive thresholding if OOF correction used
        UseReconstructedForGradient = 1; %Whether gradient edge correction uses reconstructed or raw image
        UseInverted = 0; %Whether or not image is inverted (segmenting bright objects)
        ClearBorderSplit = 1; %Whether or not border is cleared before splitting

        EdgePreference = 'Gradient + Watershed'

        
        MainSegmentColor = [0 0 1]; %Colors for different segment outlines or tool markers
        NonCircColor = [1 1 0];
        ContaminantColor = [1 0 1];
        RemoveColor = [1 0 0];
        CombineColor = [0 1 0];
        SplitColor = [0.8500 0.3250 0.0980];
        EdgeCorrectColor = [1 1 0];
        
        FilenameRegex = "NA"; %Default filename regex used to group images
        TrackingFormat %Used to distinguish single batch or multibatch tracking for tracking startup


        PreviousVersion = {};%Stores data from previous version of app to do undo

        LeftGridWidth = 170;


        %%%%%%% Metrics %%%%%%
        MetricsScene %app which chooses metrics
        
        % will be used in export (if 'All metrics' switch not selected)
        Resize = {}; %if image is resized, stores the resize fraction to account for area, perimeter, etc conversions
        MicronsPerPixel = 1; %used for calibration and conversion from pixels to actual size


        %%%%%% Flourescence %%%%
        FluorescenceMode = false; %says if images are being selected for fluorescence (changes function becuase multiple image select allowed)     
        FluorescenceScene %app which allows for fluorescence analysis

        %%%%%% Tracking %%%%%%%%
        TrackingMode = false; %says if images are being selected for fluorescence (changes function becuase multiple image select allowed)     
        TrackingScene %app which allows for tracking analysis

        MultiExporting = false; 
        MultiSegmenting = false;
        ImagesToExport = [];
        ImagesToSegment = [];

        MostRecentImageOpenFolder = nan; %Folder locations to default to for repeated actions
        MostRecentImageSaveFolder = nan;
        MostRecentImageExportFolder = nan;
        QuitSegment = false;
        MostRecentPrevOpenFolder = nan;

        MostRecentRadius = nan; %Used for cropping with same dimensions
        MostRecentWidth = nan;
        MostRecentHeight = nan;
        FirstCornerX = nan;
        FirstCornerY = nan;


    end

    methods (Access = private)


     %%%%%%%%%% Screen appearance customization %%%%%%%%%%

        function collapseLeft(app)
            app.TotalGrid.ColumnWidth = {25, '1x'};
            app.LeftGrid.Padding = [0,0,0,0];
            app.LeftGrid.RowHeight = {'1x','0x','0x','0x'};
            app.CollapseLeftButton.Text = '>';
            app.LeftPanelCollapsed = true;

        end

        function collapseBottom(app)
            app.RightGrid.RowHeight = {'1x', 0};
            app.BottomPanelCollapsed = true;
        end

        function expandLeft(app)
            app.TotalGrid.ColumnWidth = {app.LeftGridWidth,'1x'};
            app.LeftGrid.Padding = [10,10,10,10];
            app.LeftGrid.RowHeight = {'0.2x','2x','1x','1x'};
            app.CollapseLeftButton.Text = '<';
            app.LeftPanelCollapsed = false;
        end

        function expandBottom(app)
            app.RightGrid.RowHeight = {'1.5x','1x'};
            app.BottomPanelCollapsed = false;
        end

        function expandRawImage(app)
            app.ImagesGrid.ColumnWidth = {'1x', 0, 0};
            app.RawImageMaximized = true;
           
        end
        function collapseRawImage(app)
            app.ImagesGrid.ColumnWidth = {'1x', '1x', 20};
            app.RawImageMaximized = false;
           
        end

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        %%%%%%%%%%%%%%%%%% Axes Display %%%%%%%%%%%%%%

        function displayRawImageOnly(app, selected)
            
            
            cla(app.RawImageAxes);
            axesChild = imshow(app.BulkI{selected},[], 'Parent', app.RawImageAxes);
            %Assign function for axes that is called when figure is clicked
            
            axesChild.ButtonDownFcn = createCallbackFcn(app, @rawImageButtonDownFunction, true);
            %Resetting desired axes values if cla(reset) is used (seems to
            %be slower)


            %if image not segmented yet
            if ~isempty(app.ColoredLabels)
                cla(app.ColoredSegmentationAxes);
                cla(app.SpheroidZoomAxes);
                app.SpheroidListBox.Items = {};
            end

            if ~isstruct(app.PreviousVersion{selected})
                app.UndoMenu.Enable = 0;
            else
                app.UndoMenu.Enable = 1;
            end


        end

        function displayRawImageWithOverlay(app, selected)

            cla(app.RawImageAxes);
            imgMasked = app.ImgMasked{selected};
            axesChild = imshow(imgMasked,[], 'Parent', app.RawImageAxes);

            axesChild.ButtonDownFcn = createCallbackFcn(app, @rawImageButtonDownFunction, true);


            %Ensure both axes at top are aligned 
            if ~app.RawImageMaximized
                app.ColoredSegmentationAxes.Position(3) = app.RawImageAxes.Position(3);
                app.ColoredSegmentationAxes.Position(4) = app.RawImageAxes.Position(4);
            end

            if ~isstruct(app.PreviousVersion{selected})
                app.UndoMenu.Enable = 0;
            else
                app.UndoMenu.Enable = 1;
            end

        end

        function displayColoredSegmentation(app, selected)

            cla(app.ColoredSegmentationAxes);
            
            colorAxesChild = imshow(app.ColoredLabels{selected},[],'Parent', app.ColoredSegmentationAxes);


            colorAxesChild.ButtonDownFcn = createCallbackFcn(app, @coloredAxesButtonDownFcn, true);


            if ~app.RawImageMaximized
                app.ColoredSegmentationAxes.Position(3) = app.RawImageAxes.Position(3);
                app.ColoredSegmentationAxes.Position(4) = app.RawImageAxes.Position(4);
            end
            if app.ShowIDsSwitch.Value == 1
                showIDSwitch(app)
            end


        end


        function displaySpheroidZoom(app, selected)
            %if image has been segmented and spheroid order assigned
            if ~isempty(app.Spheroids_order{selected})
                imgMasked = app.ImgMasked;
                %Display correct listbox values
                spheroid_str = cell(1,length(app.Spheroids_order{selected}));
                spheroid_order = app.Spheroids_order{selected};
                for j = 1:length(spheroid_order)
                    %Display only spheroid number (quicker than
                    %concatenating string)
                    spheroid_str{j} = num2str(spheroid_order(j));                    
                end
                app.SpheroidListBox.ItemsData = app.Spheroids_order{selected};


                app.SpheroidListBox.Items = spheroid_str;
                app.SpheroidListBox.Value = 1;


                %Choose bounding box to crop
                bbox = app.BoundingBox{selected}{spheroid_order(1)};
                bbox(1:2) = (bbox(1:2)) -5;
                bbox(3:4) = (bbox(3:4)) +10;
                
                img = imgMasked{selected};
                
                %Display zoom image in GUI
                cla(app.SpheroidZoomAxes);
                
                
                imshow(imcrop(img, bbox),[], 'Parent', app.SpheroidZoomAxes);

                
            else
                % if no segment
                cla(app.SpheroidZoomAxes)
            end

            
        end


        %Assign controls on screen to correct values for an image
        function setSettings(app, selected)

            app.WindowSizeEditField.Value = app.WindowSize{selected}(1);
            app.SizeThresholdEditField.Value = app.SizeThresh{selected};
            app.IntensityThresholdEditField.Value = app.IntensityThresh{selected};
            app.SplitWholeImageCheckBox.Value = app.CheckWater{selected};
            app.OutofFocusCorrectionCheckBox.Value = app.CheckOOF{selected};
            app.DICCorrectionCheckBox.Value = app.CheckDIC{selected};
            app.EdgeCorrectionCheckBox.Value = app.CheckEdge{selected};
            
            app.EdgeIntensityEditField.Value = app.EdgeIntensity{selected};

            app.MinimumCircularityEditField.Value = app.MinimumCircularity{selected};
          
            app.ContaminantIntensityEditField.Value = app.ContaminantIntensity{selected};
            
           
            app.WatershedFilterSigma = app.AdditionalParameters{selected}.watershedFilterSigma;
            app.NumberWatershedIterations = app.AdditionalParameters{selected}.numberWatershedIterations;
            app.ImageClosingDegree = app.AdditionalParameters{selected}.imageClosingDegree;
            app.ImageReconstructionRadius = app.AdditionalParameters{selected}.imageReconstructionRadius;
            app.WindowSizeJump = app.AdditionalParameters{selected}.windowSizeJump;
            app.UseReconstructedForGradient = app.AdditionalParameters{selected}.useReconstructedForGradient;
            app.UseInverted = app.AdditionalParameters{selected}.useInverted;
            app.ClearBorderSplit = app.AdditionalParameters{selected}.clearBorderSplit;
            app.EdgePreference = app.AdditionalParameters{selected}.edgePreference;
            if app.CheckEdge{selected} == 1 & ~strcmp(app.EdgePreference, 'Gradient + Watershed')
                app.EdgeIntensityEditField.Enable = 1;
            else
                app.EdgeIntensityEditField.Enable = 0;
            end
            
        end

         %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        %%%%%%%%%%% Segment and Export %%%%%%%%%%%

       %%%Primary segment function%%%
       %Start and finish are indices of images to be segmented
       function segmentUpdate(app, finish, singleImage)
            % segment current image or all images


            %Initialize to send to segment function
            mainParameters.ws = app.WindowSizeEditField.Value;
            mainParameters.intensityThresh = app.IntensityThresholdEditField.Value;
            mainParameters.sizeThresh = app.SizeThresholdEditField.Value;
            mainParameters.checkOOF = app.OutofFocusCorrectionCheckBox.Value;
            mainParameters.checkDIC = app.DICCorrectionCheckBox.Value;
            mainParameters.checkWater = app.SplitWholeImageCheckBox.Value;
            mainParameters.checkEdge = app.EdgeCorrectionCheckBox.Value;
            mainParameters.edgeIntensity = app.EdgeIntensityEditField.Value;          
            mainParameters.minimumCircularity = app.MinimumCircularityEditField.Value;
            mainParameters.contaminantIntensity = app.ContaminantIntensityEditField.Value;
            
            
            multiSegmenting = app.MultiSegmenting;

            additionalParameters = struct;
            additionalParameters.watershedFilterSigma = app.WatershedFilterSigma;
            additionalParameters.numberWatershedIterations = app.NumberWatershedIterations;
            additionalParameters.imageClosingDegree = app.ImageClosingDegree;
            additionalParameters.imageReconstructionRadius = app.ImageReconstructionRadius;
            additionalParameters.windowSizeJump = app.WindowSizeJump;
            additionalParameters.useReconstructedForGradient = app.UseReconstructedForGradient;
            additionalParameters.clearBorderSplit = app.ClearBorderSplit;
            additionalParameters.useInverted = app.UseInverted;
            additionalParameters.edgePreference = app.EdgePreference;
            
            numWorkers = app.NumWorkersEditField.Value;


            if multiSegmenting
                
                indices = app.ImagesToSegment;
            elseif singleImage ~=0
                indices = [singleImage];
            else
                indices = 1:finish;
                
            end        


            for index = indices
                
                getPreviousVersion(app, index)
            end


            [outputs] = segmentFunction(finish, numWorkers, mainParameters, additionalParameters, app.BulkI(indices));


            %Assign outputs to app properties
            for index = 1:length(indices)

                
                 selected = indices(index);


                app.ImgMasked{selected} = outputs(index).imgMasked;
                app.NonCircularCC{selected} = outputs(index).nonCircularCC;
                app.ContaminantCC{selected} =  outputs(index).contaminantCC;
                app.NonContaminantBoolean{selected} = outputs(index).nonContaminantBoolean;
                app.CircularBoolean{selected} = outputs(index).circularBoolean;
                app.ColoredLabels{selected} =  outputs(index).coloredLabels;
                app.Centers{selected} = outputs(index).centers;
                app.BoundingBox{selected} = outputs(index).boundingBox;
                app.Spheroids_order{selected} = outputs(index).spheroids_order;
                app.Bwfinal{selected} = outputs(index).bwfinal;
                app.CC{selected} = outputs(index).cc;
                app.Bw_nowater{selected} = outputs(index).bw_noWater; 
                app.BeenSplit{selected} = logical(mainParameters.checkWater);


                app.AdditionalParameters{selected} = additionalParameters;
                app.IntensityThresh{selected} = mainParameters.intensityThresh;
                app.WindowSize{selected} = [round(mainParameters.ws/10)*10, round(mainParameters.ws)/10*10];
                app.SizeThresh{selected} = round(mainParameters.sizeThresh);
                app.CheckDIC{selected} = mainParameters.checkDIC;
                app.CheckOOF{selected}= mainParameters.checkOOF;
                app.CheckWater{selected} = mainParameters.checkWater;
                app.CheckEdge{selected} = mainParameters.checkEdge;
                app.EdgeIntensity{selected} = mainParameters.edgeIntensity;
                 
                app.MinimumCircularity{selected} = mainParameters.minimumCircularity;
                app.ContaminantIntensity{selected} = mainParameters.contaminantIntensity;
                app.CentersDeleted{selected} = 0;
                app.CcDeleted{selected} = 0;
                
            end


            % app.SegmentGrid.ColumnWidth = {'1x', '1x', 0};
            if app.MultiSegmenting
                startInd = app.ImagesToSegment(1);
            elseif singleImage~=0
                startInd = singleImage;
            else
                startInd = 1;
            end
        
            
            displayColoredSegmentation(app, startInd)
            displaySpheroidZoom(app,startInd);


            displayRawImageWithOverlay(app, startInd)
            app.ImageSelectList.Value = startInd;
            

        end

        %%Function used for exporting data for one or multiple images
        function export(app, startInd, finish, path, group)
            %Check for image load   


                %Ask for export type/name
                if isnan(path)
                    f = figure('Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
                    if isnan(app.MostRecentImageExportFolder)
                        [answer,PathName] = uiputfile({'*.xls','Excel File (*.xls)';'*.xlsx','Excel File (*.xlsx)';'*.mat','MAT File (*.mat)'},'Export Metrics As');
                    else
                        [answer,PathName] = uiputfile({'*.xls','Excel File (*.xls)';'*.xlsx','Excel File (*.xlsx)';'*.mat','MAT File (*.mat)'},'Export Metrics As', app.MostRecentImageExportFolder);
                        
                    end
                    delete(f)
                else
                    PathName = path;
                    answer = char(group);
                end

                if PathName~=0
                    app.MostRecentImageExportFolder = PathName;
                end
               
                if answer == 0
                    %do nothing
                else


                    h = waitbar(0,'Please wait... compiling metric signature...');
                    if startInd == finish
                        signatureTot = cell(1);
                    elseif app.MultiExporting
                        signatureTot = cell(length(app.ImagesToExport));
                    else
                        signatureTot = cell(length(app.CC));
                    end

                    
                    metrics = {'Area', 'Perimeter', 'PerimeterToAreaRatio', 'Circularity', 'EquivDiameter',...
                            'Eccentricity', 'MajorAxisLength', 'MinorAxisLength', ...
                            'ConvexArea', 'Extent', 'Orientation',   ...
                            'Solidity',   'Z', 'A', 'Phi', ...
                            'MeanPixelIntensity', 'StdPixelIntensity',...
                            'CVPixelIntensity','Skewness'...
                            'Kurtosis','Contrast','Correlation','Energy','Homogeneity'};

                    %If switch is set to all metrics, assign all
                    %metrics for export
                    if strcmp(app.WhichMetricsSwitch.Value, 'AllMetrics')
                        
                        keepMetrics = ones(1, length(metrics));
                    else
                        %otherwise, determine which metrics to export
                        %based on metric scene
                        keepMetrics = [app.MetricsSelected.Area, app.MetricsSelected.Perimeter, app.MetricsSelected.PerimeterToAreaRatio, app.MetricsSelected.Circularity, ...
                            app.MetricsSelected.EquivDiameter, app.MetricsSelected.Eccentricity, app.MetricsSelected.MajorAxisLength, app.MetricsSelected.MinorAxisLength,  ...
                            app.MetricsSelected.ConvexArea, app.MetricsSelected.Extent, app.MetricsSelected.Orientation, app.MetricsSelected.Solidity, ...
                            app.MetricsSelected.Z, app.MetricsSelected.A, app.MetricsSelected.Phi, app.MetricsSelected.MeanPixelIntensity, app.MetricsSelected.StdPixelIntensity,...
                            app.MetricsSelected.CVPixelIntensity, app.MetricsSelected.Skewness, app.MetricsSelected.Kurtosis, app.MetricsSelected.Contrast,...
                            app.MetricsSelected.Correlation, app.MetricsSelected.Energy, app.MetricsSelected.Homogeneity ];
                    end
                    %Get indices of metrics for export
                    selectedMetrics = find(keepMetrics);
                    metricsString = cell(1,26);
                    statsString = cell(1,25);
    
                   %Create string of metric names that will be exported
                    for i = 1:length(keepMetrics)
                        if keepMetrics(i)
                            metricsString{i} = metrics{i};
    
                        end
                    end
                    if strcmp(app.AddClassificationSwitch.Value, 'Include')
                        metricsString{25} = 'NonCircular';
                        metricsString{26} = 'Contaminant';
                    end
                    %Select the metrics for export that are calculated
                    %with region props
                    for i = 1:length(keepMetrics)
                        if keepMetrics(i)
    
                            if ismember([metrics{i}], {'Area', 'MajorAxisLength', 'MinorAxisLength', 'Eccentricity',...
                                    'Orientation', 'ConvexArea', 'EquivDiameter', 'Solidity', 'Extent', 'Perimeter'})
                                statsString{i} = metrics{i};
                            end
                        end
                    end
                    %Also add bounding box to region prop since used
                    %for other metrics
                    statsString{25} = 'BoundingBox';
                    %Eliminate empty strings from cell array
                    metricsString = metricsString(~cellfun('isempty',metricsString));
                    statsString = statsString(~cellfun('isempty',statsString));

                    cc = app.CC;
                    bulkI = app.BulkI;
                    bwfinalMetrics = app.Bwfinal;
                    
                    for index = startInd:finish
                        
                        %If only exporting selected images, assign selected image
                        %based on this
                        
                        if app.MultiExporting
                            selected = app.ImagesToExport(index);
                        else
                            selected = index;
                        end

                        image = bulkI{selected};
                        %Create temp CC without removed objects
                        CC_temp = cc{selected};
                        CC_temp.PixelIdxList = CC_temp.PixelIdxList(~cellfun('isempty',CC_temp.PixelIdxList));
                        CC_temp.NumObjects = length(CC_temp.PixelIdxList);


                        %%%Modified export%%%


                        %Find metrics in string 
                        statsBW = regionprops(CC_temp, statsString);
                        
                        %Assign metrics to struct to store results
                        for i = 1:length(statsString)
                            metricsResults.(statsString{i}) = [statsBW.(statsString{i})];

                        end
                        %Calculate P2A ratio if selected
                        if ismember(3, selectedMetrics)

                            metricsResults.PerimeterToAreaRatio = [statsBW.Perimeter]./[statsBW.Area];
                        end
                        
                        %Calculate circularity if selected (uses region
                        %prop with different form%
                        if ismember(4, selectedMetrics)
                            props = regionprops("table",CC_temp,["Circularity" "Perimeter"]);
                            c = props.Circularity;
                            p = props.Perimeter;
                            r_eq = p/(2*pi) + 0.5;
                            correction = (1 - (0.5./r_eq)).^2;
                            props.Circularity = min(c .* correction, 1);
                            circ = cat(1,props.Circularity);

                            metricsResults.Circularity = circ';
                        end


                        boundingBox = {statsBW.BoundingBox};
                       
                        %Calculate Zernike Characteristics if any selected
                        if any(ismember([13, 14, 15], selectedMetrics))

                            %Preallocate for Zernike Characteristics
                            Z = zeros(1,length(boundingBox));
                            A = zeros(1,length(boundingBox));
                            Phi = zeros(1,length(boundingBox));

                            
                            bwfinalIm = bwfinalMetrics{selected};
                            for j = 1:length(boundingBox)
                                %Find zernike moments

                                p = imcrop(bwfinalIm,boundingBox{j});
                                if boundingBox{j}(3) > boundingBox{j}(4)
                                    p2 = zeros(boundingBox{j}(3)-boundingBox{j}(4),boundingBox{j}(3)+1);
                                    p = [p2;p];
                                else
                                    p2 = zeros(boundingBox{j}(4)+1,boundingBox{j}(4)-boundingBox{j}(3));
                                    p = [p2 p];
                                end
                                [Z(j),A(j),Phi(j)] = Zernikmoment(p,4,2);


                            end
                            %Assign zernicke results to results struct
                            
                            metricsResults.Z = Z;
                            metricsResults.A = A;
                            metricsResults.Phi = Phi;

                        end
                        
                        
                        %Find pixel characteristics if any selected
                        if any(ismember([16, 17, 18, 19, 20], selectedMetrics))

                            %Preallocate for Pixel Characteristics

                            meanTotalPixels = zeros(1,length(boundingBox));
                            stdTotalPixels = zeros(1,length(boundingBox));
                            skewTotalPixels = zeros(1,length(boundingBox));
                            kurtTotalPixels = zeros(1,length(boundingBox));

                            CClabel = labelmatrix(CC_temp);
                            for j = 1:length(boundingBox)


                                %Find Pixel characteristics
                                %Create mask of spheroid j

                                
                                %Find pixels of only spheroid, crop to
                                %improve speed
                                bbox = boundingBox{j};
                                imageNew = imcrop(image, bbox);
                                CCNew = imcrop(CClabel, bbox);
                                wholeSpheroidPixels = double(imageNew(CCNew == j));
                                meanTotalPixels(j) = mean(wholeSpheroidPixels);
                                stdTotalPixels(j) = std(wholeSpheroidPixels);
                                skewTotalPixels(j) = skewness(wholeSpheroidPixels);
                                kurtTotalPixels(j) = kurtosis(wholeSpheroidPixels);
                            end
                            %Assign pixel characteristic results to struct


                            metricsResults.MeanPixelIntensity = meanTotalPixels;
                            metricsResults.StdPixelIntensity = stdTotalPixels;
                            metricsResults.Kurtosis = kurtTotalPixels;
                            metricsResults.Skewness = skewTotalPixels;
                            pixelCV = stdTotalPixels./meanTotalPixels;
                            metricsResults.CVPixelIntensity = pixelCV;
                        end
                        
                        %Find Haralick features if any selected
                        if any(ismember([21, 22, 23, 24], selectedMetrics))
                            %Calculate Haralick Features
                            %initialize
                            glcmFeatures = zeros(4,CC_temp.NumObjects);
                            %for all spheroids
                            for j = 1:CC_temp.NumObjects
                                %crop image around spheroid
                                croppedIm = imcrop(image,boundingBox{j});
                                
                                %Calculate symmetric graycomatrix
                                glcm = graycomatrix(croppedIm,'Symmetric',true);
                                
                                %Calculate haralick features of glcm
                                glcmFeatures(:,j) = cell2mat(struct2cell(graycoprops(glcm)));
                            end
                            glcmCells = mat2cell(glcmFeatures,ones(1,4));
                            %Assign Haralick features to results struct
                            metricsResults.Contrast = glcmCells{1,:};
                            metricsResults.Correlation = glcmCells{2,:};
                            metricsResults.Energy = glcmCells{3,:};
                            metricsResults.Homogeneity = glcmCells{4,:};
                        end

                        if strcmp(app.AddClassificationSwitch.Value, 'Include')
                            thisCC = cc{selected};
                            contaminantTemp = zeros(1,length(CC_temp.PixelIdxList));
                            nonCircularTemp = zeros(1,length(CC_temp.PixelIdxList));
                            nonContaminantFull = app.NonContaminantBoolean{selected};
                            circularFull = app.CircularBoolean{selected};
                            nonEmptySpheroid = 0;
                            for i = 1:length(thisCC.PixelIdxList)
                                if ~isempty(thisCC.PixelIdxList{i})
                                    nonEmptySpheroid = nonEmptySpheroid + 1;
                                    contaminantTemp(nonEmptySpheroid) = ~nonContaminantFull(i);
                                    nonCircularTemp(nonEmptySpheroid) = ~circularFull(i);
                                end
                            end
                            metricsResults.Contaminant = contaminantTemp;
                            metricsResults.NonCircular = nonCircularTemp;
                        end


                        resizeFactor = app.Resize{selected};
                        micronsPerPixel = app.MicronsPerPixel;
                        %Assign selected values to a cell array, and resize
                        %metrics with units of length
                        signature = cell(25,1);
                        %Won't include bounding box in metrics results
                        for i = 1:length(metricsString)
                            metricName = metricsString{i};
                            signature{i} = metricsResults.(metricName);
                            if (strcmp(metricName, 'Area') || strcmp(metricName, 'ConvexArea'))
                                signature{i} = signature{i}./(resizeFactor^2);
                                signature{i} = signature{i}.*(micronsPerPixel^2);
                            elseif (strcmp(metricName, 'MajorAxisLength')||strcmp(metricName, 'MinorAxisLength')||strcmp(metricName, 'Perimeter')||strcmp(metricName, 'EquivDiameter'))
                                signature{i} = signature{i}./resizeFactor;
                                signature{i} = signature{i}.*micronsPerPixel;
                            elseif (strcmp(metricName, 'PerimeterToAreaRatio'))
                                
                                signature{i} = signature{i}.*resizeFactor;
                                signature{i} = signature{i}./micronsPerPixel;

                            end

                        end
                        %Remove empty cells from signature
                        signature = signature(~cellfun('isempty',signature));

                        %If exporting multiple images
                        if startInd ~= finish
                            signatureTot{index} = signature;
                        else
                            signatureTot{1} = signature;
                        end
                        
                        waitbar(selected/length(app.CC));
                    end

                end
                
                %Check answer
                if answer == 0
                    %if no answer, do nothing
                elseif contains(answer,'.mat') == 0
                    %if .xls storage (not .mat), do as follows


                    waitbar(1,h,'Please wait...exporting to excel file');

                    %initialize
                    for j = 1:length(signatureTot)
                        
                        if j == 1
                            writemode = 'replacefile';
                        else
                            writemode = 'overwritesheet';
                        end
                        
                        sheet = app.Filename{j};                     
                        
                        %write to excel file
                        topRow = [{'Spheroid #'} metricsString {'Filename'}];
                        dbl = cell2mat(signatureTot{j})';
                        signatureTot

                        
                        if ~isempty(signatureTot{j})
                            filenameCell = repmat({sheet}, 1, length(signatureTot{j}{1}))';
                            numbers = [num2cell((1:length(signatureTot{j}{1}))'), num2cell(dbl), filenameCell];
                            
                            matrix = [topRow;numbers];
                            
                            
                            %Name sheets based on image name, but can only take
                            %30 characters, so catch error and adjust sheet
                            %name
                            %Start each sheet with index to prevent repeats
                        else
                            matrix = topRow;
                        end
                        sheet = strcat(num2str(j), '_', sheet);
                        if length(sheet) > 31
                            sheet = sheet(1:31);
                        end
                        %replace invalid characters
                        sheet = regexprep(sheet,'[\\\/\[\]*?:]','_');


                        try
                            try
                                writecell(matrix,char(strcat(PathName,answer)), 'Sheet', sheet, 'WriteMode', writemode);
                            catch                            
                                
                                writecell(matrix,char(strcat(PathName,answer)), 'Sheet', j, 'WriteMode', writemode);
    
                                
                            end
                        catch
                            mb1 = errordlg('The Excel file you choose to write to may be open. Please close it and attempt the export again');
                            t = timer;
                            t.StartDelay = 2;
                            t.TimerFcn = @(~,~) delete(mb1);
                            start(t)
                            delete(h)
                            return;
                        end


                        waitbar(j/length(signatureTot))
                    end

                    %Display message box
                    delete(h);
                    mb = msgbox('Excel export successful!');
                    t = timer;
                    t.StartDelay = 2;
                    t.TimerFcn = @(~,~) delete(mb);
                    start(t)


                else
                    
                    %identify status of metric selections

                    %If one image
                    if startInd == finish
                        indices = [startInd];
                         
                        %%Find values to store for selected image (any app
                        %%component that could be called on in a function
                        %%without being initialized)
                        signatureStruct = cell2struct(signature,metricsString,1);

                    elseif app.MultiExporting
                        signatureTrimmed = signatureTot;
                        signatureStruct = cell(1,length(signatureTrimmed));

                        %Make signature struct to store 
                        for k = 1:length(signatureTot)


                            signatureStruct{k} = cell2struct(signatureTot{k},metricsString,1);
                        end
                        indices = app.ImagesToExport;


                    else

                        signatureTrimmed = signatureTot;
                        signatureStruct = cell(1,length(signatureTrimmed));

                        %Make signature struct to store 
                        for k = 1:length(signatureTot)
                            
                            signatureStruct{k} = cell2struct(signatureTot{k},metricsString,1);
                        end
                        indices = 1:length(app.BulkI);


%                         
                    end
                    
                    delete(h);
                   
                    saveName = char(strcat(PathName,answer));
                    saveMatFile(app, indices, saveName, signatureStruct)
 
                 end
            

        end

        function saveMatFile(app, indices, saveName, signatureStruct)
            mb = msgbox('Please wait... storing variables...');
            filename = app.Filename(indices);
            baseName = app.Basename(indices);
         

            bulkI = app.BulkI(indices);
            cc = app.CC(indices);
         
            ccDeleted = app.CcDeleted(indices);
            centersDeleted = app.CentersDeleted(indices);
            
            centers = app.Centers(indices);
            
            intensityThresh = app.IntensityThresh(indices);
            windowSize = app.WindowSize(indices);
            sizeThresh = app.SizeThresh(indices);

           
            bw_nowater = app.Bw_nowater(indices);
            beenSplit = app.BeenSplit(indices);
            spheroids_order = app.Spheroids_order(indices);
            checkWater = app.CheckWater(indices);
            checkOOF = app.CheckOOF(indices);
            checkDIC = app.CheckDIC(indices);
            checkEdge = app.CheckEdge(indices);
            edgeIntensity = app.EdgeIntensity(indices);
            additionalParameters = app.AdditionalParameters(indices);
            
            
            minimumCircularity = app.MinimumCircularity(indices);
            contaminantIntensity = app.ContaminantIntensity(indices);
            
            micronsPerPixel = app.MicronsPerPixel;
            resize = app.Resize(indices);
            nonCircularCC = app.NonCircularCC(indices);
            circularBoolean = app.CircularBoolean(indices);
            nonContaminantBoolean = app.NonContaminantBoolean(indices);
            contaminantCC = app.ContaminantCC(indices);
            

            hiddenString = {'filename', 'bulkI',...
                'intensityThresh', 'CC','ccDeleted','centersDeleted',...
                'centers','windowSize', 'sizeThresh','baseName',...
                'bw_nowater','spheroids_order','checkWater',...
                'checkOOF','checkDIC', 'minimumCircularity', 'contaminantIntensity','micronsPerPixel','resize',...
                'nonCircularCC', 'circularBoolean', 'contaminantCC',...
                'nonContaminantBoolean', 'checkEdge', 'edgeIntensity',...
                'beenSplit', 'additionalParameters'};
            
    
            hiddenCell = [{filename}, {bulkI},...
                {intensityThresh}, {cc}, {ccDeleted}, {centersDeleted},...
                {centers}, {windowSize}, {sizeThresh}, {baseName},...
                {bw_nowater}, {spheroids_order}, {checkWater},...
                {checkOOF}, {checkDIC}, {minimumCircularity}, {contaminantIntensity}, {micronsPerPixel}, {resize},...
                {nonCircularCC}, {circularBoolean}, {contaminantCC},...
                {nonContaminantBoolean}, {checkEdge}, {edgeIntensity},...
                {beenSplit}, {additionalParameters}]';


            hiddenProps = cell2struct(hiddenCell,hiddenString,1);
            s = whos('hiddenProps');
            
            bytesInGB = s.bytes/1e9;
            if s.bytes > 2e9
                delete(mb)
                baseSaveName = erase(saveName, '.mat');
                recommendedGroups = ceil(s.bytes/1.5e9);
                answer = uiconfirm(app.UIFigure, {sprintf(['Your saved data is %.1f GB, but the maximum size for typical .mat files is 2 GB\n' ...
                     'Would you like to automatically split into %d files?\n' ...
                     'If you prefer to split up images for export on your own, please select "No", and use the "Select Images to Export" option.\n' ...
                     'If you select "No", your data will not be saved so do not clear your workspace!\n'...
                     'You may also save data in a v7.3 .mat file, which will result in slow loading times. To do this, select "Save v7.3"'
                     ], bytesInGB, recommendedGroups)
                
                }, ...
                'File Export Options', ...
                'Options', {'Yes', 'Save v7.3', 'No'}, 'DefaultOption', 'Yes', 'CancelOption', 'No');
               %Split up mat files over 2 GB by dividing indices
                if strcmp(answer, 'Yes')
                    groupingInd = 0:recommendedGroups;
                    groupingInd = ceil(groupingInd*length(indices)/recommendedGroups);
                    groupingInd(1) = 1;
                    
                    for group = 1:recommendedGroups
                        saveNameGroup = strcat(baseSaveName,'_', num2str(group), '.mat');
                        groupIndices = groupingInd(group):groupingInd(group+1)-1;
                        saveMatFile(app, indices(groupIndices), saveNameGroup, signatureStruct(groupIndices))
                    end
    
                elseif strcmp(answer, 'Save v7.3')
                    save(saveName,'hiddenProps','signatureStruct', '-v7.3');             
                    delete(mb)
                    mb = msgbox('Signature export successful!');
                    t = timer;
                    t.StartDelay = 2;
                    t.TimerFcn = @(~,~) delete(mb);
                    start(t)

                else

                    delete(mb)
                    mb = msgbox('Export Canceled');
                    t = timer;
                    t.StartDelay = 2;
                    t.TimerFcn = @(~,~) delete(mb);
                    start(t)
    
                end
    
            else 


                save(saveName,'hiddenProps','signatureStruct');
                
                
                delete(mb)
                mb = msgbox('Signature export successful!');
                t = timer;
                t.StartDelay = 2;
                t.TimerFcn = @(~,~) delete(mb);
                start(t)
            end


        end


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

%%%%%%%%%%%%%%%%%%User Interactions%%%%%%%%%%%%%%%%%%%%%%%


        %%Called when raw image axes is selected
        %Used for variety of tools
        function rawImageButtonDownFunction(app, event)
            selected = app.ImageSelectList.Value;
            if app.RawImageAxesEnabled
                if ~isempty(app.ImgMasked)


                    %Check if Spheroid Removal Tool is checked
                    %Selectes region of image for spheroid removal
                    if app.RemoveCheckBox.Value == 1 || app.CombinecCheckBox.Value == 1
                        if app.RemoveCheckBox.Value == 1
                            color = app.RemoveColor;
                        else
                            color = app.CombineColor;
                        end
                        selected = app.ImageSelectList.Value;
                        %Adjust marker size depending on image size
                        markerSize = size(app.BulkI{selected});
                        markerSize = markerSize(1)/60;
                        if app.FirstRemoveCombineSelected == false

                            %Load coordinates of click
                            [coords] = event.IntersectionPoint(1:2);
                            
                            %Create circular marker
                            app.Marker  = rectangle(app.RawImageAxes, 'Position', [(coords(1,1) - markerSize/2) (coords(1,2)-markerSize/2) markerSize markerSize], 'Curvature', [1 1], 'FaceColor', color);
                            app.FirstRemoveCombineSelected = true;
                            app.FirstSelectedCoords = coords;
                        else
                            [coords] = event.IntersectionPoint(1:2);
                            
                            app.SecondSelectedCoords = coords;
                            app.SecondRemoveCombineSelected = true;
                            %Find which coordinates correspond to which
                            %edges of rectangle
                            [left, right, top, bottom] = findRectangle(app, app.FirstSelectedCoords, app.SecondSelectedCoords);
                            position = [left, top, (right - left), (bottom - top)];
                            %If second corner already selected, remove it
                            if app.RectangleRemoveCombineMarker ~= 0
                                delete(app.RectangleRemoveCombineMarker)

                            end
                            app.RectangleRemoveCombineMarker = rectangle(app.RawImageAxes, 'Position', position , 'EdgeColor', color);

                        end
                        

                    %Check if individual split check box is selected
                    %ALlow for selection and storing of which spheroids
                    %should be split
                    elseif app.SplitSelectedSpheroidtCheckBox.Value == 1

                        prevSelected = app.SpheroidsToSplit;
                        numPrevSelected = app.SplitSpheroidsSelected;
                        [app.SpheroidsToSplit, app.SplitSpheroidsSelected] = getSelectedSpheroids(app, event, prevSelected, numPrevSelected);
                        
                  
                    %Check if individual edge correction check box is selected
                    %ALlow for selection and storing of which spheroids
                    %should be corrected

                    elseif app.IndividualEdgeCorrectiCheckBox.Value == 1
                        prevSelected = app.SpheroidsToCorrect;
                        numPrevSelected = app.EdgeSpheroidsSelected;
                        [app.SpheroidsToCorrect, app.EdgeSpheroidsSelected] = getSelectedSpheroids(app, event, prevSelected, numPrevSelected);

                    %See if crop button was pressed, allow for selecting
                    %region of crop (rectangular or circular)
                    elseif app.CroppingImage == true
                        if strcmp(app.CropPreference,'Rectangle')
                            
                            %Select first corner
                            if isnan(app.FirstCropCorner) | app.UseLastCropDimensionsCheckBox.Value == 1
                                app.FirstCropCorner = event.IntersectionPoint(1:2);
                                markerSize = size(app.BulkI{selected});
                                markerSize = markerSize(1)/60;
                                app.CornerMarker = rectangle(app.RawImageAxes, 'Position', [(app.FirstCropCorner(1,1) - markerSize/2) (app.FirstCropCorner(1,2)-markerSize/2) markerSize markerSize], 'Curvature', [1 1], 'FaceColor',[0.8500 0.3250 0.0980]);
                                
                                if ~isnan(app.MostRecentHeight) & app.UseLastCropDimensionsCheckBox.Value == 1
                                    height = app.MostRecentHeight;
                                    width = app.MostRecentWidth;
                                    if strcmp(app.FirstCornerY, 'top')
                                        top = app.FirstCropCorner(1,2);
                                    else
                                        top = app.FirstCropCorner(1,2) - height;    
                                    end
                                    if strcmp(app.FirstCornerX, 'left')
                                        left = app.FirstCropCorner(1,1);
                                    else
                                        left = app.FirstCropCorner(1,1) - width;    
                                    end
                                    
                                    position = [left, top, width, height];
                                    if app.RectangleMarker ~= 0
                                        delete(app.RectangleMarker)
                                    end
                                    if app.CornerMarker~=0
                                       delete(app.CornerMarker)
                                    end
                                    app.RectangleMarker = rectangle(app.RawImageAxes, 'Position', position , 'EdgeColor',[0.8500 0.3250 0.0980]);
                                    app.CropDimensionsLabel.Text = sprintf('Dimensions = %d x %d pixels', round(width), round(height));
                                end
                            %Select second corner
                            else
                                app.SecondCropCorner = event.IntersectionPoint(1:2);
                                

                                [left, right, top, bottom] = findRectangle(app, app.FirstCropCorner, app.SecondCropCorner);
                                width = right - left;
                                height = bottom - top;
                                position = [left, top, width, height];
                                
                                if app.RectangleMarker ~= 0
                                    delete(app.RectangleMarker)
                                end
                                app.RectangleMarker = rectangle(app.RawImageAxes, 'Position', position , 'EdgeColor',[0.8500 0.3250 0.0980]);
                                app.CropDimensionsLabel.Text = sprintf('Dimensions = %d x %d pixels', round(width), round(height));
                            end

                        elseif strcmp(app.CropPreference,'Circle')
                            %Select center
                            
                            if isnan(app.CropCenter) | app.UseLastCropDimensionsCheckBox.Value == 1
                                app.CropCenter = event.IntersectionPoint(1:2);
                                markerSize = size(app.BulkI{selected});
                                markerSize = markerSize(1)/60;
                                app.CenterMarker = rectangle(app.RawImageAxes, 'Position', [(app.CropCenter(1,1) - markerSize/2) (app.CropCenter(1,2)-markerSize/2) markerSize markerSize], 'Curvature', [1 1], 'FaceColor',[0.8500 0.3250 0.0980]);
                                if ~isnan(app.MostRecentRadius) & app.UseLastCropDimensionsCheckBox.Value == 1
                                    radius = app.MostRecentRadius;
                                    app.CropRadius = radius;
                                    
                                    if app.CircleMarker~=0
                                        delete(app.CircleMarker)
                                    end
                                    if app.CenterMarker~=0
                                        delete(app.CenterMarker)
                                    end
                                    app.CircleMarker = rectangle(app.RawImageAxes, 'Position', [app.CropCenter(1,1)-radius, app.CropCenter(1,2)-radius, 2*radius, 2*radius]  ,'Curvature', [1 1], 'EdgeColor',[0.8500 0.3250 0.0980]);
                                    app.CropDimensionsLabel.Text = sprintf('Radius = %d pixels', round(radius));
                                end

                            %Select edge and caluclate radius
                            else
                                cropEdge = event.IntersectionPoint(1:2);
                                centerX = app.CropCenter(1,1);
                                centerY = app.CropCenter(1,2);
                                edgeX = cropEdge(1,1);
                                edgeY = cropEdge(1,2);
                                radius = sqrt((centerX-edgeX)^2 + (centerY-edgeY)^2);
                                
                                app.CropRadius = radius;
                                if app.CircleMarker~=0
                                    delete(app.CircleMarker)
                                end
                                app.CircleMarker = rectangle(app.RawImageAxes, 'Position', [centerX-radius, centerY-radius, 2*radius, 2*radius]  ,'Curvature', [1 1], 'EdgeColor',[0.8500 0.3250 0.0980]);
                                app.CropDimensionsLabel.Text = sprintf('Radius = %d pixels', round(radius));
                            end

                        end
                    %Check if one of the reclassification drop down items
                    %has been selected, and allow for selecting which items
                    %to reclassify
                    elseif ~strcmp(app.ClassificationDropDown.Value, 'None')
                        
                        
                        %If 'make spheroid' selected, allow for selecting
                        %either contaminant or non-circular items to be
                        %classified as regular spheroids
                        if strcmp(app.ClassificationDropDown.Value, 'Spheroid')


                            prevSelected = app.SpheroidsToClassify;
                            numPrevSelected = app.ClassifySpheroidsSelected;
                            [app.SpheroidsToClassify, app.ClassifySpheroidsSelected] = getSelectedSpheroids(app, event, prevSelected, numPrevSelected);
                        %Check if spheroids are being classified as eother
                        %contaminant or non-circular
                        elseif strcmp(app.ClassificationDropDown.Value, 'Contaminant') || strcmp(app.ClassificationDropDown.Value, 'NonCircular')
                            prevSelected = app.SpheroidsToClassify;
                            numPrevSelected = app.ClassifySpheroidsSelected;
                            [app.SpheroidsToClassify, app.ClassifySpheroidsSelected] = getSelectedSpheroids(app, event, prevSelected, numPrevSelected);


                        end

                    end
                end
            end

        end


        %Used to label spheroids with number when clicked in colored axes
        function coloredAxesButtonDownFcn(app, event)
            if app.ShowIDsSwitch.Value == 0
                if app.ColoredSegmentationAxesEnabled
                    selected = app.ImageSelectList.Value;
                    centers_display = app.Centers{selected};
                    
    
                    spherNum = 0;
                    [coords] = event.IntersectionPoint(1:2);
                    cc = app.CC{selected};
                    for i = 1:length(app.CC{selected}.PixelIdxList)
                        %%acount for removed spheroids
                        if (~isnan(centers_display(i)) && ~isempty(app.CC{selected}.PixelIdxList))
                            spherNum = spherNum + 1;
                            % find if selected point is inside of spheroid
                            if any(cc.PixelIdxList{i}==sub2ind(size(app.ColoredLabels{selected}),round(coords(1,2)),round(coords(1,1))))
    
                                text(app.ColoredSegmentationAxes, centers_display(i,1)-5, centers_display(i,2), num2str(spherNum), 'color', 'w', 'FontWeight', 'bold');
                                break
                                
                            end
                        end
                    end
                end
            end

        end


       %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

       %%%%%%%%%%%%%%%%%%%%%% Tools %%%%%%%%%%%%%%%%%%%%

        %%Function called when enter is pressed to actually remove
        %%spheroids
        function removeSpheroid(app)
            
            %Load selected image
            
            selected = app.ImageSelectList.Value;

            %determine edges of remove region
            [leftCoord, rightCoord, topCoord, bottomCoord] = findRectangle(app, app.FirstSelectedCoords, app.SecondSelectedCoords);
            if leftCoord == rightCoord || topCoord == bottomCoord
                return
            end
          
            %Initialize/pre-allocate
            centers = app.Centers{selected};
            
            circularBoolean = app.CircularBoolean{selected};
            nonCircCC = app.NonCircularCC{selected};
            nonContaminantBoolean = app.NonContaminantBoolean{selected};
            contaminantCC = app.ContaminantCC{selected};

           
            image = app.BulkI{selected};


            cc = app.CC{selected};


            %Check if current images has not already had spheroids deleted

            [ccDeleted, centersDeleted] = preallocateDeleted(app);


            %indicates in loop if organoids were deleted, so it
            %won't restore them
            deleted = false;


            %Search through centers
            for i = 1:size(centers,1)
                [rows,columns] = ind2sub(size(image), cc.PixelIdxList{i});
                
                %If click is within spheroid's pixels
                inRegionCol = (columns >= leftCoord & columns <= rightCoord);
                inRegionRow = (rows <= bottomCoord & rows >= topCoord);

                if any(inRegionCol & inRegionRow)
                    %if find(rows <= bottomCoord & rows >= topCoord,1)
                        deleted = true;
                       
                        %store spheroid's CC data in ccDeleted
                        ccDeleted{i} = cc.PixelIdxList{i};


                        %Remove spheroid's CC data
                        cc.PixelIdxList{i} = [];
                        %store spheroid's center data in centersDeleted
                        centersDeleted(i,:) = centers(i,:);


                        %remove center from non circular  outline
                        nonCircCC.PixelIdxList{i} = [];
                        contaminantCC.PixelIdxList{i} = [];


                        %remove spheroid's center  data
                        centers(i,:) = NaN;
                        

                    %end
                end
            end

            %Check if restoring spheorids, only if no spheroids deleted
            if ~deleted
                
                %totalPixels = cell2mat(cc.PixelIdxList');                
                for i = 1:size(centers,1)
                    [rows,columns] = ind2sub(size(image), ccDeleted{i});
                
                %If click is within spheroid's pixels
                    inRegionCol = (columns >= leftCoord & columns <= rightCoord);
                    inRegionRow = (rows <= bottomCoord & rows >= topCoord);
                    
                    if any(inRegionCol & inRegionRow)

                        %Restore CC data from ccDeleted
                        cc.PixelIdxList{i} = ccDeleted{i};
                        

                        %Restore centers 
                        centers(i,:) = centersDeleted(i,:);    
                        %Restore in noncircular or contaminant CC
                        if circularBoolean(i) == 0
                            nonCircCC.PixelIdxList{i} = ccDeleted{i};
                        end

                        if nonContaminantBoolean(i) == 0
                            contaminantCC.PixelIdxList{i} = ccDeleted{i};
                        end 
                        %Remove restored data from centersDeleted and ccDeleted
                        centersDeleted(i,:) = NaN;
                        ccDeleted{i} = [];


                    end
                end
            end


             %Store all data in app
            app.CcDeleted{selected} = ccDeleted;
            app.CentersDeleted{selected} = centersDeleted;            
            app.CC{selected} = cc;
            app.Centers{selected} = centers;
            app.NonCircularCC{selected} = nonCircCC;
            app.CircularBoolean{selected} = circularBoolean;
            app.ContaminantCC{selected} = contaminantCC;
            app.NonContaminantBoolean{selected} = nonContaminantBoolean;

            delete(app.Marker);
            delete(app.RectangleRemoveCombineMarker);
            app.Marker = 0;
            app.RectangleRemoveCombineMarker = 0;
            app.FirstRemoveCombineSelected = false;
            app.SecondRemoveCombineSelected = false;
            app.FirstSelectedCoords = NaN;
            app.SecondSelectedCoords = NaN;

            redisplayWithoutChangeToSpheroidData(app, cc, selected)


        end

        %Combine spheroids, called when spheroid selected for combining

        function combineSpheroids(app, event)
           
            %Determine selected image
            selected = app.ImageSelectList.Value;
            
%             %Determine coordinates of click
            getPreviousVersion(app, selected)
            %Initiailize/pre-allocated
            cc = app.CC{selected};
            centers = app.Centers{selected};
            

            image = app.BulkI{selected};
            %imgMasked = app.ImgMasked{selected};
            spheroidsToCombine = [];
            [leftCoord, rightCoord, topCoord, bottomCoord] = findRectangle(app, app.FirstSelectedCoords, app.SecondSelectedCoords);

            for i = 1:size(centers,1)
                [rows,columns] = ind2sub(size(image), cc.PixelIdxList{i});
                
                %If click is within spheroid's pixels
                inRegionCol = (columns >= leftCoord & columns <= rightCoord);
                inRegionRow = (rows <= bottomCoord & rows >= topCoord);

                if any(inRegionCol & inRegionRow)
                    spheroidsToCombine(end+1) = i;
                end
            end
            
            [tempCCDeleted, tempcentersDeleted] = preallocateDeleted(app);
            %Initialize spheroid2, spheroid1
            if ~isempty(spheroidsToCombine)
                spheroid1 = spheroidsToCombine(1);
    
                CClabelOrig = labelmatrix(cc);


                bwfinal1 = logical(CClabelOrig);
                %bwfinal2 = bwfinalOrig;
               %Create BWimage that only contains selected
               %spheroids
                bwfinal1(~ismember(CClabelOrig,spheroidsToCombine)) = 0;
                
                %bwfinal2(CClabelOrig ~= spheroid2) = 0;
                %Done making regions, will use later
    
    
                %First, add pixels of 2 to pixels of 1 and remove
                %pixels of 2
    %                         CClabelTemp = labelmatrix(cc);
    % 
    %                         %Label pixels of spheroid2 as spheroid1
    %                         CClabelTemp(cc.PixelIdxList{spheroid2}) = spheroid1;
                %Add pixels of spheroid2 to CC of spheroid1
                tempCC = cc;
                combined = [];
                for spher = 1:length(spheroidsToCombine)
                    currSpher = spheroidsToCombine(spher);
                    combined = [combined tempCC.PixelIdxList{currSpher}'];
                    tempCCDeleted{currSpher} = tempCC.PixelIdxList{currSpher};                        
                    %store spheroid's center data in centersDeleted
                    tempcentersDeleted(currSpher,:) = centers(currSpher,:);                      
                    %remove spheroid's center  data
                    
                    tempCC.PixelIdxList{currSpher} = [];
                
                end
                
                tempCC.PixelIdxList{spheroid1} = unique(combined)';
                
                %Remove former pixels of spheroid2 from CC
                %store spheroid's CC data in ccDeleted to use if
                %combine is reverted


                %Restore wastershed gap between spheroids (actually
                %connect the regions)


                stats = regionprops(tempCC, 'BoundingBox');
                bbox = {stats.BoundingBox};
                
                bboxIndiv = bbox{spheroid1};
                leftColumn = round(bboxIndiv(1) - 1);
                topRow = round(bboxIndiv(2) - 1);
                rightColumn = round(leftColumn)  + bboxIndiv(3) + 2;
                bottomRow = round(topRow)  + bboxIndiv(4) + 2;
                
                region1 = bwfinal1(topRow:bottomRow, leftColumn:rightColumn);
                
    
                %Close region to rejoin spheroids if near each
                %other
               
                region = imclose(region1, ones(app.CombineCloseSizeEditField.Value));
                region = imfill(region, 'holes');


                regionCC = bwconncomp(region);
                 
                if regionCC.NumObjects == 1
                    %Get pixels of new full spheroid (with gap filled)
                    mockPixels = zeros(size(CClabelOrig));
                    mockPixels(topRow:bottomRow, leftColumn:rightColumn) = region;
                    tempCC.PixelIdxList{spheroid1} = find(mockPixels);
    
                    app.CcDeleted{selected} = tempCCDeleted;
                    app.CentersDeleted{selected} = tempcentersDeleted;
                    cc = tempCC;
                    app.CC{selected} = cc;
                end
                necessaryData = getNecessaryData(app, selected);
    
    
                outputs = redisplayWithChangeToSpheroidData(app, necessaryData, 0);
                

                app.ImgMasked{selected} = outputs.ImgMasked;
                app.NonCircularCC{selected} = outputs.NonCircularCC;
                app.ContaminantCC{selected} = outputs.ContaminantCC;
                app.NonContaminantBoolean{selected} = outputs.NonContaminantBoolean;
                app.CircularBoolean{selected} = outputs.CircularBoolean;
                app.ColoredLabels{selected} = outputs.ColoredLabels;
                app.Centers{selected} = outputs.Centers;
                app.BoundingBox{selected} = outputs.BoundingBox;
                app.Spheroids_order{selected} = outputs.Spheroids_order;
                app.Bwfinal{selected} = outputs.Bwfinal;
                displayColoredSegmentation(app, selected)
                displaySpheroidZoom(app,selected);
                displayRawImageWithOverlay(app, selected)

            else
                displayRawImageWithOverlay(app,selected)
            end
            delete(app.Marker);
            delete(app.RectangleRemoveCombineMarker);
            app.Marker = 0;
            app.RectangleRemoveCombineMarker = 0;
            app.FirstRemoveCombineSelected = false;
            app.SecondRemoveCombineSelected = false;
            app.FirstSelectedCoords = NaN;
            app.SecondSelectedCoords = NaN;


        end


        %Called when enter is pressed after selected spheroids to split
        function splitSpheroids(app)
            selected = app.ImageSelectList.Value;
            additionalParameters = struct;
            additionalParameters.watershedFilterSigma = app.WatershedFilterSigma;
            additionalParameters.numberWatershedIterations = app.NumberWatershedIterations;
            additionalParameters.imageClosingDegree = app.ImageClosingDegree;
            additionalParameters.imageReconstructionRadius = app.ImageReconstructionRadius;
            additionalParameters.windowSizeJump = app.WindowSizeJump;
            additionalParameters.useReconstructedForGradient = app.UseReconstructedForGradient;
            additionalParameters.clearBorderSplit = app.ClearBorderSplit;
            additionalParameters.useInverted = app.UseInverted;
            additionalParameters.edgePreference = app.EdgePreference;            
            app.AdditionalParameters{selected} = additionalParameters;


            stats = regionprops(app.CC{selected}, 'BoundingBox');
            bbox = {stats.BoundingBox};

%             %Get original bw segment before splitting


            cc = app.CC{selected};
         
            CClabel = labelmatrix(cc);
            
            
            sizeThresh = app.SizeThresh{selected};
            sel = app.SpheroidsToSplit;

            %Iterate through the indices of selected spheroids
            for spher = 1:app.SplitSpheroidsSelected
                sel(spher)
                mockCClabel = CClabel;
                mockCClabel(CClabel ~= sel(spher)) = 0;
                mockCClabel = logical(mockCClabel);
                %choose region to apply watershed on and crop
                
                bboxIndiv = bbox{sel(spher)};
                leftColumn = round(bboxIndiv(1) - 2);
                if leftColumn < 1
                    leftColumn = 1;
                end
                topRow = round(bboxIndiv(2) - 2);
                if topRow < 1
                    topRow = 1;
                end
                rightColumn = round(leftColumn)  + bboxIndiv(3) + 4;
                if rightColumn > size(mockCClabel, 2)
                    rightColumn = size(mockCClabel, 2);
                end
                
                bottomRow = round(topRow)  + bboxIndiv(4) + 4;
                size(mockCClabel, 1)
                if bottomRow > size(mockCClabel, 1)
                    bottomRow = size(bottomRow, 1);
                end
                
                
                region = mockCClabel(topRow:bottomRow, leftColumn:rightColumn);
                bw_nobord_region = imclearborder(region);
                
                bwfinalSpher = watershedSplit(app, bw_nobord_region, app.IndivSplitBlurringEditField.Value, app.NumberWatershedIterations, sizeThresh);


                mask = zeros(size(CClabel));
                %insert new watershed into total bwimage
                mask(topRow:bottomRow, leftColumn:rightColumn) = bwfinalSpher;
                mockCC = bwconncomp(mask);
                for newSpher = 1:length(mockCC.PixelIdxList)
                    newPixels = mockCC.PixelIdxList{newSpher};
                    if newSpher == 1
                        cc.PixelIdxList{sel(spher)} = newPixels;
                      
                    else
                        cc.PixelIdxList{end + 1} = newPixels;
                       
                        cc.NumObjects = cc.NumObjects + 1;
                    
                    end
                    
                end
                app.BeenSplit{selected} = true;
            end


            app.CC{selected} = cc;
            newCCLabel = labelmatrix(cc);
            app.Bwfinal{selected} = logical(newCCLabel);
           
            
            app.SplitSpheroidsSelected = 0;
            app.SpheroidsToSplit = [];
            necessaryData = getNecessaryData(app, selected);

             outputs = redisplayWithChangeToSpheroidData(app, necessaryData, 0);
            

            app.ImgMasked{selected} = outputs.ImgMasked;
            app.NonCircularCC{selected} = outputs.NonCircularCC;
            app.ContaminantCC{selected} = outputs.ContaminantCC;
            app.NonContaminantBoolean{selected} = outputs.NonContaminantBoolean;
            app.CircularBoolean{selected} = outputs.CircularBoolean;
            app.ColoredLabels{selected} = outputs.ColoredLabels;
            app.Centers{selected} = outputs.Centers;
            app.BoundingBox{selected} = outputs.BoundingBox;
            app.Spheroids_order{selected} = outputs.Spheroids_order;
            app.Bwfinal{selected} = outputs.Bwfinal;
            displayColoredSegmentation(app, selected)
            displaySpheroidZoom(app,selected);
            displayRawImageWithOverlay(app, selected)


        end
%Used for individual edge correction
        function correctSpheroids(app)
            selected = app.ImageSelectList.Value;

            additionalParameters = struct;
            additionalParameters.watershedFilterSigma = app.WatershedFilterSigma;
            additionalParameters.numberWatershedIterations = app.NumberWatershedIterations;
            additionalParameters.imageClosingDegree = app.ImageClosingDegree;
            additionalParameters.imageReconstructionRadius = app.ImageReconstructionRadius;
            additionalParameters.windowSizeJump = app.WindowSizeJump;
            additionalParameters.useReconstructedForGradient = app.UseReconstructedForGradient;
            additionalParameters.clearBorderSplit = app.ClearBorderSplit;
            additionalParameters.useInverted = app.UseInverted;
            additionalParameters.edgePreference = app.EdgePreference;            
            app.AdditionalParameters{selected} = additionalParameters;
            
            
            origI = app.BulkI{selected};
            %%Create segment to edge correct on, draw from to add to
            %%original segmenation           

            %Create structure element
            se = strel('disk', app.ImageReconstructionRadius);
            Iobrcbr = prepareImage(app, origI, se, app.UseInverted);


            sizeThresh = app.SizeThresh{selected};
            edgeIntensity = app.IndividualEdgeIntensityEditField.Value;

                 %Apply edge correction by using gradient to find edges
            bwfinal = app.Bwfinal{selected};
            
            if strcmp(app.IndividualEdgeCorrectPreference.Value, 'Gradient Only')

               bw_holes = gradientOnlyEdgeCorrect(app, bwfinal, edgeIntensity, Iobrcbr, origI, app.ImageReconstructionRadius, app.UseReconstructedForGradient, app.UseInverted, sizeThresh, selected);


            elseif strcmp(app.IndividualEdgeCorrectPreference.Value, 'Gradient Preserve Boundary')

                bw_holes = gradientPreserveBoundaryEdgeCorrect(app, bwfinal, edgeIntensity, Iobrcbr, origI, app.ImageReconstructionRadius, app.UseReconstructedForGradient, app.UseInverted, sizeThresh, selected);

            elseif strcmp(app.IndividualEdgeCorrectPreference.Value, 'Gradient + Watershed')

                bw_holes = watershedEdgeCorrect(app, bwfinal, origI, app.ImageReconstructionRadius, app.UseReconstructedForGradient, app.UseInverted, sizeThresh, selected);

            end


            bw_holes = logical(bw_holes);    
            
            if strcmp(app.IndividualEdgeCorrectPreference.Value, 'Gradient Only')
                %Use watershed on corrected edge to limit overlap in
                %spheroids
                

                bwfinal = watershedSplit(app, bw_holes, 5, 1, sizeThresh);
            else
                bwfinal = imclearborder(bw_holes);
                bwfinal = logical(bwfinal);
            end


            %Add color labels
            CC_Edge = bwconncomp(bwfinal);
            CClabel_Edge = labelmatrix(CC_Edge);           


            CC_noEdge = app.CC{selected};


            sel = app.SpheroidsToCorrect;
            
            for spher = 1:app.EdgeSpheroidsSelected
               
                %find which spheroid in the edge corrected CC most contains
                %the spheroid without edge correction
                location = CClabel_Edge(CC_noEdge.PixelIdxList{sel(spher)});
                spheroid_Edge = round(mode(location(location~=0)));
                %Add pixels from edge corrected spheroid to original
                %spheroid
                if ~isnan(spheroid_Edge) & spheroid_Edge ~= 0
                    
                    
                    newPixels = CC_Edge.PixelIdxList{spheroid_Edge};
                    totalPixels = [newPixels; CC_noEdge.PixelIdxList{sel(spher)}];
                    CC_noEdge.PixelIdxList{sel(spher)} = unique(totalPixels);

                end
            end

            statsBW = regionprops(CC_noEdge, "Centroid");         

            centersStat = [statsBW.Centroid];
            centers = reshape(centersStat, 2, length(statsBW))';

            %Preallocate for storing deletions of spheroids
            %Check if current images has not already had spheroids deleted

%             [ccDeleted, centersDeleted] = preallocateDeleted(app);
             nonCircCC = app.NonCircularCC{selected};
             contaminantCC = app.ContaminantCC{selected};
            ccDeleted = app.CcDeleted{selected};
            centersDeleted = app.CentersDeleted{selected};
            for spher = 1:app.EdgeSpheroidsSelected

                    %If new CC for corrected spheroid with pixels added
                    %contains over 80% of the pixels for another spheroid,
                    %combine the two and then remove that spheroid
                    %(allows for combining of spheroids when
                    %edge correcting engulfs smaller spheroids
                    %Stores spheroids as removed so that they can be
                    %restored)
                    for i = 1:size(centers,1)


                        %if any(CC_noEdge.PixelIdxList{sel(spher)}==round(median(CC_noEdge.PixelIdxList{i})))
                        
                        sharedPixels = ismember(CC_noEdge.PixelIdxList{i},CC_noEdge.PixelIdxList{sel(spher)});
                        numberSharedPixels = nnz(sharedPixels);
                        totalPixelsOtherSpheroid = length(CC_noEdge.PixelIdxList{i});
                        if centersDeleted ~= 0
                            sharedPixelsRemoved = ismember(ccDeleted{i}, CC_noEdge.PixelIdxList{sel(spher)});
                        else
                            sharedPixelsRemoved = [];
                        end

                        if numberSharedPixels/ totalPixelsOtherSpheroid > 0.8
                            
                            if i ~= sel(spher)
                                %Combine spheroids
                                combined = [CC_noEdge.PixelIdxList{i};CC_noEdge.PixelIdxList{sel(spher)}];
                                CC_noEdge.PixelIdxList{sel(spher)} = unique(combined);
                                 %store spheroid's CC data in ccDeleted
                                %ccDeleted{i} = CC_noEdge.PixelIdxList{i};
        
                                %Remove spheroid's CC data
                                CC_noEdge.PixelIdxList{i} = [];
                                %store spheroid's center data in centersDeleted
                                %centersDeleted(i,:) = centers(i,:);
        
                                %remove center from non circular  outline
                                nonCircCC.PixelIdxList{i} = [];
                                contaminantCC.PixelIdxList{i} = [];
                                %remove spheroid's center  data
                                centers(i,:) = NaN;

                                
                            end
                        %Otherwise, get rid of shared pixels from the new corrected spheroid so that there isn't overlap 
                        elseif any(ismember(CC_noEdge.PixelIdxList{i},CC_noEdge.PixelIdxList{sel(spher)}))
                            labelTemp = zeros(size(bwfinal));
                            labelTemp(CC_noEdge.PixelIdxList{i}) = 1;
                            labelTemp = imdilate(labelTemp, ones(3));
                            
                            otherOrgPixels = find(labelTemp);
                            notUniquePixels = ismember(CC_noEdge.PixelIdxList{sel(spher)},otherOrgPixels);
                            uniquePixels = CC_noEdge.PixelIdxList{sel(spher)}(~notUniquePixels);

                            CC_noEdge.PixelIdxList{sel(spher)} = uniquePixels;


                        elseif nnz(sharedPixelsRemoved) > 0
                            ccDeleted{i} = [];
                            centersDeleted(i,:) = NaN;
                         
                        end
                    end
                
            end


            bwMock = labelmatrix(CC_noEdge);
            
            %bwMock(bwMock > 0) = 1
            bwfinal = logical(bwMock);
            
            app.Bwfinal{selected} = bwfinal;

            cc = CC_noEdge;
            app.CC{selected} = cc;


            %app.CcDeleted{selected} = ccDeleted;
            %app.CentersDeleted{selected} = centersDeleted;
            app.NonCircularCC{selected} = nonCircCC;
            app.ContaminantCC{selected} = contaminantCC;
            app.CcDeleted{selected} = ccDeleted;
            app.CentersDeleted{selected} = centersDeleted;
            app.EdgeSpheroidsSelected = 0;
            app.SpheroidsToCorrect = [];
            necessaryData = getNecessaryData(app, selected);
            
             outputs = redisplayWithChangeToSpheroidData(app, necessaryData, 0);
                

            app.ImgMasked{selected} = outputs.ImgMasked;
            app.NonCircularCC{selected} = outputs.NonCircularCC;
            app.ContaminantCC{selected} = outputs.ContaminantCC;
            app.NonContaminantBoolean{selected} = outputs.NonContaminantBoolean;
            app.CircularBoolean{selected} = outputs.CircularBoolean;
            app.ColoredLabels{selected} = outputs.ColoredLabels;
            app.Centers{selected} = outputs.Centers;
            app.BoundingBox{selected} = outputs.BoundingBox;
            app.Spheroids_order{selected} = outputs.Spheroids_order;
            app.Bwfinal{selected} = outputs.Bwfinal;
            displayColoredSegmentation(app, selected)
            displaySpheroidZoom(app,selected);
            displayRawImageWithOverlay(app, selected)


        end

        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        %%%%%%%%%%%%%%% Manage Tools %%%%%%%%%%%%%


        % Used to centralize tool using process to make sure do not get
        % mixed up, stores state of enabled/ disabled objects, and the
        % disables everything
        function disenableEverything(app)

            %https://www.mathworks.com/matlabcentral/answers/1697425-is-there-an-easier-way-to-disable-all-the-buttons-in-matlab-app-designer
            h = findobj(app.UIFigure,{'Type', 'uibutton', '-or','Type', 'uinumericeditfield', '-or', 'Type', 'uimenu', '-or', 'Type', 'uicheckbox','-or', 'Type', 'uidropdown', '-or', 'Type', 'uiswitch', '-or', 'Type', 'uilistbox'});

            %Store current state of stuff
            savedStates = cell(1,length(h));
            for obj = 1:length(h)
                savedStates{obj} = h(obj).Enable;
            end
            app.Saved_states = savedStates; %keep this somewhere

            set(h,'Enable','off')
            app.ThingsDisenabled = true;
            app.RawImageAxesEnabled = false;
            app.ColoredSegmentationAxesEnabled = false;
            app.CropGrid.ColumnWidth = {'1x',0,0,0,0,0,0,0,0};
            
            app.RawGrayscaleImageLabel.Text = 'Click anywhere and press "enter" to confirm your selection(s), or "e" to exit tool usage';


        end

        %Returns things to their original status
        function reenableStuff(app)

            h= findobj(app.UIFigure,{'Type', 'uibutton', '-or','Type', 'uinumericeditfield', '-or', 'Type', 'uimenu', '-or', 'Type', 'uicheckbox','-or', 'Type', 'uidropdown', '-or', 'Type', 'uiswitch', '-or', 'Type', 'uilistbox'});
            %https://www.mathworks.com/matlabcentral/answers/1697425-is-there-an-easier-way-to-disable-all-the-buttons-in-matlab-app-designer
            
            %if ~isempty(app.Saved_states)
                for obj = 1:length(h)
    
                    h(obj).Enable = app.Saved_states{obj};
                end
            %end
            %app.Saved_states = [];
            app.RawImageAxesEnabled = true;
            app.ColoredSegmentationAxesEnabled = true;
            app.ThingsDisenabled = false;
            app.RawGrayscaleImageLabel.Text = 'Raw Grayscale Image';
            if app.ImagesMaximized
                app.CropGrid.ColumnWidth = {'1x',50,50,0,50,0,0,50,50};
                app.CropGrid.RowHeight = {'1x', '0x'};
                app.RawImageGrid.RowHeight = {20, '1x'};
                app.CropDimensionsLabel.Text = "Crop Dimensions: NA";
            else

                app.CropGrid.ColumnWidth = {'1x',50,50,0,50,0,0,50,0};
                app.CropGrid.RowHeight = {'1x', '0x'};
                app.RawImageGrid.RowHeight = {20, '1x'};
                app.CropDimensionsLabel.Text = "Crop Dimensions: NA";
            end
        end
        %in case program gets stuck
        function reenableEverything(app)
            h= findobj(app.UIFigure,{'Type', 'uibutton', '-or','Type', 'uinumericeditfield', '-or', 'Type', 'uimenu', '-or', 'Type', 'uicheckbox','-or', 'Type', 'uidropdown', '-or', 'Type', 'uiswitch', '-or', 'Type', 'uilistbox'});
            %https://www.mathworks.com/matlabcentral/answers/1697425-is-there-an-easier-way-to-disable-all-the-buttons-in-matlab-app-designer
            

            for obj = 1:length(h)

                h(obj).Enable = 1;
            end
            app.ThingsDisenabled = false;
            app.IndivSplitBlurringEditField.Enable = 0;
            app.IndividualEdgeIntensityEditField.Enable = 0;
            app.CombineCloseSizeEditField.Enable = 0;
        end

        %%Used whenever a tool is called, disneables stuff and resets
        %%values (like coordinates, booleans, indices, etc.) so that there
        %%is no carry over between uses, generally centralizes tool use
        function toolSelection(app, toolSelected)
            image = app.ImageSelectList.Value;
            %Check that image is loaded
            if isempty(app.BulkI)
                app.ToolReadyToUse = false;
                mb1 = errordlg('Please select an image file');
                t = timer;
                t.StartDelay = 2;
                t.TimerFcn = @(~,~) delete(mb1);
                start(t)


            %Check for certain tools that image is segmented
            elseif app.ColoredLabels{image} == 0 & ~strcmp(toolSelected, 'Crop') & ~strcmp(toolSelected, 'RevertImage') & ~strcmp(toolSelected, 'Brightness') & ~strcmp(toolSelected, 'Rotate') & ~strcmp(toolSelected, 'Clearing') 
                app.ToolReadyToUse = false;
                mb3 = errordlg('Please segment image');
                t = timer;
                t.StartDelay = 2;
                t.TimerFcn = @(~,~) delete(mb3);
                start(t)
            else
                %Goes back to tool callback function and says to proceed
                %with tool
                app.ToolReadyToUse = true;
                resetToolData(app)

                if strcmp(toolSelected, 'Clearing') 
                    if app.ColoredLabels{image} == 0
                        displayRawImageOnly(app, image)
                    else
                        
                        displayRawImageWithOverlay(app, image)
                    end
                    app.IndivSplitBlurringEditField.Enable = 0;
                    app.IndividualEdgeIntensityEditField.Enable = 0;
                    app.CombineCloseSizeEditField.Enable = 0;
                    
                end


                %Reset the displayed values of the tools in the app
                toolComponents = {app.RemoveCheckBox, app.CombinecCheckBox, app.SplitSelectedSpheroidtCheckBox, app.IndividualEdgeCorrectiCheckBox};
                for comp = 1:length(toolComponents)
                    toolComponents{comp}.Value = 0;
                end
                app.BrightnessButton.Text = 'Brightness';
                app.ClassificationDropDown.Value = 'None';


            end
        end

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        %%%%%%%%%%%%%%%%%%%% Undo %%%%%%%%%%%%%%%%%%%%


        function getPreviousVersion(app, selected)
            
            if ~isstruct(app.PreviousVersion{selected})
                previousVersion = struct;
                previousVersion.PreviousVersion = 0;
            else
                previousVersion = app.PreviousVersion{selected};
                temp = previousVersion;
                previousVersion.PreviousVersion = temp;

            end
            
            
            previousVersion.BulkI = app.BulkI{selected};
            

            previousVersion.WindowSize = app.WindowSize{selected};
            previousVersion.SizeThresh = app.SizeThresh{selected};
            previousVersion.IntensityThresh = app.IntensityThresh{selected};
            previousVersion.CheckWater = app.CheckWater{selected};
            previousVersion.CheckOOF = app.CheckOOF{selected};
            previousVersion.CheckDIC = app.CheckDIC{selected};
            previousVersion.CheckEdge = app.CheckEdge{selected};
            previousVersion.EdgeIntensity = app.EdgeIntensity{selected};
            previousVersion.MinimumCircularity = app.MinimumCircularity{selected};
            previousVersion.ContaminantIntensity = app.ContaminantIntensity{selected};


            previousVersion.AdditionalParameters = app.AdditionalParameters{selected};


            previousVersion.Bw_nowater = app.Bw_nowater{selected};
            previousVersion.Bwfinal = app.Bwfinal{selected};
            previousVersion.CC = app.CC{selected};  
            
            previousVersion.Centers = app.Centers{selected};
            previousVersion.Spheroids_order = app.Spheroids_order{selected};
            previousVersion.ColoredLabels = app.ColoredLabels{selected};
            
            previousVersion.ImgMasked = app.ImgMasked{selected};
            previousVersion.BoundingBox = app.BoundingBox{selected};
            previousVersion.NonCircularCC = app.NonCircularCC{selected};
            previousVersion.CircularBoolean = app.CircularBoolean{selected};
            previousVersion.ContaminantCC = app.ContaminantCC{selected};
            previousVersion.NonContaminantBoolean = app.NonContaminantBoolean{selected};


            previousVersion.CentersDeleted = app.CentersDeleted{selected};
            previousVersion.CcDeleted = app.CcDeleted{selected};

            app.PreviousVersion{selected} = previousVersion;


        end

        function undo(app, selected, showUndone)
            
            if ~isempty(app.BulkI)
               
                if isstruct(app.PreviousVersion{selected})
                  
                    previousVersion = app.PreviousVersion{selected};

                    app.PreviousVersion{selected} = previousVersion.PreviousVersion;

                    app.BulkI{selected} = previousVersion.BulkI;


                    app.WindowSize{selected} = previousVersion.WindowSize;
                    app.SizeThresh{selected} = previousVersion.SizeThresh;
                    app.IntensityThresh{selected} = previousVersion.IntensityThresh;
                    app.CheckWater{selected} = previousVersion.CheckWater;
                    app.CheckOOF{selected} = previousVersion.CheckOOF;
                    app.CheckDIC{selected} = previousVersion.CheckDIC;
                    app.CheckEdge{selected} = previousVersion.CheckEdge;
                    app.EdgeIntensity{selected} = previousVersion.EdgeIntensity;
                    app.MinimumCircularity{selected} = previousVersion.MinimumCircularity;
                    app.ContaminantIntensity{selected} = previousVersion.ContaminantIntensity;
                    app.AdditionalParameters{selected} = previousVersion.AdditionalParameters;


                    app.Bw_nowater{selected} = previousVersion.Bw_nowater;
                    app.Bwfinal{selected} = previousVersion.Bwfinal;
                    app.CC{selected} = previousVersion.CC;  
                  
                    app.Centers{selected} = previousVersion.Centers;
                    app.Spheroids_order{selected} = previousVersion.Spheroids_order;
                    app.ColoredLabels{selected} = previousVersion.ColoredLabels;
                    app.ImgMasked{selected} = previousVersion.ImgMasked;
                    app.BoundingBox{selected} = previousVersion.BoundingBox;
                    app.NonCircularCC{selected} = previousVersion.NonCircularCC;
                    app.CircularBoolean{selected} = previousVersion.CircularBoolean;
                    app.ContaminantCC{selected} = previousVersion.ContaminantCC;
                    app.NonContaminantBoolean{selected} = previousVersion.NonContaminantBoolean;


                    app.CentersDeleted{selected} = previousVersion.CentersDeleted;
                    app.CcDeleted{selected} = previousVersion.CcDeleted;
                    if showUndone == 1
                        if app.ColoredLabels{selected} == 0
                            displayRawImageOnly(app, selected)
                        else
                            setSettings(app, selected)
                            displayRawImageWithOverlay(app,selected)
                            displayColoredSegmentation(app, selected)
                            displaySpheroidZoom(app, selected)
                        end
                    end


                end
                
                    
            end


        end


        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        %%%%%%%%%%%%%%%% Helper Functions %%%%%%%%%%%%%%%%

        %Prepare the image with opening and closing for segmentation
        function Iobrcbr = prepareImage(~, origI, se, useInverted)
            if useInverted
                origI = imcomplement(origI);
            end


            %Sharpen
            K = imsharpen(origI);
            %Smooth background
            K = medfilt2(K);

            %Opening and closing by reconstruction


            %Erode and open
            Ie = imerode(K, se);
            Iobr = imreconstruct(Ie, K);
            
            %Opening-closing by reconstruction(Iobrcbr)
            Iobrd = imdilate(Iobr, se);
            Iobrcbr = imreconstruct(imcomplement(Iobrd), imcomplement(Iobr));
            Iobrcbr = imcomplement(Iobrcbr);

            %Remove border
            Iobrcbr(1:end,1) = Iobrcbr([2 2:end],2);
            Iobrcbr(1:end,end) = Iobrcbr([2 2:end],end-1);
            Iobrcbr(1,1:end) = Iobrcbr(2,[2 2:end]);
            Iobrcbr(end,1:end) = Iobrcbr(end-1,[2 2:end]);
            
        end

        %Used for tools where several individual spheroids can be selected
        % by the user clicking on them, identifies them with a marker and
        %stores them in a list of spheroids to be operated on
        function [spheroidsSelected, numSpheroidsSelected] = getSelectedSpheroids(app, event, prevSelected, numPrevSelected)
            %Determine selected image
            if app.SplitSelectedSpheroidtCheckBox.Value == 1
                color = app.SplitColor;
            elseif strcmp(app.ClassificationDropDown.Value, 'Spheroid')
                color = app.MainSegmentColor;
            elseif strcmp(app.ClassificationDropDown.Value, 'Contaminant')
                color = app.ContaminantColor;
            elseif strcmp(app.ClassificationDropDown.Value, 'NonCircular')
                color = app.NonCircColor;
            else
                color = app.EdgeCorrectColor;
            end
                        
            selected = app.ImageSelectList.Value;
            %Determine coordinates of click
            [coords] = event.IntersectionPoint(1:2);
            cc = app.CC{selected};
            centers = app.Centers{selected};
            image = app.BulkI{selected};
            markerSize = size(image);
            markerSize = markerSize(1)/60;
            
            for i = 1:size(centers, 1)
                %Check if coordinates match spheroid region
                
                    if any(cc.PixelIdxList{i}==sub2ind(size(image),round(coords(1,2)),round(coords(1,1))))
                        %Update the number of selected spheroids
                        %and the array of spheroid indices to be
                        %split
                        numPrevSelected = numPrevSelected + 1;
                        prevSelected(numPrevSelected) = i;
    
                        rectangle(app.RawImageAxes, 'Position', [(coords(1,1) - markerSize/2) (coords(1,2)-markerSize/2) markerSize markerSize], 'Curvature', [1 1], 'FaceColor', color);
    
    
                    end
                

            end

            numSpheroidsSelected = numPrevSelected;
            spheroidsSelected = prevSelected;

        end


        % Used to determined which edge is top, left, right, bottom in
        % order to display a rectangle to mark a selected region
        
        function [leftCoord, rightCoord, topCoord, bottomCoord] = findRectangle(~,firstCoord, secondCoord)
            if firstCoord(1,1) < secondCoord(1,1)
                leftCoord = firstCoord(1,1);
                rightCoord = secondCoord(1,1);
            else
                rightCoord = firstCoord(1,1);
                leftCoord = secondCoord(1,1);
            end


            if firstCoord(1,2) > secondCoord(1,2)
                bottomCoord = firstCoord(1,2);
                topCoord = secondCoord(1,2);
            else
                topCoord = firstCoord(1,2);
                bottomCoord = secondCoord(1,2);
            end


        end

        
        %Reclassify spheroid as either contaminant or non-circular
        %ClassifyCC has the CC values if it is classified as non-spheroid
        %(either noncircular or contaminant)
        %ClassifyBoolean has a value of 
        %0 if classified with the respective classfication
        function [classifyCCNew, classifyBooleanNew] = changeToNotSpheroid(app, classifyCC, classifyBoolean)
            selected = app.ImageSelectList.Value;
            cc = app.CC{selected};
            sel = app.SpheroidsToClassify;
%             if ~any(~(classifyBoolean))

            classifyCC = cc;


            for i = 1:length(cc.PixelIdxList)

                classifyCC.PixelIdxList{i} = [];

            end
    
%             end
            for spher = 1:app.ClassifySpheroidsSelected
    
                classifyBoolean(sel(spher)) = 0;
    
    
            end
            for sel = 1:length(classifyBoolean)
                if classifyBoolean(sel) == 0
                    classifyCC.PixelIdxList{sel} = cc.PixelIdxList{sel};
                end
            end
            app.SpheroidsToClassify = [];
            app.ClassifySpheroidsSelected = 0;

            classifyCCNew = classifyCC;
            classifyBooleanNew = classifyBoolean;
            
        end

        function [nonCircCCNew, circularBooleanNew, contaminantCCNew, nonContaminantBooleanNew] = changeToSpheroid(app, nonCircCC, circularBoolean, contaminantCC, nonContaminantBoolean)
            sel = app.SpheroidsToClassify;
            for spher = 1:app.ClassifySpheroidsSelected
               
                if circularBoolean(sel(spher)) == 0
                    nonCircCC.PixelIdxList{sel(spher)} = [];
                    circularBoolean(sel(spher)) = 1;
                end
                sel(spher)
                if nonContaminantBoolean(sel(spher)) == 0
                    contaminantCC.PixelIdxList{sel(spher)} = [];
                    nonContaminantBoolean(sel(spher)) = 1;
                end
            end
            %reset values
            app.SpheroidsToClassify = [];
            app.ClassifySpheroidsSelected = 0;
            nonCircCCNew = nonCircCC;
            circularBooleanNew = circularBoolean;
            contaminantCCNew = contaminantCC;
            nonContaminantBooleanNew = nonContaminantBoolean;
        end


        % Used to remove all spheroids from input CC (contaminant or
        % boolean)
        function [cc, nonCircCC, contaminantCC] = bulkRemove(app, removeCC, removeBoolean)
            
            selected = app.ImageSelectList.Value;
            getPreviousVersion(app, selected);
            cc = app.CC{selected};
            centers = app.Centers{selected};  
            nonCircCC = app.NonCircularCC{selected};
            contaminantCC = app.ContaminantCC{selected};

            %Check to see if current image has had spheroids removed, if no...

            [ccDeleted, centersDeleted] = preallocateDeleted(app);
            %Loop through remove any spheroid that is contaminant
            for i = 1:size(centers,1)
                if removeBoolean(i) == 0 && ~isempty(removeCC.PixelIdxList{i})

                    
                    %If yes,
                    %store spheroid's CC data in ccDeleted

                    ccDeleted{i} = cc.PixelIdxList{i};


                    %Remove spheroid's CC data
                    cc.PixelIdxList{i} = [];
                    %store spheroid's center  data in centersDeleted
                    centersDeleted(i,:) = centers(i,:);                                


                    %remove center from non circular  outline
                    nonCircCC.PixelIdxList{i} = [];
                    contaminantCC.PixelIdxList{i} = [];

                    %remove spheroid's center  data
                    centers(i,:) = NaN;

                end
            end
            app.CentersDeleted{selected} = centersDeleted;
            app.CcDeleted{selected} = ccDeleted;
            app.Centers{selected} = centers;
            
            
        end


        function clearSegmentProducts(app, selected)
            
            app.Bw_nowater{selected} = 0;                
            app.BeenSplit{selected} = false;
            app.Bwfinal{selected} = 0;
            app.CC{selected} = 0;
         
            app.Centers{selected} = 0;
            app.Spheroids_order{selected} = 0;
            app.ColoredLabels{selected} = 0;
            app.ImgMasked{selected} = 0;
            app.BoundingBox{selected} = 0;
            app.NonCircularCC{selected} = 0;
            app.CircularBoolean{selected} = 0;
            app.ContaminantCC{selected} = 0;
            app.NonContaminantBoolean{selected} = 0;


            %%%%%Deletions and Stuff%%%%%%
            app.CentersDeleted{selected} = 0;
            app.CcDeleted{selected} = 0;
        end


        %%%%% Helpers for post-segmentation/ tool usage process %%%%


        % Stores necessary data to app and displays data when using tools
        % that don't change individual spheroid data 
        % (remove, change classifications)
        function redisplayWithoutChangeToSpheroidData(app, cc, selected)
            %Create NEW label matrix without spheroid


            CClabel = labelmatrix(cc);
            app.ColoredLabels{selected} = label2rgb(CClabel,'hsv','k','shuffle')*0.9;
            
            bwMock = CClabel;
            bwfinal = logical(bwMock);
            
            app.Bwfinal{selected} = bwfinal;
            
            app.ImgMasked{selected} = makeImageMaskedNoDataChange(app, bwfinal, selected);
            %Create temp CC
            
            CC_temp = findCCTemp(app, cc);
            app.Centers{selected} = findCentersFromCC(app, cc);
            app.BoundingBox{selected} = findBoundingBoxFromTemp(app, CC_temp);

            %Create new spheroid order matrix
            %Watershed checkbox?
            if app.CheckWater{selected} == 1 || app.BeenSplit{selected}
                  app.Spheroids_order{selected} = orderSpheroids(app, CC_temp, app.CheckWater{selected}, app.BeenSplit{selected}, app.Bw_nowater{selected});
%                         
            else
                %Linear spheroid order
                app.Spheroids_order{selected} = 1:CC_temp.NumObjects;
            end


            if selected == app.ImageSelectList.Value
                displayColoredSegmentation(app, selected)
                displaySpheroidZoom(app,selected);
                displayRawImageWithOverlay(app, selected)
            end
        end
    
        % Stores necessary data to app and displays data when using tools
        % that change individual spheroid data (combine, correct, split...),
        % or after initial segmentation       

        %newSegment: 1 if this is being called where a new cc is
        %generated (segment or split), 0 if using original cc (combine,
        %correct)
        function outputs = redisplayWithChangeToSpheroidData(app, necessaryData, newSegment)
            cc = necessaryData.cc;
            CClabel = labelmatrix(cc);
            %Convert CClabel to bw                       
            bwfinal = logical(CClabel);            
            %app.Bwfinal{selected} = bwfinal;   
            %app.ColoredLabels{selected} = label2rgb(CClabel, 'hsv', 'k', 'shuffle')*0.9;


            CC_temp = findCCTemp(app, cc);
            centers = findCentersFromCC(app, cc);            
            bbox = findBoundingBoxFromTemp(app,CC_temp);

            outputs = makeImageMaskedChangeData(app, bwfinal, bbox, newSegment, necessaryData);
          
            outputs.Spheroids_order = orderSpheroids(app, CC_temp, necessaryData.checkWater, necessaryData.beenSplit, necessaryData.bw_nowater);
            outputs.Bwfinal = bwfinal;
            outputs.BoundingBox = bbox;
            outputs.Centers = centers;
            outputs.ColoredLabels = label2rgb(CClabel, 'hsv', 'k', 'shuffle')*0.9;
            
        end


        %%% Determine if spheroids are circular or not based on threshold,
        %%% generates and returns new image masked along with CC and
        %%% boolean for circularity

        %newSegment: 1 if this is being called where a new cc is
        %generated (segment or split), 0 if using original cc (combine,
        %correct)
        function [imgMaskedNew, nonCirc, circularBoolean, circ] = findCircularity(app, necessaryData, imgMaskedOrig, newSegment)
            
            minimumCircularity = necessaryData.minimumCircularity;
            cc = necessaryData.cc;
            
            if newSegment == 1                
                circularBoolean = ones(1, length(cc.PixelIdxList));
                %initial struct to allow for other functions to
            %operate on it

            else               
            
                circularBoolean = necessaryData.circularBoolean;
                
                circularBoolean(end+1:length(cc.PixelIdxList)) = 1;
            end
                

            %if minimumCircularity ~= 0
            nonCirc = cc;
            if minimumCircularity ~=0
                CC_temp = cc;
                CC_temp.PixelIdxList = cc.PixelIdxList(~cellfun('isempty',cc.PixelIdxList));
                CC_temp.NumObjects = length(CC_temp.PixelIdxList);
                %Highlight non-circular spheroids
                
                props = regionprops("table", CC_temp, ["Circularity" "Perimeter"]);
                c = props.Circularity;
                p = props.Perimeter;
                r_eq = p/(2*pi) + 0.5;
                correction = (1 - (0.5./r_eq)).^2;
                props.Circularity = min(c .* correction, 1);
                circ = cat(1,props.Circularity);           
                
                nonEmptySpher = 0;
                for sphere = 1:length(cc.PixelIdxList)
                    if ~isempty(cc.PixelIdxList{sphere})
                        nonEmptySpher = nonEmptySpher + 1;
                        if circ(nonEmptySpher) >= minimumCircularity
                            nonCirc.PixelIdxList{sphere} = [];
                            circularBoolean(sphere) = 1;
    
                        else
                            circularBoolean(sphere) = 0;
                        end
                    end
                end
            else
                for sphere = 1:length(cc.PixelIdxList)
                    nonCirc.PixelIdxList{sphere} = [];
                    circularBoolean(sphere) = 1;
                end
                ccTemp = findCCTemp(app, cc);
                circ = ones(length(ccTemp.PixelIdxList));
            end

                
                nonCircCCLabel = labelmatrix(nonCirc);

                nonCircPerim = bwperim(nonCircCCLabel ~= 0);
                nonCirc_perim_dil = imdilate(nonCircPerim, ones(3,3));
                imgMaskedNew = imoverlay(imgMaskedOrig,nonCirc_perim_dil,[1 1 0]);
                               
%             elseif any(~(circularBoolean))
%                 
%                 nonCircCCLabel = labelmatrix(nonCirc);
% 
%                 nonCircPerim = bwperim(nonCircCCLabel ~= 0);
%                 nonCirc_perim_dil = imdilate(nonCircPerim, ones(3,3));
%                 imgMaskedNew = imoverlay(imgMaskedOrig,nonCirc_perim_dil,[1 1 0]);
%             else
%                 imgMaskedNew = imgMaskedOrig;
%             end
            
        end


        %%% Determine if spheroids are contamiant or not based on threshold,
        %%% generates and returns new image masked along with CC and
        %%% boolean for contaminant
        function [imgMaskedNew, contaminant, nonContaminantBoolean] = findContaminant(app, necessaryData, imgMaskedOrig, boundingBox, newSegment)
            
            contaminantIntensityScale = necessaryData.contaminantIntensity;
            cc = necessaryData.cc;
            bulkI = necessaryData.bulkI;
            
            CClabel = labelmatrix(cc);
            
%             
            
            
            if newSegment == 1                
                nonContaminantBoolean = ones(1, length(cc.PixelIdxList));
                %initial struct to allow for other functions to
            %operate on it

            else               
            
                nonContaminantBoolean = necessaryData.nonContaminantBoolean;
                nonContaminantBoolean(end+1:length(cc.PixelIdxList)) = 1;
            end
                
            
            %if contaminantIntensity ~= 0

                %Copy original cc
            contaminant = cc;
            if contaminantIntensityScale ~= 0
                
                image = bulkI;
                contaminantIntensity = contaminantIntensityScale*max(image(:));
                nonEmptySpher = 0;


                for sphere = 1:length(cc.PixelIdxList)
                    if ~isempty(cc.PixelIdxList{sphere})
                        nonEmptySpher = nonEmptySpher + 1;
                        bbox = boundingBox{nonEmptySpher};
                        imageNew = imcrop(image, bbox);
                        CCNew = imcrop(CClabel, bbox);
                        
                        wholeSpheroidPixels = double(imageNew(CCNew == sphere));
                        medianTotalPixels = median(wholeSpheroidPixels);


                        %compare to threshold
                        if medianTotalPixels > contaminantIntensity
                            contaminant.PixelIdxList{sphere} = [];
    
                        else
                            nonContaminantBoolean(sphere) = 0;
                        end
                    end
                end
            else
                for sphere = 1:length(cc.PixelIdxList)
                    contaminant.PixelIdxList{sphere} = [];
                    nonContaminantBoolean(sphere) = 1;
                end


            end

                
                contaminantCCLabel = labelmatrix(contaminant);

                contaminantPerim = bwperim(contaminantCCLabel ~= 0);
                contaminant_perim_dil = imdilate(contaminantPerim, ones(3,3));
                imgMaskedNew = imoverlay(imgMaskedOrig,contaminant_perim_dil,[1 0 1]);

                
        end


        %Assembles full imgMasked (image with perimeter overlay) 
        % when individual spheroid data has changed,
        %generating perimeter from black and white mask
        function outputs = makeImageMaskedChangeData(app, bwfinal, bbox, newSegment, necessaryData)
            outputs = struct;
            %Find perimeter
            bw_perim = double(bwperim(bwfinal));
            %Make visible (dilate)
            bw_perim_dil = imdilate(bw_perim,ones(3,3));
            bulkI = necessaryData.bulkI;


            displayI = bulkI;
            %Normalize raw image to min and max
            displayI = double(displayI);
            displayI = (displayI - min(displayI(:)))./(max(displayI(:)) - min(displayI(:)));

            imgMasked = imoverlay(displayI,bw_perim_dil, [0 0 1]);              

            [imgMasked, nonCirc, circularBoolean] = findCircularity(app, necessaryData, imgMasked, newSegment);
            

            [imgMasked, contaminant, nonContaminantBoolean] = findContaminant(app, necessaryData, imgMasked, bbox, newSegment);


            outputs.ImgMasked = imgMasked;
            outputs.NonCircularCC = nonCirc;
            outputs.CircularBoolean = circularBoolean;
            outputs.ContaminantCC = contaminant;
            outputs.NonContaminantBoolean = nonContaminantBoolean; 


        end

        %Assembles full imgMasked from black and white mask when spheroid
        %data has not changed (cirularity and contaminant will not change)
        function imgMasked = makeImageMaskedNoDataChange(app, bwfinal, selected)
            %Find perimeter and crop with raw image
            
            image = app.BulkI{selected};
            bw_perim = double(bwperim(bwfinal));
            bw_perim_dil = imdilate(bw_perim,ones(3,3));
            

            image = double(image);
            image = (image - min(image(:)))./(max(image(:)) - min( image(:)));
            imgMasked = imoverlay(image,bw_perim_dil,app.MainSegmentColor);
            

            %add label for non-circularity


            if any(~(app.CircularBoolean{selected}))
                nonCircCC = app.NonCircularCC{selected};
                nonCircCCLabel = labelmatrix(nonCircCC);

                nonCircPerim = bwperim(nonCircCCLabel ~= 0);
                nonCirc_perim_dil = imdilate(nonCircPerim, ones(3,3));
                imgMasked = imoverlay(imgMasked,nonCirc_perim_dil,app.NonCircColor);
            end


            if any(~(app.NonContaminantBoolean{selected}))
                contaminantCC = app.ContaminantCC{selected};
                contaminantLabel = labelmatrix(contaminantCC);

                contaminantPerim = bwperim(contaminantLabel ~= 0);
                contaminant_perim_dil = imdilate(contaminantPerim, ones(3,3));
                imgMasked = imoverlay(imgMasked,contaminant_perim_dil,app.ContaminantColor);
            end
        end

        %Assign spheroids in order so that clusters (spheroids
        %after splitting that come from same original spheroid) are
        %in order together
        %CC_temp is cc without empty (removed) spheroids
        function spheroids_order = orderSpheroids(app, CC_temp, checkWater, beenSplit, bw_nowater)            
            
            if checkWater == 1 || beenSplit
                %Load pre-watershed
                
                CC_nowater = bwconncomp(bw_nowater);
                
                CClabel_nowater = labelmatrix(CC_nowater);
                %Preallocate
                cluster = cell(1,CC_nowater.NumObjects+1);
                %Determine spheroid numbering
                 
                for k = 1:CC_temp.NumObjects
                    %original spheroid cluster
                   
                    location = CClabel_nowater(CC_temp.PixelIdxList{k});
                    spheroid_orig = round(mode(location(location~=0)));
                    if spheroid_orig ~= 0
                    %store cluster index
                        a = [cluster{spheroid_orig} k];
                        cluster{spheroid_orig} = a;
                    else
                        a = [cluster{end} k];
                        cluster{end} = a;
                    end
                end
                %Matricize and store
                spheroids_order = cell2mat(cluster);  
            else
                spheroids_order = 1:CC_temp.NumObjects;
            end
                
                
        end

        %Generates CC_temp from cc by removing entries with no pixels
        function CC_temp = findCCTemp(~, cc)
                       
            CC_temp = cc;                     
          
            CC_temp.PixelIdxList = cc.PixelIdxList(~cellfun('isempty',cc.PixelIdxList));
            CC_temp.NumObjects = length(CC_temp.PixelIdxList);
            
        end

        %Finds bounding box from CC_temp (bounding box won't work if CC is
        %empty)
        function boundingBox = findBoundingBoxFromTemp(~, CC_temp)
             
            %Create bounding box matrix
            statsBW_temp = regionprops(CC_temp, 'BoundingBox');
            boundingBox = {statsBW_temp.BoundingBox};
            
        end

        %Finds centers of spheroids using cc (stores all spheroids
        %including removed ones so that they can be restored and the index
        %is retained)
        function centers = findCentersFromCC(~, cc)
            statsBW = regionprops(cc, 'Centroid');
            centersOrig = [statsBW.Centroid];
            centers = reshape(centersOrig, 2, length(statsBW))';
            
        end

        %Prepare the ccDeleted and centersDeleted so that spheroids that
        %are removed can have their data retained and identified with 
        % its original index
        function [ccDeleted, centersDeleted] = preallocateDeleted(app)
            selected = app.ImageSelectList.Value;
            cc = app.CC{selected};
            centers = app.Centers{selected};
            if app.CentersDeleted{selected} == 0
                %Pre-allocate
                
                centersDeleted = zeros(length(cc.PixelIdxList),2);
                
                ccDeleted = cell(1,size(centers,1));

             %if centers have been deleted
            else
                %Load previously initizialized deleted objects
                centersDeleted = app.CentersDeleted{selected};
                
                ccDeleted = app.CcDeleted{selected};
                deletedLength = length(centersDeleted);
                newLength = size(centers,1);
                
                centersDeleted(deletedLength+1:newLength,:) = zeros(newLength-deletedLength,2);
                
                ccDeleted(deletedLength+1:newLength) = {[]};

            end
        end

        function mag = getImageMag(app, origI, imageReconstructionRadius, useReconstructedForGradient,  useInverted)
            if useReconstructedForGradient == 1
                se2 = strel('disk', round(imageReconstructionRadius/2));
                Iobrcbr2 = prepareImage(app, origI, se2, useInverted);
                [mag, ~] = imgradient(Iobrcbr2);
                    %[mag, ~] = imgradient(imgaussfilt(origI,.5));

            else
                [mag, ~] = imgradient(origI);
            end
        end


        function background  = getBackground(~, Iobrcbr)
            %background = imdilate(Iobrcbr, strel('Disk', round(size(Iobrcbr, 1)/20)));
            background = imfilter(Iobrcbr,fspecial('average',[500 500]),'replicate');
            
        end

        function bw_holesNew = gradientOnlyEdgeCorrect(app, bw_holes, edgeIntensity, Iobrcbr, bulkI, imageReconstructionRadius, useReconstructedForGradient, useInverted,sizeThresh, selected)


            mag = getImageMag(app, bulkI, imageReconstructionRadius, useReconstructedForGradient,  useInverted);
            

            background = getBackground(app, Iobrcbr);
            mag = mag > edgeIntensity.*background;
            
            bw_holes_temp = mag + bw_holes;
            
            %Remove extra area around perimeter added by
            %magnitude
            
            bwsmall2 = bwareaopen(bw_holes_temp, sizeThresh);
           
            %Smooth
            bwsmooth2 = imclose(bwsmall2,ones(3));

            %fill holes
            bw_holes_temp = bwfill(bwsmooth2,'holes');
            bw_holes_temp = imerode(bw_holes_temp, strel('disk', 3));           
            bw_holes_temp = bwareaopen(bw_holes_temp, sizeThresh);
            bw_holesNew = bw_holes+bw_holes_temp;


        end

        function bw_holesNew = gradientPreserveBoundaryEdgeCorrect(app, bw_holes, edgeIntensity, Iobrcbr, bulkI, imageReconstructionRadius, useReconstructedForGradient, useInverted, sizeThresh, selected)
            %mark background based on segmentation
                    
                D = bwdist(bw_holes);
                DL = watershed(D);
                bgm = DL == 0;


                mag = getImageMag(app, bulkI,imageReconstructionRadius, useReconstructedForGradient,  useInverted);


                background = getBackground(app, Iobrcbr);

                
                %Threshold image gradient based on intensity of
                %background at that point
                mag = mag > edgeIntensity*background;
                mag = bwmorph(mag, 'spur', inf);
               
                %Add thresholded image gradient to original bwfinal
                combine = mag+bw_holes;

                %Zero-out combined bw at background marker to
                %eliminate combining of spheroids
                combine(imdilate(bgm, ones(3,3))) = 0;
                
                
                bwsmall2 = bwareaopen(combine, sizeThresh);
                
                
                bwsmooth2 = imclose(bwsmall2, ones(3));             
                bw_holes2 = imfill(bwsmooth2, 'holes');


                bw_holes_temp = imerode(bw_holes2, strel('disk', 3));
                bw_holes_temp = imclearborder(bw_holes_temp);
                bw_holes_temp = bwareaopen(bw_holes_temp, sizeThresh);
               

                %Remove any objects from bwfinalTemp which do not
                %appear in original bwfinal
                pixels = find(bw_holes);
                newCC = bwconncomp(bw_holes_temp);


                for spheroid = 1:length(newCC.PixelIdxList)
                    if ~any(ismember(pixels, newCC.PixelIdxList{spheroid}))
                        newCC.PixelIdxList{spheroid} = [];
                    end
                end
                
                
                finalCCLabel = labelmatrix(newCC);
                bw_holes_temp = logical(finalCCLabel);
               
                %Remove extra area around perimeter added by
                %magnitude
                %bw_holes_temp = logical(bw_holes_temp);
                
                bw_holesNew = bw_holes_temp + bw_holes;


        end

        function bw_holesNew = watershedEdgeCorrect(app, bw_holes, bulkI, imageReconstructionRadius, useReconstructedForGradient, useInverted,sizeThresh, selected)
            
            D = bwdist(bw_holes);
            DL = watershed(D);
            bgm = DL == 0;         
            
            
            bw_holes_temp = bw_holes;
            bw_holes_temp(DL == 0) = 0;


            se2 = strel(ones(3,3));                        
            fgm = imerode(bw_holes_temp,se2);
            
       
            fgm = bwareaopen(fgm,sizeThresh/2);

            mag = getImageMag(app, bulkI, imageReconstructionRadius, useReconstructedForGradient,  useInverted);         


            mag = imgaussfilt(mag,1);
            mag2 = imimposemin(mag,  fgm | bgm );
            
            L = watershed(mag2);
            
            mask = imfill(L==0, 'holes');
            combine = mask+bw_holes_temp;

            bwsmall2 = bwareaopen(combine, sizeThresh);                       
            %bw_holesNew = imclearborder(bwsmall2);
            bw_holesNew = bwsmall2;

        end

        function splitBw = watershedSplit(app, bwToSplit, blurring, numIterations, sizeThresh)
            bw_holesTemp = bwToSplit;
            for iteration = 1:numIterations
                
                %Find distance transform and smooth
                dist = bwdist(imcomplement(double(bw_holesTemp)));
                
                if blurring > 0
                    smooth = imgaussfilt(dist,blurring);
                else
                    smooth = dist;
                end
                %smooth = dist;
                
                dist2 = -smooth;
                dist2(~bw_holesTemp) = Inf;

                %Watershed based on distance transform, splitting organoids
                L = watershed(dist2);
                bw_holesTemp(L==0) = 0;
                %Remove noise based on size threshold
                bwlil = bwareaopen(bw_holesTemp,sizeThresh);
                %Clear border
                bw_holesTemp = imclearborder(bwlil);
                bw_holesTemp = logical(bw_holesTemp);
            end
            splitBw = bw_holesTemp;
        end

        function keyPressedReturn(app, event)
            selected = app.ImageSelectList.Value;
            getPreviousVersion(app, selected);
            
            %If removing, call remove
            if app.RemoveCheckBox.Value == 1
                if app.FirstRemoveCombineSelected == true && app.SecondRemoveCombineSelected == true

                    removeSpheroid(app)

                end
            %If cropping, select region of interest and crop, also
            %resetting values
            elseif app.IndividualEdgeCorrectiCheckBox.Value == 1
                correctSpheroids(app)
            
            elseif app.CroppingImage
                keyPressedReturnCrop(app, event)

            %If one of the change classification drop down items is
            %selected
            elseif app.ChangingClassification || app.BulkRemove
                keyPressedReturnClassification(app, event)

            %Call split on selected spheroids if splitting
            elseif app.SplitSelectedSpheroidtCheckBox.Value == 1

                splitSpheroids(app)
            elseif app.CombinecCheckBox.Value == 1
                if app.FirstRemoveCombineSelected == true && app.SecondRemoveCombineSelected == true
                    combineSpheroids(app)
                end


            end
        end

        function keyPressedReturnCrop(app, event)
            
            if strcmp(app.CropPreference, 'Rectangle')
                cropRectangle(app, event)
            elseif strcmp(app.CropPreference, 'Circle')
                cropCircle(app,event)
            end

        end

        function cropCircle(app,event)
            selected = app.ImageSelectList.Value;
            if (~isnan(app.CropCenter)) & (~isnan(app.CropRadius) | ~isnan(app.MostRecentRadius))
                %https://www.mathworks.com/matlabcentral/answers/442825-crop-with-imfreehand
                circleROI = images.roi.Circle(app.RawImageAxes, 'Center', [app.CropCenter(1,1), app.CropCenter(1,2)], 'Radius', app.CropRadius);
                binaryImage = circleROI.createMask();
                blackMaskedImage = app.BulkI{selected};
                blackMaskedImage(~binaryImage) = 0;
                structBoundaries = bwboundaries(binaryImage);
                xy=structBoundaries{1}; % Get n by 2 array of x,y coordinates.
                x = xy(:, 2); % Columns.
                y = xy(:, 1); % Rows.

                % Now crop the image.
                leftColumn = min(x);
                rightColumn = max(x);
                topLine = min(y);
                bottomLine = max(y);
                width = rightColumn - leftColumn + 1;
                height = bottomLine - topLine + 1;
                croppedImage = imcrop(blackMaskedImage, [leftColumn, topLine, width, height]);
                app.BulkI{selected} = croppedImage;


                delete(app.CircleMarker)
                app.CircleMarker = 0;
                delete(app.CenterMarker)
                app.CenterMarker = 0;
                displayRawImageOnly(app, selected);
                app.CroppingImage = false;
                app.MostRecentRadius = app.CropRadius;
                app.CropCenter = NaN;
                app.CropRadius = NaN;
                

                clearSegmentProducts(app, selected);
                reenableStuff(app)
            end
        end

        function cropRectangle(app,event)
            selected = app.ImageSelectList.Value;
          
            if (~isnan(app.FirstCropCorner)) & (~isnan(app.SecondCropCorner) | (~isnan(app.MostRecentHeight) & app.UseLastCropDimensionsCheckBox.Value == 1))
                if ~isnan(app.SecondCropCorner) 
                    [left, right, top, bottom] = findRectangle(app, app.FirstCropCorner, app.SecondCropCorner);
                    width = right - left;
                    height = bottom - top;
                    if app.FirstCropCorner(1,1) < app.SecondCropCorner(1,1)
                        app.FirstCornerX = 'left';
                    else
                        app.FirstCornerX = 'right';
                    end
                    if app.FirstCropCorner(1,2) < app.SecondCropCorner(1,2)
                        app.FirstCornerY = 'top';
                    else
                        app.FirstCornerY = 'bottom';
                    end
                    app.MostRecentWidth = (right - left);
                    app.MostRecentHeight = (bottom - top);
                else %Using stored width and height
                    height = app.MostRecentHeight;
                    width = app.MostRecentWidth;
                    if strcmp(app.FirstCornerY, 'top')
                        top = app.FirstCropCorner(1,2);
                        
                    else
                        top = app.FirstCropCorner(1,2) - height;    
                    end
                    if strcmp(app.FirstCornerX, 'left')
                        left = app.FirstCropCorner(1,1);
                    else
                        left = app.FirstCropCorner(1,1) - width;    
                    end
                    

                end

                croppedImage = imcrop(app.BulkI{selected}, [left, top, width, height]);
                app.BulkI{selected} = croppedImage;

               
                displayRawImageOnly(app, selected);
                
                
                app.FirstCropCorner = NaN;
                app.SecondCropCorner = NaN;
                delete(app.CornerMarker)
                app.CornerMarker = 0;
                delete(app.RectangleMarker)
                app.RectangleMarker = 0;
                app.CroppingImage = false;
                

                clearSegmentProducts(app, selected);
                reenableStuff(app)

            end
        end

        function keyPressedReturnClassification(app, ~)
            selected = app.ImageSelectList.Value;
            cc = app.CC{selected};
            nonCircCC = app.NonCircularCC{selected};
            contaminantCC = app.ContaminantCC{selected};
            circularBoolean = app.CircularBoolean{selected};
            nonContaminantBoolean = app.NonContaminantBoolean{selected};

            %if changing to regular spheroid
            if strcmp(app.ClassificationDropDown.Value, 'Spheroid')
                [nonCircCC, circularBoolean, contaminantCC, nonContaminantBoolean] = changeToSpheroid(app, nonCircCC, circularBoolean, contaminantCC, nonContaminantBoolean);
            %if changing to contaminant
            elseif strcmp(app.ClassificationDropDown.Value, 'Contaminant')
                [contaminantCC, nonContaminantBoolean] = changeToNotSpheroid(app, contaminantCC, nonContaminantBoolean);
%                         
            %if changing to non-circular
            elseif strcmp(app.ClassificationDropDown.Value, 'NonCircular')
                [nonCircCC, circularBoolean] = changeToNotSpheroid(app, nonCircCC, circularBoolean);
            %if doing a bulk remove of contaminants
            elseif strcmp(app.ClassificationDropDown.Value, 'RemoveContaminant')
                [cc, nonCircCC, contaminantCC] = bulkRemove(app, contaminantCC, nonContaminantBoolean);
            %bulk remove but for non-circular
            elseif strcmp(app.ClassificationDropDown.Value, 'RemoveNonCircular')
                [cc, nonCircCC, contaminantCC] = bulkRemove(app, nonCircCC, circularBoolean);                        
            end
            app.CC{selected} = cc;
            app.NonCircularCC{selected} = nonCircCC;
            app.CircularBoolean{selected} = circularBoolean;
            app.ContaminantCC{selected} = contaminantCC;
            app.NonContaminantBoolean{selected} = nonContaminantBoolean;


            app.ClassificationDropDown.Value = 'None';
            redisplayWithoutChangeToSpheroidData(app, cc, selected)
            reenableStuff(app)

        end

        function keyPressedEscape_eButton(app, event)
            
            if app.FluorescenceMode || app.TrackingMode || app.MultiExporting || app.MultiSegmenting
                keyPressedEscapeMultiselect(app, event)

            elseif app.ThingsDisenabled == true
                selected = app.ImageSelectList.Value;
                if strcmp(app.BrightnessButton.Text, 'Done') 
                    app.CropGrid.ColumnWidth = {'1x',50,50,0,50,0,0,50,0};
                    app.CropGrid.RowHeight = {'1x', '0x'};
                    app.RawImageGrid.RowHeight = {20, '1x'};
                    app.BrightnessButton.Text= 'Brightness';
                elseif strcmp(app.RotateButton.Text, 'Done Rotating')
                    app.RotateButton.Text= 'Rotate';
                    app.CropGrid.ColumnWidth = {'1x',50,50,0,50,0,0,50,0};
                    app.CropGrid.RowHeight = {'1x', '0x'};
                    app.RawImageGrid.RowHeight = {20, '1x'};
                    if app.ColoredLabels{selected} == 0
                        displayRawImageOnly(app, selected);
                    else
                        displayRawImageWithOverlay(app,selected)
                        displayColoredSegmentation(app, selected);
                        displaySpheroidZoom(app, selected);
                    
                        
                    end
                end
                reenableStuff(app)
                toolSelection(app, 'Clearing')


            elseif app.ImageSelectList.Enable == 0 %|| app.FluorescenceMode == true
                
                
                reenableEverything(app)
                toolSelection(app, 'Clearing')

            elseif app.SegmentCurrentButton.Enable == 0 || app.ApplytoAllButton.Enable == 0 || app.SelectImagestoSegmentButton.Enable == 0 ||app.SegmentCurrentButton.Enable == 0 || app.ExportCurrentButton.Enable == 0 || app.ExportSetButton.Enable == 0 || app.SelectImagestoExportButton.Enable == 0
                reenableEverything(app)
                toolSelection(app, 'Clearing')
                
            end

        end


        function keyPressedEscapeMultiselect(app, ~)
            app.ImageSelectGrid.RowHeight = {'0.2x', '1x', 0};
            app.LeftGrid.RowHeight = {20, '1.5x', '1x', '1.2x'};
            app.ImageSelectList.Multiselect = 0;
            app.FluorescenceMode = false;
            app.TrackingMode = false;
            app.MultiExporting = false;
            app.MultiSegmenting = false;
            reenableStuff(app)

        end

        function newImage = adjustImage(app, oldImage, style)
            
            param1 = app.FirstBrightParam.Value;
            
            param2 = app.SecondBrightParam.Value;

            switch style
                case 'Lb'    
                    if param2 ~= 0
                        param2 = 1;
                    end
                    newImage = imlocalbrighten(oldImage, param1, 'AlphaBlend', param2);                    

                case 'Ff'
                    newImage = imflatfield(oldImage, param1);
                    
                case 'Lc'
                    newImage = localcontrast(oldImage, param1, param2);

                case 'Oc'
                    newImage = imadjust(oldImage);
                    
                case 'Ll'
                    newImage = locallapfilt(oldImage, param1, param2);

                case 'Rh'
                    newImage = imreducehaze(oldImage, param1);
                case 'Eh'
                    newImage = histeq(oldImage, param1);

                case 'No'
                    newImage = oldImage;
                    
            end

        end

        function resetToolData(app)
            %Reset stuff
            app.FirstRemoveCombineSelected = false;
            app.SecondRemoveCombineSelected = false;
            app.FirstSelectedCoords = NaN;
            app.SecondSelectedCoords = NaN;

            if app.Marker ~= 0
                delete(app.Marker)
                app.Marker = 0;
            end

            if app.RectangleRemoveCombineMarker ~= 0
                delete(app.RectangleRemoveCombineMarker)
                app.RectangleRemoveCombineMarker = 0;
            end
            app.Marker = 0;
            app.RectangleRemoveCombineMarker = 0;

            app.FirstCombine = NaN;


            app.SpheroidsToSplit = [];
            app.SplitSpheroidsSelected = 0;
            

            app.SpheroidsToCorrect = [];
            app.EdgeSpheroidsSelected = 0;


            app.CroppingImage = false;
            app.FirstCropCorner = NaN;
            app.SecondCropCorner = NaN;


            if app.CornerMarker ~= 0
                delete(app.CornerMarker)
                app.CornerMarker = 0;
            end
            if app.RectangleMarker ~= 0
                delete(app.RectangleMarker)
                app.RectangleMarker = 0;
            end

            app.CornerMarker = 0;
            app.RectangleMarker = 0;


            if app.CenterMarker ~= 0
                delete(app.CenterMarker)
                app.CenterMarker = 0;
            end
            if app.CircleMarker  ~= 0
                delete(app.CircleMarker)
                app.CircleMarker = 0;
            end

            app.CropCenter = NaN;
            app.CircleMarker = 0;
            app.CenterMarker = 0;
            app.CropRadius = NaN;

            app.SpheroidsToClassify = [];
            app.ClassifySpheroidsSelected = 0;
            app.ChangingClassification = false;
            app.BulkRemove = false;
        end

        %When loading mat files, adjust for any discrepancies in previous versions 
        function oldParameters = checkHiddenPropsParameters(~, hiddenParameters)
            oldParameters = cell(1,length(hiddenParameters));
            for i = 1:length(hiddenParameters)
                thisImageParameters = struct;
    
                try 
                    thisImageParameters.windowSizeJump = hiddenParameters{i}.windowSizeJump;
                catch
                    thisImageParameters.windowSizeJump = 5;
                end
    
                try 
                    thisImageParameters.watershedFilterSigma = hiddenParameters{i}.watershedFilterSigma;
                catch
                    thisImageParameters.watershedFilterSigma = 5;
                end
    
                try 
                    thisImageParameters.numberWatershedIterations = hiddenParameters{i}.numberWatershedIterations;
                catch
                    thisImageParameters.numberWatershedIterations = 1;
                end
    
                try 
                    thisImageParameters.imageClosingDegree = hiddenParameters{i}.imageClosingDegree;
                catch
                    thisImageParameters.imageClosingDegree = 3;
                end
    
                try 
                    thisImageParameters.imageReconstructionRadius = hiddenParameters{i}.imageReconstructionRadius;
                catch
                    thisImageParameters.imageReconstructionRadius = 5;
                end
    
                try 
                    thisImageParameters.useReconstructedForGradient = hiddenParameters{i}.useReconstructedForGradient;
                catch
                    thisImageParameters.useReconstructedForGradient = 1;
                end
    
                try 
                    thisImageParameters.clearBorderSplit = hiddenParameters{i}.clearBorderSplit;
                catch
                    thisImageParameters.clearBorderSplit = 0;
                end
    
                try 
                    thisImageParameters.useInverted = hiddenParameters{i}.useInverted;
                catch
                    thisImageParameters.useInverted = 0;
                end
    
                try 
                    thisImageParameters.edgePreference = hiddenParameters{i}.edgePreference;
                catch
                    thisImageParameters.edgePreference = 'Gradient + Watershed';
                end 
                oldParameters{i} = thisImageParameters;

                
            end
            
        end


        function group(app, newGroup)


            if ~isempty(app.FilenameRegex)
                %Get the variable names from regex
                fileNameGroups = fieldnames(regexp(app.Filename{1}, app.FilenameRegex, "names"));
                tokens = cell(length(app.Filename), length(fileNameGroups));
                for file = 1:length(app.Filename)
                    %Find corresponding values for each variable name
                    match = regexp(app.Filename{file}, app.FilenameRegex, "tokens");
                    if ~isempty(match)
                        try
                            tokens(file, :) = match{1};
                        catch
                            errordlg("Error in matching, number of tokens captured did not match number of variable names")
                            break
                        end
                    end
                end
                %Assign variable values to struct with varaible names
                for name = 1:length(fileNameGroups)
                    groups.(fileNameGroups{name}) = [tokens(:,name)];
                end


                %Only allow batch tracking if groups have been assigned
                if ~isempty(fileNameGroups)
                    app.TrackInBatchesMenu.Enable = 1;
                    groupsTable = struct2table(groups);
                    app.GroupsTable.Data = groupsTable;
                    app.GroupsTable.ColumnName = groupsTable.Properties.VariableNames;
                else
                    app.TrackInBatchesMenu.Enable = 0;
                    app.GroupingOptionsTable.Data(:,:) = [];
                    app.GroupsTable.Data(:,:) = [];

                end

                %If new grouping, creating grouping options table
                if ~isempty(fileNameGroups) & newGroup
                    groupNames = (app.GroupsTable.ColumnName);
                    useInGrouping = false(length(groupNames),1);
                    keys = strings(length(groupNames), 1);
                    MoC = categorical(["Exact Match", "Contains"]);


                    matchOrContains = repmat(MoC(1), length(groupNames), 1);


                    exportOptionsTable = table(groupNames, useInGrouping, keys, matchOrContains, 'VariableNames', ["Group","UseInGrouping","Key","MatchOrContains"]);
            
                    
                    app.GroupingOptionsTable.Data = exportOptionsTable;
                end
            else
                app.TrackInBatchesMenu.Enable = 0;
                app.GroupingOptionsTable.Data(:,:) = [];
                app.GroupsTable.Data(:,:) = [];
            end
        end

        function necessaryData = getNecessaryData(app, selected)
            necessaryData = struct;
            necessaryData.cc = app.CC{selected};
            necessaryData.bulkI = app.BulkI{selected};
            necessaryData.nonContaminantBoolean = app.NonContaminantBoolean{selected};
            necessaryData.circularBoolean = app.CircularBoolean{selected};
            necessaryData.minimumCircularity = app.MinimumCircularity{selected};
            necessaryData.contaminantIntensity = app.ContaminantIntensity{selected};
            necessaryData.mainSegmentColor = app.MainSegmentColor;
            necessaryData.nonCircColor = app.NonCircColor;
            necessaryData.contaminantColor = app.ContaminantColor;            
            necessaryData.bw_nowater = app.Bw_nowater{selected};
            necessaryData.beenSplit = app.BeenSplit{selected};
            necessaryData.checkWater = app.CheckWater{selected};
        end


    end


    methods (Access = public)

        function updateMetricsSelected(app, metricsSelected)
            app.MetricsSelected  = metricsSelected;

        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Remove interactivity from axes (reduces lagging)
            disableDefaultInteractivity(app.RawImageAxes)
            disableDefaultInteractivity(app.ColoredSegmentationAxes)
            disableDefaultInteractivity(app.SpheroidZoomAxes)


        end

        % Button pushed function: MaximizeImageButton
        function maximizeImage(app, event)
            %Screen appearance customization callback
            if (app.ImagesMaximized)
                expandBottom(app);
                expandLeft(app);
                app.MaximizeImageButton.Text = "+";
                app.CropGrid.ColumnWidth = {'1x',50,50,0,50,0,0,50,0};
                app.ImagesMaximized = false;

            else
                collapseLeft(app);
                collapseBottom(app);
                app.CropGrid.ColumnWidth = {'1x',50,50,0,50,0,0,50,50};
                app.MaximizeImageButton.Text = "-";
                app.ImagesMaximized = true;
            end
            if ~app.RawImageMaximized
                app.ColoredSegmentationAxes.Position(3) = app.RawImageAxes.Position(3);
                app.ColoredSegmentationAxes.Position(4) = app.RawImageAxes.Position(4);
            end
        end

        % Button pushed function: CollapseLeftButton
        function collapseLeftButtonCallback(app, event)
            if (app.LeftPanelCollapsed)
                expandLeft(app);
            else
                collapseLeft(app);
            end
        end

        % Size changed function: SegmentationOptionsPanel
        function optionsPanelResize(app, event)
            %Resizes font of options when screen is resized
            newSize = app.UIFigure.Position(3)/100;
            app.MinimumCircularity01Label.FontSize = newSize;
            app.ContaminantIntesity0255Label.FontSize = newSize;
            app.SizeThreshold105000Label.FontSize = newSize;
            app.IntensityThreshold01Label.FontSize = newSize;
            app.WindowSize20500Label.FontSize = newSize;
            app.OutofFocusCorrectionCheckBox.FontSize = newSize;
            app.DICCorrectionCheckBox.FontSize = newSize;
            app.SplitWholeImageCheckBox.FontSize = newSize;
            app.EdgeCorrectionCheckBox.FontSize = newSize;
            app.EdgeIntensity01Label.FontSize = newSize;

            app.MinimumCircularityEditField.FontSize = newSize;
            app.ContaminantIntensityEditField.FontSize = newSize;
            app.WindowSizeEditField.FontSize = newSize;
            app.SizeThresholdEditField.FontSize = newSize;
            app.IntensityThresholdEditField.FontSize = newSize;
        end

        % Menu selected function: NewImageSetMenu
        function newOpenCallback(app, event)
            f = figure('Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI

            
            if isnan(app.MostRecentImageOpenFolder) 
                [filename, baseName] = uigetfile('*.jpg;*.tiff;*.tif;*.png;*.bmp','Select an Image','Multiselect', 'on');
            else
                [filename, baseName] = uigetfile('*.jpg;*.tiff;*.tif;*.png;*.bmp','Select an Image',app.MostRecentImageOpenFolder, 'Multiselect', 'on');
            end
            delete(f); %delete the dummy figure
            if baseName~=0
                app.MostRecentImageOpenFolder = baseName;
            end
            %Find how many images are already in BulkI and get index for
            %new image
            currentImage = length(app.BulkI) + 1;
            bulkI = app.BulkI;
            
            %Load images

            %if no entry
            if isequal(filename,0)
                %do nothing
                %if single image
            elseif iscell(filename) == 0

                %initialize everything for current image
                app.Filename(currentImage) = {filename};
                app.Basename(currentImage) = {baseName};
                filenameCell = {filename};
                %set listbox strings to file names
                app.ImageSelectList.Items = cat(2,app.ImageSelectList.Items, filenameCell);
                app.ImageSelectList.ItemsData(currentImage) = currentImage;
                app.CC(currentImage) = {0};
              
                app.ColoredLabels(currentImage) = {0};
                app.Centers(currentImage) = {0};
                app.ImgMasked(currentImage) = {0};
                app.Spheroids_order(currentImage) = {0};
                
                app.Bw_nowater(currentImage) = {0};
                app.BeenSplit(currentImage) = {0};
                app.Bwfinal(currentImage) = {0};
                app.BoundingBox(currentImage) = {0};
                app.CcDeleted(currentImage) = {0};
                app.CentersDeleted(currentImage) = {0};
                app.NonCircularCC(currentImage) = {0};
                app.CircularBoolean(currentImage) = {0};
                app.ContaminantCC(currentImage) = {0};
                app.NonContaminantBoolean(currentImage) = {0};
                app.Resize(currentImage) = {1};
                app.PreviousVersion(currentImage) = {0};


                %load images
                name = strcat(baseName,filename);
                rgb = imread(name);
                
                %if rgb
                if length(size(rgb))==3
                    
                    %convert to grayscale
                    try 
                        bulkI{currentImage} = rgb2gray(rgb);
                    catch 
                        
                        
                        if (strcmp(imfinfo(name).PhotometricInterpretation, 'RGB'))
                            bulkI{currentImage} = rgb2gray(rgb(:,:,1:3));
                        else
                            C = makecform("cmyk2srgb","RenderingIntent",'RelativeColorimetric');
                            I_rgb = applycform(rgb,C);
                            bulkI{currentImage} = rgb2gray(I_rgb);

                        end
                    end
                   
                    %if non-rgb
                else
                    %already grayscale
                    bulkI{currentImage} = rgb;
                    
                end
                %Account for different bit-depth images


                %Initialize variables
                app.BulkI = bulkI;
                app.WindowSize{currentImage} = [100,100];
                app.SizeThresh{currentImage} = 100;
                app.IntensityThresh{currentImage} = 1;                
                app.CheckWater{currentImage} = 0;
                app.CheckOOF{currentImage} = 1;
                app.CheckDIC{currentImage} = 0;
                app.CheckEdge{currentImage} = 0;
                app.EdgeIntensity{currentImage} = 0.3;
                
                app.MinimumCircularity{currentImage} = 0;
                app.ContaminantIntensity{currentImage} = 0;

                additionalParameters = struct;
                additionalParameters.watershedFilterSigma = 5;
                additionalParameters.numberWatershedIterations = 1;
                additionalParameters.imageClosingDegree = 3;
                additionalParameters.imageReconstructionRadius = 5;
                additionalParameters.windowSizeJump = 10;
                additionalParameters.useReconstructedForGradient = 1;
                additionalParameters.useInverted = 0;
                additionalParameters.clearBorderSplit = 1;
                additionalParameters.edgePreference = 'Gradient + Watershed';
                app.AdditionalParameters{currentImage} = additionalParameters;


                displayRawImageOnly(app, currentImage);

                %Set sliders and checkboxes
                
                setSettings(app,currentImage);


                app.ImageSelectList.Value = currentImage;

                %if multiple images
            else

                %initialize
                app.Filename(currentImage:currentImage+length(filename)-1) = filename;
                app.Basename(currentImage:currentImage+length(filename)-1) = {baseName};
                

                %preallocate new space in bulkI cell, as well as all
                %segmentation product cell arrays
                bulkI(currentImage:currentImage+length(filename)-1) = {0};
                
                app.CC(currentImage:currentImage+length(filename)-1) = {0};
             
                app.ColoredLabels(currentImage:currentImage+length(filename)-1) = {0};
                app.Centers(currentImage:currentImage+length(filename)-1) = {0};
                app.ImgMasked(currentImage:currentImage+length(filename)-1) = {0};
                app.Spheroids_order(currentImage:currentImage+length(filename)-1) = {0};
                
                app.Bw_nowater(currentImage:currentImage+length(filename)-1) = {0};
                app.BeenSplit(currentImage:currentImage+length(filename)-1) = {0};
                app.Bwfinal(currentImage:currentImage+length(filename)-1) = {0};
                app.BoundingBox(currentImage:currentImage+length(filename)-1) = {0};
                app.CcDeleted(currentImage:currentImage+length(filename)-1) = {0};
                app.CentersDeleted(currentImage:currentImage+length(filename)-1) = {0};
                app.NonCircularCC(currentImage:currentImage+length(filename)-1) = {0};
                app.CircularBoolean(currentImage:currentImage+length(filename)-1) = {0};
                app.ContaminantCC(currentImage:currentImage+length(filename)-1) = {0};
                app.NonContaminantBoolean(currentImage:currentImage+length(filename)-1) = {0};
                app.Resize(currentImage:currentImage+length(filename)-1) = {1};
                app.PreviousVersion(currentImage:currentImage+length(filename)-1) = {0};

                additionalParameters = struct;
                additionalParameters.watershedFilterSigma = 5;
                additionalParameters.numberWatershedIterations = 1;
                additionalParameters.imageClosingDegree = 3;
                additionalParameters.imageReconstructionRadius = 5;
                additionalParameters.windowSizeJump = 10;
                additionalParameters.useReconstructedForGradient = 1;
                additionalParameters.useInverted = 0;
                additionalParameters.clearBorderSplit = 1;
                additionalParameters.edgePreference = 'Gradient + Watershed';


                %set listbox strings to file names
                app.ImageSelectList.Items = cat(2,app.ImageSelectList.Items, filename);

                %loop through each image, where newImage is index of image
                %being added
                for newImage = currentImage:(currentImage+length(filename)-1)
                    %add files to list

                    app.ImageSelectList.ItemsData(newImage) = newImage;
                    %load images
                    indexInFileName = newImage-currentImage+1;
                    name = strcat(baseName,filename{indexInFileName});


                    rgb = imread(name);
                    
                    %if rgb
                    if length(size(rgb))==3
                    
                        %convert to grayscale
                        try 
                            bulkI{newImage} = rgb2gray(rgb);
                        catch 
                            
                            
                            if (strcmp(imfinfo(name).PhotometricInterpretation, 'RGB'))
                                bulkI{newImage} = rgb2gray(rgb(:,:,1:3));
                            else
                                C = makecform("cmyk2srgb","RenderingIntent",'RelativeColorimetric');
                                I_rgb = applycform(rgb,C);
                                bulkI{newImage} = rgb2gray(I_rgb);
                            end
                        end
                    else
                        %already grayscale
                        bulkI{newImage} = rgb;
                    end


                    %Initialize variables
                    app.WindowSize{newImage} = [100,100];
                    app.SizeThresh{newImage} = 100;
                    app.IntensityThresh{newImage} = 1;
                    
                    app.CheckWater{newImage} = 0;
                    app.CheckOOF{newImage} = 1;
                    app.CheckDIC{newImage} = 0;
                    app.CheckEdge{newImage} = 0;
                    app.EdgeIntensity{newImage} = 0.3;
                   
                    app.MinimumCircularity{newImage} = 0;
                    app.ContaminantIntensity{newImage} = 0;
                    app.AdditionalParameters{newImage} = additionalParameters;
                    
                end
                

                %Set sliders and checkboxes
                
                setSettings(app, currentImage);

                app.BulkI = bulkI;
                %app.DisplayBulkI = displayBulkI;
                %app.OriginalBulkI = originalBulkI;
                
                app.ImageSelectList.Value = currentImage;
                displayRawImageOnly(app,currentImage);
                group(app, false)

            end


        end

        % Value changed function: ImageSelectList
        function imageSelectListCallback(app, event)
            %Make sure that not choosing images for fluorescene (i.e., the
            %image list is multi-select)


            if ~app.FluorescenceMode & ~app.TrackingMode & ~app.MultiSegmenting & ~app.MultiExporting
                selected = app.ImageSelectList.Value;               

                
                if app.ColoredLabels{selected} == 0
                    displayRawImageOnly(app, selected);

                else


                    setSettings(app, selected);
                    displayColoredSegmentation(app, selected);
                    displaySpheroidZoom(app, selected);
                    displayRawImageWithOverlay(app, selected);
                end
            end


        end

        % Button pushed function: ClearWorkspaceButton
        function clearWorkspaceCallback(app, event)
            answer = questdlg('Are you sure you want to clear? This action cannot be undone.', ...
	        'Clear Workspace', ...
	        'Yes','No','No');
            if strcmp(answer, 'Yes')
                app.ImageSelectList.Items = {};
                app.ImageSelectList.ItemsData = [];
                app.ImageSelectList.Value = {};
                app.SpheroidListBox.Items = {};
                app.SpheroidListBox.Value = {};
                app.SpheroidListBox.ItemsData = [];
                app.GroupsTable.Data = {};
                app.GroupingOptionsTable.Data = {};
    
                app.BulkI = {};
                app.Filename = {};
              
                app.Basename = {};


                %%%Segment Parameters%%%%
    
                app.WindowSize = {};
                app.SizeThresh = {};
                app.IntensityThresh = {};
                app.CheckWater = {};
                app.CheckOOF = {};
                app.CheckDIC = {};
                app.CheckEdge = {};
                app.EdgeIntensity = {};
                app.AdditionalParameters = {};
                
                app.MicronsPerPixel = 1;
                app.Resize = {};
                app.MinimumCircularity = {};
                app.ContaminantIntensity = {};


                %%%%Segment Products%%%%%%%
                
                app.Bw_nowater = {};
                app.Bwfinal = {};
                app.CC = {};  
                
                app.Centers = {};
                app.Spheroids_order = {};
                app.ColoredLabels = {};
                app.ImgMasked = {};
                app.BoundingBox = {};
                app.NonCircularCC = {};
                app.CircularBoolean = {};
                app.ContaminantCC = {};
                app.NonContaminantBoolean = {};
                app.PreviousVersion = {};
    
    
                %%%%%Deletions and Stuff%%%%%%
                app.CentersDeleted = {};
                app.CcDeleted = {};
    
    
                %%%%%%%%%Using tools%%%%%%%%%
                app.FirstRemoveCombineSelected = false;
                app.SecondRemoveCombineSelected = false;
                app.FirstSelectedCoords = NaN;
                app.SecondSelectedCoords = NaN;
    
                if app.Marker ~= 0
                    delete(app.Marker)
                    app.Marker = 0;
                end
    
                if app.RectangleRemoveCombineMarker ~= 0
                    delete(app.RectangleRemoveCombineMarker)
                    app.RectangleRemoveCombineMarker = 0;
                end
                app.Marker = 0;
                app.RectangleRemoveCombineMarker = 0;
    
                app.FirstCombine = NaN;
    
    
                app.SpheroidsToSplit = [];
                app.SplitSpheroidsSelected = 0;
                app.BeenSplit = {};
    
                app.SpheroidsToCorrect = [];
                app.EdgeSpheroidsSelected = 0;
                
    
                app.CroppingImage = false;
                app.FirstCropCorner = NaN;
                app.SecondCropCorner = NaN;


                if app.CornerMarker ~= 0
                    delete(app.CornerMarker)
                    app.CornerMarker = 0;
                end
                if app.RectangleMarker ~= 0
                    delete(app.RectangleMarker)
                    app.RectangleMarker = 0;
                end
    
                app.CornerMarker = 0;
                app.RectangleMarker = 0;


                if app.CenterMarker ~= 0
                    delete(app.CenterMarker)
                    app.CenterMarker = 0;
                end
                if app.CircleMarker  ~= 0
                    delete(app.CircleMarker)
                    app.CircleMarker = 0;
                end
    
                app.CropCenter = NaN;
                app.CircleMarker = 0;
                app.CenterMarker = 0;
                app.CropRadius = NaN;
    
                app.SpheroidsToClassify = [];
                app.ClassifySpheroidsSelected = 0;
                app.ChangingClassification = false;
                app.BulkRemove = false;


                toolComponents = {app.RemoveCheckBox, app.CombinecCheckBox, app.SplitSelectedSpheroidtCheckBox};
                for comp = 1:length(toolComponents)
                    toolComponents{comp}.Value = 0;
                end
                app.BrightnessButton.Text = 'Brightness';
                app.ClassificationDropDown.Value = 'None';
                app.ToolReadyToUse = false;
                reenableEverything(app)
                cla(app.RawImageAxes)
               
    
                cla(app.ColoredSegmentationAxes);
                cla(app.SpheroidZoomAxes);
            end


        end

        % Button pushed function: SegmentCurrentButton
        function segmentCurrentCallback(app, event)

            value = app.ImageSelectList.Value;
            
            app.SegmentCurrentButton.Enable = 0;
            %Check for image
            if isempty(app.BulkI)


                mb1 = errordlg('Please select an image file');
                %delete after 2 sec
                t = timer;
                t.StartDelay = 2;
                t.TimerFcn = @(~,~) delete(mb1);
                start(t)


                %set to initial values


            else
                segmentUpdate(app, 1, value);

            end
            app.SegmentCurrentButton.Enable = 1;
        end

        % Value changed function: SpheroidListBox
        function spheroidSelectListCallback(app, event)
            spheroid = app.SpheroidListBox.Value;
            selected = app.ImageSelectList.Value;


            %Load connected components
            cc = app.CC{selected};

            %Check for watershed splitting
            if app.CheckWater{selected} == 1 || app.BeenSplit{selected}


                %Create temp CC
                CC_temp = cc;
                CC_temp.PixelIdxList = cc.PixelIdxList(~cellfun('isempty',cc.PixelIdxList));
                CC_temp.NumObjects = length(CC_temp.PixelIdxList);
                %Create bounding box matrix
                statsBW_temp = regionprops(CC_temp, 'BoundingBox');
                app.BoundingBox{selected} = {statsBW_temp.BoundingBox};

                %Load CC/ CClabel prior to watershed
                CC_nowater = bwconncomp(app.Bw_nowater{selected});
                CClabel_nowater = labelmatrix(CC_nowater);
                %For given spheroid, load it's source cluster
                location = CClabel_nowater(CC_temp.PixelIdxList{spheroid});
                spheroid_orig = round(mode(location(location~=0)));

                %Load CC label and perimeter crop after watershed
                CClabel = labelmatrix(CC_temp);
                imgMasked = app.ImgMasked{selected};

                %Define perimeter of selected spheroid
                spheroid_perim = bwperim(CClabel == spheroid);
                spheroid_perim_dil = imdilate(spheroid_perim,ones(3,3));
                %Label selected spheroid red overlayed on top of blue watershed crop
                imgMasked_new = imoverlay(imgMasked,spheroid_perim_dil,[1 0 0]);

                if spheroid_orig ~= 0
                    %Determine bounding box of pre-watershed cluster
                    statsBW = regionprops(app.Bw_nowater{selected}, 'BoundingBox');
                    %Use either cluster bounding box, or current boudning box
                    %of spheroid (which includes edge correction), whichever is
                    %larger
                    boundingBox = {statsBW.BoundingBox};
                    currentBoundingBox = app.BoundingBox{selected};
                    if (boundingBox{spheroid_orig}(3)*boundingBox{spheroid_orig}(4) > currentBoundingBox{spheroid}(3)*currentBoundingBox{spheroid}(4) )
                        bbox = boundingBox{spheroid_orig};
                        bbox(1:2) = bbox(1:2)-5;
                        bbox(3:4) = bbox(3:4)+10;
                    else
                        bbox = currentBoundingBox{spheroid};
                        bbox(1:2) = bbox(1:2)-5;
                        bbox(3:4) = bbox(3:4)+10;
                    end
                %if spheroid does not share pixels with any originally
                %segmented region (due to edge correction)
        
                else
                    
                    currentBoundingBox = app.BoundingBox{selected};
                    
                    bbox = currentBoundingBox{spheroid};
                    bbox(1:2) = bbox(1:2)-5;
                    bbox(3:4) = bbox(3:4)+10;
                    

                end

                %Display cropped cluster (blue), with selected spheroid highlighted (red)
                cla(app.SpheroidZoomAxes);
                
                
                imshow(imcrop(imgMasked_new, bbox),[], 'Parent', app.SpheroidZoomAxes);


            else
                %Determine bounding box
                boundingBox = app.BoundingBox{selected};
                bbox = boundingBox{spheroid};
                bbox(1:2) = bbox(1:2)-5;
                bbox(3:4) = bbox(3:4)+10;
                
                %Display cropped spheroid
                cla(app.SpheroidZoomAxes);
                
                
                imgMasked = app.ImgMasked{selected};
                imshow(imcrop(imgMasked, bbox),[], 'Parent', app.SpheroidZoomAxes);

            end


        end

        % Button pushed function: ExportCurrentButton
        function exportCurrent(app, event)
            image = app.ImageSelectList.Value;
            app.ExportCurrentButton.Enable = 0;
            %Check for image
            if isempty(app.BulkI)
                mb1 = errordlg('Please select an image file');
                t = timer;
                t.StartDelay = 2;
                t.TimerFcn = @(~,~) delete(mb1);
                start(t)


             %check for segment
            elseif app.ColoredLabels{image} == 0
                mb3 = errordlg('Please segment image');
                t = timer;
                t.StartDelay = 2;
                t.TimerFcn = @(~,~) delete(mb3);
                start(t)
            else

                export(app, image, image, nan, nan);

            end
            app.ExportCurrentButton.Enable = 1;


        end

        % Menu selected function: MetricsMenu
        function metricsButton(app, event)
            selected  = app.ImageSelectList.Value;

            if isempty(app.BulkI)
                mb1 = errordlg('Please select an image file');
                t = timer;
                t.StartDelay = 2;
                t.TimerFcn = @(~,~) delete(mb1);
                start(t)
            elseif (app.ColoredLabels{selected} == 0)
                mb3 = errordlg('Please segment images');
                t = timer;
                t.StartDelay = 2;
                t.TimerFcn = @(~,~) delete(mb3);
                start(t)
            else
                representationStruct = struct;
                %%%%%%%%%%%%%%%%%%%%%%%
                %create all example images here
                selected = app.ImageSelectList.Value;
                
                spheroid = app.SpheroidListBox.Value;
                CC_temp = findCCTemp(app,app.CC{selected});
                
                statsBW = regionprops(CC_temp,'Image','ConvexImage');
                
                %Create perimeters
                convex_perim = bwperim(statsBW(spheroid).ConvexImage);
                %convex_perim_dil = imdilate(convex_perim,ones(3,3));
                
                bw_perim = bwperim(statsBW(spheroid).Image);
                bw_perim_dil = imdilate(bw_perim,ones(3,3));
                
                bw_perim_red = zeros(size(bw_perim_dil));
                bw_perim_green = zeros(size(bw_perim_dil));
                bw_perim_blue = bw_perim_dil;
                bw_perim_color = cat(3,bw_perim_red,bw_perim_green,bw_perim_blue);
                
                representationStruct.convex_perim = convex_perim;
                representationStruct.bw_perim_color = bw_perim_color;
                
                perim_mask = double(bw_perim_dil);
                perim_mask(bw_perim_dil) = 0.8;
                representationStruct.perim_mask = perim_mask;
                
                %Create area example
                bbox = app.BoundingBox{selected}{spheroid};
                bbox(1) = bbox(1)+0.5;
                bbox(2) = bbox(2)+0.5;
                bbox(3) = bbox(3)-1;
                bbox(4) = bbox(4)-1;
                
                crop = imcrop(app.BulkI{selected},bbox);
                
                area = statsBW(spheroid).Image;
                area_red = zeros(size(area));
                area_green = zeros(size(area));
                area_blue = area;
                area_color = cat(3,area_red,area_green,area_blue);
                
                %Create mask for opacity
                area_mask = double(area);
                area_mask(area) = 0.4;
                
                area = statsBW(spheroid).Image;
                area_red = area;
                area_green = zeros(size(area));
                area_blue = zeros(size(area));
                area_color_red = cat(3,area_red,area_green,area_blue);
                
                
                representationStruct.crop = crop;
                representationStruct.area_color = area_color;
                representationStruct.area_color_red = area_color_red;
                representationStruct.area_mask = area_mask;
                
                %Create Extent
                
                %create another mask for grid lines
                grid = zeros(size(statsBW(spheroid).Image));
                grid(10:10:end,:) = 0.7;
                grid(:,10:10:end) = 0.7;
                
                representationStruct.grid = grid;
                
                %Create convexArea
                % convexArea = imoverlay(crop,statsBW(37).Image,[0 0 1]);
                
                convexArea = statsBW(spheroid).ConvexImage;
                convexArea_red = zeros(size(convexArea));
                convexArea_green = zeros(size(convexArea));
                convexArea_blue = convexArea;
                convexArea_color = cat(3,convexArea_red,convexArea_green,convexArea_blue);
                
                convexArea_mask = double(convexArea);
                convexArea_mask(convexArea) = 0.4;
                
                representationStruct.convexArea_color = convexArea_color;
                representationStruct.convexArea_mask = convexArea_mask;
                
                %Create Solidity
                grid2 = grid;
                grid2(~statsBW(spheroid).ConvexImage) = 0;
                grid2(convex_perim) = 0.7;
                
                representationStruct.grid2 = grid2;
                
                %Create new cropping for ellipses
                bbox = app.BoundingBox{selected}{spheroid};
                up = 2*round(bbox(4)*0.2/2)/2;
                left = 2*round(bbox(3)*0.2/2)/2;
                ext_h = 2*round(bbox(4)*0.2/2);
                ext_w = 2*round(bbox(3)*0.2/2);
                bbox(1) = bbox(1)-left+0.5;
                bbox(2) = bbox(2)-up+0.5;
                bbox(3) = bbox(3)+ext_w-1;
                bbox(4) = bbox(4)+ext_h-1;
                
                crop_ellipse = imcrop(app.BulkI{selected},bbox);
                
                %Create Ellipse for ellipse-based metrics
                newAreaHeight = [zeros(up,size(area,2)); area; zeros(up,size(area,2))];
                newArea = [zeros(size(area,1)+ext_h,left) newAreaHeight zeros(size(area,1)+ext_h,left)];
                
                s = regionprops(newArea,{'Centroid' 'MajorAxisLength' 'MinorAxisLength' 'Orientation'});
                
                phi = linspace(0,2*pi,1000);
                cosphi = cos(phi);
                sinphi = sin(phi);
                
                xbar = s.Centroid(1);
                ybar = s.Centroid(2);
                
                a = s.MajorAxisLength/2;
                b = s.MinorAxisLength/2;
                
                theta = pi*s.Orientation/180;
                R = [ cos(theta)   sin(theta); -sin(theta)   cos(theta)];
                
                xy = [a*cosphi; b*sinphi];
                xy = R*xy;
                
                x = xy(1,:) + xbar;
                y = xy(2,:) + ybar;
                
                representationStruct.crop_ellipse = crop_ellipse;
                representationStruct.x = x;
                representationStruct.y = y;
                
                %Create maj axis
                x_Maj = [x(1) x(length(phi)/2)];
                y_Maj = [y(1) y(length(phi)/2)];
                representationStruct.x_Maj = x_Maj;
                representationStruct.y_Maj = y_Maj;
                
                %Create min axis
                x_Min = [x(length(phi)/4) x(length(phi)*3/4)];
                y_Min = [y(length(phi)/4) y(length(phi)*3/4)];
                representationStruct.x_Min = x_Min;
                representationStruct.y_Min = y_Min;
                
                %Create orientation
                x_axis = [s.Centroid(1) size(newArea,2)];
                y_axis = [s.Centroid(2) s.Centroid(2)];
                
                x_maj2 = [s.Centroid(1) x(1)];
                y_maj2 = [s.Centroid(2) y(1)];
                
                representationStruct.x_axis = x_axis;
                representationStruct.y_axis = y_axis;
                representationStruct.x_maj2 = x_maj2;
                representationStruct.y_maj2 = y_maj2;
                
                %Find foci and plot eccentricity
                fociLength = sqrt(a^2-b^2);
                x_foci = [s.Centroid(1)-cos(theta)*fociLength s.Centroid(1)+cos(theta)*fociLength];
                y_foci = [s.Centroid(2)+sin(theta)*fociLength s.Centroid(2)-sin(theta)*fociLength];
                
                representationStruct.x_foci = x_foci;
                representationStruct.y_foci = y_foci;
            
                app.MetricsMenu.Enable = "off";
                app.ExportCurrentButton.Enable = "off";
                app.ExportSetButton.Enable = "off";
                metricsSelected = app.MetricsSelected;
    
                app.MetricsScene = metricSelectionUpdate(app, metricsSelected, representationStruct);
            end

        end

        % Button pushed function: ApplytoAllButton
        function applyToAll(app, event)
            
            app.ApplytoAllButton.Enable = 0;
            if isempty(app.BulkI)
                mb1 = errordlg('Please select an image file');
                t = timer;
                t.StartDelay = 2;
                t.TimerFcn = @(~,~) delete(mb1);
                start(t)


            else
                answer = uigetpref(...
                    'mygraphics',...                        % Group
                    'segmentAll',...           % Preference
                    'Segment All',...                    % Window title
                    {'Are you sure you want to segment all images. You cannot quit this process'
                    },...
                    {'Yes'},...
                    'ExtraOptions','Cancel',...
                    'DefaultButton','Cancel');            % Default choice
                
                if strcmpi(answer, 'Yes')
                    segmentUpdate(app, length(app.BulkI), 0);
                end

            end
            app.ApplytoAllButton.Enable = 1;
            
        end

        % Button pushed function: ExportSetButton
        function exportSet(app, event)
            hasOrganoids = true;
            segmented = true;
            app.ExportSetButton.Enable = 0;
            
            cc = app.CC;
            for i = 1:length(app.BulkI)
               
                
                if ~isstruct(cc{i})
                    segmented = false;
                elseif isempty(cc{i}.PixelIdxList)
                    hasOrganoids=false;
                end
                
            end
            
            
            if isempty(app.BulkI)
                mb1 = errordlg('Please select an image file');
                t = timer;
                t.StartDelay = 2;
                t.TimerFcn = @(~,~) delete(mb1);
                start(t)

            elseif segmented == false
                mb3 = errordlg('Please segment all images');
                t = timer;
                t.StartDelay = 2;
                t.TimerFcn = @(~,~) delete(mb3);
                start(t)
            elseif hasOrganoids == false
                answer = questdlg('One or more of your images has no organoids. Are you sure you want to export?', ...
	            'Export without organoids', ...
	            'Yes','No','No');
                if strcmp(answer, 'Yes')
                    export(app, 1, length(app.BulkI), nan, nan);
                end
            else


                export(app, 1, length(app.BulkI), nan, nan);

            end
            app.ExportSetButton.Enable = 1;
        end

        % Menu selected function: PreviosulySegmentedImagesMenu
        function prevOpen(app, event)
            f = figure('Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
            if isnan(app.MostRecentPrevOpenFolder) 
                [filenames, basename] = uigetfile('*.mat' ,'Select an image set', 'MultiSelect', 'on');
            else
                [filenames, basename] = uigetfile('*.mat' ,'Select an image set', app.MostRecentPrevOpenFolder, 'MultiSelect', 'on');
            end
            delete(f)
            if basename~=0
                app.MostRecentPrevOpenFolder = basename;
            end
            %Request image set
            
            stringFormat = 'Please wait... loading file %d/%d...';
            %If no answer
            if isequal(filenames,0)
                % do nothing
            else
                if ~iscell(filenames)
                    filenames = {filenames};
                end


                additionalParameters = struct;
                additionalParameters.watershedFilterSigma = 5;
                additionalParameters.numberWatershedIterations = 1;
                additionalParameters.imageClosingDegree = 3;
                additionalParameters.imageReconstructionRadius = 5;
                additionalParameters.windowSizeJump = 10;
                additionalParameters.useReconstructedForGradient = 1;
                additionalParameters.clearBorderSplit = 0;
                
                additionalParameters.useInverted = 0;
                additionalParameters.edgePreference = 'Gradient + Watershed';
                h = waitbar(0,sprintf(stringFormat, 1, length(filenames)));
                
                for file = 1:length(filenames)
                    currentImage = length(app.BulkI) + 1;
                    filenameNew = filenames{file};
                    %h = waitbar(0,'Please wait... importing signature...');
                    load(strcat(basename,filenameNew), 'hiddenProps');
                    newAppFile = true;
                    if isfield(hiddenProps, 'waterThresh')
                        newAppFile = false;
                    end
    
                    
                    numNewImages = length(hiddenProps.bulkI);
    
                    %Add saved data into app data
                    app.CC = [app.CC, hiddenProps.CC];
                    
                    
                    app.Filename = [app.Filename, hiddenProps.filename];
                    
                    if newAppFile
                        app.Basename = [app.Basename, hiddenProps.baseName];
                    else
                        newBaseName = cell(1,length(hiddenProps.filename));
                        
                        for i =1:length(newBaseName)
                            newBaseName{i} = hiddenProps.baseName;
                        end
                        app.Basename = [app.Basename, newBaseName];
                    end
                   
                    app.BulkI = [app.BulkI, hiddenProps.bulkI];

                    if newAppFile
                        %app.DisplayBulkI = [app.DisplayBulkI, hiddenProps.displayBulkI];
                        %app.OriginalBulkI = [app.OriginalBulkI, hiddenProps.originalBulkI];
                    else
                        %app.DisplayBulkI = [app.DisplayBulkI, hiddenProps.bulkI];
                        %app.OriginalBulkI = [app.OriginalBulkI, hiddenProps.bulkI];
                    end
    
                    if newAppFile
                        app.CircularBoolean = [app.CircularBoolean, hiddenProps.circularBoolean];
                        app.NonCircularCC = [app.NonCircularCC, hiddenProps.nonCircularCC];
                        app.NonContaminantBoolean = [app.NonContaminantBoolean, hiddenProps.nonContaminantBoolean];
                        app.ContaminantCC = [app.ContaminantCC, hiddenProps.contaminantCC];
                    else
                        tempCCs = hiddenProps.CC;
                        tempBools = cell(1, length(hiddenProps.CC));
                        for i = 1:length(tempCCs)
                            for j = 1:tempCCs{i}.NumObjects
                                tempCCs{i}.PixelIdxList{j} = [];
                            end
                            tempBools{i} = ones(1, tempCCs{i}.NumObjects);
                        end
                        
                        app.CircularBoolean = [app.CircularBoolean, tempBools];
                        app.NonCircularCC = [app.NonCircularCC, tempCCs];
                        app.NonContaminantBoolean = [app.NonContaminantBoolean, tempBools];
                        app.ContaminantCC = [app.ContaminantCC, tempCCs];


                    end
    
    
                    app.Bw_nowater = [app.Bw_nowater, hiddenProps.bw_nowater];
                    if newAppFile
                        app.BeenSplit = [app.BeenSplit, hiddenProps.beenSplit];
                    else
                        app.BeenSplit = [app.BeenSplit, hiddenProps.checkWater];
                    end
    
                    %Preallocate for app components not saved in hiddenProps                
                    app.ColoredLabels(currentImage:currentImage+numNewImages-1) = {0};
                    app.ImgMasked(currentImage:currentImage+numNewImages-1) = {0};
                    
                    app.Bwfinal(currentImage:currentImage+numNewImages-1) = {0};
                    app.BoundingBox(currentImage:currentImage+numNewImages-1) = {0};
                    app.PreviousVersion(currentImage:currentImage+numNewImages-1) = {0};
    
    
                    %Regenerate values for above components (including generate
                    %image mask)
                    waitbar(0,h,sprintf(stringFormat, file, length(filenames)));
                    for newImage = currentImage:(currentImage + numNewImages - 1)
                        
                        app.ImageSelectList.ItemsData(newImage) = newImage;
                        CClabel = labelmatrix(app.CC{newImage});
                        app.ColoredLabels{newImage} = label2rgb(CClabel, 'hsv', 'k', 'shuffle')*0.9;
    
                        bwMock = CClabel;
                        %bwMock(bwMock > 0) = 1;
                        app.Bwfinal{newImage} = logical(bwMock);
                        CC_temp = findCCTemp(app, app.CC{newImage});
                        statsBW = regionprops(CC_temp, 'BoundingBox');
                        app.BoundingBox{newImage} = {statsBW.BoundingBox};
    
                        %Find perimeter
                        bw_perim = double(bwperim(app.Bwfinal{newImage}));
                        %Make visible (dilate)
                        bw_perim_dil = imdilate(bw_perim,ones(3,3));
    
                        % %Color perimeter if wanted
                        %  imgMasked{i} = imoverlay(I,bw_perim_dil,[183 226 232]./255);
    
                        %Normalize raw image to min and max
                        displayI = app.BulkI{newImage};
                        %imgtype(origI)
                        displayI = double(displayI);
                        displayI = (displayI - min(displayI(:)))./(max(displayI(:)) - min(displayI(:)));
                        %Create crop of perimeter and raw image
                        
                        imgMasked = imoverlay(displayI,bw_perim_dil,app.MainSegmentColor);
    
                        
                        if any(~(app.CircularBoolean{newImage}))
                            nonCircCCLabel = labelmatrix(app.NonCircularCC{newImage});
    
                            nonCircPerim = bwperim(nonCircCCLabel ~= 0);
                            nonCirc_perim_dil = imdilate(nonCircPerim, ones(3,3));
                            imgMasked = imoverlay(imgMasked,nonCirc_perim_dil,app.NonCircColor);
                        end
    
                        if any(~(app.NonContaminantBoolean{newImage}))
                            contaminantLabel = labelmatrix(app.ContaminantCC{newImage});
    
                            contaminantPerim = bwperim(contaminantLabel ~= 0);
                            contaminant_perim_dil = imdilate(contaminantPerim, ones(3,3));
                            imgMasked = imoverlay(imgMasked,contaminant_perim_dil,app.ContaminantColor);
                        end
                    
    
                        app.ImgMasked{newImage} = imgMasked;
                        waitbar((newImage+1-currentImage)/numNewImages, h)
                    end
    
                    %Continue adding saved data to app components
    
    
                    if ~newAppFile
                        for i = 1:length(hiddenProps.ccDeleted)
                            if isempty(hiddenProps.ccDeleted{i})
                                hiddenProps.ccDeleted{i} = 0;
                                hiddenProps.centersDeleted{i} = 0;
                            end
                        end
                    end
                    app.CcDeleted = [app.CcDeleted, hiddenProps.ccDeleted];
                    app.CentersDeleted = [app.CentersDeleted, hiddenProps.centersDeleted];
                    
                    app.Centers = [app.Centers, hiddenProps.centers];
    
                    
                    if newAppFile
                        app.IntensityThresh = [app.IntensityThresh, hiddenProps.intensityThresh];
                        app.WindowSize = [app.WindowSize, hiddenProps.windowSize];
                        app.SizeThresh = [app.SizeThresh, hiddenProps.sizeThresh];
                        app.CheckOOF = [app.CheckOOF, hiddenProps.checkOOF];
                        app.CheckEdge = [app.CheckEdge, hiddenProps.checkEdge];
                        app.EdgeIntensity = [app.EdgeIntensity, hiddenProps.edgeIntensity];
                        app.MinimumCircularity = [app.MinimumCircularity, hiddenProps.minimumCircularity];
                        contaminantIntensities = hiddenProps.contaminantIntensity;
                        for entry = 1:length(contaminantIntensities)
                            if contaminantIntensities{entry} > 1
                                
                                contaminantIntensities{entry} = round(double(contaminantIntensities{entry}) / double(max(hiddenProps.bulkI{entry}(:))), 1);
                            end
                        end

                        app.ContaminantIntensity = [app.ContaminantIntensity, contaminantIntensities];
                        app.MicronsPerPixel = hiddenProps.micronsPerPixel;
                    else
                        app.IntensityThresh = [app.IntensityThresh, hiddenProps.thresh];
                        app.WindowSize = [app.WindowSize, hiddenProps.ws];
                        app.SizeThresh = [app.SizeThresh, hiddenProps.small];
                        app.CheckOOF = [app.CheckOOF, hiddenProps.checkScale];
                        newParameters = cell(1, length(hiddenProps.CC));
                        for i = 1:length(newParameters)
                            newParameters{i}= 0;
                        end
                        app.CheckEdge = [app.CheckEdge, newParameters];
                        
                        
                        app.MinimumCircularity = [app.MinimumCircularity, newParameters];
                        app.ContaminantIntensity = [app.ContaminantIntensity, newParameters];
                        for i = 1:length(newParameters)
                            newParameters{i}= 0.3;
                        end
                        app.EdgeIntensity = [app.EdgeIntensity, newParameters];
                        try 
                            app.MicronsPerPixel = hiddenProps.m2p;
                        catch
                            app.MicronsPerPixel = 1;
                        end
                    end
                    if isfield(hiddenProps, 'additionalParameters')
                        oldParameters = checkHiddenPropsParameters(app, hiddenProps.additionalParameters);
                        app.AdditionalParameters = [app.AdditionalParameters, oldParameters];
                    else
                        newParameters = cell(1, length(hiddenProps.CC));
                        for i = 1:length(newParameters)
                            newParameters{i}= additionalParameters;
                        end
                        app.AdditionalParameters = [app.AdditionalParameters, newParameters];
                    end
                    
                    app.CheckWater = [app.CheckWater, hiddenProps.checkWater];
                    
                    app.CheckDIC = [app.CheckDIC, hiddenProps.checkDIC];


                    app.Spheroids_order = [app.Spheroids_order, hiddenProps.spheroids_order];


                    try
                        app.Resize = [app.Resize, hiddenProps.resize];
                    catch
                        newResize = cell(1, length(hiddenProps.CC));
                        for i = 1:length(newResize)
                            newResize{i} = 1;
                        end
                        app.Resize = [app.Resize newResize];
                    end
                    
                    %waitbar(file/length(filenames))
                end
                
                %Set sliders, textboxes, and checkboxes                
                setSettings(app, currentImage);


                %Set listbox2 strings to filenames
                filename = app.Filename;
                
                app.ImageSelectList.Items = filename;
                app.ImageSelectList.Value = currentImage;

                displayColoredSegmentation(app, currentImage);
                displayRawImageWithOverlay(app,currentImage);
                displaySpheroidZoom(app, currentImage);


                close(h);
                group(app, false)
            end
        end

        % Value changed function: RemoveCheckBox
        function removeCheckboxCallback(app, event)
            
            
            value = app.RemoveCheckBox.Value;

            toolSelection(app, 'Remove')
            if app.ToolReadyToUse
                app.RemoveCheckBox.Value = value;


                if value == 1                 


                    disenableEverything(app)
                    app.RawImageAxesEnabled = true;
                    app.ColoredSegmentationAxesEnabled = true;
                    app.RemoveCheckBox.Enable = 1;
                    app.SpheroidListBox.Enable = 1;

                else
                    reenableStuff(app)
                    toolSelection(app, 'Clearing')
                end
            else
                app.RemoveCheckBox.Value = 0;

            end
        end

        % Value changed function: SplitSelectedSpheroidtCheckBox
        function splitSpheroidsCallback(app, event)
            value = app.SplitSelectedSpheroidtCheckBox.Value;
            selected = app.ImageSelectList.Value;
            toolSelection(app, 'Split')
            if app.ToolReadyToUse
                app.SplitSelectedSpheroidtCheckBox.Value = value;

                if value == 1


                    disenableEverything(app)
                    app.RawImageAxesEnabled = true;
                    app.ColoredSegmentationAxesEnabled = true;
                    app.SplitSelectedSpheroidtCheckBox.Enable = 1;
                    app.SpheroidListBox.Enable = 1;
                    %app.ToolsGrid.ColumnWidth = {'1x','1x', '1x', '1x', '0.2x','1x','0.2x', '1x'};
                    app.IndivSplitBlurringEditField.Enable = 1;
                    app.SpheroidsToSplit = zeros(1, length(app.CC{selected}));
                    app.SplitSpheroidsSelected = 0;


                else
                    reenableStuff(app)
                    toolSelection(app, 'Clearing')
                end
            else
                app.SplitSelectedSpheroidtCheckBox.Value = 0;

            end

        end

        % Value changed function: CombinecCheckBox
        function combineCheckBoxCallback(app, event)
            value = app.CombinecCheckBox.Value;
            toolSelection(app, 'Combine')
            if app.ToolReadyToUse
                app.CombinecCheckBox.Value = value;
                if value == 1

                    disenableEverything(app)
                    app.RawImageAxesEnabled = true;
                    app.ColoredSegmentationAxesEnabled = true;
                    app.CombinecCheckBox.Enable = 1;
                    app.SpheroidListBox.Enable = 1;
                    app.CombineCloseSizeEditField.Enable = 1;


                else
                    reenableStuff(app)
                    toolSelection(app, 'Clearing')
                end
            else
                app.CombinecCheckBox.Value = 0;

            end
        end

        % Menu selected function: RawOverlayColoredImagesMenu
        function saveRawAndColorImage(app, event)
            selected = app.ImageSelectList.Value;
            %Check for image load


            if isempty(app.BulkI)
                mb1 = errordlg('Please select an image file');
                t = timer;
                t.StartDelay = 2;
                t.TimerFcn = @(~,~) delete(mb1);
                start(t)
            elseif app.ColoredLabels{selected} == 0
                mb3 = errordlg('Please segment images');
                t = timer;
                t.StartDelay = 2;
                t.TimerFcn = @(~,~) delete(mb3);
                start(t)
            else
                imgMasked = app.ImgMasked{selected};
                coloredLabels = app.ColoredLabels{selected};

                %determine filename for 'save as' input
                
                name = app.Filename{selected};
                ind = find(name == '.',1,'last');
                name(ind:end) = [];

                %Ask for save directory
                if isnan(app.MostRecentImageSaveFolder)
                    dname = uigetdir('Choose folder to save images');
                else
                    dname = uigetdir(app.MostRecentImageSaveFolder,'Choose folder to save images');
                end
                if dname~=0
                    app.MostRecentImageSaveFolder = dname;
                end
                if dname == 0
                    % do nothing
                else
                    %Check for mac vs windows
                    %k = strfind(dname,'\');
                    %if windows
                    if contains(dname, '\')
                        dname = strcat(dname,'\');
                    else
                        dname = strcat(dname,'/');
                    end
                    
                    char(nextname(strcat(dname,name,'_overlay_<1>.tif'), true))
                    imwrite(imgMasked,char(nextname(strcat(dname,name,'_overlay_<1>.tif'), true)));
                    imwrite(coloredLabels,char(nextname(strcat(dname,name,'_colored_<1>.tif'), true)));
                end
            end
        end

        % Menu selected function: CroppedSpheroidMenu
        function saveCroppedSpheroid(app, event)
            selected = app.ImageSelectList.Value;
            spheroid = app.SpheroidListBox.Value;


            if isempty(app.BulkI)
                mb1 = errordlg('Please select an image file');
                t = timer;
                t.StartDelay = 2;
                t.TimerFcn = @(~,~) delete(mb1);
                start(t)
            elseif app.ColoredLabels{selected} == 0
                mb3 = errordlg('Please segment images');
                t = timer;
                t.StartDelay = 2;
                t.TimerFcn = @(~,~) delete(mb3);
                start(t)
            else
                imgMasked = app.ImgMasked{selected};
                %Find bounding box to crop
                bbox = app.BoundingBox{selected}{spheroid};
                bbox(1:2) = bbox(1:2)-5;
                bbox(3:4) = bbox(3:4)+10;
                %create zoom image
                crop = imcrop(imgMasked, bbox);

                name = app.Filename{selected};
                ind = find(name == '.',1,'last');
                name(ind:end) = [];

                if isnan(app.MostRecentImageSaveFolder)
                    dname = uigetdir('Choose folder to save images');
                else
                    dname = uigetdir(app.MostRecentImageSaveFolder,'Choose folder to save images');
                end
                if dname~=0
                    app.MostRecentImageSaveFolder = dname;
                end
                if dname == 0
                    %do nothing
                else
                    %Check for mac vs windows
                    %k = strfind(dname,'\');
                    %if windows
                    if contains(dname, '\')
                        dname = strcat(dname,'\');
                    else
                        dname = strcat(dname,'/');
                    end
                    

                    imwrite(crop,char(nextname(strcat(dname,name,'_',num2str(spheroid),'_crop_<1>.tif'), true)));
                    
                end
            end
        end

        % Menu selected function: ImageSetMenu
        function saveImageSet(app, event)

            segmented = true;
            
            cc = app.CC;
            for i = 1:length(app.BulkI)
               
                
                if ~isstruct(cc{i})
                    segmented = false;
                
                end
                
            end


            if isempty(app.BulkI)
                mb1 = errordlg('Please select an image file');
                t = timer;
                t.StartDelay = 2;
                t.TimerFcn = @(~,~) delete(mb1);
                start(t)
            elseif segmented == false
                mb3 = errordlg('Please segment images');
                t = timer;
                t.StartDelay = 2;
                t.TimerFcn = @(~,~) delete(mb3);
                start(t)
            else
                if isnan(app.MostRecentImageSaveFolder)
                    dname = uigetdir('Choose folder to save images');
                else
                    dname = uigetdir(app.MostRecentImageSaveFolder,'Choose folder to save images');
                   
                end
                if dname~=0
                    app.MostRecentImageSaveFolder = dname;
                end
                if dname == 0
                    %do nothing
                else
                    %Check for mac vs windows
                    %k = strfind(dname,'\');
                    %if windows
                    if contains(dname, '\')
                        dname = strcat(dname,'\');
                    else
                        dname = strcat(dname,'/');
                    end

                   masks = app.ImgMasked;
                   colored = app.ColoredLabels;
                    filename = app.Filename;
                    for i = 1:length(app.BulkI)
                        name = filename{i};
                        ind = find(name == '.',1,'last');
                        name(ind:end) = [];

                        imgMasked = masks{i};
                        coloredLabels = colored{i};

                        imwrite(imgMasked,char(nextname(strcat(dname,name,'_overlay_<1>.tif'),true)));
                        
                        imwrite(coloredLabels,char(nextname(strcat(dname,name,'_colored_<1>.tif'), true)));
                    end
                    %end
                end
            end
        end

        % Menu selected function: CalibrationMenu
        function calibrationCallback(app, event)
            prompt = {'Pixels:','Microns:'};

            dlg_title = 'Calibration';
            num_lines = 1;
            definput = {'1', num2str(app.MicronsPerPixel)};

            answer = inputdlg(prompt,dlg_title,num_lines, definput);
            if isempty(answer) == 1
            else
                micronsPerPixel = str2double(answer{2})./str2double(answer{1});
                app.MicronsPerPixel = micronsPerPixel;


            end
        end

        % Button pushed function: Crop
        function cropImageCallback(app, event)
            
            selected = app.ImageSelectList.Value;
            toolSelection(app, 'Crop')
            if app.ToolReadyToUse

                app.CroppingImage = true;
                disenableEverything(app)
                app.RawImageAxesEnabled = true;
                app.SpheroidListBox.Enable = 1;


                if strcmp(app.CropPreference,'Freehand')
                    getPreviousVersion(app, selected)
                    %https://www.mathworks.com/matlabcentral/answers/442825-crop-with-imfreehand
                    roi = drawfreehand(app.RawImageAxes); % Actual line of code to do the drawing.
                    % Create a binary image ("mask") from the ROI object.
                    if ~isempty(roi.Position)
                        
                        binaryImage = roi.createMask();
                        blackMaskedImage = app.BulkI{selected};
                        blackMaskedImage(~binaryImage) = 0;
                        structBoundaries = bwboundaries(binaryImage);
                        if ~isempty(structBoundaries)
                            xy=structBoundaries{1}; % Get n by 2 array of x,y coordinates.
                            x = xy(:, 2); % Columns.
                            y = xy(:, 1); % Rows.


                            % Now crop the image.
                            leftColumn = min(x);
                            rightColumn = max(x);
                            topLine = min(y);
                            bottomLine = max(y);
                            width = rightColumn - leftColumn + 1;
                            height = bottomLine - topLine + 1;
                            croppedImage = imcrop(blackMaskedImage, [leftColumn, topLine, width, height]);
                            app.BulkI{selected} = croppedImage;


                            displayRawImageOnly(app, selected);
                            clearSegmentProducts(app, selected);
                            app.CroppingImage = false;
                        end
                    end

                    reenableStuff(app)
                else
                    app.RawImageGrid.RowHeight = {40, '1x'};
                    app.CropGrid.RowHeight = {'1x', '1x'};
                    if strcmp(app.CropPreference,'Rectangle')
                        if ~isnan(app.MostRecentHeight)
                            app.UseLastCropDimensionsCheckBox.Enable = 1;
                        else
                            app.UseLastCropDimensionsCheckBox.Enable = 0;
                            app.UseLastCropDimensionsCheckBox.Value = 0;
                        end
                    elseif strcmp(app.CropPreference,'Circle')
                        if ~isnan(app.MostRecentRadius)
                            app.UseLastCropDimensionsCheckBox.Enable = 1;
                        else
                            app.UseLastCropDimensionsCheckBox.Enable = 0;
                            app.UseLastCropDimensionsCheckBox.Value = 0;
                        end
                    end


                end
            end


        end

        % Menu selected function: CroppingMenu
        function cropPreference(app, event)

            default = app.CropPreference;

            answer = uiconfirm(app.UIFigure, {'Please select your preference of cropping style' ...
                'Warning: Freehand cropping may cause the program to lag'}, ...
    	        'Cropping Preference', ...
    	        'Options', {'Rectangle','Circle','Freehand'}, 'DefaultOption', default);
            app.CropPreference = answer;


        end

        % Key release function: UIFigure
        function keyPressedFunction(app, event)
            %%Function called whenever a key is pressed (sometimes app must
            %%be clicked on first for this function to call)
            
            key = event.Key;
            
            %If return/ enter pressed
            if strcmp(key, 'return')

                keyPressedReturn(app,event);
            %if 'e' is pressed, generally to get out of a tool
            elseif strcmp(key, 'e')

                keyPressedEscape_eButton(app,event);

            elseif isempty(event.Modifier) & strcmp(key, 's') & ~app.ThingsDisenabled
                segmentCurrentCallback(app, event)
%             elseif isempty(event.Modifier) & strcmp(key, 'a') & ~app.ThingsDisenabled
%                 applyToAll(app, event)
            elseif  isempty(event.Modifier) &strcmp(key, 'downarrow') & ~app.ThingsDisenabled
                
                if ~app.FluorescenceMode & ~app.TrackingMode
                    selected = app.ImageSelectList.Value + 1;               
                    
                    if selected <= length(app.BulkI)
                        app.ImageSelectList.Value = selected;
                        if app.ColoredLabels{selected} == 0
                            displayRawImageOnly(app, selected);
        
                        else
        
        
                            setSettings(app, selected);
                            displayColoredSegmentation(app, selected);
                            displaySpheroidZoom(app, selected);
                            displayRawImageWithOverlay(app, selected);
                        end
                    end
                end
            elseif isempty(event.Modifier) & strcmp(key, 'uparrow') & ~app.ThingsDisenabled
                
                if ~app.FluorescenceMode & ~app.TrackingMode
                    selected = app.ImageSelectList.Value - 1;   
                    
    
                    if selected > 0
                        app.ImageSelectList.Value = selected;
                        if app.ColoredLabels{selected} == 0
                            displayRawImageOnly(app, selected);
        
                        else
        
        
                            setSettings(app, selected);
                            displayColoredSegmentation(app, selected);
                            displaySpheroidZoom(app, selected);
                            displayRawImageWithOverlay(app, selected);
                        end
                    end
                end
             
            
            elseif strcmp(event.Key, 'z') & ~app.ThingsDisenabled
                selected = app.ImageSelectList.Value;
                undo(app, selected, 1)
            elseif strcmp(event.Key, 'c')
                if app.ThingsDisenabled
                    keyPressedEscape_eButton(app,event);
                end
                if app.CombinecCheckBox.Value == 1
                    app.CombinecCheckBox.Value = 0;
                else
                    app.CombinecCheckBox.Value = 1;
                end
                combineCheckBoxCallback(app, event)
            elseif strcmp(event.Key, 'i')
                if app.ThingsDisenabled
                    keyPressedEscape_eButton(app,event);
                end
                if app.IndividualEdgeCorrectiCheckBox.Value == 1
                    app.IndividualEdgeCorrectiCheckBox.Value = 0;
                else
                    app.IndividualEdgeCorrectiCheckBox.Value = 1;
                end
                edgeCorrctIndivCheckBox(app, event)
            elseif strcmp(event.Key, 't')
                if app.ThingsDisenabled
                    keyPressedEscape_eButton(app,event);
                end
                if app.SplitSelectedSpheroidtCheckBox.Value == 1
                    app.SplitSelectedSpheroidtCheckBox.Value = 0;
                else
                    app.SplitSelectedSpheroidtCheckBox.Value = 1;
                end
                splitSpheroidsCallback(app, event)
            elseif strcmp(event.Key, 'x')
                if app.ThingsDisenabled
                    keyPressedEscape_eButton(app,event);
                end
            
                cropImageCallback(app, event)

            elseif strcmp(event.Key, 'r')
                if app.ThingsDisenabled
                    keyPressedEscape_eButton(app,event);
                end
                if app.RemoveCheckBox.Value == 1
                    app.RemoveCheckBox.Value = 0;
                else
                    app.RemoveCheckBox.Value = 1;
                end
                removeCheckboxCallback(app, event)
            elseif strcmp(event.Key, '1')
                if app.ThingsDisenabled
                    keyPressedEscape_eButton(app,event);
                end
                app.ClassificationDropDown.Value = 'Spheroid';
                changeClassificationCallback(app, event)
            elseif strcmp(event.Key, '2')
                if app.ThingsDisenabled
                    keyPressedEscape_eButton(app,event);
                end
                app.ClassificationDropDown.Value = 'Contaminant';
                changeClassificationCallback(app, event)
            elseif strcmp(event.Key, '3')
                if app.ThingsDisenabled
                    keyPressedEscape_eButton(app,event);
                end
                app.ClassificationDropDown.Value = 'NonCircular';
                changeClassificationCallback(app, event)
            elseif strcmp(event.Key, '4')
                if app.ThingsDisenabled
                    keyPressedEscape_eButton(app,event);
                end
                app.ClassificationDropDown.Value = 'RemoveContaminant';                
                changeClassificationCallback(app, event)
                keyPressedReturnClassification(app, event)
            elseif strcmp(event.Key, '5')
                if app.ThingsDisenabled
                    keyPressedEscape_eButton(app,event);
                end
                app.ClassificationDropDown.Value = 'RemoveNonCircular';
                changeClassificationCallback(app, event)
                keyPressedReturnClassification(app, event)


            elseif ~isempty(event.Modifier) & (strcmp(event.Modifier{1},'control') | strcmp(event.Modifier{1},'command')) & strcmp(event.Key, 'o') & ~app.ThingsDisenabled
                newOpenCallback(app, event)
                
            end
        end

        % Menu selected function: ResizeImagesMenu
        function resizeOptionCallback(app, event)
            %Ask if they want to convert big images to small, reduces
            %resolution but improves speed

            currentImage = app.ImageSelectList.Value;
            bulkI = app.BulkI;
           
            button = uiconfirm(app.UIFigure, 'Convert image size? Smaller image improves run time of segmentation.',...
                'Resize Images', 'Options', {'Yes (Current Image)','Yes (All Images)', 'No'}, 'DefaultOption', 'Yes (Current Image)');
            
            if contains(button, 'Yes')
                scalingFactorString = inputdlg(sprintf("Please enter a scaling factor. Your current image dimensions are %d x %d. The recommended scaling factor is %.2f", size(bulkI{currentImage}, 2), size(bulkI{currentImage}, 1), 768/size(bulkI{currentImage},1)), "Resize Factor", [1 45], string(num2str(768/size(bulkI{currentImage},1))));
                if ~isempty(scalingFactorString)
                    scalingFactor = str2num(scalingFactorString{1});
                else
                    button = 'No';
                end
            end
            if strcmp(button,'Yes (All Images)')
                for selected = 1:length(app.BulkI)
                    getPreviousVersion(app, selected)
                    
                    app.Resize{selected} = app.Resize{selected} * scalingFactor;
                    bulkI{selected} = imresize(bulkI{selected}, scalingFactor);
                        
                    
                end
                app.BulkI = bulkI;
                

                %%%%%%%%Clear data%%%%%
                app.SpheroidListBox.Items = {};
                app.SpheroidListBox.Value = {};
                app.SpheroidListBox.ItemsData = [];


                %%%Segment Parameters%%%%
    
                app.WindowSize(1:length(app.BulkI)) = {0};
                app.SizeThresh(1:length(app.BulkI)) = {0};
                app.IntensityThresh(1:length(app.BulkI)) = {0};
                app.CheckWater(1:length(app.BulkI)) = {0};
                app.CheckOOF(1:length(app.BulkI)) = {0};
                app.CheckDIC(1:length(app.BulkI)) = {0};
                app.CheckEdge(1:length(app.BulkI)) = {0};
                app.EdgeIntensity(1:length(app.BulkI)) = {0};
                app.AdditionalParameters(1:length(app.BulkI)) = {0};
                
                
                app.MinimumCircularity(1:length(app.BulkI)) = {0};
                app.ContaminantIntensity(1:length(app.BulkI)) = {0};
    
    
                %%%%Segment Products%%%%%%%
                
                app.Bw_nowater(1:length(app.BulkI)) = {0};
                app.Bwfinal(1:length(app.BulkI)) = {0};
                app.CC(1:length(app.BulkI)) = {0};  
             
                app.Centers(1:length(app.BulkI)) = {0};
                app.Spheroids_order(1:length(app.BulkI)) = {0};
                app.ColoredLabels(1:length(app.BulkI)) = {0};
                app.ImgMasked(1:length(app.BulkI)) = {0};
                app.BoundingBox(1:length(app.BulkI)) = {0};
                app.NonCircularCC(1:length(app.BulkI)) = {0};
                app.CircularBoolean(1:length(app.BulkI)) = {0};
                app.ContaminantCC(1:length(app.BulkI)) = {0};
                app.NonContaminantBoolean(1:length(app.BulkI)) = {0};
                %app.AxesChild = NaN;
    
    
                %%%%%Deletions and Stuff%%%%%%
                app.CentersDeleted(1:length(app.BulkI)) = {0};
                app.CcDeleted(1:length(app.BulkI)) = {0};


                cla(app.RawImageAxes)
               
    
                cla(app.ColoredSegmentationAxes);
                cla(app.SpheroidZoomAxes);

                displayRawImageOnly(app, currentImage)
            elseif strcmp(button,'Yes (Current Image)')
                getPreviousVersion(app, currentImage)
                %f size(bulkI{currentImage},1) > 768
                    % app.Resize{currentImage} = app.Resize{currentImage} * 768/size(bulkI{currentImage},1);
                    % bulkI{currentImage} = imresize(bulkI{currentImage}, 768/size(bulkI{currentImage},1));
                    app.Resize{currentImage} = app.Resize{currentImage} * scalingFactor;
                    bulkI{currentImage} = imresize(bulkI{currentImage}, scalingFactor);
                   
                %else
                    %app.Resize{currentImage} = app.Resize{currentImage};
                %end

                app.BulkI{currentImage} = bulkI{currentImage};
                %app.DisplayBulkI{currentImage} = displayBulkI{currentImage};

                %%%%%%%%Clear data%%%%%
                app.SpheroidListBox.Items = {};
                app.SpheroidListBox.Value = {};
                app.SpheroidListBox.ItemsData = [];


                %%%Segment Parameters%%%%
    
                app.WindowSize(currentImage) = {0};
                app.SizeThresh(currentImage) = {0};
                app.IntensityThresh(currentImage) = {0};
                app.CheckWater(currentImage) = {0};
                app.CheckOOF(currentImage) = {0};
                app.CheckDIC(currentImage) = {0};
                app.CheckEdge(currentImage) = {0};
                app.EdgeIntensity(currentImage) = {0};
                app.AdditionalParameters(currentImage) = {0};


                app.MinimumCircularity(currentImage) = {0};
                app.ContaminantIntensity(currentImage) = {0};
    
    
                %%%%Segment Products%%%%%%%
                
                app.Bw_nowater(currentImage) = {0};
                app.Bwfinal(currentImage) = {0};
                app.CC(currentImage) = {0};  
           
                app.Centers(currentImage) = {0};
                app.Spheroids_order(currentImage) = {0};
                app.ColoredLabels(currentImage) = {0};
                app.ImgMasked(currentImage) = {0};
                app.BoundingBox(currentImage) = {0};
                app.NonCircularCC(currentImage) = {0};
                app.CircularBoolean(currentImage) = {0};
                app.ContaminantCC(currentImage) = {0};
                app.NonContaminantBoolean(currentImage) = {0};
                %app.AxesChild = NaN;
    
    
                %%%%%Deletions and Stuff%%%%%%
                app.CentersDeleted(currentImage) = {0};
                app.CcDeleted(currentImage) = {0};


                cla(app.RawImageAxes)
               
    
                cla(app.ColoredSegmentationAxes);
                cla(app.SpheroidZoomAxes);

                displayRawImageOnly(app, currentImage)
            end


        end

        % Button pushed function: BrightnessButton
        function brightnessAdjustment(app, event)
            %Rearranges tool grid and allow for brightness adjustments
            text = app.BrightnessButton.Text;
            toolSelection(app, 'Brightness')
            if app.ToolReadyToUse
                %app.BrightnessAdjustCheckBox.Value = value;


                if strcmp(text, 'Brightness')


                    disenableEverything(app)
                    app.RawImageAxesEnabled = true;
                    app.ColoredSegmentationAxesEnabled = true;
                    app.Change_Contrast.Enable = 1;
                    app.Change_Contrast_All.Enable = 1;
                    app.Increase.Enable = 1;
                    app.Decrease.Enable = 1;
                    app.BrightnessButton.Enable = 1;
                    app.FirstBrightParam.Enable = 1;
                    app.SecondBrightParam.Enable = 1;
                    app.BrightnessAdjustment.Enable = 1;
                    app.AdjustmentStyleLabel.Enable = 1;
                   
                    app.Parameter1Label.Enable = 1;
                    app.Parameter2Label.Enable = 1;
                    app.CropGrid.ColumnSpacing = 5;

                    app.BrightnessButton.Text= 'Done';
                    
                    app.SpheroidListBox.Enable = 1;
                    app.CropGrid.ColumnWidth = {0,0, 50, '1x', 0,0,0,0,0};
                    app.RawImageGrid.RowHeight = {100, '1x'};
                elseif strcmp(text, 'Done')
                    
                    app.CropGrid.ColumnWidth = {'1x',50,50,0,50,0,0,50,0};   
                    app.RawImageGrid.RowHeight = {20, '1x'};
                    reenableStuff(app)
                    app.BrightnessButton.Text= 'Brightness';
                    app.CropGrid.ColumnSpacing = 0;
                    
                    
                end
            else
                app.BrightnessButton.Text= 'Brightness';
            end

            
        end

        % Button pushed function: Decrease
        function decreaseBrightness(app, event)
            %Decrease display brightness (pixel intensity) without affecting segmentation
            selected = app.ImageSelectList.Value;
            app.BulkI{selected} = app.BulkI{selected} - 10;
            if app.ColoredLabels{selected} == 0
                displayRawImageOnly(app, selected)
            else
                app.ImgMasked{selected} = app.ImgMasked{selected} - 10;
                displayRawImageWithOverlay(app, selected)
            end
        end

        % Button pushed function: Increase
        function increaseBrightness(app, event)
            %Increase display brightness (pixel intensity) without affecting segmentation
            selected = app.ImageSelectList.Value;
            app.BulkI{selected} = app.BulkI{selected} + 10;
            if app.ColoredLabels{selected} == 0
                displayRawImageOnly(app, selected)
            else
                app.ImgMasked{selected} = app.ImgMasked{selected} + 10;
                displayRawImageWithOverlay(app, selected)

            end
        end

        % Button pushed function: Change_Contrast
        function changeContrast(app, event)
            %Change image contrast depending on adjustment style in
            %preferences, will affect segmentation if selected as
            %preference
            
            selected = app.ImageSelectList.Value;
            getPreviousVersion(app, selected)


            i = app.BulkI{selected};
            i = adjustImage(app, i, app.BrightnessAdjustment.Value);
            app.BulkI{selected} = i;
           
           %Reset values in the case that contrast change affects
           %segmentation


            displayRawImageOnly(app, selected)


           clearSegmentProducts(app, selected);
            

        end

        % Value changed function: ClassificationDropDown
        function changeClassificationCallback(app, event)
            value = app.ClassificationDropDown.Value;
            toolSelection(app, 'Classify')
            if app.ToolReadyToUse


                app.ClassificationDropDown.Value = value;
                app.ChangingClassification = false;
                app.BulkRemove = false;
                disenableEverything(app)
                app.RawImageAxesEnabled = true;
                app.ColoredSegmentationAxesEnabled = true;
                app.SpheroidListBox.Enable = 1;


                if strcmp(app.ClassificationDropDown.Value, 'Spheroid')|| strcmp(app.ClassificationDropDown.Value, 'Contaminant')  || strcmp(app.ClassificationDropDown.Value, 'NonCircular')
                    app.ChangingClassification = true;
                    
                   
                elseif strcmp(app.ClassificationDropDown.Value, 'RemoveContaminant')|| strcmp(app.ClassificationDropDown.Value, 'RemoveNonCircular')
                    app.BulkRemove = true;
                end
            else
                app.ClassificationDropDown.Value = 'None';
            end
        end

        % Button pushed function: RevertButton
        function revertImageCrop(app, event)
            toolSelection(app, 'RevertImage')
            if app.ToolReadyToUse
                %Return image and display to original state, undoing crop and
                %brightness changes. Will undo segmentation results.
                
                selected = app.ImageSelectList.Value;
                getPreviousVersion(app, selected)
   
                displayRawImageOnly(app, selected);


                clearSegmentProducts(app, selected);
            end


        end

        % Button pushed function: BeginAnalysisButton
        function multiSelectDoneSelecting(app, event)
           if app.FluorescenceMode
               %store data to be used for fluorescence and call fluorescence
               %scene
    
                shareData = struct;
                shareData.CC = app.CC;
                shareData.ImgMasked = app.ImgMasked;
                shareData.ImageIndexes = app.ImageSelectList.Value;
                shareData.MainSegmentColor = app.MainSegmentColor;
                shareData.FileNames = app.ImageSelectList.Items;
                shareData.BulkI = app.BulkI;
                %shareData.DisplayBulkI = app.DisplayBulkI;
                
                shareData.Spheroids_order = app.Spheroids_order;
                shareData.Centers = app.Centers;
                shareData.Resize = app.Resize;
                shareData.MicronsPerPixel = app.MicronsPerPixel;
                shareData.MostRecentExport = app.MostRecentImageExportFolder;
                shareData.MostRecentSave = app.MostRecentImageSaveFolder;
    
    
                app.FluorescenceScene = fluorescenceAnalysisScene(app, shareData, false, 'Main');
           elseif app.TrackingMode
                   shareData = struct;
                   shareData.CC = app.CC;
                   shareData.ImgMasked = app.ImgMasked;                   
                   shareData.FileNames = app.ImageSelectList.Items;
                   shareData.BulkI = app.BulkI;
                   selectedImages = app.ImageSelectList.Value;
                   shareData.MainSegmentColor = app.MainSegmentColor;
                   shareData.Centers = app.Centers;
                   shareData.Spheroids_order = app.Spheroids_order;
                   shareData.Resize = app.Resize;
                   shareData.MicronsPerPixel = app.MicronsPerPixel;
                   shareData.MetricsSelected = app.MetricsSelected;
                   shareData.MostRecentExport = app.MostRecentImageExportFolder;
                   shareData.MostRecentSave = app.MostRecentImageSaveFolder;
               if strcmp(app.TrackingFormat, "MultiBatch")  

                    %Select images and divide into groups based on grouping
                    %variable information (see export in groups function
                    %for details on implementation)
                    imageGroupData = app.GroupsTable.Data;                   
                    
                    groupingOptionsData = app.GroupingOptionsTable.Data;
                    useInGrouping = groupingOptionsData.UseInGrouping;
                    groupsToUse = groupingOptionsData{useInGrouping, ["Group"]};
                    if isempty(groupsToUse)
                        errordlg("You must select a grouping criteria to track in batches")
                    else
                        groupsWithKeys = ~strcmp(groupingOptionsData.Key, "");
                        groupsWithKeysToUse = groupingOptionsData{groupsWithKeys, ["Group"]};
                        
                        
                        noGroupingImages = cellfun(@isempty,imageGroupData{:,groupsToUse{1}});
                        imagesWithGroups = imageGroupData(:, groupsToUse);
                        
                        imagesWithGroups{noGroupingImages, :} = {'0'};


                        groups = unique(imagesWithGroups(~noGroupingImages, :),'stable');                   
                        
                        indicesByBatches = [];
                        batchStarts = [];
                        groupConditionsArray = {};
                        for group = 1:height(groups)                
                            groupConditions = groups(group, :);
                            matchesKey = true;
                            if ~isempty(groupsWithKeysToUse)
                                for keyNum = 1:length(groupsWithKeysToUse)
                                    keyGroup = groupsWithKeysToUse{keyNum};
                                    
                                    key = groupingOptionsData{strcmp(groupingOptionsData.Group, keyGroup), ["Key"]};
                                    MoC = groupingOptionsData{strcmp(groupingOptionsData.Group, keyGroup), ["MatchOrContains"]};
                                    if MoC == categorical("Exact Match")
                                        
                                        if ~strcmp(key, groupConditions{1,keyGroup})
                                            matchesKey = false;
                                        end
            
                                    else
                                        
                                        if ~contains(groupConditions{1,keyGroup}, key)
                                            matchesKey = false;
                                        end
                                    end
                                end
                            end
                            %If matches key, assign images in group as a
                            %batch to be tracked, and assign batch
                            %information
                            if matchesKey


                                indicesToUse = intersect(selectedImages, find(ismember(imagesWithGroups,groupConditions, "rows"))');
                                if length(indicesToUse) > 1
                                    groupConditionsArray{end+1} = groupConditions;
                                    batchStarts(end+1) = length(indicesByBatches)+1;
                                    indicesByBatches = [indicesByBatches indicesToUse];
                                    
                                end


                            end
            
                          
                        end
                        if ~isempty(indicesByBatches)
                            shareData.ImageIndexes = indicesByBatches;
                            shareData.BatchStartIndices = batchStarts;
                            shareData.groupConditions = groupConditionsArray;
                            shareData.groupingTable = app.GroupsTable.Data(indicesByBatches, :);
                            app.TrackingScene = trackingAnalysisScene(app, shareData, false);
                        else
                            errordlg("Unable to load tracking scene ... no images matched grouping criteria. Check the 'Groups' tab in the image selection panel to ensure that your grouping was applied correctly")
                        end
                    end


               else
                   shareData.ImageIndexes = selectedImages;
                   shareData.BatchStartIndices = [1];
                   shareData.groupConditions = nan;
                   shareData.groupingTable = nan;
                   app.TrackingScene = trackingAnalysisScene(app, shareData, false);
               end


           elseif app.MultiExporting
               app.ImagesToExport = app.ImageSelectList.Value;
               start = 1;
               finish = length(app.ImagesToExport);
               export(app, start, finish, nan, nan);
               app.ImageSelectGrid.RowHeight = {'0.2x', '1x', 0};
               app.LeftGrid.RowHeight = {20, '1.5x', '1x', '1.2x'};
               app.ImageSelectList.Multiselect = 0;
               app.FluorescenceMode = false;
               app.TrackingMode = false;
               app.MultiExporting = false;
               app.MultiSegmenting = false;
               reenableStuff(app)


           elseif app.MultiSegmenting
               %%%%%%%%%%Replace after testing
               app.ImagesToSegment = app.ImageSelectList.Value; 


               finish = length(app.ImagesToSegment);
               app.ImageSelectList.Multiselect = 0;
               app.ImageSelectList.Value = app.ImagesToSegment(1);
               segmentUpdate(app, finish, 0);
               app.ImageSelectGrid.RowHeight = {'0.2x', '1x', 0};
               app.LeftGrid.RowHeight = {20, '1.5x', '1x', '1.2x'};
               
               app.FluorescenceMode = false;
               app.TrackingMode = false;
               app.MultiExporting = false;
               app.MultiSegmenting = false;
               reenableStuff(app)


           end
        end

        % Button pushed function: EscButton
        function escapeFluorescenceOrTracking(app, event)
            %Escape flurorescence image choosing
            app.ImageSelectGrid.RowHeight = {'0.2x', '1x', 0};
            app.LeftGrid.RowHeight = {20, '1.5x', '1x', '1.2x'};
            app.ImageSelectList.Multiselect = 0;
            app.FluorescenceMode = false;
            app.TrackingMode = false;
            app.MultiExporting = false;
            app.MultiSegmenting = false;
            reenableStuff(app)
        end

        % Menu selected function: FluorescenceMenu
        function fluorescence(app, event)
        %Called when flurescence tab is selected, changes layout 
           
          hasNoOrganoids = [];
          segmented = true;

          cc = app.CC;
          for i = 1:length(app.BulkI)


              if ~isstruct(cc{i})
                  segmented = false;
              elseif isempty(cc{i}.PixelIdxList)
                  hasNoOrganoids=[hasNoOrganoids i];
              end

          end


            if segmented == false | isempty(app.BulkI)
                
                group = 'Fluorescence';
                pref =  'Fluorescence';
                title = 'Fluorescence';
                quest = {'You are about to enter fluorescence analysis mode' ...
                    '' ...
                    'You will only be able to select the option to load a' ...
                    'previously saved tracking file.'};
                pbtns = {'OK'};

                [~,~] = uigetpref(group,pref,title,quest,pbtns);

                app.ImageSelectGrid.RowHeight = {'0x', '1x', 60};
                app.FluorescenceButtonsGrid.RowHeight = {'1x', '1x'};
                app.LeftGrid.RowHeight = {20, '1x', 0, 0};
                app.ImageSelectList.Multiselect = 1;
                app.FluorescenceMode = true;
                disenableEverything(app)
                app.RawImageAxesEnabled = true;

                app.BeginAnalysisButton.Enable = 0;
                app.EscButton.Enable = 1;
                app.ImageSelectList.Enable = 1;
                app.LoadSavedFileButton.Enable = 1;
                app.BeginAnalysisButton.Text = 'Begin Fluorescence';

            elseif ~isempty(hasNoOrganoids)
                organoidString = "na";
                for org = hasNoOrganoids
                    if strcmp(organoidString, "na")
                        organoidString = num2str(org);
                    else
                        organoidString = organoidString+", "+num2str(org);
                    end
                end
                answer = questdlg(sprintf('Some images have no organoids. Please delete these images to remove downstream complications.\n Image indice(s) %s \n Select ignore to continue to load a saved file', organoidString), 'Track Without Organoids', 'OK','Ignore','OK');
                if strcmp(answer, "Ignore")
                    app.ImageSelectGrid.RowHeight = {'0x', '1x', 60};
                    app.FluorescenceButtonsGrid.RowHeight = {'1x', '1x'};
                    app.LeftGrid.RowHeight = {20, '1x', 0, 0};
                    app.ImageSelectList.Multiselect = 1;
                    app.FluorescenceMode = true;
                    disenableEverything(app)
                    app.RawImageAxesEnabled = true;
    
                    app.BeginAnalysisButton.Enable = 0;
                    app.EscButton.Enable = 1;
                    app.ImageSelectList.Enable = 1;
                    app.LoadSavedFileButton.Enable = 1;
                    app.BeginAnalysisButton.Text = 'Begin Fluorescence';
                end
            else
                toolSelection(app, 'Fluorescence')


                if app.ToolReadyToUse
    
    
                    group = 'Fluorescence';
                    pref =  'Fluorescence';
                    title = 'Fluorescence';
                    quest = {'You are about to enter fluorescence analysis mode' ...
                        '' ...
                        'Please select the image files from the list box that you ' ...
                        'would like to analyze '};
                    pbtns = {'OK'};
    
                    [~,~] = uigetpref(group,pref,title,quest,pbtns);
    
                    app.ImageSelectGrid.RowHeight = {'0x', '1x', 60};
                    app.FluorescenceButtonsGrid.RowHeight = {'1x', '1x'};
                    app.LeftGrid.RowHeight = {20, '1x', 0, 0};
                    app.ImageSelectList.Multiselect = 1;
                    app.FluorescenceMode = true;
                    disenableEverything(app)
                    app.RawImageAxesEnabled = true;
                    app.LoadSavedFileButton.Enable = 1;
                    app.BeginAnalysisButton.Enable = 1;
                    app.EscButton.Enable = 1;
                    app.ImageSelectList.Enable = 1;
                    app.BeginAnalysisButton.Text = 'Begin Fluorescence';
                end

            end
        end

        % Menu selected function: BrightnessMenu
        function brightnessPreference(app, event)
            %Called when brightness tabis selected, stores information for
            %segmentation example and calls brightness scene
            selected = app.ImageSelectList.Value;
            if isempty(app.BulkI)


                mb1 = errordlg('Please select an image file');
                %delete after 2 sec
                t = timer;
                t.StartDelay = 2;
                t.TimerFcn = @(~,~) delete(mb1);
                start(t)


                %set to initial values


            else


                image = app.BulkI{selected};
                settings = struct;

                settings.checkOOF = app.OutofFocusCorrectionCheckBox.Value;
                settings.checkDIC = app.DICCorrectionCheckBox.Value;
                settings.checkWater = app.SplitWholeImageCheckBox.Value;
                settings.checkEdge = app.EdgeCorrectionCheckBox.Value;
                settings.edgeIntensity = app.EdgeIntensityEditField.Value;
                settings.edgePreference = app.EdgePreference;
                settings.intensityThresh = app.IntensityThresholdEditField.Value;
                settings.sizeThresh = app.SizeThresholdEditField.Value;
                settings.windowSize = app.WindowSizeEditField.Value;

                
                settings.watershedFilterSigma = app.WatershedFilterSigma;
                settings.imageClosingDegree = app.ImageClosingDegree;
                settings.imageReconstructionRadius = app.ImageReconstructionRadius;
                settings.windowSizeJump = app.WindowSizeJump;
                settings.useReconstructedForGradient = app.UseReconstructedForGradient;
                settings.clearBorderSplit = app.ClearBorderSplit;
                settings.useInverted = app.UseInverted;


                app.BrightnessScene = brightnessSelection(app, settings, image);
            end
        end

        % Value changed function: IndividualEdgeCorrectiCheckBox
        function edgeCorrctIndivCheckBox(app, event)
            %Correct edges for individual edges, not implemented yet
            value = app.IndividualEdgeCorrectiCheckBox.Value;
            
            selected = app.ImageSelectList.Value;
            toolSelection(app, 'Edge')
            if app.ToolReadyToUse
                app.IndividualEdgeCorrectiCheckBox.Value = value;

                if value == 1


                    disenableEverything(app)
                    app.RawImageAxesEnabled = true;
                    app.ColoredSegmentationAxesEnabled = true;
                    app.IndividualEdgeCorrectiCheckBox.Enable = 1;   
                    app.IndividualEdgeCorrectPreference.Enable = 1;   
                    if ~strcmp(app.IndividualEdgeCorrectPreference.Value, 'Gradient + Watershed')
                        app.IndividualEdgeIntensityEditField.Enable = 1;
                    end
                    app.SpheroidListBox.Enable = 1;


                    app.SpheroidsToCorrect = zeros(1, length(app.CC{selected}));
                    app.EdgeSpheroidsSelected = 0;
                    %app.ToolsGrid.ColumnWidth = {'1x','1x', '1x', '1x','0.2x', '1x','0.2x', '1x'};


                else
                    reenableStuff(app)
                    toolSelection(app, 'Clearing')
                    
                end
            else
                app.IndividualEdgeCorrectiCheckBox.Value = 0;
                app.IndividualEdgeIntensityEditField.Enable = 0;

            end
        end

        % Button pushed function: Change_Contrast_All
        function changeContrastAll(app, event)
            %Change image contrast depending on adjustment style in
            %preferences, will affect segmentation if selected as
            %preference
            
            for selected = 1:length(app.BulkI)
                getPreviousVersion(app, selected)


                    i = app.BulkI{selected};
                    i = adjustImage(app, i, app.BrightnessAdjustment.Value);
                    app.BulkI{selected} = i;
                    clearSegmentProducts(app, selected);
                
                
            %Reset values in the case that contrast change affects
             %segmentation
            
            end
            
            displayRawImageOnly(app, app.ImageSelectList.Value)

        end

        % Button pushed function: MaximizeRawImage
        function maximzeRawImage(app, event)
            if ~app.RawImageMaximized
                expandRawImage(app)
                app.MaximizeRawImage.Text = "-";
            else
                collapseRawImage(app)
                app.MaximizeRawImage.Text = "+";
            end
        end

        % Value changed function: EdgeCorrectionCheckBox
        function edgeCorrectAll(app, event)
            value = app.EdgeCorrectionCheckBox.Value;
            if value == 1 & ~strcmp(app.IndividualEdgeCorrectPreference.Value, 'Gradient + Watershed')
                app.EdgeIntensityEditField.Enable = 1;
            else
                app.EdgeIntensityEditField.Enable = 0;
            end
        end

        % Menu selected function: UndoMenu
        function undoCallback(app, event)
            
        end

        % Menu selected function: TrackingMenu
        function tracking(app, event)
            
        end

        % Menu selected function: EdgeCorrectionMenu
        function edgeCorrectionPreference(app, event)
            if isempty(app.BulkI)
                mb1 = errordlg('Please select an image file');
                t = timer;
                t.StartDelay = 2;
                t.TimerFcn = @(~,~) delete(mb1);
                start(t)
            else
                
                default = app.EdgePreference;

                answer = uiconfirm(app.UIFigure, {'Please select your preference of edge correction' ...
                    '' ...
                    '"Gradient Preserve Boundary" will maintain boundaries and only extend objects segmented without correction' ...
                    'This correction is more conservative that "Gradient Only": recommended for crowded images'...
                    ''...
                    '"Gradient Only" may produce new segmented objects or combine existing ones' ...
                    '' ...
                    '"Gradient + Watershed" uses a watershed transform to preserve boundaries and segment the most ' ...
                    'noticeable boundaries in the region, and therefore may not extend to the true boundary.' ...
                    'It is the most conservative and slowest option.' ...
                    '' ...
                    'Boundary Preservation may not preserve watershed splits if used in conjunction with the ' ...
                    '"Split Whole Image" tool.' ...
                    
                    }, ...
    	            'Correction Preference', ...
    	            'Options', {'Gradient Preserve Boundary', 'Gradient Only', 'Gradient + Watershed'}, 'DefaultOption', default, 'CancelOption', default);
                app.EdgePreference = answer;
                if strcmp(answer, 'Gradient + Watershed')
                    app.EdgeIntensityEditField.Enable = 0;
                else
                    app.EdgeIntensityEditField.Enable = 1;
                end

            end
        end

        % Button pushed function: SelectImagestoExportButton
        function exportSelected(app, event)
            %Allow users to choose images to export
            hasOrganoids = true;
            segmented = true;
            app.ExportSetButton.Enable = 0;
            
            cc = app.CC;
            for i = 1:length(app.BulkI)
               
                
                if ~isstruct(cc{i})
                    segmented = false;
                elseif isempty(cc{i}.PixelIdxList)
                    hasOrganoids=false;
                end
                
            end
            if isempty(app.BulkI)
                mb1 = errordlg('Please select an image file');
                t = timer;
                t.StartDelay = 2;
                t.TimerFcn = @(~,~) delete(mb1);
                start(t)

            elseif segmented == false
                mb3 = errordlg('Please segment all images');
                t = timer;
                t.StartDelay = 2;
                t.TimerFcn = @(~,~) delete(mb3);
                start(t)
            elseif hasOrganoids == false
                answer = questdlg('One or more of your images has no organoids. Are you sure you want to export?', ...
	            'Export without organoids', ...
	            'Yes','No','No');
                if strcmp(answer, 'Yes')
                    app.ImageSelectGrid.RowHeight = {'0x', '1x', 30};
                    app.FluorescenceButtonsGrid.RowHeight = {'1x', '0x'};
                    app.LeftGrid.RowHeight = {20, '1x', 0, 0};
                    app.ImageSelectList.Multiselect = 1;               
                    disenableEverything(app)
                    app.RawImageAxesEnabled = true;
                    app.MultiExporting = true;
                    app.BeginAnalysisButton.Enable = 1;
                    app.EscButton.Enable = 1;
                    app.ImageSelectList.Enable = 1;
                    app.BeginAnalysisButton.Text = 'Export Images';
                end
            else


                app.ImageSelectGrid.RowHeight = {'0x', '1x', 30};
                app.FluorescenceButtonsGrid.RowHeight = {'1x', '0x'};
                app.LeftGrid.RowHeight = {20, '1x', 0, 0};
                app.ImageSelectList.Multiselect = 1;               
                disenableEverything(app)
                app.RawImageAxesEnabled = true;
                app.MultiExporting = true;
                app.BeginAnalysisButton.Enable = 1;
                app.EscButton.Enable = 1;
                app.ImageSelectList.Enable = 1;
                app.BeginAnalysisButton.Text = 'Export Images';

            end
            app.ExportSetButton.Enable = 1;
        end

        % Button pushed function: SelectImagestoSegmentButton
        function segmentSelected(app, event)
            %Allow users to choose images to segment
            if isempty(app.BulkI)
                mb3 = errordlg('Please select an image file');
                t = timer;
                t.StartDelay = 2;
                t.TimerFcn = @(~,~) delete(mb3);
                start(t)


            else

                app.ImageSelectGrid.RowHeight = {'0x', '1x', 30};
                
                app.FluorescenceButtonsGrid.RowHeight = {'1x', '0x'};
                app.LeftGrid.RowHeight = {20, '1x', 0, 0};
                app.ImageSelectList.Multiselect = 1;               
                disenableEverything(app)
                app.RawImageAxesEnabled = true;
                app.MultiSegmenting = true;
                app.BeginAnalysisButton.Enable = 1;
                app.EscButton.Enable = 1;
                app.ImageSelectList.Enable = 1;
                app.BeginAnalysisButton.Text = 'Segment Images';

            end
        end

        % Menu selected function: AdditionalParametersMenu
        function additionalParameters(app, event)
            
            prompt = {'WindowStepSize: (Positive Integer >= 10)', 'SegmentCloseSize: (Positive Integer)', 'PresegReconstrSize: (Non-negative integer)',  'ReconstrForEdgeCorrect: (false, true) *true will reduce noise but is more likely to miss details near organoid edge','WatershedBlurStdDev: (Non-negative integer)', 'ClearBorderBeforeSplit: (false, true) *true is recommended if no organoids are present near edge of image*', 'BrightForegroundObj (Segment bright regions): (false, true) *true is preferable if attempting segmentation using fluorescent markers, false is required for traditional brightfield organoid segmentation*', 'Enter 1 to reset all above parameters to default values'};

            dlg_title = 'Additional Parameters';
            num_lines = 1;

            if app.ClearBorderSplit == 1
                clearBorderInput = "true";
            else
                clearBorderInput = "false";
            end

            if app.UseReconstructedForGradient == 1
                useReconstructedInput = "true";
            else
                useReconstructedInput = "false";
            end

            if app.UseInverted == 1
                useInvertedInput = "true";
            else
                useInvertedInput = "false";
            end


            definput = {num2str(app.WindowSizeJump), num2str(app.ImageClosingDegree), num2str(app.ImageReconstructionRadius), num2str(useReconstructedInput), num2str(app.WatershedFilterSigma), num2str(clearBorderInput), num2str(useInvertedInput), '0'};
            logicals = [4,6,7];
            nonLogicals = [1,2,3,5];
            goodInput = false;
            nonNumeric = false;
            while goodInput == false
                answer = inputdlg(prompt,dlg_title,num_lines, definput);
                if isempty(answer) == 1
                    goodInput = true;
                else
                    goodInput = true;
                    for i = logicals
                        if ~strcmp(answer{i}, 'true') && ~strcmp(answer{i}, 'false')
                            goodInput = false;
                        end
                    end
                    for i = nonLogicals
                        str2double(answer{i})
                        if isnan(str2double(answer{i}))                          
                        
                            goodInput = false;
                            nonNumeric = true;
                        end
                    end
                end
                if goodInput == false
                    if nonNumeric
                        d = errordlg("Make sure that all numeric values are entered correctly", "Additional Parameters Bad Input");
                    else
                        d = errordlg("Make sure that all 'true' or 'false' values are entered correctly", "Additional Parameters Bad Input");
                    end
                    pause(1)
                    try
                        close(d)
                    catch
                    end

                end

            end
            
            if isempty(answer) == 1
            else
                if strcmp(answer{8}, '1')
                    app.WatershedFilterSigma = 5;
                    app.NumberWatershedIterations = 1;
                    app.ImageClosingDegree = 3;
                    app.ImageReconstructionRadius = 5;
                    app.WindowSizeJump = 10;
                    app.UseReconstructedForGradient = 1;
                    app.UseInverted = 0;
                    app.ClearBorderSplit = 1;
                    

                else


                    app.WatershedFilterSigma = round(str2double(answer{5}));
                    if isnan(app.WatershedFilterSigma)
                        app.WatershedFilterSigma = 5;
                    elseif app.WatershedFilterSigma < 0 
                        app.WatershedFilterSigma = 0;
                    end


                    app.ImageReconstructionRadius = round(str2double(answer{3}));
                    if isnan(app.ImageReconstructionRadius)
                        app.ImageReconstructionRadius = 5;
                    elseif app.ImageReconstructionRadius < 0
                        app.ImageReconstructionRadius = 0;
                    end
    
                    app.ImageClosingDegree = round(str2double(answer{2}));
                    if isnan(app.ImageClosingDegree)
                        app.ImageClosingDegree = 3;
                    elseif app.ImageClosingDegree < 1
                        app.ImageClosingDegree = 1;
                    end
    
                    app.WindowSizeJump = round(str2double(answer{1}));
                    if isnan(app.WindowSizeJump)
                        app.WindowSizeJump = 10;
                    elseif app.WindowSizeJump < 10
                        app.WindowSizeJump = 10;
                    end
    
%                     app.UseReconstructedForGradient = str2double(answer{4});
%                     if app.UseReconstructedForGradient ~= 0 || isnan(app.UseReconstructedForGradient)
%                         app.UseReconstructedForGradient = 1;
%                     end
% 
%                     app.ClearBorderSplit = str2double(answer{6});
%                     if app.ClearBorderSplit ~= 0 || isnan(app.ClearBorderSplit)
%                         app.ClearBorderSplit = 1;
%                     end
                    if strcmp(answer{4},"true")
                        useReconstructedOutput = 1;
                    else
                        useReconstructedOutput = 0;
                    end
                    if strcmp(answer{6},"true")
                        clearBorderOutput = 1;
                    else
                        clearBorderOutput = 0;
                    end
                    if strcmp(answer{7},"true")
                        useInvertedOutput = 1;
                    else
                        useInvertedOutput = 0;
                    end
                    app.UseReconstructedForGradient = useReconstructedOutput;
                    app.ClearBorderSplit = clearBorderOutput;
                    app.UseInverted = useInvertedOutput;
%                     app.UseInverted = str2double(answer{7});
%                     if isnan(app.UseInverted)
%                         app.UseInverted = 0;
%                     elseif app.UseInverted ~= 0
%                         app.UseInverted = 1;
%                     end
                end


            end
        end

        % Button pushed function: RotateButton
        function rotateImage(app, event)
            selected = app.ImageSelectList.Value;
            toolSelection(app, 'Rotate')
            getPreviousVersion(app, selected);
            if app.ToolReadyToUse
                if strcmp(app.RotateButton.Text, 'Rotate')
                    disenableEverything(app)
                    app.RawImageAxesEnabled = true;
                    app.RotateButton.Enable = 1;
                    app.RotateButton.Text = 'Done Rotating';
                    app.RotationAngleEditField.Enable = 1;
                    app.RotationAngleEditField.Value = 0;
                    app.CropGrid.ColumnWidth = {0,0, 0, 0,'1x','1x',50,0,0};
                else
                    value = app.RotationAngleEditField.Value;
                    app.RotateButton.Text = 'Rotate';
                    app.CropGrid.ColumnWidth = {'1x',50,50,0,50,0,0,50,0};
                    
                    if app.ColoredLabels{selected} == 0
                    else
                        clearSegmentProducts(app, selected);
                    end
                    app.BulkI{selected} = imrotate(app.BulkI{selected}, value, 'bilinear', 'loose');
                    %app.DisplayBulkI{selected} = imrotate(app.DisplayBulkI{selected}, value, 'bilinear', 'loose');
                    reenableStuff(app)
                end
            
            end
        end

        % Value changed function: RotationAngleEditField
        function rotateDegreesCallback(app, event)
            selected = app.ImageSelectList.Value;
            value = app.RotationAngleEditField.Value;
            
            cla(app.RawImageAxes);
            displayBulkI = imrotate(app.BulkI{selected}, value, 'bilinear', 'loose');
            imshow(displayBulkI,[], 'Parent', app.RawImageAxes);
            %if image not segmented yet
            if app.ColoredLabels{selected} == 0
            else
            
                cla(app.ColoredSegmentationAxes);
                cla(app.SpheroidZoomAxes);
                app.SpheroidListBox.Items = {};
            end
            
        end

        % Value changed function: ContaminantIntensityEditField
        function changeContaminant(app, event)
            value = app.ContaminantIntensityEditField.Value;
            
            selected = app.ImageSelectList.Value;
            app.ContaminantIntensity{selected} = value;
            newSegment = 0;
            bwfinal = app.Bwfinal{selected};
            bbox = app.BoundingBox{selected};
            necessaryData = getNecessaryData(app, selected);


            if app.ColoredLabels{selected} == 0
            else
                
                outputs = makeImageMaskedChangeData(app, bwfinal, bbox, newSegment, necessaryData);
                app.ImgMasked{selected} = outputs.ImgMasked;
                app.NonCircularCC{selected} = outputs.NonCircularCC;
                app.CircularBoolean{selected} = outputs.CircularBoolean;
                app.ContaminantCC{selected} = outputs.ContaminantCC;
                app.NonContaminantBoolean{selected} = outputs.NonContaminantBoolean; 
                displayRawImageWithOverlay(app, selected)
            end
        end

        % Value changed function: MinimumCircularityEditField
        function changeCircularity(app, event)
            value = app.MinimumCircularityEditField.Value;
            selected = app.ImageSelectList.Value;
            app.MinimumCircularity{selected} = value;
            newSegment = 0;
            bwfinal = app.Bwfinal{selected};
            bbox = app.BoundingBox{selected};
            necessaryData = getNecessaryData(app, selected);
            
            if app.ColoredLabels{selected} == 0
            else
             
                outputs = makeImageMaskedChangeData(app, bwfinal, bbox, newSegment, necessaryData);
                app.ImgMasked{selected} = outputs.ImgMasked;
                app.NonCircularCC{selected} = outputs.NonCircularCC;
                app.CircularBoolean{selected} = outputs.CircularBoolean;
                app.ContaminantCC{selected} = outputs.ContaminantCC;
                app.NonContaminantBoolean{selected} = outputs.NonContaminantBoolean; 
                
                displayRawImageWithOverlay(app, selected)
              
            end
        end

        % Value changed function: BrightnessAdjustment
        function changeBrightnessStyle(app, event)
            value = app.BrightnessAdjustment.Value;
            param1 = app.FirstBrightParam;
            param1Label = app.Parameter1Label;
            param2 = app.SecondBrightParam;
            param2Label = app.Parameter2Label;

            switch value
                case 'Lb'
                    param1.Limits = [0 1];
                    param1.RoundFractionalValues = 0;
                    param1.Enable = 1;
                    param1.Value = 0.5;                    
                    param1Label.Text = 'Amount';


                    param2.Limits = [0 1];
                    param2.RoundFractionalValues = 0;
                    param2.Enable = 1;
                    param2.Value = 0;                    
                    param2Label.Text = 'Alpha Blend (0 or 1)';


                case 'Ff'
                    param1.Limits = [0 Inf];
                    param1.RoundFractionalValues = 1;
                    param1.Enable = 1;
                    param1.Value = 30;                    
                    param1Label.Text = 'Sigma';
                    
                    param2.Enable = 0;
                    param2Label.Text = 'Parameter 2: ';
                case 'Lc'
                    param1.Limits = [0 1];
                    param1.RoundFractionalValues = 0;
                    param1.Enable = 1;
                    param1.Value = 0.3;                    
                    param1Label.Text = 'Edge Threshold';
                    

                    param2.Limits = [-1 1];
                    param2.RoundFractionalValues = 0;
                    param2.Enable = 1;
                    param2.Value = 0.5;                    
                    param2Label.Text = 'Amount';
                   

                case 'Oc'
                    param1.Enable = 0;
                    param1Label.Text = 'Parameter 1: ';
                    param2.Enable = 0;
                    param2Label.Text = 'Parameter 2: ';
                case 'Ll'
                    param1.Limits = [0 1];
                    param1.RoundFractionalValues = 0;
                    param1.Enable = 1;
                    param1.Value = 0.5;                    
                    param1Label.Text = 'Sigma';
                    
                
                    param2.Limits = [0.01 10];
                    param2.RoundFractionalValues = 0;
                    param2.Enable = 1;
                    param2.Value = 1;                    
                    param2Label.Text = 'Alpha';
                    

                case 'Rh'
                    param1.Limits = [0 1];
                    param1.RoundFractionalValues = 0;
                    param1.Enable = 1;
                    param1.Value = 0.5;                    
                    param1Label.Text = 'Amount';
                    
                    param2.Enable = 0;
                    param2Label.Text = 'Parameter 2: ';

                case 'Eh'
                    param1.Limits = [0 Inf];
                    param1.RoundFractionalValues = 1;
                    param1.Enable = 1;
                    param1.Value = 64;                    
                    param1Label.Text = 'Number of Bins';
                    
                    param2.Enable = 0;
                    param2Label.Text = 'Parameter 2: ';


                case 'No'
                    param1.Enable = 0;
                    param1Label.Text = 'Parameter 1: ';
                    param2.Enable = 0;
                    param2Label.Text = 'Parameter 2: ';
                    
            end
            app.FirstBrightParam = param1;
            app.SecondBrightParam = param2;
            app.Parameter1Label = param1Label;
            app.Parameter2Label = param2Label;
           
        end

        % Value changed function: ShowIDsSwitch
        function showIDSwitch(app, event)
            value = app.ShowIDsSwitch.Value;
            selected = app.ImageSelectList.Value;
            centers_display = app.Centers{selected};
            if app.ColoredLabels{selected} == 0
            else
                if value == 1
                    spherNum = 0;
                    for i = 1:length(app.CC{selected}.PixelIdxList)
                        %%acount for removed spheroids
                        
                        if (~isempty(app.CC{selected}.PixelIdxList{i}))
                            spherNum = spherNum + 1;
                            % find if selected point is inside of spheroid                       
    
                                text(app.ColoredSegmentationAxes, centers_display(i,1)-5, centers_display(i,2), num2str(spherNum), 'color', 'w', 'FontWeight', 'bold');                          
                                
                            
                        end
                    end
                else
                    displayColoredSegmentation(app, selected)
                end
            end
        end

        % Button pushed function: QuitSegmentationButton
        function quitSegment(app, event)
            
        end

        % Value changed function: UseLastCropDimensionsCheckBox
        function useLastCrop(app, event)
            value = app.UseLastCropDimensionsCheckBox.Value;
            if app.RectangleMarker ~= 0
                delete(app.RectangleMarker)
            end
            if app.CornerMarker~=0
               delete(app.CornerMarker)
            end
            if app.CircleMarker~=0
                delete(app.CircleMarker)
            end
            if app.CenterMarker~=0
                delete(app.CenterMarker)
            end
            app.FirstCropCorner = nan;
            app.SecondCropCorner = nan;
            app.CropCenter = nan;
            app.CropRadius = nan;
            
            
        end

        % Button pushed function: LoadSavedFileButton
        function loadSavedImageTrackOrFluor(app, event)
            shareData = struct;
            f = figure('Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
            if isnan(app.MostRecentPrevOpenFolder) 
                [filename, basename] = uigetfile('*.mat' ,'Select an image set', 'MultiSelect', 'off');
            else
                [filename, basename] = uigetfile('*.mat' ,'Select an image set', app.MostRecentPrevOpenFolder, 'MultiSelect', 'off');
            end
            delete(f)
            if basename~=0
                app.MostRecentPrevOpenFolder = basename;
            end
            
            %Request image set


            %If no answer
            strfrmt = 'You must select a file to open the %s window';
            if app.TrackingMode
                mode = 'tracking';
            else
                mode = 'fluorescence';
            end
            if isequal(filename,0)
                % do nothing
                mb1 = errordlg(sprintf(strfrmt, mode));
                t = timer;
                t.StartDelay = 2;
                t.TimerFcn = @(~,~) delete(mb1);
                start(t)            
            else
                shareData.filename = filename;
                shareData.basename = basename;
                
                shareData.MostRecentExport = app.MostRecentImageExportFolder;
                shareData.MostRecentSave = app.MostRecentImageSaveFolder;
                if app.TrackingMode
                     app.TrackingScene = trackingAnalysisScene(app, shareData, true);
                else
                    app.FluorescenceScene = fluorescenceAnalysisScene(app, shareData, true, nan);
                end
            end
        end

        % Button pushed function: ExportingroupsButton
        function exportInGroups(app, event)
            hasOrganoids = true;
            segmented = true;
            app.ExportSetButton.Enable = 0;
            
            cc = app.CC;
            for i = 1:length(app.BulkI)
               
                
                if ~isstruct(cc{i})
                    segmented = false;
                elseif isempty(cc{i}.PixelIdxList)
                    hasOrganoids=false;
                end
                
            end
            if isempty(app.BulkI)
                mb1 = errordlg('Please select an image file');
                t = timer;
                t.StartDelay = 2;
                t.TimerFcn = @(~,~) delete(mb1);
                start(t)
                app.ExportSetButton.Enable = 1;
                return

            elseif segmented == false
                mb3 = errordlg('Please segment all images');
                t = timer;
                t.StartDelay = 2;
                t.TimerFcn = @(~,~) delete(mb3);
                start(t)
                app.ExportSetButton.Enable = 1;
                return
            elseif hasOrganoids == false
                answer = questdlg('One or more of your images has no organoids. Are you sure you want to export?', ...
	            'Export without organoids', ...
	            'Yes','No','No');
                if strcmp(answer, 'No')
                    app.ExportSetButton.Enable = 1;
                    return
                end
            end
            
                f = figure('Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
                if isnan(app.MostRecentImageExportFolder)
                    [answer,PathName] = uiputfile({'*.xls','Excel File (*.xls)';'*.xlsx','Excel File (*.xlsx)';'*.mat','MAT File (*.mat)'},'Export Metrics As');
                else
                    [answer,PathName] = uiputfile({'*.xls','Excel File (*.xls)';'*.xlsx','Excel File (*.xlsx)';'*.mat','MAT File (*.mat)'},'Export Metrics As', app.MostRecentImageExportFolder);
                    
                end
                delete(f)
    
                imageGroupData = app.GroupsTable.Data; %Variable values for each image
                groupingOptionsData = app.GroupingOptionsTable.Data; %Selected options for image grouping
                useInGrouping = groupingOptionsData.UseInGrouping; %Binary values for whether a variable is used to determine grouping
                groupsToUse = groupingOptionsData{useInGrouping, ["Group"]}; %Retrieve actual names of groups to be used
                groupsWithKeys = ~strcmp(groupingOptionsData.Key, ""); %Check if any groups have a specific key (that is, are they going to be used to filter images)
                groupsWithKeysToUse = groupingOptionsData{groupsWithKeys, ["Group"]}; %Get names of groups with keys
                
                imagesWithGroups = imageGroupData(:, groupsToUse); %Only use images that have grouping information
                
                groups = unique(imagesWithGroups); %Find distinct groups (unique combinations of the selected variable values)


                for group = 1:height(groups)                
                    groupConditions = groups(group, :);
                    matchesKey = true;
                    if ~isempty(groupsWithKeysToUse)
                        for keyNum = 1:length(groupsWithKeysToUse)
                            keyGroup = groupsWithKeysToUse{keyNum};
                            %Check if group matches key (if given)
                            key = groupingOptionsData{strcmp(groupingOptionsData.Group, keyGroup), ["Key"]};
                            MoC = groupingOptionsData{strcmp(groupingOptionsData.Group, keyGroup), ["MatchOrContains"]};
                            if MoC == categorical("Exact Match")
                                
                                if ~strcmp(key, groupConditions{1,keyGroup})
                                    matchesKey = false;
                                end
    
                            else
                                
                                if ~contains(groupConditions{1,keyGroup}, key)
                                    matchesKey = false;
                                end
                            end
                        end
                    end
                    %Break up selected export filename so it can be modified
                    %with group information
                    filenameSplit = split(answer, ".");
                    fileBase = filenameSplit{1};
                    exportFormat = filenameSplit{2};
                    %If matches key (or no key applied to groups), export
                    if matchesKey
                        indices = find(ismember(imagesWithGroups,groupConditions, "rows"));
                        app.ImagesToExport = indices;
                        startInd = 1;
                        finish = length(app.ImagesToExport);
                        app.MultiExporting = true;
    
                        filename = fileBase;
                        for groupName = 1:width(groupConditions)
                            filename = filename + "_" + groupConditions{1, groupName};                    
                        end
                        filename = filename + "." + exportFormat;
                        export(app, startInd, finish, strcat(PathName, '\'), filename)
                    end
    
                  
                end
                
                app.MultiExporting = false;
            
            app.ExportSetButton.Enable = 1;

        end

        % Menu selected function: TrackSingleBatchMenu
        function trackSingleBatch(app, event)
            %Called when tracking tab is selected, changes layout 
            
            app.TrackingFormat = "SingleBatch";
            hasNoOrganoids = [];
            segmented = true;
            
            cc = app.CC;
            for i = 1:length(app.BulkI)
               
                
                if ~isstruct(cc{i})
                    segmented = false;
                elseif isempty(cc{i}.PixelIdxList)
                    hasNoOrganoids=[hasNoOrganoids i];
                end
                
            end
           
            if segmented == false | isempty(app.BulkI)
               
                group = 'Tracking';
                pref =  'Tracking';
                title = 'Tracking';
                quest = {'You are about to enter tracking analysis mode' ...
                    '' ...
                    'You will only be able to select the option to load a' ...
                    'previously saved tracking file.'};
                pbtns = {'OK'};

                [~,~] = uigetpref(group,pref,title,quest,pbtns);

                app.ImageSelectGrid.RowHeight = {'0x', '1x', 60};
                app.FluorescenceButtonsGrid.RowHeight = {'1x', '1x'};
                app.LeftGrid.RowHeight = {20, '1x', 0, 0};
                app.ImageSelectList.Multiselect = 1;
                app.TrackingMode = true;
                disenableEverything(app)
                app.RawImageAxesEnabled = true;

                app.BeginAnalysisButton.Enable = 0;
                app.EscButton.Enable = 1;
                app.ImageSelectList.Enable = 1;
                app.LoadSavedFileButton.Enable = 1;
                app.BeginAnalysisButton.Text = 'Begin Tracking';

            elseif ~isempty(hasNoOrganoids)
                organoidString = "na";
                for org = hasNoOrganoids
                    if strcmp(organoidString, "na")
                        organoidString = num2str(org);
                    else
                        organoidString = organoidString+", "+num2str(org);
                    end
                end
                answer = questdlg(sprintf('Some images have no organoids. Please delete these images to remove downstream complications.\n Image indice(s) %s \n Select ignore to continue to load a saved file', organoidString), 'Track Without Organoids', 'OK','Ignore','OK');
                if strcmp(answer, "Ignore")
                    app.ImageSelectGrid.RowHeight = {'0x', '1x', 60};
                    app.FluorescenceButtonsGrid.RowHeight = {'1x', '1x'};
                    app.LeftGrid.RowHeight = {20, '1x', 0, 0};
                    app.ImageSelectList.Multiselect = 1;
                    app.TrackingMode = true;
                    disenableEverything(app)
                    app.RawImageAxesEnabled = true;
    
                    app.BeginAnalysisButton.Enable = 0;
                    app.EscButton.Enable = 1;
                    app.ImageSelectList.Enable = 1;
                    app.LoadSavedFileButton.Enable = 1;
                    app.BeginAnalysisButton.Text = 'Begin Tracking';
                end
            else
                toolSelection(app, 'Tracking')


                if app.ToolReadyToUse
    
    
                    group = 'Tracking';
                    pref =  'Tracking';
                    title = 'Tracking';
                    quest = {'You are about to enter tracking analysis mode' ...
                        '' ...
                        'Please select the image files from the list box that you ' ...
                        'would like to analyze. Select images in chronological order.'};
                    pbtns = {'OK'};
    
                    [~,~] = uigetpref(group,pref,title,quest,pbtns);
    
                    app.ImageSelectGrid.RowHeight = {'0x', '1x', 60};
                    app.FluorescenceButtonsGrid.RowHeight = {'1x', '1x'};
                    app.LeftGrid.RowHeight = {20, '1x', 0, 0};
                    app.ImageSelectList.Multiselect = 1;
                    app.TrackingMode = true;
                    disenableEverything(app)
                    app.RawImageAxesEnabled = true;
                    app.LoadSavedFileButton.Enable = 1;
                    app.BeginAnalysisButton.Enable = 1;
                    app.EscButton.Enable = 1;
                    app.ImageSelectList.Enable = 1;
                    app.BeginAnalysisButton.Text = 'Begin Tracking';
                end

            end
        end

        % Menu selected function: TrackInBatchesMenu
        function trackBatches(app, event)
            %Called when tracking tab is selected, changes layout 
            
            app.TrackingFormat = "MultiBatch";
            hasNoOrganoids = [];
            segmented = true;
            
            cc = app.CC;
            for i = 1:length(app.BulkI)
               
                
                if ~isstruct(cc{i})
                    segmented = false;
                elseif isempty(cc{i}.PixelIdxList)
                    hasNoOrganoids=[hasNoOrganoids i];
                end
                
            end
            
            if segmented == false | isempty(app.BulkI)
              
                group = 'Tracking';
                pref =  'Tracking';
                title = 'Tracking';
                quest = {'You are about to enter tracking analysis mode' ...
                    '' ...
                    'You will only be able to select the option to load a' ...
                    'previously saved tracking file.'};
                pbtns = {'OK'};

                [~,~] = uigetpref(group,pref,title,quest,pbtns);

                app.ImageSelectGrid.RowHeight = {'0x', '1x', 60};
                app.FluorescenceButtonsGrid.RowHeight = {'1x', '1x'};
                app.LeftGrid.RowHeight = {20, '1x', 0, 0};
                app.ImageSelectList.Multiselect = 1;
                app.TrackingMode = true;
                disenableEverything(app)
                app.RawImageAxesEnabled = true;

                app.BeginAnalysisButton.Enable = 0;
                app.EscButton.Enable = 1;
                app.ImageSelectList.Enable = 1;
                app.LoadSavedFileButton.Enable = 1;
                app.BeginAnalysisButton.Text = 'Begin Tracking';

            elseif ~isempty(hasNoOrganoids)
                organoidString = "na";
                for org = hasNoOrganoids
                    if strcmp(organoidString, "na")
                        organoidString = num2str(org);
                    else
                        organoidString = organoidString+", "+num2str(org);
                    end
                end
                answer = questdlg(sprintf('Some images have no organoids. Please delete these images to remove downstream complications.\n Image indice(s) %s \n Select ignore to continue to load a saved file', organoidString), 'Track Without Organoids', 'OK','Ignore','OK');
                if strcmp(answer, "Ignore")
                    app.ImageSelectGrid.RowHeight = {'0x', '1x', 60};
                    app.FluorescenceButtonsGrid.RowHeight = {'1x', '1x'};
                    app.LeftGrid.RowHeight = {20, '1x', 0, 0};
                    app.ImageSelectList.Multiselect = 1;
                    app.TrackingMode = true;
                    disenableEverything(app)
                    app.RawImageAxesEnabled = true;
    
                    app.BeginAnalysisButton.Enable = 0;
                    app.EscButton.Enable = 1;
                    app.ImageSelectList.Enable = 1;
                    app.LoadSavedFileButton.Enable = 1;
                    app.BeginAnalysisButton.Text = 'Begin Tracking';
                end
            else
                toolSelection(app, 'Tracking')


                if app.ToolReadyToUse
    
    
                    group = 'Tracking';
                    pref =  'Tracking';
                    title = 'Tracking';
                    quest = {'You are about to enter tracking analysis mode' ...
                        '' ...
                        'Please select the image files from the list box that you ' ...
                        'would like to analyze. Only select images that have the same set of time' ...
                        'points if you would like to sort your images'};
                    pbtns = {'OK'};
    
                    [~,~] = uigetpref(group,pref,title,quest,pbtns);
    
                    app.ImageSelectGrid.RowHeight = {'0x', '1x', 60};
                    app.FluorescenceButtonsGrid.RowHeight = {'1x', '1x'};
                    app.LeftGrid.RowHeight = {20, '1x', 0, 0};
                    app.ImageSelectList.Multiselect = 1;
                    app.TrackingMode = true;
                    disenableEverything(app)
                    app.RawImageAxesEnabled = true;
                    app.LoadSavedFileButton.Enable = 1;
                    app.BeginAnalysisButton.Enable = 1;
                    app.EscButton.Enable = 1;
                    app.ImageSelectList.Enable = 1;
                    app.BeginAnalysisButton.Text = 'Begin Tracking';
                end

            end
        end

        % Button pushed function: DeleteImageButton
        function deleteImage(app, event)
            value = app.ImageSelectList.Value;
            
            length(app.BulkI)
            app.BulkI(value) = [];
            app.Filename(value) = [];
            length(app.BulkI)
            app.Basename(value) = [];
            %app.DisplayBulkI = {}; 
            %app.OriginalBulkI = {}; %Cell array storing all images in organoseg workspace


            %%%Segment Parameters%%%%

            app.WindowSize(value) = [];
            app.SizeThresh(value) = [];
            app.IntensityThresh(value) = [];
            app.CheckWater(value) = [];
            app.CheckOOF(value) = [];
            app.CheckDIC(value) = [];
            app.CheckEdge(value) = [];
            app.EdgeIntensity(value) = [];
            app.AdditionalParameters(value) = [];
            
            app.MicronsPerPixel = 1;
            
            app.MinimumCircularity(value) = [];
            app.ContaminantIntensity(value) = [];


            %%%%Segment Products%%%%%%%
            
            app.Bw_nowater(value) = [];
            app.Bwfinal(value) = [];
            app.CC(value) = [];
            
            app.Centers(value) = [];
            app.Spheroids_order(value) = [];
            app.ColoredLabels(value) = [];
            app.ImgMasked(value) = [];
            app.BoundingBox(value) = [];
            app.NonCircularCC(value) = [];
            app.CircularBoolean(value) = [];
            app.ContaminantCC(value) = [];
            app.NonContaminantBoolean(value) = [];
            %app.AxesChild = NaN;
            app.PreviousVersion(value) = [];


            %%%%%Deletions and Stuff%%%%%%
            app.CentersDeleted(value) = [];
            app.CcDeleted(value) = [];


            app.BeenSplit(value) = [];


            app.ImageSelectList.Items(value) = [];
            app.ImageSelectList.ItemsData(end) = []; 
            group(app, false)
            if value ~= 1
                
                app.ImageSelectList.Value = value-1;
            else
               
                app.ImageSelectList.Value = value;
            end
            imageSelectListCallback(app, event)

            group(app, false)


        end

        % Value changed function: RegexEditField
        function changeRegexGrouping(app, event)
            value = app.RegexEditField.Value;
           
                
            app.FilenameRegex = value;
            group(app, true)
            
        end

        % Menu selected function: UndoCurrentMenu
        function undoCurrentCallback(app, event)
            selected = app.ImageSelectList.Value;
            undo(app, selected, 1)
        end

        % Menu selected function: UndoAllMenu
        function undoAllCallback(app, event)
            selected = app.ImageSelectList.Value;
            for i = 1:length(app.BulkI)
                if i == selected
                    undo(app,i,1)
                else
                    undo(app,i,0)
                end
            end
        end

        % Value changed function: IndividualEdgeCorrectPreference
        function indivEdgeCorrectPreferenceChange(app, event)
            value = app.IndividualEdgeCorrectPreference.Value;
            if app.IndividualEdgeCorrectiCheckBox.Value == 1
                if ~strcmp(app.IndividualEdgeCorrectPreference.Value, 'Gradient + Watershed')
                    app.IndividualEdgeIntensityEditField.Enable = 1;
                else
                    app.IndividualEdgeIntensityEditField.Enable = 0;
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyReleaseFcn = createCallbackFcn(app, @keyPressedFunction, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.Text = 'Open';
            app.NewImageSetMenu = uimenu(app.OpenMenu);
            app.NewImageSetMenu.MenuSelectedFcn = createCallbackFcn(app, @newOpenCallback, true);
            app.NewImageSetMenu.Text = 'New Image Set';
            app.PreviosulySegmentedImagesMenu = uimenu(app.OpenMenu);
            app.PreviosulySegmentedImagesMenu.MenuSelectedFcn = createCallbackFcn(app, @prevOpen, true);
            app.PreviosulySegmentedImagesMenu.Text = 'Previosuly Segmented Images';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.Text = 'Save';
            app.CurrentImagesMenu = uimenu(app.SaveMenu);
            app.CurrentImagesMenu.Text = 'Current Images';
            app.RawOverlayColoredImagesMenu = uimenu(app.CurrentImagesMenu);
            app.RawOverlayColoredImagesMenu.MenuSelectedFcn = createCallbackFcn(app, @saveRawAndColorImage, true);
            app.RawOverlayColoredImagesMenu.Text = 'Raw-Overlay/ Colored Images';
            app.CroppedSpheroidMenu = uimenu(app.CurrentImagesMenu);
            app.CroppedSpheroidMenu.MenuSelectedFcn = createCallbackFcn(app, @saveCroppedSpheroid, true);
            app.CroppedSpheroidMenu.Text = 'Cropped Spheroid';
            app.ImageSetMenu = uimenu(app.SaveMenu);
            app.ImageSetMenu.MenuSelectedFcn = createCallbackFcn(app, @saveImageSet, true);
            app.ImageSetMenu.Text = 'Image Set';
            app.UndoMenu = uimenu(app.FileMenu);
            app.UndoMenu.MenuSelectedFcn = createCallbackFcn(app, @undoCallback, true);
            app.UndoMenu.Text = 'Undo ';
            app.UndoCurrentMenu = uimenu(app.UndoMenu);
            app.UndoCurrentMenu.MenuSelectedFcn = createCallbackFcn(app, @undoCurrentCallback, true);
            app.UndoCurrentMenu.Text = 'Undo Current';
            app.UndoAllMenu = uimenu(app.UndoMenu);
            app.UndoAllMenu.MenuSelectedFcn = createCallbackFcn(app, @undoAllCallback, true);
            app.UndoAllMenu.Text = 'Undo All';
            app.PreferencesMenu = uimenu(app.UIFigure);
            app.PreferencesMenu.Text = 'Preferences';
            app.MetricsMenu = uimenu(app.PreferencesMenu);
            app.MetricsMenu.MenuSelectedFcn = createCallbackFcn(app, @metricsButton, true);
            app.MetricsMenu.Text = 'Metrics';
            app.CalibrationMenu = uimenu(app.PreferencesMenu);
            app.CalibrationMenu.MenuSelectedFcn = createCallbackFcn(app, @calibrationCallback, true);
            app.CalibrationMenu.Text = 'Calibration';
            app.BrightnessMenu = uimenu(app.PreferencesMenu);
            app.BrightnessMenu.MenuSelectedFcn = createCallbackFcn(app, @brightnessPreference, true);
            app.BrightnessMenu.Text = 'Brightness';
            app.ResizeImagesMenu = uimenu(app.PreferencesMenu);
            app.ResizeImagesMenu.MenuSelectedFcn = createCallbackFcn(app, @resizeOptionCallback, true);
            app.ResizeImagesMenu.Text = 'Resize Images';
            app.CroppingMenu = uimenu(app.PreferencesMenu);
            app.CroppingMenu.MenuSelectedFcn = createCallbackFcn(app, @cropPreference, true);
            app.CroppingMenu.Text = 'Cropping';
            app.EdgeCorrectionMenu = uimenu(app.PreferencesMenu);
            app.EdgeCorrectionMenu.MenuSelectedFcn = createCallbackFcn(app, @edgeCorrectionPreference, true);
            app.EdgeCorrectionMenu.Text = 'Edge Correction';
            app.AdditionalParametersMenu = uimenu(app.PreferencesMenu);
            app.AdditionalParametersMenu.MenuSelectedFcn = createCallbackFcn(app, @additionalParameters, true);
            app.AdditionalParametersMenu.Text = 'Additional Parameters';
            app.AdditionalAnalysisMenu = uimenu(app.UIFigure);
            app.AdditionalAnalysisMenu.Text = 'Additional Analysis';
            app.FluorescenceMenu = uimenu(app.AdditionalAnalysisMenu);
            app.FluorescenceMenu.MenuSelectedFcn = createCallbackFcn(app, @fluorescence, true);
            app.FluorescenceMenu.Text = 'Fluorescence';
            app.TrackingMenu = uimenu(app.AdditionalAnalysisMenu);
            app.TrackingMenu.MenuSelectedFcn = createCallbackFcn(app, @tracking, true);
            app.TrackingMenu.Text = 'Tracking';
            app.TrackSingleBatchMenu = uimenu(app.TrackingMenu);
            app.TrackSingleBatchMenu.MenuSelectedFcn = createCallbackFcn(app, @trackSingleBatch, true);
            app.TrackSingleBatchMenu.Text = 'Track Single Batch';
            app.TrackInBatchesMenu = uimenu(app.TrackingMenu);
            app.TrackInBatchesMenu.MenuSelectedFcn = createCallbackFcn(app, @trackBatches, true);
            app.TrackInBatchesMenu.Enable = 'off';
            app.TrackInBatchesMenu.Text = 'Track In Batches';
            app.TotalGrid = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.TotalGrid);
            app.LeftGrid = uigridlayout(app.LeftPanel);
            app.ExportPanel = uipanel(app.LeftGrid);
            app.ExportPanel.Title = 'Export';
            app.ExportGrid = uigridlayout(app.ExportPanel);
            app.ExportCurrentButton = uibutton(app.ExportGrid, 'push');
            app.ExportCurrentButton.ButtonPushedFcn = createCallbackFcn(app, @exportCurrent, true);
            app.ExportCurrentButton.Text = 'Current';
            app.ExportSetButton = uibutton(app.ExportGrid, 'push');
            app.ExportSetButton.ButtonPushedFcn = createCallbackFcn(app, @exportSet, true);
            app.ExportSetButton.Text = 'Export Set';
            app.WhichMetricsSwitch = uiswitch(app.ExportGrid, 'slider');
            app.WhichMetricsSwitch.Items = {'Selected Metrics', 'All metrics'};
            app.WhichMetricsSwitch.ItemsData = {'SelectedMetrics', 'AllMetrics'};
            app.WhichMetricsSwitch.Value = 'SelectedMetrics';
            app.SelectImagestoExportButton = uibutton(app.ExportGrid, 'push');
            app.SelectImagestoExportButton.ButtonPushedFcn = createCallbackFcn(app, @exportSelected, true);
            app.SelectImagestoExportButton.Tooltip = {'Press this button to allow multiselection of images'};
            app.SelectImagestoExportButton.Text = 'Select Images to Export';
            app.AddClassificationSwitch = uiswitch(app.ExportGrid, 'slider');
            app.AddClassificationSwitch.Items = {'No Classifications', 'Include '};
            app.AddClassificationSwitch.ItemsData = {'DoNotInclude', 'Include'};
            app.AddClassificationSwitch.Value = 'DoNotInclude';
            app.ExportingroupsButton = uibutton(app.ExportGrid, 'push');
            app.ExportingroupsButton.ButtonPushedFcn = createCallbackFcn(app, @exportInGroups, true);
            app.ExportingroupsButton.Text = 'Export in groups';
            app.SegmentPanel = uipanel(app.LeftGrid);
            app.SegmentPanel.Title = 'Segment';
            app.SegmentGrid = uigridlayout(app.SegmentPanel);
            app.SegmentCurrentButton = uibutton(app.SegmentGrid, 'push');
            app.SegmentCurrentButton.ButtonPushedFcn = createCallbackFcn(app, @segmentCurrentCallback, true);
            app.SegmentCurrentButton.Text = 'Segment Current (s)';
            app.ApplytoAllButton = uibutton(app.SegmentGrid, 'push');
            app.ApplytoAllButton.ButtonPushedFcn = createCallbackFcn(app, @applyToAll, true);
            app.ApplytoAllButton.Text = 'Apply to All ';
            app.SelectImagestoSegmentButton = uibutton(app.SegmentGrid, 'push');
            app.SelectImagestoSegmentButton.ButtonPushedFcn = createCallbackFcn(app, @segmentSelected, true);
            app.SelectImagestoSegmentButton.Tooltip = {'Press this button to allow multiselection of images'};
            app.SelectImagestoSegmentButton.Text = 'Select Images to Segment';
            app.QuitSegmentationButton = uibutton(app.SegmentGrid, 'push');
            app.QuitSegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @quitSegment, true);
            app.QuitSegmentationButton.Text = 'Quit Segmentation';
            app.NumWorkersLabel = uilabel(app.SegmentGrid);
            app.NumWorkersLabel.Text = 'Num Workers';
            app.NumWorkersEditField = uieditfield(app.SegmentGrid, 'numeric');
            app.NumWorkersEditField.Limits = [0 Inf];
            app.NumWorkersEditField.RoundFractionalValues = 'on';
            app.ImageSelectionPanel = uipanel(app.LeftGrid);
            app.ImageSelectionPanel.Title = 'Image Selection';
            app.ImageSelectGrid = uigridlayout(app.ImageSelectionPanel);
            app.FluorescenceButtonsGrid = uigridlayout(app.ImageSelectGrid);
            app.BeginAnalysisButton = uibutton(app.FluorescenceButtonsGrid, 'push');
            app.BeginAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @multiSelectDoneSelecting, true);
            app.BeginAnalysisButton.Text = 'Begin Analysis';
            app.EscButton = uibutton(app.FluorescenceButtonsGrid, 'push');
            app.EscButton.ButtonPushedFcn = createCallbackFcn(app, @escapeFluorescenceOrTracking, true);
            app.EscButton.Text = 'Esc';
            app.LoadSavedFileButton = uibutton(app.FluorescenceButtonsGrid, 'push');
            app.LoadSavedFileButton.ButtonPushedFcn = createCallbackFcn(app, @loadSavedImageTrackOrFluor, true);
            app.LoadSavedFileButton.Text = 'Load Saved File';
            app.TabGroup = uitabgroup(app.ImageSelectGrid);
            app.ImagesTab = uitab(app.TabGroup);
            app.ImagesTab.Title = 'Images';
            app.ImageSelectList = uilistbox(app.ImagesTab);
            app.ImageSelectList.Items = {};
            app.ImageSelectList.ValueChangedFcn = createCallbackFcn(app, @imageSelectListCallback, true);
            app.ImageSelectList.Value = {};
            app.GroupsTab = uitab(app.TabGroup);
            app.GroupsTab.Title = 'Groups';
            app.GroupsTable = uitable(app.GroupsTab);
            app.GroupsTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.GroupsTable.RowName = {};
            app.ClearWorkspaceButton = uibutton(app.ImageSelectGrid, 'push');
            app.ClearWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @clearWorkspaceCallback, true);
            app.ClearWorkspaceButton.Text = 'Clear Workspace';
            app.DeleteImageButton = uibutton(app.ImageSelectGrid, 'push');
            app.DeleteImageButton.ButtonPushedFcn = createCallbackFcn(app, @deleteImage, true);
            app.DeleteImageButton.Text = 'Delete Image';
            app.LeftTitleGrid = uigridlayout(app.LeftGrid);
            app.ManageImagesLabel = uilabel(app.LeftTitleGrid);
            app.ManageImagesLabel.Text = 'Manage Images';
            app.CollapseLeftButton = uibutton(app.LeftTitleGrid, 'push');
            app.CollapseLeftButton.ButtonPushedFcn = createCallbackFcn(app, @collapseLeftButtonCallback, true);
            app.CollapseLeftButton.Text = '<';
            app.RightPanel = uipanel(app.TotalGrid);
            app.RightGrid = uigridlayout(app.RightPanel);
            app.ImagesPanel = uipanel(app.RightGrid);
            app.ImagesGrid = uigridlayout(app.ImagesPanel);
            app.RawGrayscaleImagePanel = uipanel(app.ImagesGrid);
            app.RawImageGrid = uigridlayout(app.RawGrayscaleImagePanel);
            app.RawImageAxes = uiaxes(app.RawImageGrid);
            app.RawImageAxes.XLimitMethod = 'tight';
            app.RawImageAxes.YLimitMethod = 'tight';
            app.RawImageAxes.ZLimitMethod = 'tight';
            app.RawImageAxes.XTick = [];
            app.RawImageAxes.XTickLabel = '';
            app.RawImageAxes.YTick = [];
            app.RawImageAxes.YTickLabel = '';
            app.RawImageAxes.ZTick = [];
            app.RawImageAxes.Visible = 'off';
            app.CropGrid = uigridlayout(app.RawImageGrid);
            app.RawGrayscaleImageLabel = uilabel(app.CropGrid);
            app.RawGrayscaleImageLabel.Text = 'Raw Grayscale Image ';
            app.Crop = uibutton(app.CropGrid, 'push');
            app.Crop.ButtonPushedFcn = createCallbackFcn(app, @cropImageCallback, true);
            app.Crop.Text = 'Crop (x)';
            app.RevertButton = uibutton(app.CropGrid, 'push');
            app.RevertButton.ButtonPushedFcn = createCallbackFcn(app, @revertImageCrop, true);
            app.RevertButton.Text = 'Revert ';
            app.BrightnessButton = uibutton(app.CropGrid, 'push');
            app.BrightnessButton.ButtonPushedFcn = createCallbackFcn(app, @brightnessAdjustment, true);
            app.BrightnessButton.Text = 'Brightness';
            app.BrightnessGrid = uigridlayout(app.CropGrid);
            app.Change_Contrast = uibutton(app.BrightnessGrid, 'push');
            app.Change_Contrast.ButtonPushedFcn = createCallbackFcn(app, @changeContrast, true);
            app.Change_Contrast.Text = 'Change Selected Image Contrast';
            app.Increase = uibutton(app.BrightnessGrid, 'push');
            app.Increase.ButtonPushedFcn = createCallbackFcn(app, @increaseBrightness, true);
            app.Increase.Text = '+';
            app.Decrease = uibutton(app.BrightnessGrid, 'push');
            app.Decrease.ButtonPushedFcn = createCallbackFcn(app, @decreaseBrightness, true);
            app.Decrease.Text = '-';
            app.Change_Contrast_All = uibutton(app.BrightnessGrid, 'push');
            app.Change_Contrast_All.ButtonPushedFcn = createCallbackFcn(app, @changeContrastAll, true);
            app.Change_Contrast_All.Text = 'Change All Images Contrast';
            app.FirstAdjustmentGrid = uigridlayout(app.BrightnessGrid);
            app.AdjustmentStyleLabel = uilabel(app.FirstAdjustmentGrid);
            app.AdjustmentStyleLabel.Text = 'Adjustment Style';
            app.BrightnessAdjustment = uidropdown(app.FirstAdjustmentGrid);
            app.BrightnessAdjustment.Items = {'Local Brighten', 'Flatfield', 'Local Contrast Adjustment', 'Overall Contrast Adjustment', 'Local Laplacian Filter', 'Reduce Haze', 'Equalize Histogram'};
            app.BrightnessAdjustment.ItemsData = {'Lb', 'Ff', 'Lc', 'Oc', 'Ll', 'Rh', 'Eh'};
            app.BrightnessAdjustment.ValueChangedFcn = createCallbackFcn(app, @changeBrightnessStyle, true);
            app.BrightnessAdjustment.Value = 'Lb';
            app.Parameter1Label = uilabel(app.FirstAdjustmentGrid);
            app.Parameter1Label.Text = 'Parameter 1:';
            app.FirstBrightParam = uieditfield(app.FirstAdjustmentGrid, 'numeric');
            app.Parameter2Label = uilabel(app.FirstAdjustmentGrid);
            app.Parameter2Label.Text = 'Parameter 2:';
            app.SecondBrightParam = uieditfield(app.FirstAdjustmentGrid, 'numeric');
            app.RotateButton = uibutton(app.CropGrid, 'push');
            app.RotateButton.ButtonPushedFcn = createCallbackFcn(app, @rotateImage, true);
            app.RotateButton.Text = 'Rotate';
            app.MaximizeRawImage = uibutton(app.CropGrid, 'push');
            app.MaximizeRawImage.ButtonPushedFcn = createCallbackFcn(app, @maximzeRawImage, true);
            app.MaximizeRawImage.Text = '+';
            app.RotationAngleCounterClockwiseEditFieldLabel = uilabel(app.CropGrid);
            app.RotationAngleCounterClockwiseEditFieldLabel.Text = 'Rotation Angle (Counter Clockwise)';
            app.RotationAngleEditField = uieditfield(app.CropGrid, 'numeric');
            app.RotationAngleEditField.ValueChangedFcn = createCallbackFcn(app, @rotateDegreesCallback, true);
            app.CropDimensionsGridLayout = uigridlayout(app.CropGrid);
            app.UseLastCropDimensionsCheckBox = uicheckbox(app.CropDimensionsGridLayout);
            app.UseLastCropDimensionsCheckBox.ValueChangedFcn = createCallbackFcn(app, @useLastCrop, true);
            app.UseLastCropDimensionsCheckBox.Enable = 'off';
            app.UseLastCropDimensionsCheckBox.Text = 'Use Last Crop Dimensions';
            app.CropDimensionsLabel = uilabel(app.CropDimensionsGridLayout);
            app.CropDimensionsLabel.Text = 'Crop Dimensions';
            app.ColoredSegmentationPanel = uipanel(app.ImagesGrid);
            app.ColoredSegmentationGrid = uigridlayout(app.ColoredSegmentationPanel);
            app.ColoredSegmentationAxes = uiaxes(app.ColoredSegmentationGrid);
            app.ColoredSegmentationAxes.XLimitMethod = 'tight';
            app.ColoredSegmentationAxes.YLimitMethod = 'tight';
            app.ColoredSegmentationAxes.ZLimitMethod = 'tight';
            app.ColoredSegmentationAxes.XTick = [];
            app.ColoredSegmentationAxes.XTickLabel = '';
            app.ColoredSegmentationAxes.YTick = [];
            app.ColoredSegmentationAxes.YTickLabel = '';
            app.ColoredSegmentationAxes.ZTick = [];
            app.ColoredSegmentationAxes.Visible = 'off';
            app.ColoredSegmentationLabelGrid = uigridlayout(app.ColoredSegmentationGrid);
            app.ColoredSegmentationLabel = uilabel(app.ColoredSegmentationLabelGrid);
            app.ColoredSegmentationLabel.Text = 'Colored Segmentation';
            app.ShowIDsSwitchLabel = uilabel(app.ColoredSegmentationLabelGrid);
            app.ShowIDsSwitchLabel.Text = 'Show IDs';
            app.ShowIDsSwitch = uiswitch(app.ColoredSegmentationLabelGrid, 'slider');
            app.ShowIDsSwitch.Items = {'Yes', 'No'};
            app.ShowIDsSwitch.ItemsData = [1 0];
            app.ShowIDsSwitch.ValueChangedFcn = createCallbackFcn(app, @showIDSwitch, true);
            app.ShowIDsSwitch.Value = 0;
            app.ToolsPanel = uipanel(app.ImagesGrid);
            app.ToolsGrid = uigridlayout(app.ToolsPanel);
            app.RemoveCheckBox = uicheckbox(app.ToolsGrid);
            app.RemoveCheckBox.ValueChangedFcn = createCallbackFcn(app, @removeCheckboxCallback, true);
            app.RemoveCheckBox.Text = 'Remove (r)';
            app.CombinecCheckBox = uicheckbox(app.ToolsGrid);
            app.CombinecCheckBox.ValueChangedFcn = createCallbackFcn(app, @combineCheckBoxCallback, true);
            app.CombinecCheckBox.Text = 'Combine (c)';
            app.ClassificationDropDown = uidropdown(app.ToolsGrid);
            app.ClassificationDropDown.Items = {'Change Classification', 'Make spheroid (1)', 'Make contaminant (2)', 'Make non-circular (3)', 'Remove Contaminants (4)', 'Remove non-circular objects (5)', ''};
            app.ClassificationDropDown.ItemsData = {'None', 'Spheroid', 'Contaminant', 'NonCircular', 'RemoveContaminant', 'RemoveNonCircular'};
            app.ClassificationDropDown.ValueChangedFcn = createCallbackFcn(app, @changeClassificationCallback, true);
            app.ClassificationDropDown.Placeholder = 'Change Classification';
            app.ClassificationDropDown.Value = 'None';
            app.IndividualEdgeCorrectiCheckBox = uicheckbox(app.ToolsGrid);
            app.IndividualEdgeCorrectiCheckBox.ValueChangedFcn = createCallbackFcn(app, @edgeCorrctIndivCheckBox, true);
            app.IndividualEdgeCorrectiCheckBox.Text = 'Individual Edge Correct (i)';
            app.IndividualEdgeIntensityEditField = uieditfield(app.ToolsGrid, 'numeric');
            app.IndividualEdgeIntensityEditField.Limits = [0 1];
            app.IndividualEdgeIntensityEditField.Enable = 'off';
            app.IndividualEdgeIntensityEditField.Value = 0.3;
            app.IndivSplitBlurringEditField = uieditfield(app.ToolsGrid, 'numeric');
            app.IndivSplitBlurringEditField.Limits = [0 Inf];
            app.IndivSplitBlurringEditField.Enable = 'off';
            app.IndivSplitBlurringEditField.Value = 5;
            app.SplitSelectedSpheroidtCheckBox = uicheckbox(app.ToolsGrid);
            app.SplitSelectedSpheroidtCheckBox.ValueChangedFcn = createCallbackFcn(app, @splitSpheroidsCallback, true);
            app.SplitSelectedSpheroidtCheckBox.Text = 'Split Selected Spheroid (t)';
            app.CombineCloseSizeEditField = uieditfield(app.ToolsGrid, 'numeric');
            app.CombineCloseSizeEditField.Limits = [1 Inf];
            app.CombineCloseSizeEditField.Enable = 'off';
            app.CombineCloseSizeEditField.Value = 5;
            app.IndividualEdgeCorrectPreference = uidropdown(app.ToolsGrid);
            app.IndividualEdgeCorrectPreference.Items = {'Gradient Only', 'Gradient Preserve Boundary', 'Gradient + Watershed'};
            app.IndividualEdgeCorrectPreference.ValueChangedFcn = createCallbackFcn(app, @indivEdgeCorrectPreferenceChange, true);
            app.IndividualEdgeCorrectPreference.Value = 'Gradient Only';
            app.MaximizeImageButton = uibutton(app.ImagesGrid, 'push');
            app.MaximizeImageButton.ButtonPushedFcn = createCallbackFcn(app, @maximizeImage, true);
            app.MaximizeImageButton.Text = '+';
            app.BottomRightPanel = uipanel(app.RightGrid);
            app.BottomGrid = uigridlayout(app.BottomRightPanel);
            app.SpheroidZoomPanel = uipanel(app.BottomGrid);
            app.SpheroidZoomGrid = uigridlayout(app.SpheroidZoomPanel);
            app.SpheroidZoomAxes = uiaxes(app.SpheroidZoomGrid);
            app.SpheroidZoomAxes.XLimitMethod = 'tight';
            app.SpheroidZoomAxes.YLimitMethod = 'tight';
            app.SpheroidZoomAxes.ZLimitMethod = 'tight';
            app.SpheroidZoomAxes.XTick = 0;
            app.SpheroidZoomAxes.XTickLabel = '';
            app.SpheroidZoomAxes.YTick = 0;
            app.SpheroidZoomAxes.YTickLabel = '';
            app.SpheroidZoomAxes.Visible = 'off';
            app.SpheroidListBox = uilistbox(app.SpheroidZoomGrid);
            app.SpheroidListBox.Items = {};
            app.SpheroidListBox.ValueChangedFcn = createCallbackFcn(app, @spheroidSelectListCallback, true);
            app.SpheroidListBox.Value = {};
            app.TabGroup2 = uitabgroup(app.BottomGrid);
            app.SegmentOptionsTab = uitab(app.TabGroup2);
            app.SegmentOptionsTab.Title = 'Segment Options';
            app.SegmentationOptionsPanel = uipanel(app.SegmentOptionsTab);
            app.SegmentationOptionsPanel.Title = 'Segmentation Options';
            app.SegmentationOptionsPanel.SizeChangedFcn = createCallbackFcn(app, @optionsPanelResize, true);
            app.SegmentationOptionsGrid = uigridlayout(app.SegmentationOptionsPanel);
            app.OutofFocusCorrectionCheckBox = uicheckbox(app.SegmentationOptionsGrid);
            app.OutofFocusCorrectionCheckBox.Text = 'Out-of-Focus Correction';
            app.DICCorrectionCheckBox = uicheckbox(app.SegmentationOptionsGrid);
            app.DICCorrectionCheckBox.Text = 'DIC Correction';
            app.SplitWholeImageCheckBox = uicheckbox(app.SegmentationOptionsGrid);
            app.SplitWholeImageCheckBox.Text = 'Split Whole Image (Watershed Transform)';
            app.IntensityThreshold01Label = uilabel(app.SegmentationOptionsGrid);
            app.IntensityThreshold01Label.Text = 'Intensity Threshold [0-3]';
            app.IntensityThresholdEditField = uieditfield(app.SegmentationOptionsGrid, 'numeric');
            app.IntensityThresholdEditField.Limits = [0 3];
            app.IntensityThresholdEditField.Value = 1;
            app.WindowSize20500Label = uilabel(app.SegmentationOptionsGrid);
            app.WindowSize20500Label.Text = 'Window Size [20 - 500]';
            app.WindowSizeEditField = uieditfield(app.SegmentationOptionsGrid, 'numeric');
            app.WindowSizeEditField.Limits = [20 500];
            app.WindowSizeEditField.RoundFractionalValues = 'on';
            app.WindowSizeEditField.Value = 100;
            app.SizeThreshold105000Label = uilabel(app.SegmentationOptionsGrid);
            app.SizeThreshold105000Label.Text = 'Size Threshold [10-5000]';
            app.SizeThresholdEditField = uieditfield(app.SegmentationOptionsGrid, 'numeric');
            app.SizeThresholdEditField.Limits = [10 5000];
            app.SizeThresholdEditField.RoundFractionalValues = 'on';
            app.SizeThresholdEditField.Value = 100;
            app.MinimumCircularity01Label = uilabel(app.SegmentationOptionsGrid);
            app.MinimumCircularity01Label.Text = 'Minimum Circularity [0-1]';
            app.MinimumCircularityEditField = uieditfield(app.SegmentationOptionsGrid, 'numeric');
            app.MinimumCircularityEditField.Limits = [0 1];
            app.MinimumCircularityEditField.ValueChangedFcn = createCallbackFcn(app, @changeCircularity, true);
            app.ContaminantIntesity0255Label = uilabel(app.SegmentationOptionsGrid);
            app.ContaminantIntesity0255Label.Text = 'Contaminant Intensity  [0-1]';
            app.ContaminantIntensityEditField = uieditfield(app.SegmentationOptionsGrid, 'numeric');
            app.ContaminantIntensityEditField.Limits = [0 1];
            app.ContaminantIntensityEditField.ValueChangedFcn = createCallbackFcn(app, @changeContaminant, true);
            app.EdgeCorrectionCheckBox = uicheckbox(app.SegmentationOptionsGrid);
            app.EdgeCorrectionCheckBox.ValueChangedFcn = createCallbackFcn(app, @edgeCorrectAll, true);
            app.EdgeCorrectionCheckBox.Text = 'Edge Correction';
            app.EdgeIntensity01Label = uilabel(app.SegmentationOptionsGrid);
            app.EdgeIntensity01Label.Text = 'Edge Threshold  [0  - 1]';
            app.EdgeIntensityEditField = uieditfield(app.SegmentationOptionsGrid, 'numeric');
            app.EdgeIntensityEditField.Limits = [0 1];
            app.EdgeIntensityEditField.Enable = 'off';
            app.EdgeIntensityEditField.Value = 0.3;
            app.GroupingOptionsTab = uitab(app.TabGroup2);
            app.GroupingOptionsTab.Title = 'Grouping Options';
            app.GroupingOptionsGrid = uigridlayout(app.GroupingOptionsTab);
            app.GroupingOptionsTable = uitable(app.GroupingOptionsGrid);
            app.GroupingOptionsTable.ColumnName = {'Group'; 'UseInGrouping'; 'Key'; 'MatchOrContains'};
            app.GroupingOptionsTable.RowName = {};
            app.GroupingOptionsTable.ColumnEditable = [false true true true];
            app.RegexEditFieldLabel = uilabel(app.GroupingOptionsGrid);
            app.RegexEditFieldLabel.Text = 'Regex';
            app.RegexEditField = uieditfield(app.GroupingOptionsGrid, 'text');
            app.RegexEditField.ValueChangedFcn = createCallbackFcn(app, @changeRegexGrouping, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OrganoSeg2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end