classdef brightnessSelection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        GridLayout2                     matlab.ui.container.GridLayout
        AdjustedLabel                   matlab.ui.control.Label
        OriginalLabel                   matlab.ui.control.Label
        NewAxes                         matlab.ui.control.UIAxes
        OriginalAxes                    matlab.ui.control.UIAxes
        OptionsGrid                     matlab.ui.container.GridLayout
        PreviewImageButton              matlab.ui.control.Button
        ThirdAdjustmentDropDown         matlab.ui.control.DropDown
        ThirdAdjustmentDropDownLabel    matlab.ui.control.Label
        SecondAdjustmentDropDown        matlab.ui.control.DropDown
        SecondAdjustmentDropDownLabel   matlab.ui.control.Label
        Parameter2EditField_3           matlab.ui.control.NumericEditField
        Parameter2Label_3               matlab.ui.control.Label
        Parameter1EditField_3           matlab.ui.control.NumericEditField
        Parameter1Label_3               matlab.ui.control.Label
        Parameter2EditField_2           matlab.ui.control.NumericEditField
        Parameter2Label_2               matlab.ui.control.Label
        Parameter1EditField_2           matlab.ui.control.NumericEditField
        Parameter1Label_2               matlab.ui.control.Label
        TestSegmentationSettingsButton  matlab.ui.control.Button
        Parameter1EditField_1           matlab.ui.control.NumericEditField
        Parameter1Label_1               matlab.ui.control.Label
        UseAdjustmentsforSegmentationDropDown  matlab.ui.control.DropDown
        UseAdjustmentsforSegmentationDropDownLabel  matlab.ui.control.Label
        Parameter2EditField_1           matlab.ui.control.NumericEditField
        Parameter2Label_1               matlab.ui.control.Label
        FirstAdjustmentDropDown         matlab.ui.control.DropDown
        FirstAdjustmentDropDownLabel    matlab.ui.control.Label
    end

    
    properties (Access = private)
        Settings % Settings from caller app
        Image %Image to be segmented 
        CallingApp %Original organoseg app
        
    end
    
    methods (Access = private)
        function Iobrcbr = prepareImage(~, origI, se)
            %Sharpen
            K = imsharpen(origI);
            %Smooth background
            K = medfilt2(K);

            %Opening and closing by reconstruction


            %Erode and open
            Ie = imerode(K, se);
            Iobr = imreconstruct(Ie, K);

            %Opening-closing by reconstruction(Iobrcbr)
            Iobrd = imdilate(Iobr, se);
            Iobrcbr = imreconstruct(imcomplement(Iobrd), imcomplement(Iobr));
            Iobrcbr = imcomplement(Iobrcbr);

            %Remove border
            Iobrcbr(1:end,1) = Iobrcbr([2 2:end],2);
            Iobrcbr(1:end,end) = Iobrcbr([2 2:end],end-1);
            Iobrcbr(1,1:end) = Iobrcbr(2,[2 2:end]);
            Iobrcbr(end,1:end) = Iobrcbr(end-1,[2 2:end]);
        end
        
        function imgMasked = segment(app, segmentImage)
            

            mainParameters.ws = app.CallingApp.WindowSizeEditField.Value;
            mainParameters.intensityThresh = app.CallingApp.IntensityThresholdEditField.Value;
            mainParameters.sizeThresh = app.CallingApp.SizeThresholdEditField.Value;
            mainParameters.checkOOF = app.CallingApp.OutofFocusCorrectionCheckBox.Value;
            mainParameters.checkDIC = app.CallingApp.DICCorrectionCheckBox.Value;
            mainParameters.checkWater = app.CallingApp.SplitWholeImageCheckBox.Value;
            mainParameters.checkEdge = app.CallingApp.EdgeCorrectionCheckBox.Value;
            mainParameters.edgeIntensity = app.CallingApp.EdgeIntensityEditField.Value;          
            mainParameters.minimumCircularity = app.CallingApp.MinimumCircularityEditField.Value;
            mainParameters.contaminantIntensity = app.CallingApp.ContaminantIntensityEditField.Value;


            additionalParameters = struct;
            additionalParameters.watershedFilterSigma = app.Settings.watershedFilterSigma;
            
            additionalParameters.imageClosingDegree = app.Settings.imageClosingDegree;
            additionalParameters.imageReconstructionRadius = app.Settings.imageReconstructionRadius;
            additionalParameters.windowSizeJump = app.Settings.windowSizeJump;
            additionalParameters.useReconstructedForGradient = app.Settings.useReconstructedForGradient;
            additionalParameters.clearBorderSplit = app.Settings.clearBorderSplit;
            additionalParameters.useInverted = app.Settings.useInverted;
            additionalParameters.edgePreference = app.Settings.edgePreference;

            outputs = segmentFunction(1, 0, mainParameters, additionalParameters, {segmentImage});
            imgMasked = outputs.imgMasked;

            % %%Regular segment function called with the adjust image
            % 
            % origI = segmentImage;
            % 
            % se = strel('disk', app.Settings.reconstructionRadius);
            % 
            % Iobrcbr = prepareImage(app, origI, se);
            % 
            % 
            % %Threshold
            % bwEdge = zeros(size(origI,1),size(origI,2));
            % fudgeFactor = app.CallingApp.IntensityThresholdEditField.Value;
            % 
            % %Multi-scale checkbox?
            % if app.CallingApp.OutofFocusCorrectionCheckBox.Value == 1
            %     for j = 20:app.Settings.windowStep:app.CallingApp.WindowSizeEditField.Value
            %         bwEdgeIter = adaptivethreshold(Iobrcbr,[j j],fudgeFactor);
            %         bwEdge = bwEdge+bwEdgeIter;
            %     end
            % else
            %     bwEdge = adaptivethreshold(Iobrcbr,[app.CallingApp.WindowSizeEditField.Value app.CallingApp.WindowSizeEditField.Value] ,fudgeFactor);
            % 
            %     %DIC Checkbox?
            %     if app.CallingApp.DICCorrectionCheckBox.Value == 1
            %         %Segment on inverted image to capture shadow vs light
            %         bwEdge2 = adaptivethreshold(imcomplement(Iobrcbr),[app.CallingApp.WindowSizeEditField.Value app.CallingApp.WindowSizeEditField.Value],fudgeFactor);
            %         %Sum together
            %         bwEdge = bwEdge|bwEdge2;
            %         bwEdge = imclose(bwEdge,strel('disk',5));
            %     end
            % end
            % edgeIntensity = app.CallingApp.EdgeIntensityEditField.Value;
            % sizeThresh = app.CallingApp.SizeThresholdEditField.Value;
            % 
            % %Remove small noise
            % bwsmall = bwareaopen(bwEdge, sizeThresh);
            % 
            % %Smooth
            % bwsmooth = imclose(bwsmall,ones(app.Settings.closingDegree));
            % 
            % %fill holes
            % 
            % bw_holes = bwfill(bwsmooth,'holes');
            % bw_holes = logical(bw_holes);
            % 
            % 
            % 
            % if app.CallingApp.EdgeCorrectionCheckBox.Value == 1
            %     if strcmp(app.Settings.edgePreference, 'Gradient Only') 
            % 
            % 
            %         if app.Settings.useReconstructedForGradient == 1
            %             se2 = strel('disk', round(app.Settings.reconstructionRadius/2));
            %             Iobrcbr2 = prepareImage(app, origI, se2);
            %             [mag, ~] = imgradient(Iobrcbr2);
            %         else
            %             [mag, ~] = imgradient(origI);
            %         end
            % 
            %         background =  imfilter(Iobrcbr,fspecial('average',[500 500]),'replicate');
            %         mag = mag > edgeIntensity*background;
            %         bw_holes_temp = mag + bw_holes;
            %         %Remove extra area around perimeter added by
            %         %magnitude
            % 
            %         bwsmall2 = bwareaopen(bw_holes_temp, sizeThresh);
            % 
            %         %Smooth
            %         bwsmooth2 = imclose(bwsmall2,ones(app.Settings.closingDegree));
            % 
            %         %fill holes
            %         bw_holes_temp = bwfill(bwsmooth2,'holes');
            % 
            %         bw_holes_temp = imerode(bw_holes_temp, ones(3,3));
            %         bw_holes_temp = bwareaopen(bw_holes_temp, sizeThresh);
            %         bw_holes = bw_holes+bw_holes_temp;
            %     elseif strcmp(app.Settings.edgePreference, 'Gradient Preserve Boundary')
            %         %mark background based on segmentation
            %         D = bwdist(bw_holes);
            %         DL = watershed(D);
            %         bgm = DL == 0;
            % 
            % 
            % 
            %         %Find Image Gradient
            % 
            %         if app.Settings.useReconstructedForGradient == 1
            %             se2 = strel('disk', round(app.Settings.reconstructionRadius/2));
            %             Iobrcbr2 = prepareImage(app, origI, se2);
            %             [mag, ~] = imgradient(Iobrcbr2);
            %         else
            %             [mag, ~] = imgradient(origI);
            %         end
            % 
            %         %mag = imerode(mag, ones(2, 2));
            % 
            %         background = imfilter(Iobrcbr,fspecial('average',[500 500]),'replicate');
            %         %Threshold image gradient based on intensity of
            %         %background at that point
            %         mag = mag > edgeIntensity*background;
            %         %Add thresholded image gradient to original bwfinal
            %         combine = mag+bw_holes;
            % 
            % 
            %         %Zero-out combined bw at background marker to
            %         %eliminate combining of spheroids
            %         combine(imdilate(bgm, ones(3,3))) = 0;
            % 
            %         bwsmall2 = bwareaopen(combine, sizeThresh);
            % 
            %         bwsmooth2 = imclose(bwsmall2, ones(app.Settings.closingDegree));
            % 
            %         bw_holes2 = imfill(bwsmooth2, 'holes');
            % 
            % 
            %         bw_holes_temp = imclearborder(bw_holes2);
            %         bw_holes_temp = imerode(bw_holes_temp, ones(3,3));
            %         bw_holes_temp = bwareaopen(bw_holes_temp, sizeThresh);
            % 
            %         %Remove any objects from bwfinalTemp which do not
            %         %appear in original bwfinal
            %         pixels = find(bw_holes);
            %         newCC = bwconncomp(bw_holes_temp);
            % 
            %         for spheroid = 1:length(newCC.PixelIdxList)
            %             if ~any(ismember(pixels, newCC.PixelIdxList{spheroid}))
            %                 newCC.PixelIdxList{spheroid} = [];
            %             end
            %         end
            % 
            % 
            %         finalCCLabel = labelmatrix(newCC);
            %         bw_holes_temp = logical(finalCCLabel);
            %         %Remove extra area around perimeter added by
            %         %magnitude
            % 
            %         bw_holes = bw_holes_temp + bw_holes;
            %     elseif strcmp(app.Settings.edgePreference, 'Gradient + Watershed')
            %         D = bwdist(bw_holes);
            %         DL = watershed(D);
            %         bgm = DL == 0;
            % 
            % 
            % 
            %         bw_holes_temp = bw_holes;
            %         bw_holes_temp(DL == 0) = 0;
            % 
            % 
            % 
            % 
            %         se2 = strel(ones(3,3));                        
            %         fgm = imerode(bw_holes_temp,se2);
            % 
            % 
            %         fgm = bwareaopen(fgm,25);
            %         if app.Settings.useReconstructedForGradient == 1
            %             se2 = strel('disk', round(app.Settings.reconstructionRadius/2));
            %             Iobrcbr2 = prepareImage(app, origI, se2);
            %             [mag, ~] = imgradient(Iobrcbr2);
            %         else
            %             [mag, ~] = imgradient(origI);
            %         end
            % 
            % 
            % 
            % 
            %         mag2 = imimposemin(mag,  fgm | bgm );
            %         L = watershed(mag2);
            %         mask = imfill(L==0, 'holes');
            %         combine = mask+bw_holes_temp;
            % 
            %         bw_holes = bwareaopen(combine, sizeThresh);                       
            % 
            % 
            %     end
            % 
            % end
            % 
            % bw_holes = logical(bw_holes);
            % bw_holes = imclearborder(bw_holes);
            % 
            % 
            % %Watershed checkbox?
            % if app.CallingApp.SplitWholeImageCheckBox.Value == 1
            % 
            % 
            %     bw_holesTemp = bw_holes;
            %     for iteration = 1:app.Settings.watershedIterations
            % 
            %         %Find distance transform and smooth
            %         dist = bwdist(imcomplement(double(bw_holesTemp)));
            %         if app.Settings.watershedFilter > 0
            %             smooth = imgaussfilt(dist,app.Settings.watershedFilter);
            %         else
            %             smooth = dist;
            %         end
            %         %smooth = dist;
            % 
            %         dist2 = -smooth;
            %         dist2(~bw_holesTemp) = Inf;
            % 
            %         %Watershed based on distance transform, splitting organoids
            %         L = watershed(dist2);
            %         bw_holesTemp(L==0) = 0;
            %         %Remove noise based on size threshold
            %         bwlil = bwareaopen(bw_holesTemp,app.CallingApp.SizeThresholdEditField.Value);
            %         %Clear border
            %         bw_holesTemp = imclearborder(bwlil);
            %         bw_holesTemp = logical(bw_holesTemp);
            %     end
            %     bwfinal = bw_holesTemp;
            % 
            % else
            %     %Clear border
            %     bw_nobord = imclearborder(bw_holes);
            %     %make logical
            %     bwfinal = logical(bw_nobord);
            % 
            % end
            % 
            % 
            % 
            % 
            % 
            % 
            % 
            % %Find perimeter
            % bw_perim = double(bwperim(bwfinal));
            % %Make visible (dilate)
            % bw_perim_dil = imdilate(bw_perim,ones(3,3));
            % 
            % % %Color perimeter if wanted
            % 
            % 
            % %Normalize raw image to min and max
            % displayImage = double(displayImage);
            % displayImage = (displayImage - min(displayImage(:)))./(max(displayImage(:)) - min(displayImage(:)));
            % 
            % %Create crop of perimeter and raw image
            % %bw_perim_dil = imresize(bw_perim, 0.2, 'bilinear');
            % 
            % 
            % 
            % imgMasked = imoverlay(displayImage,bw_perim_dil,[0 0 1]);

            
        end
        
        function showSettings(app)
            
            brightenImage = app.Image;
            brightenImage = adjustImage(app, brightenImage, app.FirstAdjustmentDropDown.Value, 1);
            brightenImage = adjustImage(app, brightenImage, app.SecondAdjustmentDropDown.Value, 2);
            brightenImage = adjustImage(app, brightenImage, app.ThirdAdjustmentDropDown.Value, 3);
            cla(app.NewAxes)
            imshow(brightenImage, [], 'Parent', app.NewAxes)
            
        end

        function changeBrightnessStyleGeneral(app, style, adjustmentNum)
            
            switch adjustmentNum
                case 1
                    param1 = app.Parameter1EditField_1;
                    param1Label = app.Parameter1Label_1;
                    param2 = app.Parameter2EditField_1;
                    param2Label = app.Parameter2Label_1;
                case 2
                    param1 = app.Parameter1EditField_2;
                    param1Label = app.Parameter1Label_2;
                    param2 = app.Parameter2EditField_2;
                    param2Label = app.Parameter2Label_2;
                case 3
                    param1 = app.Parameter1EditField_3;
                    param1Label = app.Parameter1Label_3;
                    param2 = app.Parameter2EditField_3;
                    param2Label = app.Parameter2Label_3;
            end
            switch style
                case 'Lb'
                    param1.Limits = [0 1];
                    param1.RoundFractionalValues = 0;
                    param1.Enable = 1;
                    param1.Value = 0.5;                    
                    param1Label.Text = 'Amount';


                    param2.Limits = [0 1];
                    param2.RoundFractionalValues = 0;
                    param2.Enable = 1;
                    param2.Value = 0;                    
                    param2Label.Text = 'Alpha Blend (0 or 1)';


                case 'Ff'
                    param1.Limits = [0 Inf];
                    param1.RoundFractionalValues = 1;
                    param1.Enable = 1;
                    param1.Value = 30;                    
                    param1Label.Text = 'Sigma';
                    
                    param2.Enable = 0;
                    param2Label.Text = 'Parameter 2: ';
                case 'Lc'
                    param1.Limits = [0 1];
                    param1.RoundFractionalValues = 0;
                    param1.Enable = 1;
                    param1.Value = 0.3;                    
                    param1Label.Text = 'Edge Threshold';
                    

                    param2.Limits = [-1 1];
                    param2.RoundFractionalValues = 0;
                    param2.Enable = 1;
                    param2.Value = 0.5;                    
                    param2Label.Text = 'Amount';
                   

                case 'Oc'
                    param1.Enable = 0;
                    param1Label.Text = 'Parameter 1: ';
                    param2.Enable = 0;
                    param2Label.Text = 'Parameter 2: ';
                case 'Ll'
                    param1.Limits = [0 1];
                    param1.RoundFractionalValues = 0;
                    param1.Enable = 1;
                    param1.Value = 0.5;                    
                    param1Label.Text = 'Sigma';
                    
                
                    param2.Limits = [0.01 10];
                    param2.RoundFractionalValues = 0;
                    param2.Enable = 1;
                    param2.Value = 1;                    
                    param2Label.Text = 'Alpha';
                    

                case 'Rh'
                    param1.Limits = [0 1];
                    param1.RoundFractionalValues = 0;
                    param1.Enable = 1;
                    param1.Value = 0.5;                    
                    param1Label.Text = 'Amount';
                    
                    param2.Enable = 0;
                    param2Label.Text = 'Parameter 2: ';

                case 'Eh'
                    param1.Limits = [0 Inf];
                    param1.RoundFractionalValues = 1;
                    param1.Enable = 1;
                    param1.Value = 64;                    
                    param1Label.Text = 'Number of Bins';
                    
                    param2.Enable = 0;
                    param2Label.Text = 'Parameter 2: ';


                case 'No'
                    param1.Enable = 0;
                    param1Label.Text = 'Parameter 1: ';
                    param2.Enable = 0;
                    param2Label.Text = 'Parameter 2: ';
                    
            end
            switch adjustmentNum
                case 1
                    app.Parameter1EditField_1 = param1;
                    app.Parameter1Label_1 = param1Label;
                    app.Parameter2EditField_1 = param2;
                    app.Parameter2Label_1 = param2Label;
                case 2
                    app.Parameter1EditField_2 = param1;
                    app.Parameter1Label_2 = param1Label;
                    app.Parameter2EditField_2 = param2;
                    app.Parameter2Label_2 = param2Label;
                case 3
                    app.Parameter1EditField_3 = param1;
                    app.Parameter1Label_3 = param1Label;
                    app.Parameter2EditField_3 = param2;
                    app.Parameter2Label_3 = param2Label;
            end
            
        end

        function newImage = adjustImage(app, oldImage, style, adjustmentNum)
            switch adjustmentNum
                case 1
                    param1 = app.Parameter1EditField_1.Value;
                    
                    param2 = app.Parameter2EditField_1.Value;
                    
                case 2
                    param1 = app.Parameter1EditField_2.Value;
                    
                    param2 = app.Parameter2EditField_2.Value;
                    
                case 3
                    param1 = app.Parameter1EditField_3.Value;
                    
                    param2 = app.Parameter2EditField_3.Value;
                    
            end

            switch style
                case 'Lb'    
                    if param2 ~= 0
                        param2 = 1;
                    end
                    newImage = imlocalbrighten(oldImage, param1, 'AlphaBlend', param2);                    

                case 'Ff'
                    newImage = imflatfield(oldImage, param1);
                    
                case 'Lc'
                    newImage = localcontrast(oldImage, param1, param2);

                case 'Oc'
                    newImage = imadjust(oldImage);
                    
                case 'Ll'
                    newImage = locallapfilt(oldImage, param1, param2);

                case 'Rh'
                    newImage = imreducehaze(oldImage, param1);
                case 'Eh'
                    newImage = histeq(oldImage, param1);

                case 'No'
                    newImage = oldImage;
                    
            end

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, settings, image)
            %Store settings from main app into local app data
            app.Settings = settings;
            
            %app.UseAdjustmentsforSegmentationDropDown.Value = settings.applyBrightnessChanges;
           
            app.Image = image;
            app.CallingApp = caller;


            imshow(app.Image, [], 'Parent', app.OriginalAxes)
            disableDefaultInteractivity(app.OriginalAxes)
            disableDefaultInteractivity(app.NewAxes)

        end

        % Button pushed function: TestSegmentationSettingsButton
        function testSettings(app, event)
           
           origImgMasked = segment(app, app.Image);
           f = msgbox("Segmenting... please wait");

           %Create image based on brightness adjustment

           brightenImage = app.Image;
           brightenImage = adjustImage(app, brightenImage, app.FirstAdjustmentDropDown.Value, 1);
           brightenImage = adjustImage(app, brightenImage, app.SecondAdjustmentDropDown.Value, 2);
           brightenImage = adjustImage(app, brightenImage, app.ThirdAdjustmentDropDown.Value, 3);
           'a'

           %Call segmenting on image


           brightenImgMasked = segment(app, brightenImage);
           %imageArray = {origImgMasked, brightenImgMasked};
           cla(app.OriginalAxes)
           cla(app.NewAxes)

           imshow(origImgMasked, 'Parent', app.OriginalAxes)
           imshow(brightenImgMasked, 'Parent', app.NewAxes)
           app.AdjustedLabel.Visible = 1;
           app.OriginalLabel.Visible = 1;
           'b'


           close(f)


        end

        % Close request function: UIFigure
        function closeBrightness(app, event)
            %Save settings for contrast adjustment


            delete(app)
            
        end

        % Value changed function: FirstAdjustmentDropDown
        function changeBrightnessStyle1(app, event)
            %Adjust which settings are available based on adjustment style
            value = app.FirstAdjustmentDropDown.Value;
            changeBrightnessStyleGeneral(app, value, 1)
             
             
        end

        % Value changed function: Parameter1EditField_1
        function brightAmountCallback(app, event)
            
            
        end

        % Callback function
        function alphaBlendCallback(app, event)
           
            
        end

        % Value changed function: Parameter2EditField_1
        function flatfieldCallback(app, event)
            
            
        end

        % Value changed function: SecondAdjustmentDropDown
        function changeBrightnessStyle2(app, event)
            value = app.SecondAdjustmentDropDown.Value;
            changeBrightnessStyleGeneral(app, value, 2)
        end

        % Value changed function: ThirdAdjustmentDropDown
        function changeBrightnessStyle3(app, event)
            value = app.ThirdAdjustmentDropDown.Value;
            changeBrightnessStyleGeneral(app, value, 3)
        end

        % Button pushed function: PreviewImageButton
        function previewImage(app, event)
            app.PreviewImageButton.Enable = 0;
            showSettings(app)
            app.PreviewImageButton.Enable = 1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeBrightness, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.OptionsGrid = uigridlayout(app.GridLayout);
            app.FirstAdjustmentDropDownLabel = uilabel(app.OptionsGrid);
            app.FirstAdjustmentDropDownLabel.Text = 'First Adjustment';
            app.FirstAdjustmentDropDown = uidropdown(app.OptionsGrid);
            app.FirstAdjustmentDropDown.Items = {'Local Brighten', 'Flatfield', 'Local Contrast Adjustment', 'Overall Contrast Adjustment', 'Local Laplacian Filter', 'Reduce Haze', 'Equalize Histogram', '', '', '', '', ''};
            app.FirstAdjustmentDropDown.ItemsData = {'Lb', 'Ff', 'Lc', 'Oc', 'Ll', 'Rh', 'Eh'};
            app.FirstAdjustmentDropDown.ValueChangedFcn = createCallbackFcn(app, @changeBrightnessStyle1, true);
            app.FirstAdjustmentDropDown.Value = 'Lb';
            app.Parameter2Label_1 = uilabel(app.OptionsGrid);
            app.Parameter2Label_1.Text = 'Parameter 2:';
            app.Parameter2EditField_1 = uieditfield(app.OptionsGrid, 'numeric');
            app.Parameter2EditField_1.Limits = [0 1];
            app.Parameter2EditField_1.ValueChangedFcn = createCallbackFcn(app, @flatfieldCallback, true);
            app.UseAdjustmentsforSegmentationDropDownLabel = uilabel(app.OptionsGrid);
            app.UseAdjustmentsforSegmentationDropDownLabel.Visible = 'off';
            app.UseAdjustmentsforSegmentationDropDownLabel.Text = 'Use Adjustments for Segmentation?';
            app.UseAdjustmentsforSegmentationDropDown = uidropdown(app.OptionsGrid);
            app.UseAdjustmentsforSegmentationDropDown.Items = {'Yes', 'No'};
            app.UseAdjustmentsforSegmentationDropDown.ItemsData = {'Yes', 'No'};
            app.UseAdjustmentsforSegmentationDropDown.Visible = 'off';
            app.UseAdjustmentsforSegmentationDropDown.Value = 'Yes';
            app.Parameter1Label_1 = uilabel(app.OptionsGrid);
            app.Parameter1Label_1.Text = 'Parameter 1:  ';
            app.Parameter1EditField_1 = uieditfield(app.OptionsGrid, 'numeric');
            app.Parameter1EditField_1.Limits = [0 1];
            app.Parameter1EditField_1.ValueChangedFcn = createCallbackFcn(app, @brightAmountCallback, true);
            app.Parameter1EditField_1.Value = 0.5;
            app.TestSegmentationSettingsButton = uibutton(app.OptionsGrid, 'push');
            app.TestSegmentationSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @testSettings, true);
            app.TestSegmentationSettingsButton.Text = 'Test Segmentation Settings';
            app.Parameter1Label_2 = uilabel(app.OptionsGrid);
            app.Parameter1Label_2.Text = 'Parameter 1:';
            app.Parameter1EditField_2 = uieditfield(app.OptionsGrid, 'numeric');
            app.Parameter1EditField_2.Limits = [0 1];
            app.Parameter1EditField_2.Enable = 'off';
            app.Parameter2Label_2 = uilabel(app.OptionsGrid);
            app.Parameter2Label_2.Text = 'Parameter 2:';
            app.Parameter2EditField_2 = uieditfield(app.OptionsGrid, 'numeric');
            app.Parameter2EditField_2.Limits = [0 Inf];
            app.Parameter2EditField_2.Enable = 'off';
            app.Parameter1Label_3 = uilabel(app.OptionsGrid);
            app.Parameter1Label_3.Text = 'Parameter 1:';
            app.Parameter1EditField_3 = uieditfield(app.OptionsGrid, 'numeric');
            app.Parameter1EditField_3.Limits = [0 1];
            app.Parameter1EditField_3.Enable = 'off';
            app.Parameter2Label_3 = uilabel(app.OptionsGrid);
            app.Parameter2Label_3.Text = 'Parameter 2:';
            app.Parameter2EditField_3 = uieditfield(app.OptionsGrid, 'numeric');
            app.Parameter2EditField_3.Limits = [0 Inf];
            app.Parameter2EditField_3.Enable = 'off';
            app.SecondAdjustmentDropDownLabel = uilabel(app.OptionsGrid);
            app.SecondAdjustmentDropDownLabel.Text = 'Second Adjustment';
            app.SecondAdjustmentDropDown = uidropdown(app.OptionsGrid);
            app.SecondAdjustmentDropDown.Items = {'None', 'Local Brighten', 'Flatfield', 'Local Contrast Adjustment', 'Overall Contrast Adjustment', 'Local Laplacian Filter', 'Reduce Haze', 'Equalize Histogram', '', '', ''};
            app.SecondAdjustmentDropDown.ItemsData = {'No', 'Lb', 'Ff', 'Lc', 'Oc', 'Ll', 'Rh', 'Eh'};
            app.SecondAdjustmentDropDown.ValueChangedFcn = createCallbackFcn(app, @changeBrightnessStyle2, true);
            app.SecondAdjustmentDropDown.Value = 'No';
            app.ThirdAdjustmentDropDownLabel = uilabel(app.OptionsGrid);
            app.ThirdAdjustmentDropDownLabel.Text = 'Third Adjustment';
            app.ThirdAdjustmentDropDown = uidropdown(app.OptionsGrid);
            app.ThirdAdjustmentDropDown.Items = {'None', 'Local Brighten', 'Flatfield', 'Local Contrast Adjustment', 'Overall Contrast Adjustment', 'Local Laplacian Filter', 'Reduce Haze', 'Equalize Histogram', '', '', ''};
            app.ThirdAdjustmentDropDown.ItemsData = {'No', 'Lb', 'Ff', 'Lc', 'Oc', 'Ll', 'Rh', 'Eh'};
            app.ThirdAdjustmentDropDown.ValueChangedFcn = createCallbackFcn(app, @changeBrightnessStyle3, true);
            app.ThirdAdjustmentDropDown.Value = 'No';
            app.PreviewImageButton = uibutton(app.OptionsGrid, 'push');
            app.PreviewImageButton.ButtonPushedFcn = createCallbackFcn(app, @previewImage, true);
            app.PreviewImageButton.Text = 'Preview Image';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.OriginalAxes = uiaxes(app.GridLayout2);
            app.OriginalAxes.XTick = [];
            app.OriginalAxes.YTick = [];
            app.OriginalAxes.Visible = 'off';
            app.NewAxes = uiaxes(app.GridLayout2);
            app.NewAxes.XTick = [];
            app.NewAxes.YTick = [];
            app.NewAxes.Visible = 'off';
            app.OriginalLabel = uilabel(app.GridLayout2);
            app.OriginalLabel.Visible = 'off';
            app.OriginalLabel.Text = 'Original';
            app.AdjustedLabel = uilabel(app.GridLayout2);
            app.AdjustedLabel.Visible = 'off';
            app.AdjustedLabel.Text = 'Adjusted';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = brightnessSelection(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end