classdef fluorescenceAnalysisScene < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        OpenFluorescenceImagesMenu      matlab.ui.container.Menu
        SaveFluorescenceOverlaysMenu    matlab.ui.container.Menu
        CurrentImageMenu                matlab.ui.container.Menu
        CurrentChannelMenu              matlab.ui.container.Menu
        AllImagesMenu                   matlab.ui.container.Menu
        PreferencesMenu                 matlab.ui.container.Menu
        PerimeterColorsMenu             matlab.ui.container.Menu
        AllspheroidsMenu                matlab.ui.container.Menu
        FluorescentlyMarkedMenu         matlab.ui.container.Menu
        ImagesGroupingMenu              matlab.ui.container.Menu
        ImageScalingMenu                matlab.ui.container.Menu
        MainGridLayout                  matlab.ui.container.GridLayout
        FluorescenceGrid                matlab.ui.container.GridLayout
        FluorescenceImageList           matlab.ui.control.ListBox
        FluorescenceButtonsGrid         matlab.ui.container.GridLayout
        ImagesareinorderCheckBox        matlab.ui.control.CheckBox
        DoneOpeningImagesButton         matlab.ui.control.Button
        DeleteImageButtonF              matlab.ui.control.Button
        ReassignButton                  matlab.ui.control.Button
        DoneAssigningButton             matlab.ui.control.Button
        AssignImageButton               matlab.ui.control.Button
        BottomTabs                      matlab.ui.container.TabGroup
        DataDisplay                     matlab.ui.container.Tab
        HistogramGridLayout             matlab.ui.container.GridLayout
        GridLayout2                     matlab.ui.container.GridLayout
        SamePercent                     matlab.ui.control.CheckBox
        SameCutoff                      matlab.ui.control.CheckBox
        IntensityCutoffEditField        matlab.ui.control.NumericEditField
        FluorescentPixelIntensityCutoffLabel  matlab.ui.control.Label
        HistogramAxes                   matlab.ui.control.UIAxes
        ImageAssignment                 matlab.ui.container.Tab
        ImageAssignmentGrid             matlab.ui.container.GridLayout
        AssignChannelButton_2           matlab.ui.control.Button
        ChannelMatchingList             matlab.ui.control.ListBox
        MatchBrightfieldButton          matlab.ui.control.Button
        BrightfieldMatchingList         matlab.ui.control.ListBox
        FluorescenceRegexEditField      matlab.ui.control.EditField
        FluorescenceRegexEditFieldLabel  matlab.ui.control.Label
        BrightfieldRegexEditField       matlab.ui.control.EditField
        BrightfieldRegexEditFieldLabel  matlab.ui.control.Label
        MainAxesGridLayout              matlab.ui.container.GridLayout
        GridLayout3                     matlab.ui.container.GridLayout
        MaxEditField                    matlab.ui.control.NumericEditField
        MaxEditFieldLabel               matlab.ui.control.Label
        MinEditField                    matlab.ui.control.NumericEditField
        MinEditFieldLabel               matlab.ui.control.Label
        BrightfieldAxes                 matlab.ui.control.UIAxes
        FluorescenceAxes                matlab.ui.control.UIAxes
        SpheroidZoomGrid                matlab.ui.container.GridLayout
        SpheroidSelectListBox           matlab.ui.control.ListBox
        PreviousChannelButton           matlab.ui.control.Button
        NextChannelButton               matlab.ui.control.Button
        SeemontageCheckBox              matlab.ui.control.CheckBox
        SpheroidZoomAxes                matlab.ui.control.UIAxes
        BrightfieldGrid                 matlab.ui.container.GridLayout
        DeleteImageButtonBF             matlab.ui.control.Button
        BrightfieldImageList            matlab.ui.control.ListBox
        AnalyzeGrid                     matlab.ui.container.GridLayout
        WhichOrganoidsExport            matlab.ui.control.DropDown
        ExportSetButton                 matlab.ui.control.Button
        ExportCurrentButton             matlab.ui.control.Button
        BinSizeEditField                matlab.ui.control.NumericEditField
        BinSizeEditFieldLabel           matlab.ui.control.Label
        PercentileEditField             matlab.ui.control.NumericEditField
        PercentileEditFieldLabel        matlab.ui.control.Label
        AnalyzeAllButton                matlab.ui.control.Button
        AnalyzeCurrentButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp %References the main OrganoSeg App
        SharedData %Data brought over from main app
        %BF_BulkI = {} %Brightfield images for segmentation
        F_BulkI = {} %Fluorescence images for analysis
        BF_ImgMasked = {} %Brightfield images with overlay
        F_ImgMasked = {} %Fluorescence images with overlay
        BF_DisplayBulkI = {} %Brightfield images for display
        F_DisplayBulkI = {} %Fluorescence images for display
        BF_CC = {} %Connected component info from brightfield image

        F_MatchingBF = [];
        BF_FirstMatchingF = [];
        
        
        F_Filename = {} %Fluorescent image filenames
        F_Basename = {} %Fluorescent image file basenames
        BF_BoundingBox = {} %Bounding box data from birghtfield segmentation
        BF_Spheroids_order = {} %Spheroid order from brightfield segmentation (used in list of spheroids)
        BF_Centers = {} %Centroids from brightfield segmentation
        BF_MicronsPerPixel %Calbiration from brightfield image (used for area calculation)
        BF_Resize = {}; %Data on image resize from brightfield image (used for area calculation)
        BF_MatchingID = {};
        BF_GoodOrganoids = [];
        

        DoneOpening = false; %Boolean to say if all fluorescent images have been selected
        DoneAssigning = false; %Boolean to say if each fluorescent image has been assigned to the correct brightfield image


        SpheroidPixelIntensity = {}; %Arrays with the intensity of each pixel in a spheroid
        PercentileIntensity = {}; %Intensity of the pixel at the selected percentile within each organoid
        Percentile = {}; %The selected percentile to choose pixel intensity from
        Analyzed = {}; %Boolean array storing whether or not image has been analyzed (to eliminated redundant calculation of pixel intensity)
        IntensityCutoff = {}; %Pixel intensity between non-fluorescent and fluorescent cutoff selected by clicking on histogtram
        Marked = {}; %Array storing whether a spheroid is fluorescent or not
        ImageScaling = {};

        
        AllColorCode = {} %Color code for oultining all organoids on fluorescent image
        FluorColorCode = {} %Color code for outlining fluorescent organoids in image

        GroupPreference = 'Image';        
        Source

        CurrentChannelMontage = 1;
        
        MaxID = 0; %Max global ID for tracking organoids
        MinChannelValues %Min pixel intensity for a channel 
        MaxChannelValues %Max pixel intensity for a channel 
        
       
        ChannelIDs = [];  %Which channel each image belongs to (channels are listed as indices)
        ChannelLabels = {}; %Name of each channel corresponding to a given index

        MostRecentImageOpenFolder = nan;
        BF_BwPerimDilate = {}
        MainSegmentColor

        BF_GroupsTable = 0  %Used for grouping images
        BFRegex = 0
        F_GroupsTable
        FRegex

        MostRecentImageExportFolder
        MostRecentImageSaveFolder

      
    end
    
    methods (Access = private)
        
        function displayBrightfieldImage(app, selected)
            

            cla(app.BrightfieldAxes); 
            
            imgMasked = app.BF_ImgMasked{selected};
            imshow(imgMasked,[], 'Parent', app.BrightfieldAxes);
            
            %axesChild.ButtonDownFcn = createCallbackFcn(app, @rawImageButtonDownFunction, true);
%             cla(app.BrightfieldAxes,'reset');
%             app.BrightfieldAxes.XTickLabel = {};                
%             app.BrightfieldAxes.YTickLabel = {};  
%             app.BrightfieldAxes.XTick = {};
%             app.BrightfieldAxes.YTick = {};
%             app.BrightfieldAxes.XColor ='w';                
%             app.BrightfieldAxes.YColor ='w';                
%             app.BrightfieldAxes.PlotBoxAspectRatio =[4 3 3];
%             app.BrightfieldAxes.Visible = 0;
%             app.BrightfieldAxes.Toolbar.Visible = 0;


            app.FluorescenceAxes.Position(3) = app.BrightfieldAxes.Position(3);
            app.FluorescenceAxes.Position(4) = app.BrightfieldAxes.Position(4);
           
            
        end

        function displayFluorescenceImageOnly(app, selected)
            cla(app.FluorescenceAxes)
            %cla(app.FluorescenceAxes, 'reset');
            maxImgValue = max(app.F_DisplayBulkI{selected}(:));
            
            imshow(app.F_DisplayBulkI{selected},[maxImgValue*app.MinEditField.Value maxImgValue*app.MaxEditField.Value], 'Parent', app.FluorescenceAxes);
            
%             app.FluorescenceAxes.XTickLabel = {};                
%             app.FluorescenceAxes.YTickLabel = {};  
%             app.FluorescenceAxes.XTick = {};
%             app.FluorescenceAxes.YTick = {};
%             app.FluorescenceAxes.XColor ='w';                
%             app.FluorescenceAxes.YColor ='w';                
%             app.FluorescenceAxes.PlotBoxAspectRatio =[4 3 3];
%             app.FluorescenceAxes.Visible = 0;
%             app.FluorescenceAxes.Toolbar.Visible = 0;                
            
        end 

        function displayFluorescenceWithOverlay(app, selected)
            
            cla(app.FluorescenceAxes)
            %cla(app.FluorescenceAxes, 'reset'); 
            maxImgMasked = max(app.F_ImgMasked{selected}(:));
            minVal = double(maxImgMasked*app.MinEditField.Value);
            maxVal = double(maxImgMasked*app.MaxEditField.Value);
            
            imgMasked = double(app.F_ImgMasked{selected});
            
            imgMasked = (imgMasked-minVal)./(maxVal-minVal);


            axesChild = imshow(imgMasked,[], 'Parent', app.FluorescenceAxes);
            axesChild.ButtonDownFcn = createCallbackFcn(app, @fluorescenceAxesButtonDown, true);
%             app.FluorescenceAxes.XTickLabel = {};                
%             app.FluorescenceAxes.YTickLabel = {};  
%             app.FluorescenceAxes.XTick = {};
%             app.FluorescenceAxes.YTick = {};
%             app.FluorescenceAxes.XColor ='w';                
%             app.FluorescenceAxes.YColor ='w';                
%             app.FluorescenceAxes.PlotBoxAspectRatio =[4 3 3];
%             app.FluorescenceAxes.Visible = 0;
%             app.FluorescenceAxes.Toolbar.Visible = 0; 
           
            
        end

        function displaySpheroidZoom(app, selected)
            if strcmp(app.Source, 'Main')
                %%%%%%%Displaying SpheroidZoom
                imgMasked = app.F_ImgMasked{selected};
                selected_BF = app.F_MatchingBF(selected);
                %Display correct listbox values
                spheroid_str = cell(1,length(app.BF_Spheroids_order{selected_BF}));
                spheroid_order = app.BF_Spheroids_order{selected_BF};
                for j = 1:length(spheroid_order)
                    spheroid_str{j} = num2str(spheroid_order(j));  
                    %app.SpheroidListBox.ItemsData(j) = app.Spheroids_order{selected}(j);
                end
                app.SpheroidSelectListBox.ItemsData = app.BF_Spheroids_order{selected_BF};


                app.SpheroidSelectListBox.Items = spheroid_str;
                app.SpheroidSelectListBox.Value = 1;


                %Choose bounding box to crop
                bbox = app.BF_BoundingBox{selected_BF}{spheroid_order(1)};
                bbox(1:2) = (bbox(1:2)) -5;
                bbox(3:4) = (bbox(3:4)) +10;
    
                img = imgMasked;
                maxImgMasked = max(imgMasked(:));
                minVal = double(maxImgMasked*app.MinEditField.Value);
                maxVal = double(maxImgMasked*app.MaxEditField.Value);
                img = double(img);
    
                img = (img-minVal)./(maxVal-minVal);
                axes = app.SpheroidZoomAxes;
                %Display zoom image in GUI
                cla(axes);
                
                imshow(imcrop(img, bbox),[], 'Parent', app.SpheroidZoomAxes);
            else
                %If images come from tracking, allow option to show montage
                %And also pull from matching ID and not spheroid order

                imgMasked = app.F_ImgMasked{selected};
                selected_BF = app.F_MatchingBF(selected);
                %Display correct listbox values
                spheroid_str = cell(1,length(app.BF_Spheroids_order{selected_BF}));
                spheroid_order = app.BF_Spheroids_order{selected_BF};
                for j = 1:length(spheroid_order)
                    spheroid_str{j} = num2str(spheroid_order(j));
                    if app.BF_GoodOrganoids(j) == 0
                        spheroid_str{j} = strcat(spheroid_str{j}, '_x');
                    elseif app.BF_GoodOrganoids(j) == 1
                        spheroid_str{j} = strcat(spheroid_str{j}, '_c');
                    end
                        
                    %app.SpheroidListBox.ItemsData(j) = app.Spheroids_order{selected}(j);
                end
                app.SpheroidSelectListBox.ItemsData = app.BF_Spheroids_order{selected_BF};


                app.SpheroidSelectListBox.Items = spheroid_str;
                
                app.SpheroidSelectListBox.Value = 1;
                if app.SeemontageCheckBox.Value == 0
                    idInImageCC = find(app.BF_MatchingID{selected_BF} == 1);
    
                    axes = app.SpheroidZoomAxes;
                    %Display zoom image in GUI
                    cla(axes);
                    if ~isempty(idInImageCC)
    
                        %Choose bounding box to crop
                        bbox = app.BF_BoundingBox{selected_BF}{idInImageCC};
                        bbox(1:2) = (bbox(1:2)) -5;
                        bbox(3:4) = (bbox(3:4)) +10;
            
                        
                        img = imgMasked;
                        maxImgMasked = max(imgMasked(:));
                        minVal = double(maxImgMasked*app.MinEditField.Value);
                        maxVal = double(maxImgMasked*app.MaxEditField.Value);
                        img = double(img);
            
                        img = (img-minVal)./(maxVal-minVal);
                        
                        
                        imshow(imcrop(img, bbox),[], 'Parent', app.SpheroidZoomAxes);
                    end
                else
                    %Show the spheroid in all image from the channel
                    
                    channel = app.CurrentChannelMontage;
                    imageArray = cell(1, length(app.BF_CC));
                    %Determine channel based on the number of brightfield
                    %and fluorescent images, assuming images of same
                    %channel are equally spaced
                    %imagesInChannel = linspace(channel, channel + (length(app.BF_CC) - 1)*app.TotalChannels, length(app.BF_CC));
                    imagesInChannel = find(app.ChannelIDs == channel);


                    idsInImageCC = zeros(1, length(app.BF_CC));
                    largestBB_X = 0;
                    largestBB_Y = 0;
        
                    for image = 1:length(app.BF_CC)
                        
                        idInImageCC = find(app.BF_MatchingID{image} == 1);
                        
                        %Prepare montage by finding largest bounding box
                        %for spheroid
                        if ~isempty(idInImageCC)
                            idsInImageCC(image) = idInImageCC;
                            %Get crop of spheroid in image
                            bbox = app.BF_BoundingBox{image}{idInImageCC};
                            bbox_X = bbox(3) + 10;
                            bbox_Y = bbox(4) + 10;
                            if bbox_X > largestBB_X
                                largestBB_X = bbox_X;
                            end
                            if bbox_Y > largestBB_Y
                                largestBB_Y = bbox_Y;
                            end
                        end
        
                    end
        
                    for image = 1:length(app.BF_CC)
                        selected = imagesInChannel(image);
                        idInImageCC = idsInImageCC(image);
                        
                        if idInImageCC ~= 0
                            %Get crop of spheroid in image
                            bbox = app.BF_BoundingBox{image}{idInImageCC};
                            center = zeros(1,2);
                            center(1:2) = bbox(1:2) + bbox(3:4)/2;
                            bbox(1:2) = [round(center(1) - largestBB_X/2) round(center(2) - largestBB_Y/2)];
        
        
                            bbox(3:4) = [largestBB_X largestBB_Y];
        
                            img = app.F_ImgMasked{selected};
                            maxImgMasked = max(img(:));
                        
                            minVal = double(maxImgMasked*app.MinEditField.Value);
                            maxVal = double(maxImgMasked*app.MaxEditField.Value);
                            img = double(img);
                
                            img = (img-minVal)./(maxVal-minVal);
                            cropped = imcrop(img, bbox);
                            imageArray{image} = cropped;
                        end
                        
        
                    end
                    
                    montage(imageArray, 'Parent', app.SpheroidZoomAxes)
                end


            end           
           

        end

        function displayHistogramAxes(app, selected)
            cla(app.HistogramAxes)
           
            formatX = '%dth Percentile Pixel Intensity';
            percentile = app.Percentile{selected};
            str = sprintf(formatX,percentile);
            xlabel(app.HistogramAxes, str)
            

            app.HistogramAxes.Visible = 1;
            
            binSize = app.BinSizeEditField.Value;
            edges = (min(app.PercentileIntensity{selected})-binSize):binSize:(max(app.PercentileIntensity{selected})+binSize);


            [f,xi] = ksdensity(app.PercentileIntensity{selected});
            yyaxis(app.HistogramAxes, 'left')
            plot(app.HistogramAxes, xi, f)
            yyaxis(app.HistogramAxes, 'right')
            histogram(app.HistogramAxes, app.PercentileIntensity{selected}, edges);
            
           
            numPastCutoff = length(find(app.Marked{selected}));
            title(app.HistogramAxes, strcat(num2str(numPastCutoff*100/length(app.PercentileIntensity{selected})), "% above cutoff"),'')

            xline(app.HistogramAxes, app.IntensityCutoff{selected})


            %Display cutoff line between fluorescent and non-fluorescent
            %xline(app.HistogramAxes, app.IntensityCutoff{selected})
            app.PercentileEditField.Value = app.Percentile{selected};
            if app.IntensityCutoffEditField.Visible == 0
                app.IntensityCutoffEditField.Visible = 1;
                app.FluorescentPixelIntensityCutoffLabel.Visible = 1;
                app.SameCutoff.Visible = 1;
                app.SamePercent.Visible = 1;
                app.HistogramGridLayout.Visible = 1;

            end
            app.IntensityCutoffEditField.Value = app.IntensityCutoff{selected};
        end

        
        function analyzeFluorescence(app, indices, callingImage)
            h = waitbar(0,'Please wait... analyzing...');
            spheroidPixelIntensity = app.SpheroidPixelIntensity;
            percentile  = app.Percentile;
            percentileIntensity = app.PercentileIntensity;
            analyzed = app.Analyzed;
            marked = app.Marked;


            count  = 0;
            for selected = indices
                selected_BF = app.F_MatchingBF(selected);
                
                %Update the percentile used for each image
               
                
                image = app.F_BulkI{selected};
                cc = app.BF_CC{selected_BF};
                
                
                CClabel = labelmatrix(cc);
                
                boundingBox = app.BF_BoundingBox{selected_BF};
                fluorescentCC = cc;
                %If image not already analyzed, calculate the pixel
                %intensities for each spheroid
                if analyzed{selected} == 0
                    percentile{selected} = app.PercentileEditField.Value;
                    %preallocated
                    spheroidPixelIntensity{selected} = cell(1, length(cc.PixelIdxList)); 
                    percentileIntensity{selected} = zeros(1, length(cc.PixelIdxList)); 
                    marked{selected} = ones(1, length(cc.PixelIdxList));


                    for sphere = 1:length(cc.PixelIdxList)
                            
                              %for each spheroid, get pixel intensity 


                            bbox = boundingBox{sphere};
                            imageNew = imcrop(image, bbox);
                            CCNew = imcrop(CClabel, bbox);
                            
                            
                            if isempty(double(imageNew(CCNew == sphere)))
                                spheroidPixelIntensity{selected}{sphere} = [nan];
                            else
                                spheroidPixelIntensity{selected}{sphere} = double(imageNew(CCNew == sphere));
                            end
                            %Determine the selected percentile intensity
                            %for each spheroid
                            
                            percentileIntensity{selected}(sphere) = prctile(spheroidPixelIntensity{selected}{sphere}, percentile{selected});
                            %Label spheroid as marked or not
%                             if percentileIntensity{selected}(sphere) < app.IntensityCutoff{selected}
%                                 fluorescentCC.PixelIdxList{sphere} = [];
%                                 marked{selected}(sphere) = 0;
%                                 
%                             end
                            
                    end
                %if already analyzed, do not need to recalculate pixel
                %intensities for each spheroid               
       
                
                else      
                    marked{selected} = ones(1, length(cc.PixelIdxList));
                    %determine percentile intensity and mark as flurescent or not    
                    for sphere = 1:length(cc.PixelIdxList)
                        percentileIntensity{selected}(sphere) = prctile(spheroidPixelIntensity{selected}{sphere}, percentile{selected});


                    end

                end

                if mod(selected,3) == 2
                    count = count + 1;
                end
                
                
                for sphere = 1:length(cc.PixelIdxList)
                    if percentileIntensity{selected}(sphere) < app.IntensityCutoff{selected}
                        fluorescentCC.PixelIdxList{sphere} = [];
                        marked{selected}(sphere) = 0;                           
                    
                    end
                end
                %Create perimter display for fluorescent image
                %Convert CClabel to bw
                
                bwMock = CClabel;
                bwMock(bwMock > 0) = 1;
                
    
                %Find perimeter and overlay with raw image
                bw_perim = double(bwperim(bwMock));
                bw_perim_dil = imdilate(bw_perim,ones(3,3)); 


                displayF_I = app.F_DisplayBulkI{selected};
                displayF_I = double(displayF_I);
                %normalize display image
                if strcmp(app.Source, 'Tracking')
                    channel = app.ChannelIDs(selected);
                    
                    minChannelValue = app.MinChannelValues(channel);                    
                    maxChannelValue = app.MaxChannelValues(channel);


                    displayF_I = (displayF_I - minChannelValue)./(maxChannelValue - minChannelValue);
                   
                else
                    
                    
                    displayF_I = (displayF_I - min(displayF_I(:)))./(max(displayF_I(:)) - min(displayF_I(:)));
                end


                imgMasked = imoverlay(displayF_I,bw_perim_dil,app.AllColorCode{selected});
                    
                fluorescentCCLabel = labelmatrix(fluorescentCC);
                %Add fluorescent perimeter
                    
                fluorescentPerim = bwperim(fluorescentCCLabel ~= 0);
                fluorescent_perim_dil = imdilate(fluorescentPerim, ones(3,3));
                imgMasked = imoverlay(imgMasked,fluorescent_perim_dil, app.FluorColorCode{selected});
                app.F_ImgMasked{selected} = imgMasked;
                analyzed{selected} = 1;


                waitbar(selected/length(app.F_BulkI))
                
            end

            close(h)
            %Display and store data
            displayBrightfieldImage(app, app.F_MatchingBF(callingImage))
            displayFluorescenceWithOverlay(app, callingImage)
            if strcmp(app.Source, 'Main')
                displaySpheroidZoom(app, callingImage)
            elseif isempty(app.SpheroidSelectListBox.Items)
                displaySpheroidZoom(app, callingImage)
            else
                spheroidSelectList(app)
            end

            
            app.Percentile = percentile;
            app.PercentileIntensity = percentileIntensity;
            app.SpheroidPixelIntensity = spheroidPixelIntensity;
            app.Analyzed = analyzed;
            
            app.BrightfieldImageList.Value = app.F_MatchingBF(callingImage);
            app.FluorescenceImageList.Value = callingImage;
            app.Marked = marked;
            displayHistogramAxes(app, callingImage)


        end


        %Used to label the number of a spheroid when selecting it on the
        %fluorescent axes
        function fluorescenceAxesButtonDown(app, event)
            selected = app.FluorescenceImageList.Value;
            selected_BF = app.F_MatchingBF(selected);
            centers_display = app.BF_Centers{selected_BF};

            
            spherNum = 0;
            [coords] = event.IntersectionPoint(1:2);
            for i = 1:length(app.BF_CC{selected_BF}.PixelIdxList)
                %account for removed spheroids
                if (~isnan(centers_display(i)) && ~isempty(app.BF_CC{selected_BF}.PixelIdxList))
                    spherNum = spherNum + 1;
                % find if selected point is inside of spheroid
                    
                    if any(app.BF_CC{selected_BF}.PixelIdxList{i}==sub2ind(size(app.F_BulkI{selected}),round(coords(1,2)),round(coords(1,1))))
                        %Adjust to follow matching ID if from tracking
                        if strcmp(app.Source, 'Tracking')
                            spherNum = app.BF_MatchingID{selected_BF}(spherNum);
                        end
                        text(app.FluorescenceAxes, centers_display(i,1)-5, centers_display(i,2), num2str(spherNum), 'color', 'w');
                        break
                        
                    end
                end
            end
        end

        %Export fluorescent metrics (area, selected percentile pixel
        %intensity, marked boolean)
        
        function exportFluorescence(app, startInd, finish)
            %Ask for export type/name
                f = figure('Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
                if isnan(app.MostRecentImageExportFolder)
                    [answer,PathName] = uiputfile({'*.xls','Excel File (*.xls)';'*.xlsx','Excel File (*.xlsx)';'*.mat','MAT File (*.mat)'},'Export Metrics As');
                else
                    [answer,PathName] = uiputfile({'*.xls','Excel File (*.xls)';'*.xlsx','Excel File (*.xlsx)';'*.mat','MAT File (*.mat)'},'Export Metrics As', app.MostRecentImageExportFolder);
                    
                end
                
                delete(f)
                if answer == 0
                    %do nothing
                else


                    h = waitbar(0,'Please wait... compiling metric signature...');
                    if startInd == finish
                        signatureTot = cell(1);
                        metricsTot = cell(1);
                    else
                        signatureTot = cell(length(app.F_BulkI));
                        metricsTot = cell(length(app.F_BulkI));
                    end
                    
                    for selected = startInd:finish
                        selected_BF = app.F_MatchingBF(selected);


                        %%%%%%%%%%%%NEW EXPORT ATTEMPT%%%%%%%%%%%

                       
                        formatX = '%dth Percentile Pixel Intensity';
                        percentile = app.Percentile{selected};                        
                        percentileString  = sprintf(formatX,percentile);

                        formatX = 'Fluorescently Marked (Intensity = %.2f)';
                        intensity = app.IntensityCutoff{selected};                        
                        markedString  = sprintf(formatX,intensity);

                        %Get area
                        metricsString = {'Area', percentileString, markedString};
                        
                        statsBW = regionprops(app.BF_CC{selected_BF}, 'Area');
                        %Adjust areas for resize
                        resize = app.BF_Resize{selected_BF};
                        micronsPerPixel = app.BF_MicronsPerPixel;
                        area = [statsBW.Area];
                        area = area./(resize^2);
                        area = area.*(micronsPerPixel^2);
                        

                        %Get selected percentile pixel intensity, and whether or not spheroid is marked as fluorescent 
                        intensity = [app.PercentileIntensity{selected}];
                        marked = [app.Marked{selected}];

                        signature = {area; intensity; marked};         

                        if startInd ~= finish
                            signatureTot{selected} = signature;
                            metricsTot{selected} = metricsString;
                        else
                            signatureTot{1} = signature;
                            metricsTot{1} = metricsString;
                        end

                        waitbar(selected/length(app.F_BulkI));
                    end


                    %initialize
                    if contains(answer,'.mat') == 0
                        waitbar(1,h,'Please wait...exporting to excel file');
                        if startInd == finish
                            f_indices = [startInd];
                            
                        else
                            f_indices = 1:length(app.F_BulkI);
                            
                            
                        end
                        for j = 1:length(signatureTot)
                            if j == 1
                                writemode = 'replacefile';
                            else
                                writemode = 'overwritesheet';
                            end
    
    
                            sheet = app.F_Filename{f_indices(j)};


                            %create trimmed version


                            %write to excel file
                            topRow = [{'Spheroid #'} metricsTot{j} {"Filename"} {"Channel"}];
                            dbl = cell2mat(signatureTot{j})';
                            filenameCell = repmat({sheet}, 1, length(signatureTot{j}{1}))';
                            channelCell = repmat({app.ChannelLabels{app.ChannelIDs(f_indices(j))}}, 1, length(signatureTot{j}{1}))';
                            numbers = [num2cell((1:length(signatureTot{j}{1}))'), num2cell(dbl), filenameCell, channelCell];
                            matrix = [topRow;numbers];
                            %Name sheets with image, but sheets can only be 30
                            %characters, so adjust if necessary

                            sheet = strcat(num2str(j), '_', sheet);
                            if length(sheet) > 31
                                sheet = sheet(1:31);
                            end
                            
                            sheet = regexprep(sheet,'[\\\/\[\]*?:]','_');
                            try
                                try
                                    writecell(matrix,char(strcat(PathName,answer)), 'Sheet', sheet, 'WriteMode', writemode);
                                catch
                                    try
                                        writecell(matrix,char(strcat(PathName,answer)), 'Sheet', sheet(1:30), 'WriteMode', writemode);
                                    catch
                                        writecell(matrix,char(strcat(PathName,answer)), 'Sheet', j, 'WriteMode', writemode);
                                    end
                                end
                            catch
                                mb1 = errordlg('The Excel file you choose to write to may be open. Please close it and attempt the export again');
                                t = timer;
                                t.StartDelay = 2;
                                t.TimerFcn = @(~,~) delete(mb1);
                                start(t)
                                delete(h)
                                return;
                            end
    
    
                            waitbar(j/length(signatureTot))
                        end
    
                        %Display message box
                        delete(h);
                        mb = msgbox('Excel export successful!');
                        t = timer;
                        t.StartDelay = 2;
                        t.TimerFcn = @(~,~) delete(mb);
                        start(t)
                    
                    else
                        mb = msgbox('Please wait... storing variables...');
                        

                        %Store variables for all images
                        if startInd == finish
                            f_indices = [startInd];
                            bf_indices = [app.F_MatchingBF(startInd)];
                        else
                            f_indices = 1:length(app.F_BulkI);
                            bf_indices = 1:length(app.BF_CC);
                            
                        end
                        
                        f_filename = app.F_Filename(f_indices);
                        bf_filename = app.BrightfieldImageList.Items(bf_indices);
                        f_baseName = app.F_Basename(f_indices);

                        
                        f_imgMasked = app.F_ImgMasked(f_indices);
                        bf_imgMasked = app.BF_ImgMasked(bf_indices);
                        f_displayBulkI = app.F_DisplayBulkI(f_indices);
                        bf_displayBulkI = app.BF_DisplayBulkI(bf_indices);

                        bf_cc = app.BF_CC(bf_indices);
                        bf_boundingBox = app.BF_BoundingBox(bf_indices);
                        bf_spheroidsOrder = app.BF_Spheroids_order;
                        bf_centers = app.BF_Centers(bf_indices);
                        bf_mpp = app.BF_MicronsPerPixel;
                        bf_resize = app.BF_Resize(bf_indices);
                        bf_firstMatchingF = app.BF_FirstMatchingF(bf_indices);
                        f_MatchingBF = app.F_MatchingBF(f_indices);
                        channelIDs = app.ChannelIDs(f_indices);
                        channelLabels = app.ChannelLabels;

                        spheroidPixelIntensity = app.SpheroidPixelIntensity;
                        percentileIntensity = app.PercentileIntensity(f_indices);
                        percentile = app.Percentile(f_indices);
                        analyzed = app.Analyzed(f_indices);
                        intensityCutoff = app.IntensityCutoff(f_indices);
                        marked = app.Marked(f_indices);
                        allColorCode = app.AllColorCode;
                        fluorColorCode = app.FluorColorCode;
                        source = app.Source;
                        groupPreference = app.GroupPreference;


                        hiddenString = {'f_filename', 'bf_filename',  ...
                            'bf_cc','bf_boundingBox','bf_centers','f_baseName',...
                            'bf_spheroidsOrder', 'bf_mpp','bf_resize', ...
                            'f_displayBulkI','bf_displayBulkI', 'bf_firstMatchingF', 'f_matchingBF', 'spheroidPixelIntensity',...
                            'percentileIntensity', 'analyzed', 'intensityCutoff', 'marked', 'allColorCode',...
                            'fluorColorCode', 'source',  'groupPreference', 'channelIDs', 'channelLabels', 'percentile'
                            };
                       

                        hiddenCell = [{f_filename}, {bf_filename},  ...
                            {bf_cc},{bf_boundingBox},{bf_centers},{f_baseName},...
                            {bf_spheroidsOrder}, {bf_mpp},{bf_resize},...
                            {f_displayBulkI},{bf_displayBulkI}, {bf_firstMatchingF}, {f_MatchingBF}, {spheroidPixelIntensity},...
                            {percentileIntensity}, {analyzed}, {intensityCutoff}, {marked}, {allColorCode},...
                            {fluorColorCode}, {source}, {groupPreference}, {channelIDs}, {channelLabels}, {percentile}]';


                        hiddenProps = cell2struct(hiddenCell,hiddenString,1);

                        s = whos('hiddenProps');
            
                        
                        if s.bytes > 2e9
                            save(char(strcat(PathName,answer)),'hiddenProps', '-v7.3');
                        else

                            save(char(strcat(PathName,answer)),'hiddenProps');
                        end
                        delete(h)
                        delete(mb)
                        mb = msgbox('Signature export successful!');
                        t = timer;
                        t.StartDelay = 2;
                        t.TimerFcn = @(~,~) delete(mb);
                        start(t)

                    end
                end

                
        end


        %Same export, but rearrange metrics to be in order of matching ID
        %and to have each metric (and channel) on different sheet
        function exportFluorescenceTrack(app, startInd, finish)
            %Ask for export type/name
                f = figure('Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
                if isnan(app.MostRecentImageExportFolder)
                    [answer,PathName] = uiputfile({'*.xls','Excel File (*.xls)';'*.xlsx','Excel File (*.xlsx)';'*.mat','MAT File (*.mat)'},'Export Metrics As');
                else
                    [answer,PathName] = uiputfile({'*.xls','Excel File (*.xls)';'*.xlsx','Excel File (*.xlsx)';'*.mat','MAT File (*.mat)'},'Export Metrics As', app.MostRecentImageExportFolder);
                    
                end
                
                delete(f)
                if answer == 0
                    %do nothing
                else


                    h = waitbar(0,'Please wait... compiling metric signature...');
                    if startInd == finish
                        signatureTot = cell(1);
                        
                    else
                        signatureTot = cell(length(app.F_BulkI));
                        
                    end
                    

                    maxVal = app.MaxID;


                    for selected = startInd:finish
                        
                        selected_BF = app.F_MatchingBF(selected);
                        matchingID = app.BF_MatchingID{selected_BF};


                        %Get area
                        metricsString = {'Area', 'Percentile Pixel Intensity', 'Fluorescently Marked'};
                        
                        statsBW = regionprops(app.BF_CC{selected_BF}, 'Area');
                        %Adjust areas for resize
                        resize = app.BF_Resize{selected_BF};
                        micronsPerPixel = app.BF_MicronsPerPixel;
                        area = [statsBW.Area];
                        area = area./(resize^2);
                        area = area.*(micronsPerPixel^2);
                        areaRe = rearrangeMetric(app,area, matchingID, maxVal);
                        

                        %Get selected percentile pixel intensity, and whether or not spheroid is marked as fluorescent 
                        intensityRe = rearrangeMetric(app, [app.PercentileIntensity{selected}], matchingID, maxVal);
                        markedRe = rearrangeMetric(app, [app.Marked{selected}], matchingID, maxVal);

                        signature = {areaRe; intensityRe; markedRe};    


                        signatureTot{selected} = signature;


                        waitbar(selected/length(app.F_BulkI));
                    end
                    
                    
                    numChannels = max(app.ChannelIDs);
                    signatureByMetricsAndChannel = cell(1, length(metricsString)*numChannels);
                    
                    sheetNum = 1;
                    %Separate images of different channel (assumes images
                    %of same channel will be equally spaced at this point)
                    for channel = 1:numChannels
                        %imagesInChannel = linspace(channel, channel + (length(app.BF_CC) - 1)*app.TotalChannels, length(app.BF_CC));
                        imagesInChannel = find(app.ChannelIDs == channel);
                        for i = 1:length(metricsString)
                            signatureByMetricsAndChannel{sheetNum} = zeros(length(imagesInChannel), maxVal);
                            
                            entry = 1;
                            for j = 1:length(signatureTot)
                                if ismember(j, imagesInChannel)
                                    
                                    signatureByMetricsAndChannel{sheetNum}(entry,:) = signatureTot{j}{i};
                                    entry = entry + 1;
                                    
                                end
                                
                            end
                            sheetNum = sheetNum + 1;
                                
                        end
                    end


                    if contains(answer,'.mat') == 0
                        waitbar(1,h,'Please wait...exporting to excel file');
    
                        %initialize
                        sheetNum = 1;
                        for channel = 1:max(app.ChannelIDs)
                            %imagesInChannel = linspace(channel, channel + (length(app.BF_CC) - 1)*app.TotalChannels, length(app.BF_CC));
                            imagesInChannel = find(app.ChannelIDs == channel);
                            for j = 1:length(metricsString)
                                if sheetNum == 1
                                    writemode = 'replacefile';
                                else
                                    writemode = 'overwritesheet';
                                end
                            
                                sheet = strcat(metricsString{j}, '_', app.ChannelLabels{channel});                      
                
                                %write to excel file


                                topRow = [{'Spheroid #/ Image #'}, num2cell(app.F_MatchingBF(imagesInChannel)), {"Annotation"}];
                                secondRow = [{'Filenames'} app.F_Filename(imagesInChannel), {""}];
                                %Add information about parameters used for each
                                %image
                                if contains(sheet, 'Percentile Pixel Intensity')
                                    thirdRow = [{'Percentile'}, app.Percentile(imagesInChannel),  {""}];
                                elseif contains(sheet, 'Fluorescently Marked')
                                    thirdRow = [{'Pixel Intensity Cutoff'}, app.IntensityCutoff(imagesInChannel), {""}];
                                    
                                else
                                    thirdRow = cell(1,length(imagesInChannel) + 2);
                                end
                                
                                dbl = signatureByMetricsAndChannel{sheetNum}';


                                app.BF_GoodOrganoids = app.BF_GoodOrganoids(1:app.MaxID);
                                goodOrganoidsString = cell(1,length(app.BF_GoodOrganoids));
                                for org = 1:length(app.BF_GoodOrganoids)
                                    switch app.BF_GoodOrganoids(org)
                                        case 2
                                            goodOrganoidsString{org} = "None";
                                        case 1
                                            goodOrganoidsString{org} = "c";
                                        case 0
                                            goodOrganoidsString{org} = "x";
                                        
                                    end
                                end
                                numbers = [num2cell((1:maxVal)'), num2cell(dbl), goodOrganoidsString'];
                                if strcmp(app.WhichOrganoidsExport.Value, 'RemoveX')
                                    goodOrgInd = find(app.BF_GoodOrganoids);
                                    numbers = numbers(goodOrgInd, :);


                                elseif strcmp(app.WhichOrganoidsExport.Value, 'KeepOnlyC')
                                    goodOrgInd = find(app.BF_GoodOrganoids == 1);
                                    numbers = numbers(goodOrgInd, :);
                                    
                                end


                                matrix = [topRow;secondRow;thirdRow;numbers];
                                try
                                    writecell(matrix,char(strcat(PathName,answer)), 'Sheet', sheet, 'WriteMode', writemode);
                                catch
                                    mb1 = errordlg('The Excel file you choose to write to may be open. Please close it and attempt the export again');
                                    t = timer;
                                    t.StartDelay = 2;
                                    t.TimerFcn = @(~,~) delete(mb1);
                                    start(t)
                                    delete(h)
                                    return;
                                end
                                waitbar(sheetNum/length(signatureByMetricsAndChannel))
                                sheetNum = sheetNum + 1;
                                
                            end
                        end


                        %Display message box
                        delete(h);
                        mb = msgbox('Excel export successful!');
                        t = timer;
                        t.StartDelay = 2;
                        t.TimerFcn = @(~,~) delete(mb);
                        start(t)
                    else
                        mb = msgbox('Please wait... storing variables...');
                        %Store variables for all images
                        if startInd == finish
                            f_indices = [startInd];
                            bf_indices = [app.F_MatchingBF(startInd)];
                        else
                            f_indices = 1:length(app.F_BulkI);
                            bf_indices = 1:length(app.BF_CC);
                            
                        end
                        
                        f_filename = app.F_Filename(f_indices);
                        bf_filename = app.BrightfieldImageList.Items(bf_indices);
                        f_baseName = app.F_Basename(f_indices);
                        

                        %f_bulkI = app.F_BulkI(f_indices);
                        %bf_bulkI = app.BF_BulkI(bf_indices);
                        %f_imgMasked = app.F_ImgMasked(f_indices);
                        %bf_imgMasked = app.BF_ImgMasked(bf_indices);
                        f_displayBulkI = app.F_DisplayBulkI(f_indices);
                        bf_displayBulkI = app.BF_DisplayBulkI(bf_indices);


                        bf_cc = app.BF_CC(bf_indices);
                        bf_boundingBox = app.BF_BoundingBox(bf_indices);
                        bf_spheroidsOrder = app.BF_Spheroids_order;
                        bf_centers = app.BF_Centers(bf_indices);
                        bf_mpp = app.BF_MicronsPerPixel;
                        bf_resize = app.BF_Resize(bf_indices);
                        bf_firstMatchingF = app.BF_FirstMatchingF(bf_indices);
                        f_matchingBF = app.F_MatchingBF(f_indices);
                        bf_matchingID = app.BF_MatchingID(bf_indices);
                        bf_goodOrganoids = app.BF_GoodOrganoids;

                        spheroidPixelIntensity = app.SpheroidPixelIntensity;
                        percentileIntensity = app.PercentileIntensity(f_indices);
                        percentile = app.Percentile(f_indices);
                        analyzed = app.Analyzed(f_indices);
                        intensityCutoff = app.IntensityCutoff(f_indices);
                        marked = app.Marked(f_indices);
                        allColorCode = app.AllColorCode;
                        fluorColorCode = app.FluorColorCode;
                        source = app.Source;


                        groupPreference = app.GroupPreference;
                        channelIDs = app.ChannelIDs;
                        channelLabels = app.ChannelLabels;
                        maxID = app.MaxID;
                        minChannelValues = app.MinChannelValues;
                        maxChannelValues = app.MaxChannelValues;
                        mainSegmentColor = app.MainSegmentColor;


                        hiddenString = {'f_filename', 'bf_filename', 'f_displayBulkI', ...
                            'bf_cc','bf_boundingBox','bf_centers','f_baseName',...
                            'bf_spheroidsOrder', 'bf_mpp','bf_resize', ...
                            'bf_displayBulkI', 'bf_firstMatchingF', 'f_matchingBF', 'spheroidPixelIntensity',...
                            'percentileIntensity', 'analyzed', 'intensityCutoff', 'marked', 'allColorCode',...
                            'fluorColorCode', 'source', 'groupPreference', 'channelIDs', 'channelLabels' 'maxID',... 
                            'minChannelValues', 'maxChannelValues', 'bf_matchingID', 'percentile', 'bf_goodOrganoids',...
                            'mainSegmentColor'
                            };
                       

                        hiddenCell = [{f_filename}, {bf_filename}, {f_displayBulkI},...
                            {bf_cc},{bf_boundingBox},{bf_centers},{f_baseName},...
                            {bf_spheroidsOrder}, {bf_mpp},{bf_resize},...
                            {bf_displayBulkI}, {bf_firstMatchingF}, {f_matchingBF}, {spheroidPixelIntensity},...
                            {percentileIntensity}, {analyzed}, {intensityCutoff}, {marked}, {allColorCode},...
                            {fluorColorCode}, {source}, {groupPreference}, {channelIDs}, {channelLabels}, {maxID},... 
                            {minChannelValues}, {maxChannelValues}, {bf_matchingID}, {percentile}, {bf_goodOrganoids},...
                            {mainSegmentColor}]';


                        hiddenProps = cell2struct(hiddenCell,hiddenString,1);
                        s = whos('hiddenProps');
            
                        
                        if s.bytes > 2e9
                            save(char(strcat(PathName,answer)),'hiddenProps', '-v7.3');
                        else                           

                            save(char(strcat(PathName,answer)),'hiddenProps');
                        end
                        delete(mb)
                        delete(h);
                        mb = msgbox('Signature export successful!');
                        t = timer;
                        t.StartDelay = 2;
                        t.TimerFcn = @(~,~) delete(mb);
                        start(t)

                    end
                end

                
        end

        %Rearranges metrics for export to follow order based on matching ID
        function newMetric = rearrangeMetric(~, metric, matchingID, maxID)
            newMetric = zeros(1, maxID);
            for id = 1:maxID
                ind = find(matchingID == id);
                if ~isempty(ind)
                    
                    newMetric(id) = metric(ind);
                else
                    newMetric(id) = nan;
                end
            end
            
        end

        function updateChannelList(app)
            
            
            if isstruct(app.F_GroupsTable) 
                
                fluorescenceVariables = app.F_GroupsTable.ColumnName;
                
               
                app.ChannelMatchingList.Items = fluorescenceVariables;
                if strcmp(app.Source, "Tracking")
                    app.AssignChannelButton_2.Enable = 1;
                end
                
            else
                app.ChannelMatchingList.Items = {'NA'};
                app.AssignChannelButton_2.Enable = 0;

            end

        end

        function updateMatchingList(app)
            
            if isstruct(app.F_GroupsTable) & isstruct(app.BF_GroupsTable)
               
                fluorescenceVariables = app.F_GroupsTable.ColumnName;
                brightfieldVariables = app.BF_GroupsTable.ColumnName;
                sameVar = intersect(fluorescenceVariables, brightfieldVariables);
                
                if ~isempty(sameVar)
                   
                    app.BrightfieldMatchingList.Items = sameVar;
                    app.MatchBrightfieldButton.Enable = 1;


                end
            else
                app.BrightfieldMatchingList.Items = {'NA'};
                app.MatchBrightfieldButton.Enable = 0;

            end

        end

        function matchingInd = getMatchingRow(app, matchingValue, matchingKeys)
            matchingInd = nan;
            for key = 1:size(matchingKeys, 1)
                if isequal(matchingValue, matchingKeys(key,:))
                    matchingInd = key;
                end
            end
        end
            
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function fluorescenceStartup(app, caller, sharedData, loadFile, source)
            app.CallingApp = caller;
            app.SharedData = sharedData;
            app.MostRecentImageExportFolder = sharedData.MostRecentExport;
            app.MostRecentImageSaveFolder = sharedData.MostRecentSave;

            if ~loadFile
                app.BF_MicronsPerPixel = sharedData.MicronsPerPixel;
                app.Source = source;
                
                if strcmp(source, 'Main')
    
                    %Add shared data to local app data
                    app.MainSegmentColor = sharedData.MainSegmentColor;
                    for image = 1:length(sharedData.ImageIndexes)
                        selectedImage = sharedData.ImageIndexes(image);
                        %app.BF_BulkI{image} = sharedData.BulkI{selectedImage};
                        
                        app.BF_DisplayBulkI{image} = sharedData.BulkI{selectedImage};
                        CC_temp = sharedData.CC{selectedImage};
                        CC_temp.PixelIdxList = CC_temp.PixelIdxList(~cellfun('isempty',CC_temp.PixelIdxList));
                        CC_temp.NumObjects = length(CC_temp.PixelIdxList);
                         
        
                        app.BF_CC{image} = CC_temp;
                        bwfinal = logical(labelmatrix(CC_temp));
                        bw_perim = double(bwperim(bwfinal));
                        %Make visible (dilate)
                        bw_perim_dil = imdilate(bw_perim,ones(3,3));


                        displayI = app.BF_DisplayBulkI{image};
                        %Normalize raw image to min and max
                        displayI = double(displayI);
                        displayI = (displayI - min(displayI(:)))./(max(displayI(:)) - min(displayI(:)));
            
                        app.BF_ImgMasked{image} = imoverlay(displayI,bw_perim_dil,app.MainSegmentColor);  
                        
                        app.BF_Spheroids_order{image} = sharedData.Spheroids_order{selectedImage};
        
                        app.BF_Resize{image} = sharedData.Resize{selectedImage};


                        statsBW_temp = regionprops(CC_temp, 'BoundingBox', 'Centroid');
                        app.BF_BoundingBox{image} = {statsBW_temp.BoundingBox};
                        centers = [statsBW_temp.Centroid];
                        recenters = reshape(centers, 2, length(statsBW_temp))';
                        app.BF_Centers{image} = recenters;
        
                        
                        filenameCell = strcat(sharedData.FileNames(selectedImage), '_Bf', int2str(image));
                        app.BrightfieldImageList.Items = cat(2,app.BrightfieldImageList.Items, filenameCell);
                        app.BrightfieldImageList.ItemsData(image) = image;
                        
                        
                    end
                    app.WhichOrganoidsExport.Visible = 0;
                elseif strcmp(source, 'Tracking')
                    app.MainSegmentColor = sharedData.MainSegmentColor;
                    app.MaxID = sharedData.MaxID;
        
                    app.BF_Resize = sharedData.Resize;
                    app.BF_MatchingID = sharedData.MatchingID;
                    app.BF_GoodOrganoids = sharedData.GoodOrganoids;
    
                    
                    app.BF_BoundingBox = sharedData.BoundingBox;
                    
                    app.BF_Centers = sharedData.Centers;
                    for image = 1:length(sharedData.BulkI)
                        
                        selectedImage = image;
                        
                        app.BF_DisplayBulkI{image} = sharedData.BulkI{selectedImage};
                        
                         
                        CC_temp = sharedData.CC{selectedImage};
                        app.BF_CC{image} = CC_temp;
                        bwfinal = logical(labelmatrix(CC_temp));
                        bw_perim = double(bwperim(bwfinal));
                        %Make visible (dilate)
                        bw_perim_dil = imdilate(bw_perim,ones(3,3));


                        displayI = app.BF_DisplayBulkI{image};
                        %Normalize raw image to min and max
                        displayI = double(displayI);
                        displayI = (displayI - min(displayI(:)))./(max(displayI(:)) - min(displayI(:)));
            
                        app.BF_ImgMasked{image} = imoverlay(displayI,bw_perim_dil,app.MainSegmentColor);  
                        
                        app.BF_Spheroids_order{image} = 1:sharedData.MaxID;


                        filenameCell = strcat(sharedData.FileNames(selectedImage), '_Bf', int2str(image));
                        app.BrightfieldImageList.Items = cat(2,app.BrightfieldImageList.Items, filenameCell);
                        app.BrightfieldImageList.ItemsData(image) = image;
                        app.SameCutoff.Text = 'Apply Same Cutoff to All Images In Channel';
                        app.SamePercent.Text = 'Apply Same Percentile to All Images In Channel';
                        app.FluorescenceButtonsGrid.RowHeight = {'1x', '1x', '0x', '0x'};
                        
                    end
    
                end


                app.BrightfieldImageList.Value = 1;
                app.BF_FirstMatchingF = zeros(1, length(app.BF_CC));
                app.FluorescenceGrid.RowHeight = {0, '1x', 80};
                displayBrightfieldImage(app, 1)
                %Remove interactivity from axes (reduces lagging)
                disableDefaultInteractivity(app.BrightfieldAxes)
                disableDefaultInteractivity(app.FluorescenceAxes)
                disableDefaultInteractivity(app.SpheroidZoomAxes)
                disableDefaultInteractivity(app.HistogramAxes)
                app.ImageAssignmentGrid.Visible = 0;
                app.HistogramGridLayout.Visible = 0;
                app.BottomTabs.SelectedTab = app.ImageAssignment;
            else
                
                mb = msgbox('Please wait... loading variables...');
                load(strcat(sharedData.basename,sharedData.filename), 'hiddenProps');
                app.Source = hiddenProps.source;


                %Add saved data into app data
                app.BF_CC = hiddenProps.bf_cc;
                
                app.BF_DisplayBulkI = hiddenProps.bf_displayBulkI;
                app.F_DisplayBulkI = hiddenProps.f_displayBulkI;
                
                
                app.BF_Resize = hiddenProps.bf_resize;
                app.BF_MicronsPerPixel = hiddenProps.bf_mpp;
                app.BF_BoundingBox = hiddenProps.bf_boundingBox;
                app.BF_Centers = hiddenProps.bf_centers;
                
                
                app.F_Filename = hiddenProps.f_filename;
                app.F_Basename = hiddenProps.f_baseName;
                app.BF_FirstMatchingF = hiddenProps.bf_firstMatchingF;
                app.F_MatchingBF = hiddenProps.f_matchingBF;
                app.BF_Spheroids_order = hiddenProps.bf_spheroidsOrder;
                
                app.SpheroidPixelIntensity = hiddenProps.spheroidPixelIntensity;
                app.PercentileIntensity = hiddenProps.percentileIntensity;
                app.Percentile = hiddenProps.percentile;
                app.Analyzed = hiddenProps.analyzed;
                app.IntensityCutoff = hiddenProps.intensityCutoff;
                app.Marked = hiddenProps.marked;
                if iscell(hiddenProps.allColorCode)
                    'iscell'
                    app.AllColorCode = hiddenProps.allColorCode;
                    app.FluorColorCode = hiddenProps.fluorColorCode;
                else
                    'isnot'
                    app.FluorColorCode(1:length(app.F_DisplayBulkI)) = {[1 0 0]};
                    app.AllColorCode(1:length(app.F_DisplayBulkI)) = {[0 0 1]};
                end
                    
                app.GroupPreference = hiddenProps.groupPreference;
                app.BF_BwPerimDilate = cell(1, length(app.BF_CC));

                if isfield(hiddenProps, 'channelIDs')
                    app.ChannelIDs = hiddenProps.channelIDs;
                    app.ChannelLabels = hiddenProps.channelLabels;
                else
                    prevBF = 0;
                    prevF = 0;
    
                    for i = 1:length(app.F_MatchingBF)
                        currBF = app.F_MatchingBF(i);
                        if currBF == prevBF
                            app.ChannelIDs(i) = prevF + 1;
                            prevF = prevF + 1;
                        else
                            app.ChannelIDs(i) = 1;
                            prevF = 1;
                        end
                        prevBF = currBF;
    
                    end

                    channelLabels = {};
                    for chan = 1:max(app.ChannelIDs)
                        channelLabels{end+1} = num2str(chan);
                    end
                    app.ChannelLabels = channelLabels;
                end
                
                if isfield(hiddenProps, 'mainSegmentColor')
                    app.MainSegmentColor = hiddenProps.mainSegmentColor;
                else
                    app.MainSegmentColor = [0 0 1];
                end
                if strcmp(app.Source, 'Tracking')
                    app.BF_MatchingID = hiddenProps.bf_matchingID;
                    app.BF_GoodOrganoids = hiddenProps.bf_goodOrganoids;
                    app.DoneOpening = true;
                    app.DoneAssigning = true;
                    app.CurrentChannelMontage = 1;
                    %app.TotalChannels = hiddenProps.totalChannels;
                    
                    app.MaxID = hiddenProps.maxID;
                    app.MinChannelValues = hiddenProps.minChannelValues;
                    app.MaxChannelValues = hiddenProps.maxChannelValues;
                end
                app.BF_ImgMasked = cell(1,length(app.BF_DisplayBulkI));
                for newBFImage = 1:length(app.BF_CC)
                    
                    CClabel = labelmatrix(app.BF_CC{newBFImage});
                    

                    bwMock = logical(CClabel);


                    %Find perimeter
                    bw_perim = double(bwperim(bwMock));
                    %Make visible (dilate)
                    bw_perim_dil = imdilate(bw_perim,ones(3,3));
                    app.BF_BwPerimDilate{newBFImage} = bw_perim_dil;
                    % %Color perimeter if wanted
                    

                    %Normalize raw image to min and max
                    displayI = app.BF_DisplayBulkI{newBFImage};
                    %imgtype(origI)
                    displayI = double(displayI);
                    displayI = (displayI - min(displayI(:)))./(max(displayI(:)) - min(displayI(:)));
                    %Create crop of perimeter and raw image
                    
                    imgMasked = imoverlay(displayI,bw_perim_dil,app.MainSegmentColor);

                    
                    app.BF_ImgMasked{newBFImage} = imgMasked;
                    
                   
                end
                app.F_ImgMasked = cell(1,length(app.F_DisplayBulkI));
                app.F_BulkI = cell(1,length(app.F_DisplayBulkI));
                for newFImage = 1:length(app.F_DisplayBulkI)
                    matchingBF = app.F_MatchingBF(newFImage);
                    fluorescentCC = app.BF_CC{matchingBF};
                    marked = app.Marked{newFImage};
                    for sphere = 1:length(fluorescentCC.PixelIdxList)
                        if ~marked(sphere)
                            fluorescentCC.PixelIdxList{sphere} = [];
                        end
                    end
                    channel = app.ChannelIDs(newFImage);
                    displayF_I = double(app.F_DisplayBulkI{newFImage});
                    if strcmp(app.Source, 'Tracking')
                        minChannelValue = app.MinChannelValues(channel);
                        maxChannelValue = app.MaxChannelValues(channel);
                    else
                        minChannelValue = min(displayF_I(:));
                        maxChannelValue = max(displayF_I(:));
                    end
                    
                    a = app.AllColorCode
                    b = app.AllColorCode{newFImage}
                    displayF_I = (displayF_I - minChannelValue)./(maxChannelValue - minChannelValue);

                   
                    bw_perim_dil = app.BF_BwPerimDilate{matchingBF};
                    
                    imgMasked = imoverlay(displayF_I,bw_perim_dil,app.AllColorCode{newFImage});
                    fluorescentCCLabel = labelmatrix(fluorescentCC);
                    %Add fluorescent perimeter
                        
                    fluorescentPerim = bwperim(fluorescentCCLabel ~= 0);
                    fluorescent_perim_dil = imdilate(fluorescentPerim, ones(3,3));
                    imgMasked = imoverlay(imgMasked,fluorescent_perim_dil, app.FluorColorCode{newFImage});
                    app.F_ImgMasked{newFImage} = imgMasked;
                    rgb = app.F_DisplayBulkI{newFImage};
                    if length(size(rgb))==3
                        %convert to grayscale
                        app.F_BulkI{newFImage} = rgb2gray(rgb);
                        %if non-rgb
                    else
                        %already grayscale
                        app.F_BulkI{newFImage} = rgb;
                    end
                   
                end


                for i = 1:length(app.F_Filename)
                    app.FluorescenceImageList.Items = cat(2,app.FluorescenceImageList.Items, app.F_Filename{i});
                    app.FluorescenceImageList.ItemsData(i) = i;
                end
                for i = 1:length(hiddenProps.bf_filename)
    
                    app.BrightfieldImageList.Items = cat(2,app.BrightfieldImageList.Items, hiddenProps.bf_filename{i});
                    app.BrightfieldImageList.ItemsData(i) = i;
                end


                    displaySpheroidZoom(app, 1)
                    
                    app.SpheroidSelectListBox.Value = 1;

                    
                    app.BrightfieldImageList.Value = 1;
                    app.FluorescenceImageList.Value = 1;
                    app.DeleteImageButtonF.Enable = 0;
                    app.DeleteImageButtonBF.Visible = 0;
                    app.DeleteImageButtonBF.Enable = 0;
                   
                    app.FluorescenceButtonsGrid.ColumnWidth = {'1x', '1x'};
                    app.FluorescenceButtonsGrid.RowHeight = {'0x', '0x', '0x' '1x'};
                    app.SameCutoff.Text = 'Apply Same Cutoff to All Images In Channel';
                    app.SamePercent.Text = 'Apply Same Percentile to All Images In Channel';
                    app.DoneOpening = true;
                    app.DoneAssigning = true;
                    app.FluorescenceGrid.RowHeight = {0, '1x', 40};
                    app.BrightfieldGrid.RowHeight = {'1x', 0};
                    app.OpenFluorescenceImagesMenu.Enable = 0;
                    app.SpheroidZoomGrid.RowHeight = {0,'3x', '0.5x'};
                    
                    displayBrightfieldImage(app, app.F_MatchingBF(1))
                    displayFluorescenceWithOverlay(app, 1)
                    

                    if strcmp(app.Source, "Tracking")
                        
                        app.SpheroidZoomGrid.RowHeight = {0,'3x', '0.5x'};
                        app.AnalyzeCurrentButton.Enable = 0;                
                        app.SeemontageCheckBox.Enable = 1;
                        app.NextChannelButton.Enable = 1;
                        app.PreviousChannelButton.Enable = 1;
                        app.SeemontageCheckBox.Visible = 1;
                        app.NextChannelButton.Visible = 1;
                        app.PreviousChannelButton.Visible = 1;
                    else
                        app.AnalyzeCurrentButton.Enable = 1;                
                        app.SeemontageCheckBox.Visible = 0;
                        app.NextChannelButton.Visible = 0;
                        app.PreviousChannelButton.Visible = 0;
                    end
                    
                    app.ImagesGroupingMenu.Enable = 0;
                    app.AnalyzeAllButton.Enable = 1;

            
                    app.ExportSetButton.Enable = 1;
                    
                    spheroidSelectList(app)


                    displayHistogramAxes(app, 1)
                    delete(mb)
                    app.ImageAssignmentGrid.Visible = 0;
                    app.HistogramGridLayout.Visible = 1;
                    app.BottomTabs.SelectedTab = app.DataDisplay;


            end


        end

        % Value changed function: BrightfieldImageList
        function brightfieldImageSelectList(app, event)
            value = app.BrightfieldImageList.Value;
            value_F = app.BF_FirstMatchingF(value);
            displayBrightfieldImage(app, value)
            %If images have been assigned, selecting brightfield image
            %selects fluorescent as well
            if value_F == 0
                
                cla(app.HistogramAxes)
                cla(app.SpheroidZoomAxes)
                app.HistogramAxes.Visible = 0;
                app.SpheroidSelectListBox.Items = {};
                app.IntensityCutoffEditField.Visible = 0;
                app.FluorescentPixelIntensityCutoffLabel.Visible = 0;

            elseif app.DoneAssigning & value_F ~= 0
                
                
                app.FluorescenceImageList.Value = value_F;
                %If analyzed, display with overlay
                if app.Analyzed{value_F} == 1
                    displayHistogramAxes(app, value_F)
                    displayFluorescenceWithOverlay(app, value_F)
                    if strcmp(app.Source, 'Main')
                        displaySpheroidZoom(app, value_F)
                    end
                else
                    cla(app.HistogramAxes)
                    app.HistogramAxes.Visible = 0;
                    displayFluorescenceImageOnly(app, value_F)
                end

             end

        end

        % Menu selected function: OpenFluorescenceImagesMenu
        function openFluorescenceImages(app, event)
            f = figure('Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
            if isnan(app.MostRecentImageOpenFolder) 
                [filename, baseName] = uigetfile('*.jpg;*.tiff;*.tif;*.png;*.bmp','Select an Image','Multiselect', 'on');
            else
                [filename, baseName] = uigetfile('*.jpg;*.tiff;*.tif;*.png;*.bmp','Select an Image',app.MostRecentImageOpenFolder, 'Multiselect', 'on');
            end
            delete(f)
            if baseName~=0
                app.MostRecentImageOpenFolder = baseName;
            end
            %Find how many images are already in BulkI and get index for
            %new image
            currentImage = length(app.F_BulkI) + 1;
            bulkI = app.F_BulkI;
            displayBulkI = app.F_DisplayBulkI;
            
            %Load images

            %if no entry
            if isequal(filename,0)
                %do nothing
                %if single image
            elseif iscell(filename) == 0
                
                %initialize
                app.F_Filename(currentImage) = {filename};
                app.F_Basename(currentImage) = {baseName};                
                filenameCell = {filename};
                %set listbox strings to file names
                app.FluorescenceImageList.Items = cat(2,app.FluorescenceImageList.Items, filenameCell);
                app.FluorescenceImageList.ItemsData(currentImage) = currentImage;
                
                
                %load images
                name = strcat(baseName,filename);
                rgb = imread(name);

                if length(size(rgb))==3
                    %convert to grayscale
                    bulkI{currentImage} = rgb2gray(rgb);
                    %if non-rgb
                else
                    %already grayscale
                    bulkI{currentImage} = rgb;
                end
                
                   
                displayBulkI{currentImage} = rgb;

                
                %display raw image in GUI
                
                app.F_DisplayBulkI = displayBulkI;
                
                %Initialize variables
                app.F_BulkI = bulkI;
                %app.I = bulkI{currentImage};
                
                displayFluorescenceImageOnly(app, currentImage);


                app.FluorescenceImageList.Value = currentImage;

                %if multiple images
            else
                
                %initialize
                app.F_Filename(currentImage:currentImage+length(filename)-1) = filename;
                app.F_Basename(currentImage:currentImage+length(filename)-1) = {baseName};
                
                %preallocate new space in bulkI cell, as well as all
                %segmentation product cell arrays
                bulkI(currentImage:currentImage+length(filename)-1) = {0};
                displayBulkI(currentImage:currentImage+length(filename)-1) = {0};


                %set listbox strings to file names
                app.FluorescenceImageList.Items = cat(2,app.FluorescenceImageList.Items, filename);

                %loop through each image, where newImage is index of image
                %being added
                for newImage = currentImage:(currentImage+length(filename)-1)
                    %add files to list
                   
                    app.FluorescenceImageList.ItemsData(newImage) = newImage;
                    %load images
                    indexInFileName = newImage-currentImage+1;
                    name = strcat(baseName,filename{indexInFileName});
                    
                    rgb = imread(name);


                    if length(size(rgb))==3
                        %convert to grayscale
                        bulkI{newImage} = rgb2gray(rgb);
                        %if non-rgb
                    else
                        %already grayscale
                        bulkI{newImage} = rgb;
                    end
                    %Maintain color in display images for fluorescence
                    displayBulkI{newImage} = rgb;
                    
                    
%                    
                end


                app.F_BulkI = bulkI;
                app.F_DisplayBulkI = displayBulkI;
                
                %app.I = bulkI{currentImage};
                app.FluorescenceImageList.Value = currentImage;  
                displayFluorescenceImageOnly(app,currentImage);

            end
        end

        % Value changed function: FluorescenceImageList
        function fluorescenceImageSelectList(app, event)
            
            value = app.FluorescenceImageList.Value;      
            
            %If images are all assigned, selecting will change brightfield
            %image as well
            if app.DoneOpening == false
                displayFluorescenceImageOnly(app, value)
            elseif app.F_MatchingBF(value) ~= 0
                displayBrightfieldImage(app, app.F_MatchingBF(value))
                
                app.BrightfieldImageList.Value = app.F_MatchingBF(value);
                
                if app.DoneAssigning & app.Analyzed{value} == 1
                
                    displayHistogramAxes(app, value)
                    displayFluorescenceWithOverlay(app, value)
                    if strcmp(app.Source, 'Main')
                        displaySpheroidZoom(app, value)
                    end
                else
                  
                    cla(app.HistogramAxes)
                    displayFluorescenceImageOnly(app, value)
                    app.HistogramAxes.Visible = 0;                    
                    cla(app.SpheroidZoomAxes)                    
                    app.SpheroidSelectListBox.Items = {};
                    app.IntensityCutoffEditField.Visible = 0;
                    app.FluorescentPixelIntensityCutoffLabel.Visible = 0;
                end
            %Otherwise just change fluorescent image
            else
                displayFluorescenceImageOnly(app, value)
            end
           

        end

        % Button pushed function: AssignImageButton
        function assignImage(app, event)
            %Rearrange image lists so that each brightfield image is
            %aligned with its corresponding fluorescence image
            
             valueF = app.FluorescenceImageList.Value;
             valueBF = app.BrightfieldImageList.Value;
             app.FluorescenceImageList.Items{valueF} = strcat(app.F_Filename{valueF}, '_Bf', int2str(valueBF));

              
             app.F_MatchingBF(valueF) = valueBF;
        end

        % Button pushed function: DoneAssigningButton
        function doneAssigning(app, event)
           
% Check than all fluorescent images have been assigned, then allow for
% analysis

        
        if ~(any(~app.F_MatchingBF))

            app.AnalyzeAllButton.Enable = 1;
            
            app.ExportSetButton.Enable = 1;
            if strcmp(app.Source, 'Main')
                app.AnalyzeCurrentButton.Enable = 1;
                app.ExportCurrentButton.Enable = 1;
                app.SpheroidZoomGrid.RowHeight = {0,'3x', 0};
            end            
            app.FluorescenceButtonsGrid.RowHeight = {'0x', '0x', '0x' '1x'};
            app.DoneAssigning = true;
            if strcmp(app.Source, 'Tracking') 
                app.SpheroidZoomGrid.RowHeight = {0,'3x', '0.5x'};
                app.SeemontageCheckBox.Enable = 0;
                app.NextChannelButton.Enable = 0;
                app.PreviousChannelButton.Enable = 0;
            end


%Reorder Fuorescent images based on the corresponding brightfield image,
%and also reorder all the data thats been assigned to the fluorescent image


            [SortF, FInd] = sort(app.F_MatchingBF);
            bulkI_temp = app.F_BulkI(FInd);
            displayBulkI_temp = app.F_DisplayBulkI(FInd);
            filename_Temp = app.F_Filename(FInd);
            basename_Temp = app.F_Basename(FInd);
            tempLabels = app.FluorescenceImageList.Items(FInd);
            

            app.F_BulkI = bulkI_temp;
            app.F_DisplayBulkI = displayBulkI_temp;
            app.F_Filename = filename_Temp;
            app.F_Basename = basename_Temp;
            app.FluorescenceImageList.Items = tempLabels;
            app.F_MatchingBF = SortF;
            

            for i = 1:length(app.BF_CC)
                for j = 1:length(app.F_MatchingBF)
                    if i == app.F_MatchingBF(j)
                        app.BF_FirstMatchingF(i) = j;
                        break
                    end
                end
            end


            if isempty(app.ChannelIDs)
                
                prevBF = 0;
                prevF = 0;
                app.ChannelIDs = zeros(1, length(app.F_BulkI));
                for i = 1:length(app.F_MatchingBF)
                    currBF = app.F_MatchingBF(i);
                    if currBF == prevBF
                        app.ChannelIDs(i) = prevF + 1;
                        prevF = prevF + 1;
                    else
                        app.ChannelIDs(i) = 1;
                        prevF = 1;
                    end
                    prevBF = currBF;

                end


            end

            if isempty(app.ChannelLabels)
                channelLabels = {};
                for chan = 1:max(app.ChannelIDs)
                    channelLabels{end+1} = num2str(chan);
                end
                app.ChannelLabels = channelLabels;
            end

            channelIDs_temp = app.ChannelIDs(FInd);
            app.ChannelIDs = channelIDs_temp;


            %Preallocate analysis values
            app.Marked(1:length(app.F_BulkI)) = {0};
            app.SpheroidPixelIntensity(1:length(app.F_BulkI)) = {0};
            app.Percentile(1:length(app.F_BulkI)) = {0};
            app.PercentileIntensity(1:length(app.F_BulkI)) = {0};
            app.FluorColorCode = {};
            app.AllColorCode = {};
            app.FluorColorCode(1:length(app.F_BulkI)) = {[1 0 0]};
            app.AllColorCode(1:length(app.F_BulkI)) = {[0 0 1]};
            
            app.IntensityCutoff(1:length(app.F_BulkI)) = {50};
            app.Analyzed(1:length(app.F_BulkI)) = {0};
            app.F_ImgMasked(1:length(app.F_BulkI)) = {0};

            
            app.FluorescenceGrid.RowHeight = {0, '1x', 40};
            app.ImagesGroupingMenu.Enable = 0;


            if strcmp(app.Source, 'Tracking')


                numChannels = max(app.ChannelIDs);
                maxChannelValues = -(Inf(numChannels));
                minChannelValues = Inf(1,numChannels);
                for i = 1:length(app.F_BulkI)
                    channel = app.ChannelIDs(i);
                    
                    minCurr = min(app.F_BulkI{i}(:));
                    maxCurr = max(app.F_BulkI{i}(:));
                    if minCurr < minChannelValues(channel)
                        minChannelValues(channel) = minCurr;
                    end
                    if maxCurr > maxChannelValues(channel)
                        maxChannelValues(channel) = maxCurr;
                    end
    
                    
                end
                app.MinChannelValues = round(minChannelValues*.9);
                app.MaxChannelValues = round(maxChannelValues*1.1);
                
                channel = app.ChannelIDs(app.FluorescenceImageList.Value);
                app.MinEditField.Value = 0;
                app.MaxEditField.Value = 1;
                
               
            end


            app.ImageAssignmentGrid.Visible = 0;
            app.HistogramGridLayout.Visible = 1;
            app.BottomTabs.SelectedTab = app.DataDisplay;

            for valueF = 1:length(app.F_Filename)
                channelValue = app.ChannelIDs(valueF);                
                valueBF = app.F_MatchingBF(valueF);                
                app.FluorescenceImageList.Items{valueF} = char(strcat(app.F_Filename{valueF}, '_Bf', int2str(valueBF), '_Channel', app.ChannelLabels{channelValue}));

            end

        else
            
            
            mb1 = errordlg(['Please make sure all fluorescence images have been assigned to a brightfield images' ...
                    
                    '']);
                t = timer;
                t.StartDelay = 2;
                t.TimerFcn = @(~,~) delete(mb1);
                start(t)
        end


        end

        % Button pushed function: DoneOpeningImagesButton
        function doneOpening(app, event)
           
            if strcmp(app.Source, 'Main')
                app.ImagesGroupingMenu.Enable = 1;
                app.FluorescenceButtonsGrid.RowHeight = {'0x', '1x', '1x', '0x'};
                app.FluorescenceButtonsGrid.ColumnWidth = {'1x', '1x'};
               
                app.DoneOpening = true;
                app.BrightfieldGrid.RowHeight = {'1x', 0};
                app.OpenFluorescenceImagesMenu.Enable = 0;
                app.F_MatchingBF = zeros(1, length(app.F_BulkI));
                
                app.Analyzed(1:length(app.F_BulkI)) = {0};
                app.DeleteImageButtonBF.Visible = 0;
                app.DeleteImageButtonBF.Enable = 0;
                app.DeleteImageButtonF.Enable = 0;

                
            else
                answer = 'Yes';
                if mod(length(app.F_BulkI), length(app.BF_CC)) ~= 0

                    answer = uiconfirm(app.UIFigure, {['The number of fluorescent images loaded' ...
                        'is not a multiple of the number of brightfield images. This will not be compatible with automated' ...
                        'image assignment to brightfield images and channels. Would you like to proceed?']}, ...
                    'Number Fluorescence Images', ...
                    'Options', {'Yes', 'No'}, 'DefaultOption', 'No', 'CancelOption', 'No');
                end
                if strcmp(answer, 'Yes')                   
                
                    app.ImagesGroupingMenu.Enable = 1;
                    app.FluorescenceButtonsGrid.RowHeight = {'0x', '1x', '1x', '0x'};
                    app.FluorescenceButtonsGrid.ColumnWidth = {'1x', '1x'};
                    app.DeleteImageButtonF.Enable = 0;
                    app.DeleteImageButtonBF.Visible = 0;
                    app.DeleteImageButtonBF.Enable = 0;
                    
                    app.DoneOpening = true;
                    app.BrightfieldGrid.RowHeight = {'1x', 0};
                    app.OpenFluorescenceImagesMenu.Enable = 0;
                    app.F_MatchingBF = zeros(1, length(app.F_BulkI));
                    
                    app.Analyzed(1:length(app.F_BulkI)) = {0};
                    if mod(length(app.F_BulkI), length(app.BF_CC)) ~= 0
                        app.ImagesareinorderCheckBox.Enable = 0;
                    else
                        app.ImagesareinorderCheckBox.Enable = 1;
                    end
                
                end
            end
            app.ImageAssignmentGrid.Visible = 1;
            app.HistogramGridLayout.Visible = 0;
            app.BottomTabs.SelectedTab = app.ImageAssignment;
            
        end

        % Button pushed function: DeleteImageButtonBF
        function deleteBF(app, event)
            %Delete brightfield image if there is no associated
            %fluorescence image
            value = app.BrightfieldImageList.Value;
            
            app.BrightfieldImageList.Items(value) = [];
            app.BrightfieldImageList.ItemsData(end) = [];

            %app.BF_BulkI(value) = [];
            app.BF_ImgMasked(value) = [];
            app.BF_DisplayBulkI(value) = [];
            app.BF_CC(value) = [];
            app.BF_Spheroids_order(value) = [];
            app.BF_BoundingBox(value) = [];
            app.BF_Centers(value) = [];
            if strcmp(app.Source, 'Tracking')
                app.BF_MatchingID(value) = [];
            end
            app.BF_Resize(value) = [];
            
            
            if value ~= 1
                displayBrightfieldImage(app, value-1)
                app.BrightfieldImageList.Value = value-1;
            else
                displayBrightfieldImage(app, value)
                app.BrightfieldImageList.Value = value;
            end

        end

        % Button pushed function: DeleteImageButtonF
        function deleteF(app, event)
            %Delete fluorescence image 
            value = app.FluorescenceImageList.Value;
            if ~isempty(value)

                if ~app.DoneOpening
                    app.F_BulkI(value) = [];
                    app.F_DisplayBulkI(value) = [];
                    app.F_Filename(value) = [];
                    app.F_Basename(value) = [];
                    app.FluorescenceImageList.Items(value) = [];
                    app.FluorescenceImageList.ItemsData(end) = []; 
                    if value ~= 1
                        displayFluorescenceImageOnly(app, value-1)
                        app.FluorescenceImageList.Value = value-1;
                    else
                        displayFluorescenceImageOnly(app, value)
                        app.FluorescenceImageList.Value = value;
                    end
    
                else    
                    app.F_BulkI(value) = [];
                    app.F_DisplayBulkI(value) = [];
                    app.F_MatchingBF(value) = [];  
                    app.F_Filename(value) = [];
                    app.F_Basename(value) = [];
                    app.Analyzed(value) = [];
                    app.FluorescenceImageList.Items(value) = [];
                    app.FluorescenceImageList.ItemsData(end) = [];
                    if value ~= 1
                        displayFluorescenceImageOnly(app, value-1)
                        app.FluorescenceImageList.Value = value-1;
                    else
                        displayFluorescenceImageOnly(app, value)
                        app.FluorescenceImageList.Value = value;
                    end
                    
                end
            end
                
        end

        % Button pushed function: ReassignButton
        function reassignCallback(app, event)
            %Remove analysis abilities while reassigning (analysis values
            %will be reset when 'done assigning' is selected again
            app.FluorescenceButtonsGrid.RowHeight = {'0x', '1x', '1x', '0x'};
            app.ImageAssignmentGrid.Visible = 1;
            app.HistogramGridLayout.Visible = 0;
            app.BottomTabs.SelectedTab = app.ImageAssignment;
            app.DeleteImageButtonF.Enable = 0;
            app.DoneAssigning = false;
            app.ImagesGroupingMenu.Enable = 1;
            app.AnalyzeAllButton.Enable = 0;
            app.AnalyzeCurrentButton.Enable = 0;
            app.ExportSetButton.Enable = 0;
            app.ExportCurrentButton.Enable = 0;
            displayFluorescenceImageOnly(app, app.FluorescenceImageList.Value)
            yyaxis(app.HistogramAxes, 'left')
            cla(app.HistogramAxes)
            yyaxis(app.HistogramAxes, 'right')
            cla(app.HistogramAxes)
            
            app.HistogramAxes.Visible = 0;                    
            cla(app.SpheroidZoomAxes)                    
            app.SpheroidSelectListBox.Items = {};
            app.IntensityCutoffEditField.Visible = 0;
            app.FluorescentPixelIntensityCutoffLabel.Visible = 0;
            app.SameCutoff.Visible = 0;
            app.SamePercent.Visible = 0;
            
            app.SpheroidZoomGrid.RowHeight = {0, '3x', 0};


        end

        % Button pushed function: AnalyzeCurrentButton
        function analyzeCurrent(app, event)
            selected = app.FluorescenceImageList.Value;
            analyzeFluorescence(app, [selected], selected)
        end

        % Button pushed function: AnalyzeAllButton
        function analyzeAll(app, event)
            analyzeFluorescence(app, 1:length(app.F_BulkI), app.FluorescenceImageList.Value)
            if app.AnalyzeCurrentButton.Enable == 0
                app.AnalyzeCurrentButton.Enable = 1;                
                app.SeemontageCheckBox.Enable = 1;
                app.NextChannelButton.Enable = 1;
                app.PreviousChannelButton.Enable = 1;
            
            end
        end

        % Value changed function: BinSizeEditField
        function numBinsChange(app, event)
            %Redisplay histogram with desired number of histogram bins
            selected = app.FluorescenceImageList.Value;
            if app.Analyzed{selected} == 1
                displayHistogramAxes(app, selected)
            end
        end

        % Button down function: HistogramAxes
        function histogramButtonDown(app, event)
            %Change cutoff by clicking on the pixel intensity histogram
            
            [coords] = event.IntersectionPoint(1:2);
            x = coords(1,1);
            app.IntensityCutoffEditField.Value = x;            
            if app.SameCutoff.Value == 0
                selected = app.FluorescenceImageList.Value;
                app.IntensityCutoff{selected} = x;
                analyzeFluorescence(app, [selected], selected)
            else
                sameCutoffCallback(app,event)
            end


        end

        % Value changed function: SpheroidSelectListBox
        function spheroidSelectList(app, event)
            %Display spheroid from fluorescent overlay image
            spheroid = app.SpheroidSelectListBox.Value;
            selected = app.FluorescenceImageList.Value;
            selected_BF = app.F_MatchingBF(selected);
            if app.SeemontageCheckBox.Value == 0
                
                if strcmp(app.Source, 'Main')
                    %Load connected components
                    
        
                    %Check for watershed splitting
                    
                        %Determine bounding box
                    
                    boundingBox = app.BF_BoundingBox{selected_BF};
                    bbox = boundingBox{spheroid};
                    bbox(1:2) = bbox(1:2)-5;
                    bbox(3:4) = bbox(3:4)+10;
        
                    %Display cropped spheroid
                    cla(app.SpheroidZoomAxes);
                    
                    img = app.F_ImgMasked{selected};
                    
                    maxImgMasked = max(img(:));
                    minVal = double(maxImgMasked*app.MinEditField.Value);
                    maxVal = double(maxImgMasked*app.MaxEditField.Value);
                    img = double(img);
        
                    img = (img-minVal)./(maxVal-minVal);
                    imshow(imcrop(img, bbox),[], 'Parent', app.SpheroidZoomAxes);
                else
                    idInImageCC = find(app.BF_MatchingID{selected_BF} == spheroid);
                    cla(app.SpheroidZoomAxes);
                    if ~isempty(idInImageCC)
                        boundingBox = app.BF_BoundingBox{selected_BF};
                        bbox = boundingBox{idInImageCC};
                        bbox(1:2) = bbox(1:2)-5;
                        bbox(3:4) = bbox(3:4)+10;
            
                        %Display cropped spheroid
                        
                        
                        img = app.F_ImgMasked{selected};
                        
                        maxImgMasked = max(img(:));
                        
                        minVal = double(maxImgMasked*app.MinEditField.Value);
                        maxVal = double(maxImgMasked*app.MaxEditField.Value);
                        img = double(img);
            
                        img = (img-minVal)./(maxVal-minVal);
                        imshow(imcrop(img, bbox),[], 'Parent', app.SpheroidZoomAxes);
                    end

                end
            else
                %Show spheroid montage for a given channel if tracking mode
                channel = app.CurrentChannelMontage;
                imageArray = cell(1, length(app.BF_CC));
                %imagesInChannel = linspace(channel, channel + (length(app.BF_CC) - 1)*app.TotalChannels, length(app.BF_CC));
                imagesInChannel = find(app.ChannelIDs == channel);


                idsInImageCC = zeros(1, length(app.BF_CC));
                largestBB_X = 0;
                largestBB_Y = 0;
                
    
                for selected_F = imagesInChannel
                    selected_BF = app.F_MatchingBF(selected_F);
                    idInImageCC = find(app.BF_MatchingID{selected_BF} == spheroid);


                    if ~isempty(idInImageCC)
                        idsInImageCC(selected_BF) = idInImageCC;
                        %Get crop of spheroid in image
                        bbox = app.BF_BoundingBox{selected_BF}{idInImageCC};
                        bbox_X = bbox(3) + 10;
                        bbox_Y = bbox(4) + 10;
                        if bbox_X > largestBB_X
                            largestBB_X = bbox_X;
                        end
                        if bbox_Y > largestBB_Y
                            largestBB_Y = bbox_Y;
                        end
                    end
    
                end
                
                for selected_F = imagesInChannel
                    selected_BF = app.F_MatchingBF(selected_F);
                    idInImageCC = idsInImageCC(selected_BF);
                    
                    if idInImageCC ~= 0
                        %Get crop of spheroid in image
                        bbox = app.BF_BoundingBox{selected_BF}{idInImageCC};
                        center = zeros(1,2);
                        center(1:2) = bbox(1:2) + bbox(3:4)/2;
                        bbox(1:2) = [round(center(1) - largestBB_X/2) round(center(2) - largestBB_Y/2)];
    
    
                        bbox(3:4) = [largestBB_X largestBB_Y];
                        
                        img = app.F_ImgMasked{selected_F};
                        maxImgMasked = max(img(:));
                        
                        minVal = double(maxImgMasked*app.MinEditField.Value);
                        maxVal = double(maxImgMasked*app.MaxEditField.Value);
                        img = double(img);
            
                        img = (img-minVal)./(maxVal-minVal);
                        cropped = imcrop(img, bbox);
                        imageArray{selected_BF} = cropped;
                    end
                    
    
                end
                
                montage(imageArray, 'Parent', app.SpheroidZoomAxes)
                title(app.SpheroidZoomAxes, sprintf("Channel %s", app.ChannelLabels{app.CurrentChannelMontage}))
            end
            
        end

        % Button pushed function: ExportCurrentButton
        function exportCurrentFluor(app, event)
            image = app.FluorescenceImageList.Value;
            %app.ExportCurrentButton.Enable = 0;
            app.ExportSetButton.Enable = 1;
            
            if isempty(app.F_BulkI)
                mb1 = errordlg('Please select an image file');
                t = timer;
                t.StartDelay = 2;
                t.TimerFcn = @(~,~) delete(mb1);
                start(t)


            elseif app.Analyzed{image} == 0
                mb3 = errordlg('Please analyze image');
                t = timer;
                t.StartDelay = 2;
                t.TimerFcn = @(~,~) delete(mb3);
                start(t)
            else

                exportFluorescence(app, image, image);

            end
            app.ExportCurrentButton.Enable = 1;
        end

        % Button pushed function: ExportSetButton
        function exportSetFluor(app, event)
            analyzed = true;
            %app.ExportSetButton.Enable = 0;
            
            for i = 1:length(app.F_BulkI)
                if app.Analyzed{i} == 0
                    analyzed = false;
                end
            end
            if isempty(app.F_BulkI)
                mb1 = errordlg('Please select an image file');
                t = timer;
                t.StartDelay = 2;
                t.TimerFcn = @(~,~) delete(mb1);
                start(t)

            elseif analyzed == false
                mb3 = errordlg('Please analyze all images');
                t = timer;
                t.StartDelay = 2;
                t.TimerFcn = @(~,~) delete(mb3);
                start(t)
            else

                if strcmp(app.Source, 'Main')
                    exportFluorescence(app, 1, length(app.F_BulkI));
                else
                    exportFluorescenceTrack(app, 1, length(app.F_BulkI));
                end

            end
            app.ExportSetButton.Enable = 1;
        end

        % Menu selected function: AllspheroidsMenu
        function allSpheroidsColor(app, event)
            
           
            button = uiconfirm(app.UIFigure, 'Which images would you like to change the outline color for',...
                'Change outline color', 'Options', {'Current Image', 'All Images in Channel','All Images', 'Cancel'}, 'DefaultOption', 'Cancel');
            if ~strcmp(button, 'Cancel')
                
                
                %Choose what color to display spheroids
                colorList = {'Red', 'Green', 'Blue', 'Cyan', 'Magenta', 'Yellow', 'Orange' 'Purple'};
                f = figure('Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
                [indx,~] = listdlg('PromptString',{'Select a Color to Outline All Spheroids ',...
                ''},...
                'SelectionMode','single','ListString',colorList);
                answer = colorList{indx};
                delete(f)
                
                switch answer
                    case 'Red'
                        allColorCode = [1 0 0];
                    case 'Green'
                        allColorCode = [0 1 0];
                    case 'Blue'
                        allColorCode = [0 0 1];
                    case 'Cyan'
                        allColorCode = [0 1 1];
                    case 'Magenta'
                        allColorCode = [1 0 1];
                    case 'Yellow'
                        allColorCode = [1 1 0];
                    case 'Orange'
                        allColorCode = [0.85 0.325 0.098];
                    case 'Purple'
                        allColorCode = [0.4940 0.1840 0.5560];
                    
                end

               switch button
                    case 'All Images in Channel'
                        channel = app.ChannelIDs(app.FluorescenceImageList.Value);
                        indices = find(app.ChannelIDs == channel);
                        
                    case 'Current Image'
                        indices = [app.FluorescenceImageList.Value];
                        
                    case 'All Images'
                        indices = 1:length(app.F_BulkI);
                
    
               end
               for index = indices
                    app.AllColorCode{index} = allColorCode;
                end


                analyzeFluorescence(app, indices, app.FluorescenceImageList.Value)
            end
            
        end

        % Menu selected function: FluorescentlyMarkedMenu
        function fluorSpheroidsColor(app, event)
            button = uiconfirm(app.UIFigure, 'Which images would you like to change the outline color for',...
                'Change outline color', 'Options', {'Current Image', 'All Images in Channel','All Images', 'Cancel'}, 'DefaultOption', 'Cancel');
            if ~strcmp(button, 'Cancel')
                %Choose what color to display fluorescent spheroids
                colorList = {'Red', 'Green', 'Blue', 'Cyan', 'Magenta', 'Yellow', 'Orange' 'Purple'};
                f = figure('Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
                [indx,~] = listdlg('PromptString',{'Select a Color to Outline All Spheroids ',...
                ''},...
                'SelectionMode','single','ListString',colorList);
                answer = colorList{indx};
                delete(f)
                
                switch answer
                    case 'Red'
                        fluorColorCode = [1 0 0];
                    case 'Green'
                        fluorColorCode = [0 1 0];
                    case 'Blue'
                        fluorColorCode = [0 0 1];
                    case 'Cyan'
                        fluorColorCode = [0 1 1];
                    case 'Magenta'
                        fluorColorCode = [1 0 1];
                    case 'Yellow'
                        fluorColorCode = [1 1 0];
                    case 'Orange'
                        fluorColorCode = [0.85 0.325 0.098];
                    case 'Purple'
                        fluorColorCode = [0.4940 0.1840 0.5560];
                        
                end
                switch button
                    case 'All Images in Channel'
                        channel = app.ChannelIDs(app.FluorescenceImageList.Value);
                        indices = find(app.ChannelIDs == channel);
                        
                    case 'Current Image'
                        indices = [app.FluorescenceImageList.Value];
                        
                    case 'All Images'
                        indices = 1:length(app.F_BulkI);
                
    
                end
                for index = indices
                    app.FluorColorCode{index} = fluorColorCode;
                end


                analyzeFluorescence(app, indices, app.FluorescenceImageList.Value)
            end
            
            
        end

        % Value changed function: IntensityCutoffEditField
        function intensityCutoffCallback(app, event)
            
            if app.SameCutoff.Value == 0
                value = app.IntensityCutoffEditField.Value;
                selected = app.FluorescenceImageList.Value;
                app.IntensityCutoff{selected} = value;
                analyzeFluorescence(app, [selected], selected)
            else
                sameCutoffCallback(app, event)
            end


        end

        % Key press function: UIFigure
        function keyPressedFcnFluor(app, event)
            if ~app.DoneOpening 
                if ~isempty(event.Modifier) & (strcmp(event.Modifier{1},'control') | strcmp(event.Modifier{1},'command')) & strcmp(event.Key, 'o')
                    openFluorescenceImages(app, event)
                end
            end
        end

        % Value changed function: PercentileEditField
        function changePercentile(app, event)
            if app.SamePercent.Value == 0 & app.AnalyzeCurrentButton.Enable == 1
                value = app.PercentileEditField.Value;
                selected = app.FluorescenceImageList.Value;
                app.Percentile{selected} = value;
                analyzeFluorescence(app, [selected], selected)
            else
                samePercentCallback(app, event)
            end
        end

        % Value changed function: ImagesareinorderCheckBox
        function imagesAreInOrderCheck(app, event)
            %Allow users to automatically assign images if they are ordered
            
            value = app.ImagesareinorderCheckBox.Value;
            
            if value == 1
                app.ChannelIDs = zeros(1, length(app.F_BulkI));
                
                %Assign images based on the assumption that fluorescent
                %images of the same brightfield image are together
                if strcmp(app.GroupPreference, 'Image')
                    fPerBF = floor(length(app.F_BulkI)/length(app.BF_CC));
                    if fPerBF < 1
                        fPerBF = 1;
                    end
                    valueBF = 1;
                    fNumber = 1;
                    for valueF = 1:length(app.F_BulkI)                        
                        app.FluorescenceImageList.Items{valueF} = strcat(app.F_Filename{valueF}, '_Bf', int2str(valueBF));
                        app.F_MatchingBF(valueF) = valueBF;
                        app.ChannelIDs(valueF) = fNumber;
                        fNumber = fNumber + 1;
                        if fNumber > fPerBF
                            fNumber = 1;
                            valueBF = valueBF + 1;
                            if valueBF > length(app.BF_CC)
                                valueBF = length(app.BF_CC);
                            end
                        end
                    end
                %Assign images based on the assumption that fluorescent
                %images of the same fluorescent channel are grouped (so
                %fluorescent images from the same brightfield image are
                %equally spaced apart)
                else
                    valueBF = 1;
                    fNumber = 1;
                    for valueF = 1:length(app.F_BulkI)                        
                        app.FluorescenceImageList.Items{valueF} = strcat(app.F_Filename{valueF}, '_Bf', int2str(valueBF));
                        app.F_MatchingBF(valueF) = valueBF;
                        app.ChannelIDs(valueF) = fNumber;
                        valueBF = valueBF + 1;                        
                        if valueBF > length(app.BF_CC)
                            valueBF = 1;
                            fNumber = fNumber + 1;
                        end                        
                    end


                end

                channelLabels = {};
                for chan = 1:max(app.ChannelIDs)
                    channelLabels{end+1} = num2str(chan);
                end
                app.ChannelLabels = channelLabels;

            else
                app.ChannelIDs = [];
                for valueF = 1:length(app.F_BulkI)
                    app.FluorescenceImageList.Items{valueF} = app.F_Filename{valueF};
                    app.F_MatchingBF(valueF) = 0;
                    
                    app.ChannelLabels = {};
                end
            end
        end

        % Menu selected function: ImagesGroupingMenu
        function imageGrouping(app, event)
            default = app.GroupPreference;

            answer = uiconfirm(app.UIFigure, {'Please select how fluorescent images are grouped' ...
                'This will determine how fluorescent images are assigned if the "Images are in order" checkbox' ...
                'is selected' ...
                '' ...
                'Selecting "Image" assumes that consecutive fluorescent images belong to the same brightfield image.' ...
                'For example, assignment will be [1 1 1 2 2 2 3 3 3]' ...
                '' ...
                'Selecting "Channel" assumes that consecutive fluorescent images belong to different brightfield images' ...
                'For example, assignment will be [1 2 3 1 2 3 1 2 3]' ...
                ''}, ...
    	        'Cropping Preference', ...
    	        'Options', {'Image','Channel'}, 'DefaultOption', default);
            app.GroupPreference = answer;
        end

        % Value changed function: SeemontageCheckBox
        function seeMontage(app, event)
            value = app.SeemontageCheckBox.Value;
            if value == 1
                app.SpheroidZoomGrid.Layout.Column = [3 5];
                app.BottomTabs.Layout.Column = 2;
                spheroidSelectList(app, event)
            else
                app.SpheroidZoomGrid.Layout.Column = [4 5];
                app.BottomTabs.Layout.Column = [2 3];
                spheroidSelectList(app, event)
            end
        end

        % Button pushed function: NextChannelButton
        function nextChannel(app, event)
            app.CurrentChannelMontage = app.CurrentChannelMontage + 1;
            if app.CurrentChannelMontage > max(app.ChannelIDs)
                app.CurrentChannelMontage = 1;
            end
            spheroidSelectList(app, event)


        end

        % Button pushed function: PreviousChannelButton
        function prevChannel(app, event)
            app.CurrentChannelMontage = app.CurrentChannelMontage - 1;
            if app.CurrentChannelMontage < 1
                app.CurrentChannelMontage = max(app.ChannelIDs);
            end
            spheroidSelectList(app, event)
            
        end

        % Value changed function: SameCutoff
        function sameCutoffCallback(app, event)
            %Apply the same pixel cutoff to all images
            %If from tracking, only apply to images from the same channel
            
            value = app.SameCutoff.Value;
            if value == 1
                if strcmp(app.Source, 'Main') & app.ChannelIDs(1) == 0
                   
                    for i = 1:length(app.IntensityCutoff)
                        app.IntensityCutoff{i} = app.IntensityCutoffEditField.Value;
                    end
                    analyzeFluorescence(app, 1:length(app.F_BulkI),app.FluorescenceImageList.Value)
                    
                    
                else
                    channel = app.ChannelIDs(app.FluorescenceImageList.Value);
                    
                    
                    %imagesInChannel = linspace(channel, channel + (length(app.BF_CC) - 1)*app.TotalChannels, length(app.BF_CC));
                    imagesInChannel = find(app.ChannelIDs == channel);
                    for i = 1:length(imagesInChannel)
                        app.IntensityCutoff{imagesInChannel(i)} = app.IntensityCutoffEditField.Value;
                    end
                    analyzeFluorescence(app, imagesInChannel,app.FluorescenceImageList.Value)
                end
            end
        end

        % Callback function
        function graph(app, event)
           
           
        end

        % Value changed function: SamePercent
        function samePercentCallback(app, event)
           
            %Apply the same pixel cutoff to all images
            %If from tracking, only apply to images from the same channel
            
            value = app.SamePercent.Value;
            if value == 1
                if strcmp(app.Source, 'Main') & app.ChannelIDs(1) == 0
                   
                    for i = 1:length(app.Percentile)
                        app.Percentile{i} = app.PercentileEditField.Value;
                    end
                    analyzeFluorescence(app, 1:length(app.F_BulkI),app.FluorescenceImageList.Value)
                    
                    
                else
                    channel = app.ChannelIDs(app.FluorescenceImageList.Value);
                    
                    
                    %imagesInChannel = linspace(channel, channel + (length(app.BF_CC) - 1)*app.TotalChannels, length(app.BF_CC));
                    imagesInChannel = find(app.ChannelIDs == channel);
                    for i = 1:length(imagesInChannel)
                        app.Percentile{imagesInChannel(i)} = app.PercentileEditField.Value;
                    end
                    
                    analyzeFluorescence(app, imagesInChannel,app.FluorescenceImageList.Value)
                end
            end
        end

        % Callback function
        function divideGreenRedCallback(app, event)
          
        end

        % Value changed function: BrightfieldRegexEditField
        function changeBFRegex(app, event)
            app.BFRegex = app.BrightfieldRegexEditField.Value;
            filenames = app.BrightfieldImageList.Items;
            
            if ~isempty(app.BFRegex)
                
                fileNameGroups = fieldnames(regexp(filenames{1}, app.BFRegex, "names"));
                tokens = cell(length(filenames), length(fileNameGroups));
                for file = 1:length(filenames)
                    match = regexp(filenames{file}, app.BFRegex, "tokens");
                    if ~isempty(match)
                        try
                            tokens(file, :) = match{1};
                        catch
                            errordlg("Error in matching, number of tokens captured did not match number of variable names")
                            break
                        end
                    end
                end
                for name = 1:length(fileNameGroups)
                    groups.(fileNameGroups{name}) = [tokens(:,name)];
                end
                if ~isempty(fileNameGroups)
                    app.BF_GroupsTable = struct;
                    groupsTable = struct2table(groups);
                    app.BF_GroupsTable.Data = groupsTable;
                    app.BF_GroupsTable.ColumnName = groupsTable.Properties.VariableNames;
                    
                else
                    app.BF_GroupsTable = 0;
                end
            else
                app.BF_GroupsTable = 0;
            end
            updateMatchingList(app)
            
        end

        % Value changed function: FluorescenceRegexEditField
        function changeFRegex(app, event)
            app.FRegex = app.FluorescenceRegexEditField.Value;
           
            filenames = app.F_Filename;
            
            if ~isempty(app.FRegex)
                
                fileNameGroups = fieldnames(regexp(filenames{1}, app.FRegex, "names"));
                tokens = cell(length(filenames), length(fileNameGroups));
                for file = 1:length(filenames)
                    match = regexp(filenames{file}, app.FRegex, "tokens");
                    if ~isempty(match)
                        try
                            tokens(file, :) = match{1};
                        catch
                            errordlg("Error in matching, number of tokens captured did not match number of variable names")
                            break
                        end
                    end
                end
                for name = 1:length(fileNameGroups)
                    groups.(fileNameGroups{name}) = [tokens(:,name)];
                end
                if ~isempty(fileNameGroups)
                    app.F_GroupsTable = struct;
                    groupsTable = struct2table(groups);
                    app.F_GroupsTable.Data = groupsTable;
                    app.F_GroupsTable.ColumnName = groupsTable.Properties.VariableNames;
                    
                else
                    app.F_GroupsTable = 0;
                end

            else
                app.F_GroupsTable = 0;
            end

            updateMatchingList(app)
            updateChannelList(app)
            
            
        end

        % Callback function
        function changeMatchingVar(app, event)


        end

        % Callback function
        function changeChannelVar(app, event)
            
        end

        % Button pushed function: MatchBrightfieldButton
        function matchBrightfieldButton(app, event)
            app.ImagesareinorderCheckBox.Value = 0;
            values = app.BrightfieldMatchingList.Value;


            bf_imageGroupData = app.BF_GroupsTable.Data;
            f_imageGroupData = app.F_GroupsTable.Data;
            bf_MatchingKeys = bf_imageGroupData{:,values};
            
            
            f_MatchingValues = f_imageGroupData{:,values};
            f_empty = cellfun(@isempty,f_MatchingValues);
            bf_empty = cellfun(@isempty,bf_MatchingKeys);


            if any(bf_empty) | any(f_empty)
                bf_emptyInd = find(bf_empty);
                f_emptyInd = find(f_empty);
                if isempty(bf_emptyInd)
                    bf_emptyList = "None";
                else
                    bf_emptyList = "";
                    for ind = f_emptyInd
                        bf_emptyList = sprintf(bf_emptyList + ", %d", ind);
                    end
                end

                if isempty(f_emptyInd)
                    f_emptyList = "None";
                else
                    f_emptyList = "";
                    for ind = f_emptyInd
                        f_emptyList = sprintf(f_emptyList + ", %d", ind);
                    end
                end
                
                errordlg(sprintf("Check your REGEX Expression! The following Images did not match the expression \n" + ...
                    "Brightfield: %s \n" + ...
                    "Fluorescence: %s", bf_emptyList, f_emptyList))
                return
            end
            bf_MatchingKeysUnique = unique(bf_imageGroupData(:,values), 'stable');
            bf_MatchingKeysUnique = bf_MatchingKeysUnique{:, :};
            if size(bf_MatchingKeysUnique, 1) < size(bf_MatchingKeys , 1)
                errordlg("Two or more of your brightfield images had the same matching key. Please use a different variable")
                return
            end
            noMatch = "";
            for f_value = 1:length(f_MatchingValues)
                
                f_MatchingValues(f_value, :)
                matchingBF = getMatchingRow(app, f_MatchingValues(f_value, :), bf_MatchingKeysUnique);
                
                if isnan(matchingBF)
                    noMatch = noMatch + num2str(f_value) + ', ';
                    
                else
                    app.F_MatchingBF(f_value) = matchingBF;
                end
                

            end
            if ~strcmp(noMatch, "")
                noMatch = char(noMatch);
                noMatch = noMatch(1:end-2);
                errordlg(sprintf("Fluorescence Image(s) %s did not match, please use a different variable.", noMatch));
            end
            for valueF = 1:length(app.F_Filename)
                valueBF = app.F_MatchingBF(valueF);
                app.FluorescenceImageList.Items{valueF} = strcat(app.F_Filename{valueF}, '_Bf', int2str(valueBF));
            end
            
        end

        % Button pushed function: AssignChannelButton_2
        function assignChannelButton(app, event)
            values = app.ChannelMatchingList.Value;
            app.ChannelLabels = {};
            app.ChannelIDs = zeros(1, length(app.F_BulkI));
            f_imageGroupData = app.F_GroupsTable.Data;
           
            
            f_MatchingValues = f_imageGroupData{:,values};
            f_empty = cellfun(@isempty,f_MatchingValues);


            if any(f_empty)
                
                f_emptyInd = find(f_empty);
                
                if isempty(f_emptyInd)
                    f_emptyList = "None";
                else
                    f_emptyList = "";
                    for ind = f_emptyInd
                        f_emptyList = sprintf(f_emptyList + ", %d", ind);
                    end
                end
                
                errordlg(sprintf("Check your REGEX Expression! The following Images did not match the expression \n" + ...
                    "Fluorescence: %s", f_emptyList))
                return
            end
            f_MatchingKeysUnique = unique(f_imageGroupData(:,values), 'stable');
            f_MatchingKeysUnique = f_MatchingKeysUnique{:, :};
            
            channelIDs = zeros(length(app.ChannelIDs));
            for f_value = 1:length(f_MatchingValues)
                
                f_MatchingValues(f_value, :)
                channel = getMatchingRow(app, f_MatchingValues(f_value, :), f_MatchingKeysUnique);
                if isnan(channel)
                    errordlg(sprintf("Fluorescence Image %d did not match, please use a different variable.", f_value));
                    return
                else
                    channelIDs(f_value) = channel;
                end

            end
            app.ChannelIDs = channelIDs;

            app.SameCutoff.Text = 'Apply Same Cutoff to All Images In Channel';
            app.SamePercent.Text = 'Apply Same Percentile to All Images In Channel';
          
            for channel = 1:size(f_MatchingKeysUnique, 1)
                channelID = f_MatchingKeysUnique{channel, 1};
                if size(f_MatchingKeysUnique,2) > 1
                    for label = 2:size(f_MatchingKeysUnique,2)
                        
                        channelID = channelID + "_" + f_MatchingKeysUnique{channel, label};
                    end
                end
                
                app.ChannelLabels{end+1} = channelID;

            end

            for valueF = 1:length(app.F_Filename)
                channelValue = app.ChannelIDs(valueF);
                char(strcat(app.F_Filename{valueF}, '_Channel', app.ChannelLabels{channelValue}))
                app.FluorescenceImageList.Items{valueF} = char(strcat(app.F_Filename{valueF}, '_Channel', app.ChannelLabels{channelValue}));
            end
            if max(app.ChannelIDs) * length(app.BF_DisplayBulkI) < length(app.F_DisplayBulkI)
                errordlg("Warning, you do not have enough unique channels for each fluorescence image to belong to a different brightfield image and channel." + ...
                    "This will impact exporting format. Please consider assigning channels again")
            end

        end

        % Value changed function: MaxEditField
        function setMaxChannelValue(app, event)
            if app.Analyzed{app.FluorescenceImageList.Value}
                spheroid = app.SpheroidSelectListBox.Value;
            end
            fluorescenceImageSelectList(app, event)
            
            if app.Analyzed{app.FluorescenceImageList.Value}
                app.SpheroidSelectListBox.Value = spheroid;
                spheroidSelectList(app, event)
            end
        end

        % Value changed function: MinEditField
        function setMinChannelValue(app, event)
           if app.Analyzed{app.FluorescenceImageList.Value}
                spheroid = app.SpheroidSelectListBox.Value;
            end
            fluorescenceImageSelectList(app, event)
            if app.Analyzed{app.FluorescenceImageList.Value}
                app.SpheroidSelectListBox.Value = spheroid;
                spheroidSelectList(app, event)
            end


        end

        % Callback function
        function setMinMaxCheckBox(app, event)
            % value = app.SetMinMaxCheckBox.Value;
            % selected = app.FluorescenceImageList.Value;
            % 
            % 
            % h = waitbar(0);
            % if value == 1
            %     app.MinEditField.Enable = 1;
            %     app.MaxEditField.Enable = 1;
            %     for image = 1:length(app.F_DisplayBulkI)
            %         channel = app.ChannelIDs(image);
            %         imgMasked = app.F_ImgMasked{image};
            % 
            % 
            %         r = imgMasked(:,:,1);
            %         g = imgMasked(:,:,2);
            %         b = imgMasked(:,:,3);
            %         overlayInd = (r~=g | r~=b | g~=b);
            %         imgMasked(~overlayInd) = 0;
            %         displayF_I = app.F_DisplayBulkI{selected};
            %         displayF_I = double(displayF_I);
            % 
            %         displayF_I = (displayF_I - app.SetMinChannelValues(channel))./(app.SetMaxChannelValues(channel) - app.SetMinChannelValues(channel));
            %         displayF_I = uint8(displayF_I*255);
            %         displayF_RGB = cat(3, displayF_I, displayF_I, displayF_I);
            % 
            %         imgMasked = imgMasked + displayF_RGB;
            %         app.F_ImgMasked{image} = imgMasked;
            %         waitbar(image/length(app.F_DisplayBulkI))
            % 
            % 
            % 
            % 
            %     end
            % 
            % 
            % else
            %     app.MinEditField.Enable = 0;
            %     app.MaxEditField.Enable = 0;
            %     for image = 1:length(app.F_DisplayBulkI)
            % 
            %         imgMasked = app.F_ImgMasked{image};
            %         channel = app.ChannelIDs(image);
            % 
            %         r = imgMasked(:,:,1);
            %         g = imgMasked(:,:,2);
            %         b = imgMasked(:,:,3);
            %         overlayInd = (r~=g | r~=b | g~=b);
            %         imgMasked(~overlayInd) = 0;
            %         displayF_I = app.F_DisplayBulkI{selected};
            %         displayF_I = double(displayF_I);
            %         displayF_I = (displayF_I - app.MinChannelValues(channel))./(app.MaxChannelValues(channel) - app.MinChannelValues(channel));
            %         displayF_I = uint8(displayF_I*255);
            %         displayF_RGB = cat(3, displayF_I, displayF_I, displayF_I);
            % 
            %         imgMasked = imgMasked + displayF_RGB;
            %         app.F_ImgMasked{image} = imgMasked;
            %         waitbar(image/length(app.F_DisplayBulkI))
            % 
            % 
            % 
            % 
            %     end
            % 
            % end
            % displayFluorescenceWithOverlay(app, selected)
            % close(h)

        end

        % Menu selected function: CurrentImageMenu
        function saveCurrImageOverlay(app, event)
            if app.Analyzed{app.FluorescenceImageList.Value} == false
                mb3 = errordlg('Please analyze images');
                t = timer;
                t.StartDelay = 2;
                t.TimerFcn = @(~,~) delete(mb3);
                start(t)
            else
                if isnan(app.MostRecentImageSaveFolder)
                    dname = uigetdir('Choose folder to save images');
                else
                    dname = uigetdir(app.MostRecentImageSaveFolder,'Choose folder to save images');
                   
                end
                if dname~=0
                    app.MostRecentImageSaveFolder = dname;
                end
                if dname == 0
                    %do nothing
                else
                    %Check for mac vs windows
                    %k = strfind(dname,'\');
                    %if windows
                    if contains(dname, '\')
                        dname = strcat(dname,'\');
                    else
                        dname = strcat(dname,'/');
                    end

                   masks = app.F_ImgMasked;
                   
                   filename = app.F_Filename;
                    
                    name = filename{app.FluorescenceImageList.Value};
                    ind = find(name == '.',1,'last');
                    name(ind:end) = [];

                    imgMasked = masks{app.FluorescenceImageList.Value};
                    

                    imwrite(imgMasked,char(nextname(strcat(dname,name,'_overlay_<1>.tif'),true)));


                    %end
                end
            end
        end

        % Menu selected function: CurrentChannelMenu
        function saveCurrChannelOverlay(app, event)
            channel = app.ChannelIDs(app.FluorescenceImageList.Value);
            imagesInChannel = find(app.ChannelIDs == channel);
            allAnalyzed = true;
            for image = imagesInChannel
                if app.Analyzed{image} == false
                    allAnalyzed = false;
                end
            end

            
            if allAnalyzed == false
                mb3 = errordlg('Please analyze images');
                t = timer;
                t.StartDelay = 2;
                t.TimerFcn = @(~,~) delete(mb3);
                start(t)
            else
                if isnan(app.MostRecentImageSaveFolder)
                    dname = uigetdir('Choose folder to save images');
                else
                    dname = uigetdir(app.MostRecentImageSaveFolder,'Choose folder to save images');
                   
                end
                if dname~=0
                    app.MostRecentImageSaveFolder = dname;
                end
                if dname == 0
                    %do nothing
                else
                    %Check for mac vs windows
                    %k = strfind(dname,'\');
                    %if windows
                    if contains(dname, '\')
                        dname = strcat(dname,'\');
                    else
                        dname = strcat(dname,'/');
                    end

                   masks = app.F_ImgMasked;
                   
                   filename = app.F_Filename;
                    for i = imagesInChannel
                        name = filename{i};
                        ind = find(name == '.',1,'last');
                        name(ind:end) = [];

                        imgMasked = masks{i};
                        

                        imwrite(imgMasked,char(nextname(strcat(dname,name,'_overlay_<1>.tif'),true)));
                        
                        
                    end
                    %end
                end
            end
        end

        % Menu selected function: AllImagesMenu
        function saveAllImageOverlay(app, event)
            
            allAnalyzed = true;
            for image = 1:length(app.F_BulkI)
                if app.Analyzed{image} == false
                    allAnalyzed = false;
                end
            end

            
            if allAnalyzed == false
                mb3 = errordlg('Please analyze images');
                t = timer;
                t.StartDelay = 2;
                t.TimerFcn = @(~,~) delete(mb3);
                start(t)
            else
                if isnan(app.MostRecentImageSaveFolder)
                    dname = uigetdir('Choose folder to save images');
                else
                    dname = uigetdir(app.MostRecentImageSaveFolder,'Choose folder to save images');
                   
                end
                if dname~=0
                    app.MostRecentImageSaveFolder = dname;
                end
                if dname == 0
                    %do nothing
                else
                    %Check for mac vs windows
                    %k = strfind(dname,'\');
                    %if windows
                    if contains(dname, '\')
                        dname = strcat(dname,'\');
                    else
                        dname = strcat(dname,'/');
                    end

                   masks = app.F_ImgMasked;
                   
                   filename = app.F_Filename;
                    for i = 1:length(app.F_BulkI)
                        name = filename{i};
                        ind = find(name == '.',1,'last');
                        name(ind:end) = [];

                        imgMasked = masks{i};
                        

                        imwrite(imgMasked,char(nextname(strcat(dname,name,'_overlay_<1>.tif'),true)));
                        
                        
                    end
                    %end
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @keyPressedFcnFluor, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenFluorescenceImagesMenu = uimenu(app.FileMenu);
            app.OpenFluorescenceImagesMenu.MenuSelectedFcn = createCallbackFcn(app, @openFluorescenceImages, true);
            app.OpenFluorescenceImagesMenu.Text = 'Open Fluorescence Images';
            app.SaveFluorescenceOverlaysMenu = uimenu(app.FileMenu);
            app.SaveFluorescenceOverlaysMenu.Text = 'Save Fluorescence Overlays';
            app.CurrentImageMenu = uimenu(app.SaveFluorescenceOverlaysMenu);
            app.CurrentImageMenu.MenuSelectedFcn = createCallbackFcn(app, @saveCurrImageOverlay, true);
            app.CurrentImageMenu.Text = 'Current Image';
            app.CurrentChannelMenu = uimenu(app.SaveFluorescenceOverlaysMenu);
            app.CurrentChannelMenu.MenuSelectedFcn = createCallbackFcn(app, @saveCurrChannelOverlay, true);
            app.CurrentChannelMenu.Text = 'Current Channel';
            app.AllImagesMenu = uimenu(app.SaveFluorescenceOverlaysMenu);
            app.AllImagesMenu.MenuSelectedFcn = createCallbackFcn(app, @saveAllImageOverlay, true);
            app.AllImagesMenu.Text = 'All Images';
            app.PreferencesMenu = uimenu(app.UIFigure);
            app.PreferencesMenu.Text = 'Preferences';
            app.PerimeterColorsMenu = uimenu(app.PreferencesMenu);
            app.PerimeterColorsMenu.Text = 'Perimeter Colors';
            app.AllspheroidsMenu = uimenu(app.PerimeterColorsMenu);
            app.AllspheroidsMenu.MenuSelectedFcn = createCallbackFcn(app, @allSpheroidsColor, true);
            app.AllspheroidsMenu.Text = 'All spheroids';
            app.FluorescentlyMarkedMenu = uimenu(app.PerimeterColorsMenu);
            app.FluorescentlyMarkedMenu.MenuSelectedFcn = createCallbackFcn(app, @fluorSpheroidsColor, true);
            app.FluorescentlyMarkedMenu.Text = 'Fluorescently Marked';
            app.ImagesGroupingMenu = uimenu(app.PreferencesMenu);
            app.ImagesGroupingMenu.MenuSelectedFcn = createCallbackFcn(app, @imageGrouping, true);
            app.ImagesGroupingMenu.Enable = 'off';
            app.ImagesGroupingMenu.Text = 'Images Grouping';
            app.ImageScalingMenu = uimenu(app.PreferencesMenu);
            app.ImageScalingMenu.Text = 'Image Scaling';
            app.MainGridLayout = uigridlayout(app.UIFigure);
            app.AnalyzeGrid = uigridlayout(app.MainGridLayout);
            app.AnalyzeCurrentButton = uibutton(app.AnalyzeGrid, 'push');
            app.AnalyzeCurrentButton.ButtonPushedFcn = createCallbackFcn(app, @analyzeCurrent, true);
            app.AnalyzeCurrentButton.Enable = 'off';
            app.AnalyzeCurrentButton.Text = 'Analyze Current';
            app.AnalyzeAllButton = uibutton(app.AnalyzeGrid, 'push');
            app.AnalyzeAllButton.ButtonPushedFcn = createCallbackFcn(app, @analyzeAll, true);
            app.AnalyzeAllButton.Enable = 'off';
            app.AnalyzeAllButton.Text = 'Analyze All';
            app.PercentileEditFieldLabel = uilabel(app.AnalyzeGrid);
            app.PercentileEditFieldLabel.Text = 'Percentile';
            app.PercentileEditField = uieditfield(app.AnalyzeGrid, 'numeric');
            app.PercentileEditField.Limits = [0 100];
            app.PercentileEditField.RoundFractionalValues = 'on';
            app.PercentileEditField.ValueChangedFcn = createCallbackFcn(app, @changePercentile, true);
            app.PercentileEditField.Value = 80;
            app.BinSizeEditFieldLabel = uilabel(app.AnalyzeGrid);
            app.BinSizeEditFieldLabel.Text = 'Bin Size';
            app.BinSizeEditField = uieditfield(app.AnalyzeGrid, 'numeric');
            app.BinSizeEditField.Limits = [0 Inf];
            app.BinSizeEditField.RoundFractionalValues = 'on';
            app.BinSizeEditField.ValueChangedFcn = createCallbackFcn(app, @numBinsChange, true);
            app.BinSizeEditField.Value = 5;
            app.ExportCurrentButton = uibutton(app.AnalyzeGrid, 'push');
            app.ExportCurrentButton.ButtonPushedFcn = createCallbackFcn(app, @exportCurrentFluor, true);
            app.ExportCurrentButton.Enable = 'off';
            app.ExportCurrentButton.Text = 'Export Current';
            app.ExportSetButton = uibutton(app.AnalyzeGrid, 'push');
            app.ExportSetButton.ButtonPushedFcn = createCallbackFcn(app, @exportSetFluor, true);
            app.ExportSetButton.Enable = 'off';
            app.ExportSetButton.Text = 'Export Set';
            app.WhichOrganoidsExport = uidropdown(app.AnalyzeGrid);
            app.WhichOrganoidsExport.Items = {'Export All Organoids ', 'Remove "x" ', 'Keep Only "c" '};
            app.WhichOrganoidsExport.ItemsData = {'ExportAll ', 'RemoveX', 'KeepOnlyC'};
            app.WhichOrganoidsExport.Value = 'ExportAll ';
            app.BrightfieldGrid = uigridlayout(app.MainGridLayout);
            app.BrightfieldImageList = uilistbox(app.BrightfieldGrid);
            app.BrightfieldImageList.Items = {};
            app.BrightfieldImageList.ValueChangedFcn = createCallbackFcn(app, @brightfieldImageSelectList, true);
            app.BrightfieldImageList.Value = {};
            app.DeleteImageButtonBF = uibutton(app.BrightfieldGrid, 'push');
            app.DeleteImageButtonBF.ButtonPushedFcn = createCallbackFcn(app, @deleteBF, true);
            app.DeleteImageButtonBF.Text = 'Delete Brightfield Image';
            app.SpheroidZoomGrid = uigridlayout(app.MainGridLayout);
            app.SpheroidZoomAxes = uiaxes(app.SpheroidZoomGrid);
            app.SpheroidZoomAxes.XLimitMethod = 'tight';
            app.SpheroidZoomAxes.YLimitMethod = 'tight';
            app.SpheroidZoomAxes.ZLimitMethod = 'tight';
            app.SpheroidZoomAxes.XTick = [];
            app.SpheroidZoomAxes.YTick = [];
            app.SpheroidZoomAxes.Visible = 'off';
            app.SeemontageCheckBox = uicheckbox(app.SpheroidZoomGrid);
            app.SeemontageCheckBox.ValueChangedFcn = createCallbackFcn(app, @seeMontage, true);
            app.SeemontageCheckBox.Enable = 'off';
            app.SeemontageCheckBox.Text = 'See montage';
            app.NextChannelButton = uibutton(app.SpheroidZoomGrid, 'push');
            app.NextChannelButton.ButtonPushedFcn = createCallbackFcn(app, @nextChannel, true);
            app.NextChannelButton.Enable = 'off';
            app.NextChannelButton.Text = 'Next Channel';
            app.PreviousChannelButton = uibutton(app.SpheroidZoomGrid, 'push');
            app.PreviousChannelButton.ButtonPushedFcn = createCallbackFcn(app, @prevChannel, true);
            app.PreviousChannelButton.Enable = 'off';
            app.PreviousChannelButton.Text = 'Previous Channel';
            app.SpheroidSelectListBox = uilistbox(app.SpheroidZoomGrid);
            app.SpheroidSelectListBox.Items = {};
            app.SpheroidSelectListBox.ValueChangedFcn = createCallbackFcn(app, @spheroidSelectList, true);
            app.SpheroidSelectListBox.Value = {};
            app.MainAxesGridLayout = uigridlayout(app.MainGridLayout);
            app.FluorescenceAxes = uiaxes(app.MainAxesGridLayout);
            title(app.FluorescenceAxes, 'Fluorescence Image')
            app.FluorescenceAxes.XLimitMethod = 'tight';
            app.FluorescenceAxes.YLimitMethod = 'tight';
            app.FluorescenceAxes.ZLimitMethod = 'tight';
            app.FluorescenceAxes.XTick = [];
            app.FluorescenceAxes.XTickLabel = '';
            app.FluorescenceAxes.YTick = [];
            app.FluorescenceAxes.YTickLabel = '';
            app.FluorescenceAxes.ZTick = [];
            app.FluorescenceAxes.Visible = 'off';
            app.BrightfieldAxes = uiaxes(app.MainAxesGridLayout);
            title(app.BrightfieldAxes, 'Brightfield Image')
            app.BrightfieldAxes.XLimitMethod = 'tight';
            app.BrightfieldAxes.YLimitMethod = 'tight';
            app.BrightfieldAxes.ZLimitMethod = 'tight';
            app.BrightfieldAxes.XTick = [];
            app.BrightfieldAxes.XTickLabel = '';
            app.BrightfieldAxes.YTick = [];
            app.BrightfieldAxes.YTickLabel = '';
            app.BrightfieldAxes.ZTick = [];
            app.BrightfieldAxes.Visible = 'off';
            app.GridLayout3 = uigridlayout(app.MainAxesGridLayout);
            app.MinEditFieldLabel = uilabel(app.GridLayout3);
            app.MinEditFieldLabel.Text = 'Min';
            app.MinEditField = uieditfield(app.GridLayout3, 'numeric');
            app.MinEditField.Limits = [0 1];
            app.MinEditField.ValueChangedFcn = createCallbackFcn(app, @setMinChannelValue, true);
            app.MaxEditFieldLabel = uilabel(app.GridLayout3);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.GridLayout3, 'numeric');
            app.MaxEditField.Limits = [0 1];
            app.MaxEditField.ValueChangedFcn = createCallbackFcn(app, @setMaxChannelValue, true);
            app.MaxEditField.Value = 1;
            app.BottomTabs = uitabgroup(app.MainGridLayout);
            app.DataDisplay = uitab(app.BottomTabs);
            app.DataDisplay.Title = 'Data Display';
            app.HistogramGridLayout = uigridlayout(app.DataDisplay);
            app.HistogramAxes = uiaxes(app.HistogramGridLayout);
            xlabel(app.HistogramAxes, 'X')
            ylabel(app.HistogramAxes, 'Frequency')
            zlabel(app.HistogramAxes, 'Z')
            app.HistogramAxes.ButtonDownFcn = createCallbackFcn(app, @histogramButtonDown, true);
            app.HistogramAxes.Visible = 'off';
            app.FluorescentPixelIntensityCutoffLabel = uilabel(app.HistogramGridLayout);
            app.FluorescentPixelIntensityCutoffLabel.Visible = 'off';
            app.FluorescentPixelIntensityCutoffLabel.Text = {'Fluorescent Pixel'; 'Intensity Cutoff'};
            app.IntensityCutoffEditField = uieditfield(app.HistogramGridLayout, 'numeric');
            app.IntensityCutoffEditField.Limits = [0 Inf];
            app.IntensityCutoffEditField.ValueChangedFcn = createCallbackFcn(app, @intensityCutoffCallback, true);
            app.IntensityCutoffEditField.Visible = 'off';
            app.IntensityCutoffEditField.Value = 50;
            app.GridLayout2 = uigridlayout(app.HistogramGridLayout);
            app.SameCutoff = uicheckbox(app.GridLayout2);
            app.SameCutoff.ValueChangedFcn = createCallbackFcn(app, @sameCutoffCallback, true);
            app.SameCutoff.Visible = 'off';
            app.SameCutoff.Text = 'Apply Same Cutoff to All Images In Channel';
            app.SamePercent = uicheckbox(app.GridLayout2);
            app.SamePercent.ValueChangedFcn = createCallbackFcn(app, @samePercentCallback, true);
            app.SamePercent.Visible = 'off';
            app.SamePercent.Text = 'Apply Same Percentile to All Images In Channel';
            app.ImageAssignment = uitab(app.BottomTabs);
            app.ImageAssignment.Title = 'Image Assignment';
            app.ImageAssignmentGrid = uigridlayout(app.ImageAssignment);
            app.BrightfieldRegexEditFieldLabel = uilabel(app.ImageAssignmentGrid);
            app.BrightfieldRegexEditFieldLabel.Text = 'Brightfield Regex';
            app.BrightfieldRegexEditField = uieditfield(app.ImageAssignmentGrid, 'text');
            app.BrightfieldRegexEditField.ValueChangedFcn = createCallbackFcn(app, @changeBFRegex, true);
            app.FluorescenceRegexEditFieldLabel = uilabel(app.ImageAssignmentGrid);
            app.FluorescenceRegexEditFieldLabel.Text = 'Fluorescence Regex';
            app.FluorescenceRegexEditField = uieditfield(app.ImageAssignmentGrid, 'text');
            app.FluorescenceRegexEditField.ValueChangedFcn = createCallbackFcn(app, @changeFRegex, true);
            app.BrightfieldMatchingList = uilistbox(app.ImageAssignmentGrid);
            app.BrightfieldMatchingList.Items = {'NA'};
            app.BrightfieldMatchingList.Multiselect = 'on';
            app.BrightfieldMatchingList.Value = {'NA'};
            app.MatchBrightfieldButton = uibutton(app.ImageAssignmentGrid, 'push');
            app.MatchBrightfieldButton.ButtonPushedFcn = createCallbackFcn(app, @matchBrightfieldButton, true);
            app.MatchBrightfieldButton.Text = 'Match Brightfield';
            app.ChannelMatchingList = uilistbox(app.ImageAssignmentGrid);
            app.ChannelMatchingList.Items = {'NA'};
            app.ChannelMatchingList.Multiselect = 'on';
            app.ChannelMatchingList.Value = {'NA'};
            app.AssignChannelButton_2 = uibutton(app.ImageAssignmentGrid, 'push');
            app.AssignChannelButton_2.ButtonPushedFcn = createCallbackFcn(app, @assignChannelButton, true);
            app.AssignChannelButton_2.Text = 'Assign Channel';
            app.FluorescenceGrid = uigridlayout(app.MainGridLayout);
            app.FluorescenceButtonsGrid = uigridlayout(app.FluorescenceGrid);
            app.AssignImageButton = uibutton(app.FluorescenceButtonsGrid, 'push');
            app.AssignImageButton.ButtonPushedFcn = createCallbackFcn(app, @assignImage, true);
            app.AssignImageButton.Text = 'Assign Image';
            app.DoneAssigningButton = uibutton(app.FluorescenceButtonsGrid, 'push');
            app.DoneAssigningButton.ButtonPushedFcn = createCallbackFcn(app, @doneAssigning, true);
            app.DoneAssigningButton.Text = 'Done Assigning';
            app.ReassignButton = uibutton(app.FluorescenceButtonsGrid, 'push');
            app.ReassignButton.ButtonPushedFcn = createCallbackFcn(app, @reassignCallback, true);
            app.ReassignButton.Text = 'Reassign';
            app.DeleteImageButtonF = uibutton(app.FluorescenceButtonsGrid, 'push');
            app.DeleteImageButtonF.ButtonPushedFcn = createCallbackFcn(app, @deleteF, true);
            app.DeleteImageButtonF.Text = 'Delete Fluorescent Image';
            app.DoneOpeningImagesButton = uibutton(app.FluorescenceButtonsGrid, 'push');
            app.DoneOpeningImagesButton.ButtonPushedFcn = createCallbackFcn(app, @doneOpening, true);
            app.DoneOpeningImagesButton.Text = 'Done Opening Images';
            app.ImagesareinorderCheckBox = uicheckbox(app.FluorescenceButtonsGrid);
            app.ImagesareinorderCheckBox.ValueChangedFcn = createCallbackFcn(app, @imagesAreInOrderCheck, true);
            app.ImagesareinorderCheckBox.Text = 'Images are in order';
            app.FluorescenceImageList = uilistbox(app.FluorescenceGrid);
            app.FluorescenceImageList.Items = {};
            app.FluorescenceImageList.ValueChangedFcn = createCallbackFcn(app, @fluorescenceImageSelectList, true);
            app.FluorescenceImageList.Value = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fluorescenceAnalysisScene(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)fluorescenceStartup(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end