classdef metricSelectionUpdate < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        MetricDescription             matlab.ui.control.Label
        VisualRepresentationLabel     matlab.ui.control.Label
        HomogeneityText               matlab.ui.control.Label
        PhiText                       matlab.ui.control.Label
        CircularirtyText              matlab.ui.control.Label
        MeanPixelIntensityText        matlab.ui.control.Label
        OrientationText               matlab.ui.control.Label
        StdPixelIntensityText         matlab.ui.control.Label
        ExtentText                    matlab.ui.control.Label
        CVPixelIntensityText          matlab.ui.control.Label
        ConvexAreaText                matlab.ui.control.Label
        SkewnessText                  matlab.ui.control.Label
        MinAxisLengthText             matlab.ui.control.Label
        KurtosisText                  matlab.ui.control.Label
        MajAxisLengthText             matlab.ui.control.Label
        ContrastText                  matlab.ui.control.Label
        ZText                         matlab.ui.control.Label
        SolidityText                  matlab.ui.control.Label
        CorrelationText               matlab.ui.control.Label
        EnergyText                    matlab.ui.control.Label
        AText                         matlab.ui.control.Label
        MetricLabel                   matlab.ui.control.Label
        AreaText                      matlab.ui.control.Label
        EccentricityText              matlab.ui.control.Label
        PerimeterText                 matlab.ui.control.Label
        P2AText                       matlab.ui.control.Label
        DiameterText                  matlab.ui.control.Label
        DescriptionLabel              matlab.ui.control.Label
        CircularityCheckBox           matlab.ui.control.CheckBox
        DiameterCheckBox              matlab.ui.control.CheckBox
        HomogeneityCheckBox           matlab.ui.control.CheckBox
        EnergyCheckBox                matlab.ui.control.CheckBox
        CorrelationCheckBox           matlab.ui.control.CheckBox
        ContrastCheckBox              matlab.ui.control.CheckBox
        KurtosisCheckBox              matlab.ui.control.CheckBox
        SkewnessCheckBox              matlab.ui.control.CheckBox
        CVPixelIntensityCheckBox      matlab.ui.control.CheckBox
        StdPixelIntensityCheckBox     matlab.ui.control.CheckBox
        MeanPixelIntensityCheckBox    matlab.ui.control.CheckBox
        PhiCheckBox                   matlab.ui.control.CheckBox
        ACheckBox                     matlab.ui.control.CheckBox
        ZCheckBox                     matlab.ui.control.CheckBox
        SolidityCheckBox              matlab.ui.control.CheckBox
        OrientationCheckBox           matlab.ui.control.CheckBox
        ExtentCheckBox                matlab.ui.control.CheckBox
        ConvexAreaCheckBox            matlab.ui.control.CheckBox
        MinorAxisLengthCheckBox       matlab.ui.control.CheckBox
        MajorAxisLengthCheckBox       matlab.ui.control.CheckBox
        EccentricityCheckBox          matlab.ui.control.CheckBox
        PerimeterToAreaRatioCheckBox  matlab.ui.control.CheckBox
        PerimeterCheckBox             matlab.ui.control.CheckBox
        AreaCheckBox                  matlab.ui.control.CheckBox
        RepresentationAxes            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp % Main OrganoSeg app
        MetricsSelected %Struct that has which metrics are selected for exporting data
        RepresentationStruct %Struct containing all information for displaying the representations of the metrics
    end
    
    methods (Access = private)
        %%_rep_callbacks are called when a checkbox is selected, and show
        %%what the respective metric represeents


        function p2a_rep_callback(app)
            cla(app.RepresentationAxes)
            imshow(app.RepresentationStruct.crop,[], 'Parent', app.RepresentationAxes)
            hold(app.RepresentationAxes, 'on');
            imArea = imshow(app.RepresentationStruct.area_color_red, 'Parent', app.RepresentationAxes);
            imPerim = imshow(app.RepresentationStruct.bw_perim_color, 'Parent', app.RepresentationAxes);
            hold(app.RepresentationAxes, 'off');
            set(imArea,'AlphaData',app.RepresentationStruct.area_mask);
            set(imPerim,'AlphaData',app.RepresentationStruct.perim_mask);

            set(app.AreaText,'FontColor','b');
            set(app.MetricDescription,'Text','Perimeter-to-Area Ratio, blue divided by red');
            set(app.PerimeterText,'FontColor','b');
            set(app.P2AText,'FontColor','r');
            set(app.EccentricityText,'FontColor','b');
            set(app.MajAxisLengthText,'FontColor','b');
            set(app.MinAxisLengthText,'FontColor','b');
            set(app.OrientationText,'FontColor','b');
            set(app.SolidityText,'FontColor','b');
            set(app.ExtentText,'FontColor','b');
            set(app.ConvexAreaText,'FontColor','b');

        end

        function perimeter_rep_callback(app)
            cla(app.RepresentationAxes)
            imshow(app.RepresentationStruct.crop,[], 'Parent', app.RepresentationAxes)
            hold(app.RepresentationAxes, 'on');
            imPerim = imshow(app.RepresentationStruct.bw_perim_color, 'Parent', app.RepresentationAxes);
            
            %change opacity if wanted
            set(imPerim,'AlphaData',app.RepresentationStruct.perim_mask);
            hold(app.RepresentationAxes, 'off');
            set(app.AreaText,'FontColor','b');
            set(app.MetricDescription,'Text','Perimeter');
            set(app.PerimeterText,'FontColor','r');
            set(app.P2AText,'FontColor','b');
            set(app.EccentricityText,'FontColor','b');
            set(app.MajAxisLengthText,'FontColor','b');
            set(app.MinAxisLengthText,'FontColor','b');
            set(app.OrientationText,'FontColor','b');
            set(app.SolidityText,'FontColor','b');
            set(app.ExtentText,'FontColor','b');
            set(app.ConvexAreaText,'FontColor','b');
            
        end

        function area_rep_callback(app)
            cla(app.RepresentationAxes)
            imshow(app.RepresentationStruct.crop,[], 'Parent', app.RepresentationAxes)
            hold(app.RepresentationAxes, 'on');
            imArea = imshow(app.RepresentationStruct.area_color, 'Parent', app.RepresentationAxes);
            hold(app.RepresentationAxes, 'off');
            set(imArea,'AlphaData',app.RepresentationStruct.area_mask);
            
            set(app.AreaText,'FontColor','r');
            set(app.MetricDescription,'Text','Area');
            set(app.PerimeterText,'FontColor','b');
            set(app.P2AText,'FontColor','b');
            set(app.EccentricityText,'FontColor','b');
            set(app.MajAxisLengthText,'FontColor','b');
            set(app.MinAxisLengthText,'FontColor','b');
            set(app.OrientationText,'FontColor','b');
            set(app.SolidityText,'FontColor','b');
            set(app.ExtentText,'FontColor','b');
            set(app.ConvexAreaText,'FontColor','b');
            
        end

        function eccentricity_rep_callback(app)
            cla(app.RepresentationAxes)
            imshow(app.RepresentationStruct.crop_ellipse,[], 'Parent', app.RepresentationAxes)
            hold(app.RepresentationAxes, 'on');
            plot(app.RepresentationStruct.x,app.RepresentationStruct.y,'w','LineWidth',2, 'Parent', app.RepresentationAxes);
            plot(app.RepresentationStruct.x_Maj,app.RepresentationStruct.y_Maj,'r','LineWidth',5, 'Parent', app.RepresentationAxes);
            plot(app.RepresentationStruct.x_foci,app.RepresentationStruct.y_foci,'b','LineWidth',2, 'Parent', app.RepresentationAxes);
            hold(app.RepresentationAxes, 'off');

            set(app.AreaText,'FontColor','b');
            set(app.MetricDescription,'Text','Eccentricity, blue divided by red');
            set(app.PerimeterText,'FontColor','b');
            set(app.P2AText,'FontColor','b');
            set(app.EccentricityText,'FontColor','r');
            set(app.MajAxisLengthText,'FontColor','b');
            set(app.MinAxisLengthText,'FontColor','b');
            set(app.OrientationText,'FontColor','b');
            set(app.SolidityText,'FontColor','b');
            set(app.ExtentText,'FontColor','b');
            set(app.ConvexAreaText,'FontColor','b');
            
        end
        function majAxis_rep_callback(app)
            cla(app.RepresentationAxes)
            imshow(app.RepresentationStruct.crop_ellipse,[], 'Parent', app.RepresentationAxes)
            hold(app.RepresentationAxes, 'on');
            plot(app.RepresentationStruct.x,app.RepresentationStruct.y,'w','LineWidth',2, 'Parent', app.RepresentationAxes);
            plot(app.RepresentationStruct.x_Maj,app.RepresentationStruct.y_Maj,'b','LineWidth',2, 'Parent', app.RepresentationAxes);
            hold(app.RepresentationAxes, 'off');

            set(app.AreaText,'FontColor','b');
            set(app.MetricDescription,'Text','Major Axis Length, blue line');
            set(app.PerimeterText,'FontColor','b');
            set(app.P2AText,'FontColor','b');
            set(app.EccentricityText,'FontColor','b');
            set(app.MajAxisLengthText,'FontColor','r');
            set(app.MinAxisLengthText,'FontColor','b');
            set(app.OrientationText,'FontColor','b');
            set(app.SolidityText,'FontColor','b');
            set(app.ExtentText,'FontColor','b');
            set(app.ConvexAreaText,'FontColor','b');
            
        end
        function minAxis_rep_callback(app)
            cla(app.RepresentationAxes)
            imshow(app.RepresentationStruct.crop_ellipse,[], 'Parent', app.RepresentationAxes)
            hold(app.RepresentationAxes, 'on');
            plot(app.RepresentationStruct.x,app.RepresentationStruct.y,'w','LineWidth',2, 'Parent', app.RepresentationAxes);
            plot(app.RepresentationStruct.x_Min,app.RepresentationStruct.y_Min,'b','LineWidth',2, 'Parent', app.RepresentationAxes);
            hold(app.RepresentationAxes, 'off');

            set(app.AreaText,'FontColor','b');
            set(app.MetricDescription,'Text','Minor Axis Length, blue line');
            set(app.PerimeterText,'FontColor','b');
            set(app.P2AText,'FontColor','b');
            set(app.EccentricityText,'FontColor','b');
            set(app.MajAxisLengthText,'FontColor','b');
            set(app.MinAxisLengthText,'FontColor','r');
            set(app.OrientationText,'FontColor','b');
            set(app.SolidityText,'FontColor','b');
            set(app.ExtentText,'FontColor','b');
            set(app.ConvexAreaText,'FontColor','b');
            
        end
        function orientation_rep_callback(app)
            cla(app.RepresentationAxes)
            imshow(app.RepresentationStruct.crop_ellipse,[], 'Parent', app.RepresentationAxes)
            hold(app.RepresentationAxes, 'on');
            plot(app.RepresentationStruct.x,app.RepresentationStruct.y,'w','LineWidth',2, 'Parent', app.RepresentationAxes);
            plot(app.RepresentationStruct.x_axis,app.RepresentationStruct.y_axis,'b','LineWidth',2, 'Parent', app.RepresentationAxes);
            plot(app.RepresentationStruct.x_maj2, app.RepresentationStruct.y_maj2,'b','LineWidth',2, 'Parent', app.RepresentationAxes);
            hold(app.RepresentationAxes, 'off');

            set(app.AreaText,'FontColor','b');
            set(app.MetricDescription,'Text', 'Orientation, angle between blue lines');
            set(app.PerimeterText,'FontColor','b');
            set(app.P2AText,'FontColor','b');
            set(app.EccentricityText,'FontColor','b');
            set(app.MajAxisLengthText,'FontColor','b');
            set(app.MinAxisLengthText,'FontColor','b');
            set(app.OrientationText,'FontColor','r');
            set(app.SolidityText,'FontColor','b');
            set(app.ExtentText,'FontColor','b');
            set(app.ConvexAreaText,'FontColor','b');
            
        end
        function solidity_rep_callback(app)
            cla(app.RepresentationAxes)
            convexArea_mask = app.RepresentationStruct.convexArea_mask;
            imshow(app.RepresentationStruct.crop,[], 'Parent', app.RepresentationAxes)
            hold(app.RepresentationAxes, 'on');
            imArea = imshow(app.RepresentationStruct.area_color, 'Parent', app.RepresentationAxes);
            set(imArea,'AlphaData',convexArea_mask)
            red = cat(3, ones(size(convexArea_mask)),zeros(size(convexArea_mask)), zeros(size(convexArea_mask)));
            imGrid = imshow(red, 'Parent', app.RepresentationAxes);
            set(imGrid,'AlphaData',app.RepresentationStruct.grid2);
            hold(app.RepresentationAxes, 'off');

            set(app.AreaText,'FontColor','b');
            set(app.MetricDescription,'Text','Solidity, blue divided by red');
            set(app.PerimeterText,'FontColor','b');
            set(app.P2AText,'FontColor','b');
            set(app.EccentricityText,'FontColor','b');
            set(app.MajAxisLengthText,'FontColor','b');
            set(app.MinAxisLengthText,'FontColor','b');
            set(app.OrientationText,'FontColor','b');
            set(app.SolidityText,'FontColor','r');
            set(app.ExtentText,'FontColor','b');
            set(app.ConvexAreaText,'FontColor','b');
            
        end
        function extent_rep_callback(app)
            cla(app.RepresentationAxes)
            area_mask = app.RepresentationStruct.area_mask;
            imshow(app.RepresentationStruct.crop,[], 'Parent', app.RepresentationAxes)
            hold(app.RepresentationAxes, 'on');
            imArea = imshow(app.RepresentationStruct.area_color, 'Parent', app.RepresentationAxes);
            set(imArea,'AlphaData',area_mask)
            red = cat(3, ones(size(area_mask)),zeros(size(area_mask)), zeros(size(area_mask)));
            imGrid = imshow(red, 'Parent', app.RepresentationAxes);
            set(imGrid,'AlphaData',app.RepresentationStruct.grid);
            hold(app.RepresentationAxes, 'off');

            set(app.AreaText,'FontColor','b');
            set(app.MetricDescription,'Text','Extent, blue divided by red');
            set(app.PerimeterText,'FontColor','b');
            set(app.P2AText,'FontColor','b');
            set(app.EccentricityText,'FontColor','b');
            set(app.MajAxisLengthText,'FontColor','b');
            set(app.MinAxisLengthText,'FontColor','b');
            set(app.OrientationText,'FontColor','b');
            set(app.SolidityText,'FontColor','b');
            set(app.ExtentText,'FontColor','r');
            set(app.ConvexAreaText,'FontColor','b');
            
        end
        function convexArea_rep_callback(app)
            cla(app.RepresentationAxes)
            imshow(app.RepresentationStruct.crop,[], 'Parent', app.RepresentationAxes)
            hold(app.RepresentationAxes, 'on');
            imArea = imshow(app.RepresentationStruct.convexArea_color, 'Parent', app.RepresentationAxes);
            hold(app.RepresentationAxes, 'off');
            set(imArea,'AlphaData',app.RepresentationStruct.convexArea_mask);

            set(app.AreaText,'FontColor','b');
            set(app.MetricDescription,'Text','Convex Area');
            set(app.PerimeterText,'FontColor','b');
            set(app.P2AText,'FontColor','b');
            set(app.EccentricityText,'FontColor','b');
            set(app.MajAxisLengthText,'FontColor','b');
            set(app.MinAxisLengthText,'FontColor','b');
            set(app.OrientationText,'FontColor','b');
            set(app.SolidityText,'FontColor','b');
            set(app.ExtentText,'FontColor','b');
            set(app.ConvexAreaText,'FontColor','r');
            
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function metricSelectionOpen(app, caller, metricsSelected, representationStruct)
            app.CallingApp = caller;
            app.MetricsSelected = metricsSelected;
            app.CircularityCheckBox.Value = app.MetricsSelected.Circularity;
            app.AreaCheckBox.Value = app.MetricsSelected.Area;
            app.PerimeterCheckBox.Value = app.MetricsSelected.Perimeter;
            app.PerimeterToAreaRatioCheckBox.Value = app.MetricsSelected.PerimeterToAreaRatio;
            app.EccentricityCheckBox.Value = app.MetricsSelected.Eccentricity;
            app.MajorAxisLengthCheckBox.Value = app.MetricsSelected.MajorAxisLength;
            app.MinorAxisLengthCheckBox.Value = app.MetricsSelected.MinorAxisLength;
            app.ConvexAreaCheckBox.Value = app.MetricsSelected.ConvexArea;
            app.ExtentCheckBox.Value = app.MetricsSelected.Extent;
            app.OrientationCheckBox.Value = app.MetricsSelected.Orientation;
            app.SolidityCheckBox.Value = app.MetricsSelected.Solidity;
            app.ZCheckBox.Value = app.MetricsSelected.Z;
            app.ACheckBox.Value = app.MetricsSelected.A;
            app.PhiCheckBox.Value = app.MetricsSelected.Phi;
            app.MeanPixelIntensityCheckBox.Value = app.MetricsSelected.MeanPixelIntensity;
            app.StdPixelIntensityCheckBox.Value = app.MetricsSelected.StdPixelIntensity;
            app.CVPixelIntensityCheckBox.Value = app.MetricsSelected.CVPixelIntensity;
            app.KurtosisCheckBox.Value = app.MetricsSelected.Kurtosis;
            app.SkewnessCheckBox.Value = app.MetricsSelected.Skewness;
            app.ContrastCheckBox.Value = app.MetricsSelected.Contrast;
            app.CorrelationCheckBox.Value = app.MetricsSelected.Correlation;
            app.EnergyCheckBox.Value = app.MetricsSelected.Energy;
            app.HomogeneityCheckBox.Value = app.MetricsSelected.Homogeneity;
            app.DiameterCheckBox.Value = app.MetricsSelected.EquivDiameter;
            app.RepresentationStruct = representationStruct;

             set(app.AreaText,'FontColor','b');
            set(app.MetricDescription,'Text','');
            set(app.PerimeterText,'FontColor','b');
            set(app.P2AText,'FontColor','b');
            set(app.EccentricityText,'FontColor','b');
            set(app.MajAxisLengthText,'FontColor','b');
            set(app.MinAxisLengthText,'FontColor','b');
            set(app.OrientationText,'FontColor','b');
            set(app.SolidityText,'FontColor','b');
            set(app.ExtentText,'FontColor','b');
            set(app.ConvexAreaText,'FontColor','b');
            

            imshow(app.RepresentationStruct.crop,[], 'Parent', app.RepresentationAxes)
          
        end

        % Close request function: UIFigure
        function closeMetrics(app, event)
            %Send data back to main app
            %try
                updateMetricsSelected(app.CallingApp, app.MetricsSelected)
                app.CallingApp.SegmentCurrentButton.Enable = "on";
                app.CallingApp.ExportSetButton.Enable = "on";
                app.CallingApp.MetricsMenu.Enable = "on";
    
                delete(app)
            %catch
                %delete(app)
            %end
            
        end

        % Value changed function: CircularityCheckBox
        function circularirtyCallback(app, event)
            value = app.CircularityCheckBox.Value;
            app.MetricsSelected.Circularity = value;
        end

        % Value changed function: AreaCheckBox
        function AreaCheckBoxValueChanged(app, event)
           %If deselecting area, deselect p2a (will only be calculated if perimeter and area are as well) 
            value = app.AreaCheckBox.Value;
            app.MetricsSelected.Area = value;
            area_rep_callback(app)
            
            if value == 0
                app.PerimeterToAreaRatioCheckBox.Value = 0;
                
                app.MetricsSelected.PerimeterToAreaRatio = 0;
                
            end
            
        end

        % Value changed function: PerimeterCheckBox
        function PerimeterCheckBoxValueChanged(app, event)
            %If deselecting perimeter, deselect p2a (will only be calculated if perimeter and area are as well) 
            value = app.PerimeterCheckBox.Value;
            app.MetricsSelected.Perimeter = value;
            perimeter_rep_callback(app)
            
            if value == 0
                app.PerimeterToAreaRatioCheckBox.Value = 0;
                
                app.MetricsSelected.PerimeterToAreaRatio = 0;
                
            end
            
        end

        % Value changed function: PerimeterToAreaRatioCheckBox
        function PerimeterToAreaRatioCheckBoxValueChanged(app, event)
            %If selecting p2a, select area and perimeter (will only be calculated if perimeter and area are as well) 
            value = app.PerimeterToAreaRatioCheckBox.Value;
            p2a_rep_callback(app)
            app.MetricsSelected.PerimeterToAreaRatio = value;
            if value == 1
                app.PerimeterCheckBox.Value = 1;
                app.MetricsSelected.Perimeter = 1;
                
                app.AreaCheckBox.Value = 1;
                app.MetricsSelected.Perimeter = 1;
                app.MetricsSelected.Area = 1;
                
            end
        end

        % Value changed function: EccentricityCheckBox
        function EccentricityCheckBoxValueChanged(app, event)
            value = app.EccentricityCheckBox.Value;
            app.MetricsSelected.Eccentricity = value;
            eccentricity_rep_callback(app)
        end

        % Value changed function: MajorAxisLengthCheckBox
        function MajorAxisLengthCheckBoxValueChanged(app, event)
            value = app.MajorAxisLengthCheckBox.Value;
            app.MetricsSelected.MajorAxisLength = value;
            majAxis_rep_callback(app)
        end

        % Value changed function: MinorAxisLengthCheckBox
        function MinorAxisLengthCheckBoxValueChanged(app, event)
            value = app.MinorAxisLengthCheckBox.Value;
            app.MetricsSelected.MinorAxisLength = value;
            minAxis_rep_callback(app)
        end

        % Value changed function: ConvexAreaCheckBox
        function ConvexAreaCheckBoxValueChanged(app, event)
            value = app.ConvexAreaCheckBox.Value;
            app.MetricsSelected.ConvexArea = value;
            convexArea_rep_callback(app)
        end

        % Value changed function: ExtentCheckBox
        function ExtentCheckBoxValueChanged(app, event)
            value = app.ExtentCheckBox.Value;
            app.MetricsSelected.Extent = value;
            extent_rep_callback(app)
        end

        % Value changed function: OrientationCheckBox
        function OrientationCheckBoxValueChanged(app, event)
            value = app.OrientationCheckBox.Value;
            app.MetricsSelected.Orientation = value;
            orientation_rep_callback(app)
        end

        % Value changed function: SolidityCheckBox
        function SolidityCheckBoxValueChanged(app, event)
            value = app.SolidityCheckBox.Value;
            app.MetricsSelected.Solidity = value;
            solidity_rep_callback(app)
        end

        % Value changed function: ZCheckBox
        function ZCheckBoxValueChanged(app, event)
            value = app.ZCheckBox.Value;
            app.MetricsSelected.Z = value;
        end

        % Value changed function: ACheckBox
        function ACheckBoxValueChanged(app, event)
            value = app.ACheckBox.Value;
            app.MetricsSelected.A = value;
        end

        % Value changed function: PhiCheckBox
        function PhiCheckBoxValueChanged(app, event)
            value = app.PhiCheckBox.Value;
            app.MetricsSelected.Phi = value;
        end

        % Value changed function: MeanPixelIntensityCheckBox
        function MeanPixelIntensityCheckBoxValueChanged(app, event)
            value = app.MeanPixelIntensityCheckBox.Value;
            app.MetricsSelected.MeanPixelIntensity = value;
        end

        % Value changed function: StdPixelIntensityCheckBox
        function StdPixelIntensityCheckBoxValueChanged(app, event)
            value = app.StdPixelIntensityCheckBox.Value;
            app.MetricsSelected.StdPixelIntensity = value;
        end

        % Value changed function: CVPixelIntensityCheckBox
        function CVPixelIntensityCheckBoxValueChanged(app, event)
            value = app.CVPixelIntensityCheckBox.Value;
            app.MetricsSelected.CVPixelIntensity = value;
        end

        % Value changed function: SkewnessCheckBox
        function SkewnessCheckBoxValueChanged(app, event)
            value = app.SkewnessCheckBox.Value;
            app.MetricsSelected.Skewness = value;
        end

        % Value changed function: KurtosisCheckBox
        function KurtosisCheckBoxValueChanged(app, event)
            value = app.KurtosisCheckBox.Value;
            app.MetricsSelected.Kurtosis = value;
        end

        % Value changed function: ContrastCheckBox
        function ContrastCheckBoxValueChanged(app, event)
            value = app.ContrastCheckBox.Value;
            app.MetricsSelected.Contrast = value;
        end

        % Value changed function: CorrelationCheckBox
        function CorrelationCheckBoxValueChanged(app, event)
            value = app.CorrelationCheckBox.Value;
            app.MetricsSelected.Correlation = value;
        end

        % Value changed function: EnergyCheckBox
        function EnergyCheckBoxValueChanged(app, event)
            value = app.EnergyCheckBox.Value;
            app.MetricsSelected.Energy = value;
        end

        % Value changed function: HomogeneityCheckBox
        function HomogeneityCheckBoxValueChanged(app, event)
            value = app.HomogeneityCheckBox.Value;
            app.MetricsSelected.Homogeneity = value;
        end

        % Value changed function: DiameterCheckBox
        function DiameterCheckBoxValueChanged(app, event)
            value = app.DiameterCheckBox.Value;
            app.MetricsSelected.EquivDiameter = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeMetrics, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.RepresentationAxes = uiaxes(app.GridLayout);
            app.RepresentationAxes.XLimitMethod = 'tight';
            app.RepresentationAxes.YLimitMethod = 'tight';
            app.RepresentationAxes.ZLimitMethod = 'tight';
            app.RepresentationAxes.XTick = [];
            app.RepresentationAxes.YTick = [];
            app.RepresentationAxes.Visible = 'off';
            app.AreaCheckBox = uicheckbox(app.GridLayout);
            app.AreaCheckBox.ValueChangedFcn = createCallbackFcn(app, @AreaCheckBoxValueChanged, true);
            app.AreaCheckBox.Text = 'Area';
            app.PerimeterCheckBox = uicheckbox(app.GridLayout);
            app.PerimeterCheckBox.ValueChangedFcn = createCallbackFcn(app, @PerimeterCheckBoxValueChanged, true);
            app.PerimeterCheckBox.Text = 'Perimeter';
            app.PerimeterToAreaRatioCheckBox = uicheckbox(app.GridLayout);
            app.PerimeterToAreaRatioCheckBox.ValueChangedFcn = createCallbackFcn(app, @PerimeterToAreaRatioCheckBoxValueChanged, true);
            app.PerimeterToAreaRatioCheckBox.Text = 'Perimeter to Area Ratio';
            app.EccentricityCheckBox = uicheckbox(app.GridLayout);
            app.EccentricityCheckBox.ValueChangedFcn = createCallbackFcn(app, @EccentricityCheckBoxValueChanged, true);
            app.EccentricityCheckBox.Text = 'Eccentricity';
            app.MajorAxisLengthCheckBox = uicheckbox(app.GridLayout);
            app.MajorAxisLengthCheckBox.ValueChangedFcn = createCallbackFcn(app, @MajorAxisLengthCheckBoxValueChanged, true);
            app.MajorAxisLengthCheckBox.Text = 'Maj. Axis Length';
            app.MinorAxisLengthCheckBox = uicheckbox(app.GridLayout);
            app.MinorAxisLengthCheckBox.ValueChangedFcn = createCallbackFcn(app, @MinorAxisLengthCheckBoxValueChanged, true);
            app.MinorAxisLengthCheckBox.Text = 'Min. Axis Length';
            app.ConvexAreaCheckBox = uicheckbox(app.GridLayout);
            app.ConvexAreaCheckBox.ValueChangedFcn = createCallbackFcn(app, @ConvexAreaCheckBoxValueChanged, true);
            app.ConvexAreaCheckBox.Text = 'Convex Area';
            app.ExtentCheckBox = uicheckbox(app.GridLayout);
            app.ExtentCheckBox.ValueChangedFcn = createCallbackFcn(app, @ExtentCheckBoxValueChanged, true);
            app.ExtentCheckBox.Text = 'Extent';
            app.OrientationCheckBox = uicheckbox(app.GridLayout);
            app.OrientationCheckBox.ValueChangedFcn = createCallbackFcn(app, @OrientationCheckBoxValueChanged, true);
            app.OrientationCheckBox.Text = 'Orientation';
            app.SolidityCheckBox = uicheckbox(app.GridLayout);
            app.SolidityCheckBox.ValueChangedFcn = createCallbackFcn(app, @SolidityCheckBoxValueChanged, true);
            app.SolidityCheckBox.Text = 'Solidity';
            app.ZCheckBox = uicheckbox(app.GridLayout);
            app.ZCheckBox.ValueChangedFcn = createCallbackFcn(app, @ZCheckBoxValueChanged, true);
            app.ZCheckBox.Text = 'Z';
            app.ACheckBox = uicheckbox(app.GridLayout);
            app.ACheckBox.ValueChangedFcn = createCallbackFcn(app, @ACheckBoxValueChanged, true);
            app.ACheckBox.Text = 'A';
            app.PhiCheckBox = uicheckbox(app.GridLayout);
            app.PhiCheckBox.ValueChangedFcn = createCallbackFcn(app, @PhiCheckBoxValueChanged, true);
            app.PhiCheckBox.Text = 'Phi';
            app.MeanPixelIntensityCheckBox = uicheckbox(app.GridLayout);
            app.MeanPixelIntensityCheckBox.ValueChangedFcn = createCallbackFcn(app, @MeanPixelIntensityCheckBoxValueChanged, true);
            app.MeanPixelIntensityCheckBox.Text = 'Mean Pixel Intensity';
            app.StdPixelIntensityCheckBox = uicheckbox(app.GridLayout);
            app.StdPixelIntensityCheckBox.ValueChangedFcn = createCallbackFcn(app, @StdPixelIntensityCheckBoxValueChanged, true);
            app.StdPixelIntensityCheckBox.Text = 'Std of Pixel Intensity';
            app.CVPixelIntensityCheckBox = uicheckbox(app.GridLayout);
            app.CVPixelIntensityCheckBox.ValueChangedFcn = createCallbackFcn(app, @CVPixelIntensityCheckBoxValueChanged, true);
            app.CVPixelIntensityCheckBox.Text = 'C.V. of Pixel Intensity';
            app.SkewnessCheckBox = uicheckbox(app.GridLayout);
            app.SkewnessCheckBox.ValueChangedFcn = createCallbackFcn(app, @SkewnessCheckBoxValueChanged, true);
            app.SkewnessCheckBox.Text = 'Skewness';
            app.KurtosisCheckBox = uicheckbox(app.GridLayout);
            app.KurtosisCheckBox.ValueChangedFcn = createCallbackFcn(app, @KurtosisCheckBoxValueChanged, true);
            app.KurtosisCheckBox.Text = 'Kurtosis';
            app.ContrastCheckBox = uicheckbox(app.GridLayout);
            app.ContrastCheckBox.ValueChangedFcn = createCallbackFcn(app, @ContrastCheckBoxValueChanged, true);
            app.ContrastCheckBox.Text = 'Contrast';
            app.CorrelationCheckBox = uicheckbox(app.GridLayout);
            app.CorrelationCheckBox.ValueChangedFcn = createCallbackFcn(app, @CorrelationCheckBoxValueChanged, true);
            app.CorrelationCheckBox.Text = 'Correlation';
            app.EnergyCheckBox = uicheckbox(app.GridLayout);
            app.EnergyCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnergyCheckBoxValueChanged, true);
            app.EnergyCheckBox.Text = 'Energy';
            app.HomogeneityCheckBox = uicheckbox(app.GridLayout);
            app.HomogeneityCheckBox.ValueChangedFcn = createCallbackFcn(app, @HomogeneityCheckBoxValueChanged, true);
            app.HomogeneityCheckBox.Text = 'Homogeneity';
            app.DiameterCheckBox = uicheckbox(app.GridLayout);
            app.DiameterCheckBox.ValueChangedFcn = createCallbackFcn(app, @DiameterCheckBoxValueChanged, true);
            app.DiameterCheckBox.Text = 'Diameter';
            app.CircularityCheckBox = uicheckbox(app.GridLayout);
            app.CircularityCheckBox.ValueChangedFcn = createCallbackFcn(app, @circularirtyCallback, true);
            app.CircularityCheckBox.Text = 'Circularity';
            app.DescriptionLabel = uilabel(app.GridLayout);
            app.DescriptionLabel.Text = 'Description';
            app.DiameterText = uilabel(app.GridLayout);
            app.DiameterText.Text = 'Diameter of a circle with same area as segmented region';
            app.P2AText = uilabel(app.GridLayout);
            app.P2AText.Text = 'Ratio of perimeter divided by area';
            app.PerimeterText = uilabel(app.GridLayout);
            app.PerimeterText.Text = 'Distance around boundary of segmented region';
            app.EccentricityText = uilabel(app.GridLayout);
            app.EccentricityText.Text = 'Ratio of the distance between foci of ellipse and major axis length';
            app.AreaText = uilabel(app.GridLayout);
            app.AreaText.Text = 'Number of pixels in segmented region';
            app.MetricLabel = uilabel(app.GridLayout);
            app.MetricLabel.Text = 'Metric';
            app.AText = uilabel(app.GridLayout);
            app.AText.Text = 'Amplitude of complex Zernike moment of 4th order shape';
            app.EnergyText = uilabel(app.GridLayout);
            app.EnergyText.Text = 'Dominance of adjacent pixel combinations';
            app.CorrelationText = uilabel(app.GridLayout);
            app.CorrelationText.Text = 'Correlation of pixel and neighbor';
            app.SolidityText = uilabel(app.GridLayout);
            app.SolidityText.Text = 'Ratio of area to convex area';
            app.ZText = uilabel(app.GridLayout);
            app.ZText.Text = 'Imaginary component of complex Zernike moment of 4th order shape';
            app.ContrastText = uilabel(app.GridLayout);
            app.ContrastText.Text = 'Intensity contrast between pixel and neighor (i.e., variance)';
            app.MajAxisLengthText = uilabel(app.GridLayout);
            app.MajAxisLengthText.Text = 'Major axis length of ellipse with same second central moments as segmented region';
            app.KurtosisText = uilabel(app.GridLayout);
            app.KurtosisText.Text = 'Kurtosis pixel intesity in segmented region';
            app.MinAxisLengthText = uilabel(app.GridLayout);
            app.MinAxisLengthText.Text = 'Minor axis length of ellipse with same second central moments as segmented region';
            app.SkewnessText = uilabel(app.GridLayout);
            app.SkewnessText.Text = ' Skewness of pixel intesity in segmented region';
            app.ConvexAreaText = uilabel(app.GridLayout);
            app.ConvexAreaText.Text = 'Number of pixels in convex hull (smallest convex polygon containing segmented region)';
            app.CVPixelIntensityText = uilabel(app.GridLayout);
            app.CVPixelIntensityText.Text = 'Coefficient of Variation of pixel intesity in segmented region';
            app.ExtentText = uilabel(app.GridLayout);
            app.ExtentText.Text = 'Ratio of pixels in segmented region to pixels in total bounding';
            app.StdPixelIntensityText = uilabel(app.GridLayout);
            app.StdPixelIntensityText.Text = 'Stadnard deviation pixel intesity in segmented region';
            app.OrientationText = uilabel(app.GridLayout);
            app.OrientationText.Text = 'Angle between x-axis and major axis';
            app.MeanPixelIntensityText = uilabel(app.GridLayout);
            app.MeanPixelIntensityText.Text = 'Average pixel intesity in segmented region';
            app.CircularirtyText = uilabel(app.GridLayout);
            app.CircularirtyText.Text = 'Roundness of segmented region';
            app.PhiText = uilabel(app.GridLayout);
            app.PhiText.Text = 'Angle of complex Zernike moment of 4th order shape';
            app.HomogeneityText = uilabel(app.GridLayout);
            app.HomogeneityText.Text = 'Likelihood of adjacent pixels being equal (i.e, smoothness)';
            app.VisualRepresentationLabel = uilabel(app.GridLayout);
            app.VisualRepresentationLabel.Text = 'Visual Representation';
            app.MetricDescription = uilabel(app.GridLayout);
            app.MetricDescription.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = metricSelectionUpdate(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)metricSelectionOpen(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end