classdef trackingAnalysisScene < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        OpenPreviouslyTrackedMenu      matlab.ui.container.Menu
        FluorescenceMenu               matlab.ui.container.Menu
        GridLayout                     matlab.ui.container.GridLayout
        ImageDisplayGrid               matlab.ui.container.GridLayout
        ShowIDsLabel                   matlab.ui.control.Label
        ShowIDsLabel_2                 matlab.ui.control.Label
        ShowIDsSwitchRight             matlab.ui.control.Switch
        ShowIDsSwitchLeft              matlab.ui.control.Switch
        FirstImageAxes                 matlab.ui.control.UIAxes
        SecondImageAxes                matlab.ui.control.UIAxes
        BottomGrid                     matlab.ui.container.GridLayout
        GridLayout3                    matlab.ui.container.GridLayout
        MaxGrowthEditField             matlab.ui.control.NumericEditField
        MaxGrowthEditFieldLabel        matlab.ui.control.Label
        MinGrowthEditField             matlab.ui.control.NumericEditField
        MinGrowthEditFieldLabel        matlab.ui.control.Label
        ManualInstructionsLabel        matlab.ui.control.Label
        RemoveSpheroidInSequence       matlab.ui.container.GridLayout
        RemoveFromSequenceButton       matlab.ui.control.Button
        ImagetoRemoveEditField         matlab.ui.control.NumericEditField
        ImagetoRemoveEditFieldLabel    matlab.ui.control.Label
        GridLayout2                    matlab.ui.container.GridLayout
        TrackButton                    matlab.ui.control.Button
        WhichOrganoidsExport           matlab.ui.control.DropDown
        ExportDataButton               matlab.ui.control.Button
        MaximumDistanceEditField       matlab.ui.control.NumericEditField
        MaximumDistanceEditFieldLabel  matlab.ui.control.Label
        UnmatchGrid                    matlab.ui.container.GridLayout
        UnmatchSpheroidsButton         matlab.ui.control.Button
        FirstWrongImageEditField       matlab.ui.control.NumericEditField
        FirstWrongImageLabel           matlab.ui.control.Label
        MatchGrid                      matlab.ui.container.GridLayout
        MatchSpheroidsButton           matlab.ui.control.Button
        Spheroid2EditField             matlab.ui.control.NumericEditField
        Spheroid2Label                 matlab.ui.control.Label
        LeftGrid                       matlab.ui.container.GridLayout
        TabGroup                       matlab.ui.container.TabGroup
        RegistrationTab                matlab.ui.container.Tab
        OrientationGrid                matlab.ui.container.GridLayout
        RegisterAllButton              matlab.ui.control.Button
        RegisterBatchButton            matlab.ui.control.Button
        RegistrationDropDown           matlab.ui.control.DropDown
        RegistrationDropDownLabel      matlab.ui.control.Label
        MaxIterations101000EditField   matlab.ui.control.NumericEditField
        MaxIterations101000EditFieldLabel  matlab.ui.control.Label
        DegreeofSmoothing053EditField  matlab.ui.control.NumericEditField
        DegreeofSmoothing053EditFieldLabel  matlab.ui.control.Label
        RegMethodGrid                  matlab.ui.container.GridLayout
        UseMaskLabel                   matlab.ui.control.Label
        UseRawImageLabel               matlab.ui.control.Label
        RegMethodSwitch                matlab.ui.control.Switch
        DoneRegisteringButton          matlab.ui.control.Button
        RegisterImagesGrid             matlab.ui.container.GridLayout
        NumWorkersEditField            matlab.ui.control.NumericEditField
        NumWorkersEditFieldLabel       matlab.ui.control.Label
        SortingTab                     matlab.ui.container.Tab
        SortingGrid                    matlab.ui.container.GridLayout
        AlphabeticalButton             matlab.ui.control.Button
        ApplyButton                    matlab.ui.control.Button
        SortingListBox                 matlab.ui.control.ListBox
        SortingDropDown                matlab.ui.control.DropDown
        ImageListGrids                 matlab.ui.container.GridLayout
        RightImageSelectList           matlab.ui.control.ListBox
        RightImageLabel                matlab.ui.control.Label
        LeftImageSelectList            matlab.ui.control.ListBox
        LeftImageLabel                 matlab.ui.control.Label
        SpheroidGrid                   matlab.ui.container.GridLayout
        SpheroidListBox                matlab.ui.control.ListBox
        SpheroidMontageAxes            matlab.ui.control.UIAxes
        AreaPlotAxes                   matlab.ui.control.UIAxes
        TrackingControlGrid            matlab.ui.container.GridLayout
    end

    
    properties (Access = private)
        CallingApp % Main OrganoSeg app
        

        %%% Data from original app for selected images 
        
        
        BulkI 
        ImgMasked 
        CC
        OldCC
        Resize
        MicronsPerPixel
        BoundingBox
        Centers
        MetricsSelected
        Filename
        BatchStarts
        RegisteredBatches


        %%%

        %%%Used for manual orientation 


        FirstImageFirstPoint = 0 %First selected point on left image
        FFMarker = 0 %Marker on image
        %FirstImageSecondPoint = 0
        %FSMarker = 0
        SecondImageFirstPoint = 0
        SFMarker = 0
        %SecondImageSecondPoint = 0
        %SSMarker = 0


        Orienting = true; %Says whether or not images are still being oriented/ registered
        XOffset = [] %For manual translation, offset required to align image with first image 
        YOffset = [] %""
        AdjustedCenters = {} %Centers with appropriate offset or displacement to align all images
        DisplacementFields = {} %For non rigid registration, Array containing transformation applied to align an image with the previous image
        TotalTform = {} % For rigid registration, transformation to align given image with the first image
        RegisteredBulkI %Images registered to align with first image
        OldRegistered = {} %Image mask with overlay of next image, for user to asses quality of registration
        RegisteredPerimeter = {} %Segmented perimeters registered to align with first image
        MaximumDistance %Parameter to set threshold for maximum distance between two matched organoids
        GoodOrganoids = {} %User-defined classifications for each organoid trace to be used in filtering
        Saved_states %used for disabling/enabling stuff
        MainSegmentColor
        LeftImageBatch = 1; %Batch of current image in left display
        RightImageBatch = 1; %Batch of current image in right display
        GroupConditions %Grouping information saying information that defines each batch
        GroupingTable %All grouping information to be used for sorting
        

        NumObjects %Total number of spheroids in each image
        Area %Area of each spheroid for each image

        XCoordsAdj %X coordinates after adjusting to align images
        YCoordsAdj %"" for y coordinates
        

        MatchingID %Array containing the associated ID that tracks spheroids in each image
        DoneTracking = false; %Boolean to say if spheroids have been tracked
        
        MostRecentExportFolder
        MostRecentSaveFolder
        FluorescenceScene

    end
    
    methods (Access = private)
        
        %Display image on left
        function displayFirstImage(app, selected)
            cla(app.FirstImageAxes); 
            
            imgMasked = app.ImgMasked{selected};
            axesChild = imshow(imgMasked,[], 'Parent', app.FirstImageAxes);
            axesChild.ButtonDownFcn = createCallbackFcn(app, @firstImageButtonDown, true);
            app.FirstImageAxes.Title.String = sprintf("Batch %d: Image %d", app.LeftImageBatch, selected - app.BatchStarts(app.LeftImageBatch) + 1);
            app.LeftImageSelectList.Value = selected;
            %Label spheroids with appropriate matching ID
            if app.DoneTracking
                if app.ShowIDsSwitchLeft.Value == 1
                    showIDLeft(app)
                end
                rectangle(app.FirstImageAxes, 'Position', [(size(imgMasked, 2) - 100 - app.MaximumDistance) (size(imgMasked, 1) - 30) app.MaximumDistance 10], 'FaceColor',[1 1 1]);
                text(app.FirstImageAxes, (size(imgMasked, 2) - 300), (size(imgMasked, 1) - 50), 'Maximum Distance', 'FontSize', 6, 'color', 'w') 
            end
            
            
        end

        %Display image on right 
        function displaySecondImage(app, selected)
            cla(app.SecondImageAxes); 
            app.RightImageSelectList.Value = selected;
            imgMasked = app.ImgMasked{selected};
            axesChild = imshow(imgMasked,[], 'Parent', app.SecondImageAxes);
            axesChild.ButtonDownFcn = createCallbackFcn(app, @secondImageButtonDown, true);
            app.SecondImageAxes.Title.String = sprintf("Batch %d: Image %d", app.RightImageBatch, selected - app.BatchStarts(app.RightImageBatch) + 1);
            %Label spheroids with appropriate matching ID
            if app.DoneTracking
                
                if app.ShowIDsSwitchRight.Value == 1
                    ShowIDRight(app)
                end
                
            end


        end


        %Show the selected spheroid across all images
        function displaySpheroidMontage(app, spheroid)
            app.SpheroidListBox.Value = spheroid;
            [startInd, endInd] = getBatchEnds(app, app.LeftImageBatch, app.BatchStarts, length(app.BulkI));
            imageArray = cell(1, length(startInd:endInd));

            areas = zeros(1, length(startInd:endInd));

            idsInImageCC = zeros(1, length(startInd:endInd));
            largestBB_X = 0;
            largestBB_Y = 0;
            %Get the largest bbox in montage so all crops in montage can
            %be same size and contain whole spheroid
            

            for ind = 1:length(startInd:endInd)
                selected = ind+startInd - 1;

                idInImageCC = find(app.MatchingID{selected} == spheroid);
                
                if ~isempty(idInImageCC)
                    idsInImageCC(ind) = idInImageCC;
                    %Get crop of spheroid in image
                    bbox = app.BoundingBox{selected}{idInImageCC};
                    bbox_X = bbox(3) + 10;
                    bbox_Y = bbox(4) + 10;
                    if bbox_X > largestBB_X
                        largestBB_X = bbox_X;
                    end
                    if bbox_Y > largestBB_Y
                        largestBB_Y = bbox_Y;
                    end
                end

            end


            for ind = 1:length(startInd:endInd)
                selected = ind+startInd - 1;
                idInImageCC = idsInImageCC(ind);
                
                if idInImageCC ~= 0
                    %Get crop of spheroid in image
                    bbox = app.BoundingBox{selected}{idInImageCC};
                    center = zeros(1,2);
                    center(1:2) = bbox(1:2) + bbox(3:4)/2;
                    %adjust image bounding box to expand from center of box
                    %and fit largest bbox
                    bbox(1:2) = [round(center(1) - largestBB_X/2) round(center(2) - largestBB_Y/2)];
                    bbox(3:4) = [largestBB_X largestBB_Y];


                    img = app.ImgMasked{selected};


                    cropped = imcrop(img, bbox);


                    imageArray{ind} = cropped;

                    %Get areas to show graph of area change over time
                    areas(ind) = app.Area{selected}(idInImageCC);


                end
                nonZero = find(areas);

            end
            montage(imageArray, 'Parent', app.SpheroidMontageAxes)
            
            plot(nonZero, areas(nonZero), '-o', 'Parent', app.AreaPlotAxes);
            app.AreaPlotAxes.YLim = [0 inf];
            app.AreaPlotAxes.XLim = [0.5 length(startInd:endInd) + 0.5];
            app.AreaPlotAxes.XTick = 1:length(startInd:endInd);
            app.AreaPlotAxes.Visible = 1;
            
           
        end

        %Allow for selection of points for orientation
        function firstImageButtonDown(app, event)
            %Steps for manual registration
            if strcmp(app.RegistrationDropDown.Value, 'Manual')
            
                markerSize = size(app.BulkI{1});
                markerSize = round(markerSize(1)/80);
                [coords] = event.IntersectionPoint(1:2);

                if app.FirstImageFirstPoint == 0
                    
                    app.FFMarker = rectangle(app.FirstImageAxes, 'Position', [(coords(1,1) - markerSize/2) (coords(1,2)-markerSize/2) markerSize markerSize], 'Curvature', [1 1], 'FaceColor',[1 1 0]);
                %Replace first marker
                else
                    delete(app.FFMarker)
                    app.FFMarker = rectangle(app.FirstImageAxes, 'Position', [(coords(1,1) - markerSize/2) (coords(1,2)-markerSize/2) markerSize markerSize], 'Curvature', [1 1], 'FaceColor',[1 1 0]);

                end
                app.FirstImageFirstPoint = coords;

                %If enough points selected, display the overlay to asses
                %alignment
                
                if app.FirstImageFirstPoint ~= 0 & app.SecondImageFirstPoint ~= 0 % & app.PointsSwitch.Value == 1
                    selected = app.RightImageSelectList.Value;
                    
                    xOffset = app.FirstImageFirstPoint(1,1) - app.SecondImageFirstPoint(1,1);
                    yOffset = app.FirstImageFirstPoint(1,2) - app.SecondImageFirstPoint(1,2);
                    secondImageCCLabel = labelmatrix(app.CC{selected});
                    perim = bwperim(secondImageCCLabel);
                    translation = [xOffset yOffset];
                    perim = imtranslate(perim, translation);
                    perim = imdilate(perim,ones(3,3));
                    tempImgMasked = imoverlay(app.ImgMasked{selected-1},perim,[1 0 1]);
        
                    cla(app.FirstImageAxes)
                    axesChild = imshow(tempImgMasked,[], 'Parent', app.FirstImageAxes);
                    axesChild.ButtonDownFcn = createCallbackFcn(app, @firstImageButtonDown, true);
                    app.FFMarker = rectangle(app.FirstImageAxes, 'Position', [(app.FirstImageFirstPoint(1,1) - markerSize/2) (app.FirstImageFirstPoint(1,2)-markerSize/2) markerSize markerSize], 'Curvature', [1 1], 'FaceColor',[1 1 0]);
                end
            elseif app.DoneTracking & app.ShowIDsSwitchLeft.Value == 0
                selected = app.LeftImageSelectList.Value;
                
                centers_display = app.Centers{selected};
    
                
                spherNum = 0;
                [coords] = event.IntersectionPoint(1:2);
                %Display corresponding global organoid ID
                for i = 1:length(app.CC{selected}.PixelIdxList)
                    %account for removed spheroids
                    if (~isnan(centers_display(i)) && ~isempty(app.CC{selected}.PixelIdxList))
                        spherNum = spherNum + 1;
                    % find if selected point is inside of spheroid
                        
                        if any(app.CC{selected}.PixelIdxList{i}==sub2ind(size(app.BulkI{selected}),round(coords(1,2)),round(coords(1,1))))
                            %Adjust to follow matching ID if from tracking
                            
                            spherNum = app.MatchingID{selected}(spherNum);
                            
                            text(app.FirstImageAxes, centers_display(i,1)-5, centers_display(i,2), num2str(spherNum), 'color', 'w');
                            break
                            
                        end
                    end
                end
            end
            
        end

        function secondImageButtonDown(app, event)
             %Steps for manual registration

            if strcmp(app.RegistrationDropDown.Value, 'Manual')
                [coords] = event.IntersectionPoint(1:2);
                markerSize = size(app.BulkI{1});
                markerSize = round(markerSize(1)/80);

                if app.SecondImageFirstPoint == 0
                    
                    app.SFMarker = rectangle(app.SecondImageAxes, 'Position', [(coords(1,1) - markerSize/2) (coords(1,2)-markerSize/2) markerSize markerSize], 'Curvature', [1 1], 'FaceColor',[1 1 0]);
                %Replace second marker
                else
                    delete(app.SFMarker)
                    app.SFMarker = rectangle(app.SecondImageAxes, 'Position', [(coords(1,1) - markerSize/2) (coords(1,2)-markerSize/2) markerSize markerSize], 'Curvature', [1 1], 'FaceColor',[1 1 0]);

                end
                app.SecondImageFirstPoint = coords;


                %If enough points selected, display the overlay to asses
                %alignment
                
                if  app.FirstImageFirstPoint ~= 0 & app.SecondImageFirstPoint ~= 0 % &app.PointsSwitch.Value == 1
                    selected = app.RightImageSelectList.Value;
                    
                    xOffset = app.FirstImageFirstPoint(1,1) - app.SecondImageFirstPoint(1,1);
                    yOffset = app.FirstImageFirstPoint(1,2) - app.SecondImageFirstPoint(1,2);
                    secondImageCCLabel = labelmatrix(app.CC{selected});
                    perim = bwperim(secondImageCCLabel);
                    translation = [xOffset yOffset];
                    perim = imtranslate(perim, translation);
                    perim = imdilate(perim,ones(3,3));
                    tempImgMasked = imoverlay(app.ImgMasked{selected-1},perim,[1 0 1]);
        
                    cla(app.FirstImageAxes)
                    axesChild = imshow(tempImgMasked,[], 'Parent', app.FirstImageAxes);
                    axesChild.ButtonDownFcn = createCallbackFcn(app, @firstImageButtonDown, true);
                    app.FFMarker = rectangle(app.FirstImageAxes, 'Position', [(app.FirstImageFirstPoint(1,1) - markerSize/2) (app.FirstImageFirstPoint(1,2)-markerSize/2) markerSize markerSize], 'Curvature', [1 1], 'FaceColor',[1 1 0]);


                end

            elseif app.DoneTracking & app.ShowIDsSwitchRight.Value == 0
                selected = app.RightImageSelectList.Value;
                
                centers_display = app.Centers{selected};
    
                
                spherNum = 0;
                [coords] = event.IntersectionPoint(1:2);
                for i = 1:length(app.CC{selected}.PixelIdxList)
                    %account for removed spheroids
                    if (~isnan(centers_display(i)) && ~isempty(app.CC{selected}.PixelIdxList))
                        spherNum = spherNum + 1;
                    % find if selected point is inside of spheroid
                        
                        if any(app.CC{selected}.PixelIdxList{i}==sub2ind(size(app.BulkI{selected}),round(coords(1,2)),round(coords(1,1))))
                            %Adjust to follow matching ID if from tracking
                            
                            spherNum = app.MatchingID{selected}(spherNum);
                            
                            text(app.SecondImageAxes, centers_display(i,1)-5, centers_display(i,2), num2str(spherNum), 'color', 'w');
                            break
                            
                        end
                    end
                end
            end
            
        end


        %Prepare metrics for use in tracking and displaying area graph
        function getMetrics(app)
             app.Area = cell(1, length(app.BulkI));

            app.XCoordsAdj = cell(1, length(app.BulkI));
            app.YCoordsAdj = cell(1, length(app.BulkI));
            

            maxNumObjects = max(app.NumObjects);

            
            for selected = 1:length(app.BulkI)
                numObjects = app.NumObjects(selected);
                areas = zeros(1, maxNumObjects);

                xcoordsAdj = zeros(1, maxNumObjects);
                ycoordsAdj = zeros(1, maxNumObjects);
                

                cc = app.CC{selected};
                
                
                statsBW = regionprops(cc, 'Area');
                
                areas(1:numObjects) = [statsBW.Area];


                app.Area{selected} = areas;


                xcoordsAdj(1:numObjects) = app.AdjustedCenters{selected}(:,1);
                ycoordsAdj(1:numObjects) = app.AdjustedCenters{selected}(:,2);
                app.XCoordsAdj{selected} = xcoordsAdj;
                app.YCoordsAdj{selected} = ycoordsAdj;


            end
            
            
        end
        
        %Given two one dimensional vectors of length m and n, generate an m X n array 
        %when array(i,j) gives the absoulte difference between the ith
        %entry of the first vector and the jth entry of the second vector
        function differenceMatrix = findDifference(app, observations, current, prev, divide)
            numObservationsFirst = app.NumObjects(prev);
            numObservationsSecond = app.NumObjects(current);
            differenceMatrix = zeros(numObservationsFirst, numObservationsSecond);
            for i = 1:numObservationsFirst
                for j = 1:numObservationsSecond
                    if ~divide
                        differenceMatrix(i,j) = abs(observations(i,1) - observations(j,2));
                    else
                        differenceMatrix(i,j) = (observations(j,2)/observations(i,1));
                    end
                end
            end
        end
            
        
        %Align images using non-rigid registration (with the built in
        %imregdemons function) and calculate adjusted centers
        function nonRigidRegister(app, batches)
            
            parameters.NumIterations = app.MaxIterations101000EditField.Value;
            
            parameters.Smoothing = app.DegreeofSmoothing053EditField.Value;
            batchStarts = app.BatchStarts;
            
            
            parameters.NumImages = length(app.BulkI);
            parameters.RegMethod = app.RegMethodSwitch.Value;
            necessaryInfo.BulkI = app.BulkI;
            necessaryInfo.ImgMasked = app.ImgMasked;
            necessaryInfo.Centers = app.Centers;
            necessaryInfo.CC = app.CC;
            numWorkers = app.NumWorkersEditField.Value;

            outputs = nonRigidRegisterFunction(batchStarts,batches, numWorkers, parameters, necessaryInfo);

    
            for batch = 1:length(batches)
                [startInd, endInd] = getBatchEnds(app,batch, app.BatchStarts, length(app.BulkI));
                
                app.AdjustedCenters(startInd:endInd) = outputs(batch).adjustedCentersFull;
                app.RegisteredBulkI(startInd:endInd) = outputs(batch).registeredBulkIFull;
                app.DisplacementFields(startInd:endInd) = outputs(batch).displacementFieldsFull;
                app.RegisteredPerimeter(startInd:endInd) =outputs(batch).registeredPerimeterFull;
                
                app.OldRegistered(startInd:endInd) = outputs(batch).oldRegisteredFull;
            end
            app.RegisteredBatches(batches) = 1;


        end

        %Find the maximum number of pyramids allowed for imregdemons
       function maxPyr = getMaxPyramids(~, curr, base)
            dims = [size(curr), size(base)];
            minDim = min(dims);
            
            maxPyr = floor(log2(minDim, 2));
        end
        
        %Combine organoid traces based on user input
        function matchSpheroids(app, spheroid1ID, spheroid2ID)
            batch = app.LeftImageBatch;
            [startInd,endInd] = getBatchEnds(app, batch, app.BatchStarts, length(app.BulkI));
             
            for i = startInd:endInd
                matchingID = app.MatchingID{i};
                ind2 = find(matchingID == spheroid2ID);
                ind1 = find(matchingID == spheroid1ID);
                %Only join image from second trace if it is missing from
                %first
                if ~isempty(ind2) & isempty(ind1)
                   matchingID(ind2(1)) = spheroid1ID;
                end
                app.MatchingID{i} = matchingID;

            end


        end

        %Split organoid trace based on user input
        function unmatchSpheroids(app, spheroid1ID, firstNewImage)
            batch = app.LeftImageBatch;
            [startInd,endInd] = getBatchEnds(app, batch, app.BatchStarts, length(app.BulkI));
            app.GoodOrganoids{batch} = [app.GoodOrganoids{batch} 2];
            
            newID = getMaxMatchingID(app, batch) + 1;
            for i = startInd+firstNewImage-1:endInd
                matchingID = app.MatchingID{i};
                ind = find(matchingID == spheroid1ID);
                if ~isempty(ind)
                   matchingID(ind(1)) = newID;
                end
                app.MatchingID{i} = matchingID;
            end
            app.GoodOrganoids{batch} = app.GoodOrganoids{batch}(1:getMaxMatchingID(app, batch));


            updateSpheroidList(app, batch)


        end

        %Remove specific instance from organoid trace
        function removeSpheroid(app, spheroid1ID, wrongImage)
            
            batch = app.LeftImageBatch;
            [startInd,endInd] = getBatchEnds(app, batch, app.BatchStarts, length(app.BulkI));
            wrongImage = wrongImage + startInd - 1;
            app.GoodOrganoids{batch} = [app.GoodOrganoids{batch} 2];
            
            newID = getMaxMatchingID(app, batch) + 1;
            
            matchingID = app.MatchingID{wrongImage};
            ind = find(matchingID == spheroid1ID);
            if ~isempty(ind)
               matchingID(ind(1)) = newID;
            end
            app.MatchingID{wrongImage} = matchingID;
           
            app.GoodOrganoids{batch} = app.GoodOrganoids{batch}(1:getMaxMatchingID(app, batch));


           updateSpheroidList(app, batch)
        end

        
        %Find maximum global ID in batch
        function maxNum = getMaxMatchingID(app, batch)
            maxNum = 0;
            [startInd,endInd] = getBatchEnds(app, batch, app.BatchStarts, length(app.BulkI));
            for i = startInd:endInd
                tempMax = max(app.MatchingID{i});
                if tempMax > maxNum
                    maxNum = tempMax;
                end
            end
            
        end
        
        %Rearrange metrics so that they are grouped by global Organoid ID
        %and not local ID
        function newMetric = rearrangeMetric(~, metric, matchingID, maxID)
            newMetric = zeros(1, maxID);
            for id = 1:maxID
                ind = find(matchingID == id);
                if ~isempty(ind)
                   
                    newMetric(id) = metric(ind);
                else
                    newMetric(id) = nan;
                end
            end
            
        end
        
        function reenableStuff(app)

            h= findobj(app.UIFigure,{'Type', 'uibutton', '-or','Type', 'uinumericeditfield', '-or', 'Type', 'uimenu', '-or', 'Type', 'uicheckbox','-or', 'Type', 'uidropdown', '-or', 'Type', 'uiswitch', '-or', 'Type', 'uilistbox'});
            %https://www.mathworks.com/matlabcentral/answers/1697425-is-there-an-easier-way-to-disable-all-the-buttons-in-matlab-app-designer
            
            %if ~isempty(app.Saved_states)
            for obj = 1:length(h)
                h(obj).Enable = app.Saved_states{obj};
            end
        end


    %Clear alignment data
        function resetBasePoints(app)
            if app.FFMarker ~= 0
                delete(app.FFMarker)
            end

            if app.SFMarker ~= 0
                delete(app.SFMarker)
            end

            app.FirstImageFirstPoint = 0;
            %app.FirstImageSecondPoint = 0;
            app.SecondImageFirstPoint = 0;
            %app.SecondImageSecondPoint = 0;
            
            displayFirstImage(app, app.LeftImageSelectList.Value)
        end


        function rigidRegisterFunc(app, batches)

            batchStarts = app.BatchStarts;
            
            
            parameters.RegMethod = app.RegMethodSwitch.Value;
            necessaryInfo.BulkI = app.BulkI;
            necessaryInfo.ImgMasked = app.ImgMasked;
            necessaryInfo.Centers = app.Centers;
            necessaryInfo.CC = app.CC;
            parameters.RegistrationType = app.RegistrationDropDown.Value;
            
            numWorkers = app.NumWorkersEditField.Value;
            outputs = rigidRegisterFunction(batchStarts,batches, numWorkers, parameters, necessaryInfo);


            for batch = 1:length(batches)
                [startInd, endInd] = getBatchEnds(app,batch, app.BatchStarts, length(app.BulkI));
                
                app.AdjustedCenters(startInd:endInd) = outputs(batch).adjustedCentersFull;
                
                app.RegisteredBulkI(startInd:endInd) = outputs(batch).registeredBulkIFull;
                app.TotalTform(startInd:endInd) = outputs(batch).totalTformFull;
                app.RegisteredPerimeter(startInd:endInd) = outputs(batch).registeredPerimeterFull;
                
                app.OldRegistered(startInd:endInd) = outputs(batch).oldRegisteredFull;
            end
            app.RegisteredBatches(batches) = 1;
            
            %close(h)


        end

        %Find start and end indices of selected batch
        function [startInd, endInd] = getBatchEnds(app, batch, batchStarts, numImages)
            
            startInd = batchStarts(batch);
            
            if batch<length(batchStarts)
                endInd = batchStarts(batch+1)-1;
            else
                endInd = numImages;
            end
        end


        %Used when traces are modified (match, unmatch, remove) or for new
        %batch to be displayed
        function updateSpheroidList(app, batch)
            newID = getMaxMatchingID(app, batch);
            spheroid_str = cell(1, newID);
            
            for j = 1:newID
                spheroid_str{j} = num2str(j);  
                if app.GoodOrganoids{batch}(j) == 0
                    spheroid_str{j}= strcat(spheroid_str{j}, '_x');
                elseif app.GoodOrganoids{batch}(j) == 1
                    spheroid_str{j}= strcat(spheroid_str{j}, '_c');
                end
            end


            app.SpheroidListBox.ItemsData = 1:newID;
            app.SpheroidListBox.Items = spheroid_str;
        end

        %Used to sort images based on grpuping data
        function displaySortingList(app)
            variable = app.SortingDropDown.Value;
            if ~strcmp(variable, 'NA')
                [startInd, endInd] = getBatchEnds(app, 1, app.BatchStarts, length(app.BulkI));
                variableEntries = app.GroupingTable{startInd:endInd, variable};
                app.SortingListBox.Items = variableEntries;
                app.SortingListBox.ItemsData = 1:length(variableEntries);

            else
                app.SortingListBox = app.LeftImageSelectList;
                
            end
            app.SortingListBox.Multiselect = 1;
        end


        function sortData(app, order)
            % try
                bulkI = cell(1,length(app.BulkI));
                imgMasked = cell(1,length(app.BulkI));
                cc = cell(1,length(app.BulkI));
                oldCC = cell(1,length(app.BulkI));
                resize = cell(1,length(app.BulkI));
                boundingBox = cell(1,length(app.BulkI));
                centers = cell(1,length(app.BulkI));
                groupingTable = app.GroupingTable;
                filename = cell(1,length(app.BulkI));
                numObjects = zeros(1,length(app.BulkI));

                try
                    for batch = 1:length(app.BatchStarts)
                        [startInd, endInd] = getBatchEnds(app,batch, app.BatchStarts, length(app.BulkI));
        
                        bulkI(startInd:endInd) = app.BulkI(order+startInd - 1); 
                        imgMasked(startInd:endInd) = app.ImgMasked(order+startInd - 1);
                        %DisplayBulkI
                        cc(startInd:endInd) = app.CC(order+startInd - 1);
                        oldCC(startInd:endInd) = app.OldCC(order+startInd - 1);
                        resize(startInd:endInd) = app.Resize(order+startInd - 1);
                        
                        boundingBox(startInd:endInd) = app.BoundingBox(order+startInd - 1); 
                        %Spheroids_order
                        centers(startInd:endInd) = app.Centers(order+startInd - 1);
                        
                        numObjects(startInd:endInd) = app.NumObjects(order+startInd - 1);
                        
                        filename(startInd:endInd) = app.Filename(order+startInd - 1);
                        groupingTable(startInd:endInd, :) = app.GroupingTable(order+startInd - 1, :);


                    end
                
                    app.BulkI = bulkI;
                    app.ImgMasked = imgMasked;
                    %DisplayBulkI
                    app.CC = cc;
                    app.OldCC = oldCC;
                    app.Resize = resize;
                    
                    app.BoundingBox = boundingBox; 
                    %Spheroids_order
                    app.Centers = centers;
                    
                    app.Filename = filename;
                    app.GroupingTable = groupingTable;
                    app.NumObjects = numObjects;
                    
                    app.AdjustedCenters = cell(1, length(app.BulkI)); 
                    app.DisplacementFields = cell(1, length(app.BulkI)); 
                    app.TotalTform = cell(1, length(app.BulkI));
                    app.RegisteredBulkI = cell(1, length(app.BulkI));
                    app.OldRegistered = cell(1, length(app.BulkI));
                    app.RegisteredPerimeter = cell(1, length(app.BulkI));
                    
                    for file = 1:length(app.Filename)
                        app.LeftImageSelectList.Items(file) = app.Filename{file};
                        app.RightImageSelectList.Items(file) = app.Filename{file};
                    end
                    displaySortingList(app)
                
                catch
                    errordlg("Error sorting, this may have occurred due to an unequal number of images per batch")
                end


        end

        function confirmManualOrientation(app)
            selected = app.RightImageSelectList.Value;
            
            
            if app.FirstImageFirstPoint ~= 0 & app.SecondImageFirstPoint ~= 0
                app.XOffset(selected) = app.FirstImageFirstPoint(1,1) - app.SecondImageFirstPoint(1,1) + app.XOffset(selected - 1);
                app.YOffset(selected) = app.FirstImageFirstPoint(1,2) - app.SecondImageFirstPoint(1,2) + app.YOffset(selected - 1);
            end

            nextImage = app.RightImageSelectList.Value + 1;


            %Once last image is reached, finalize adjusted coordinates
            if nextImage > length(app.BulkI)


                for i = 1:length(app.BulkI)
                    adjustedCenters = zeros(app.NumObjects(i), 2);
                    adjustedCenters(:,1) = app.Centers{i}(:,1) + app.XOffset(i);
                    adjustedCenters(:,2) = app.Centers{i}(:,2) + app.YOffset(i);
                    app.AdjustedCenters{i} = adjustedCenters;
                    
                end


                app.Orienting = false;
                app.LeftImageSelectList.Enable = 1;
                app.RightImageSelectList.Enable = 1;
                app.LeftGrid.RowHeight = {0, '1x'};
                app.GridLayout.RowHeight = {'1.2x', '1x', 40};
                app.BottomGrid.RowHeight = {0, '1x'};
                resetBasePoints(app);
                
                
            else
                app.LeftImageSelectList.Value = nextImage-1;
                app.RightImageSelectList.Value = nextImage;
                displayFirstImage(app, nextImage - 1)
                displaySecondImage(app, nextImage)

                %Transfer the selected point from the second image to the
                %first image
                tempFirstPoint = app.SecondImageFirstPoint;
                resetBasePoints(app)
                app.FirstImageFirstPoint = tempFirstPoint;

                markerSize = size(app.BulkI{1});
                markerSize = round(markerSize(1)/80);
                if tempFirstPoint ~= 0
                        
                    app.FFMarker = rectangle(app.FirstImageAxes, 'Position', [(tempFirstPoint(1,1) - markerSize/2) (tempFirstPoint(1,2)-markerSize/2) markerSize markerSize], 'Curvature', [1 1], 'FaceColor',[1 1 0]);
                end


            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function trackingStartup(app, caller, sharedData, loadFile)
          
            app.CallingApp = caller;
            app.MostRecentExportFolder = sharedData.MostRecentExport;
            app.MostRecentSaveFolder = sharedData.MostRecentSave;
            if loadFile == false
    
                %app.SharedData = sharedData;
                app.MicronsPerPixel = sharedData.MicronsPerPixel;
                app.MetricsSelected = sharedData.MetricsSelected;
                app.MainSegmentColor = sharedData.MainSegmentColor;
                app.BatchStarts = sharedData.BatchStartIndices;
                app.GoodOrganoids = cell(1, length(app.BatchStarts));
                app.GroupConditions = sharedData.groupConditions;


                maxRow = 0;
                maxCol = 0;
                
                for image = 1:length(sharedData.ImageIndexes)
                    selectedImage = sharedData.ImageIndexes(image);
                    
                    [numRow, numCol] = size(sharedData.BulkI{selectedImage});
                    if numRow > maxRow
                        maxRow = numRow;
                    end
                    if numCol > maxCol
                        maxCol = numCol;
                    end
                end
                %Add shared data to local app data
                for image = 1:length(sharedData.ImageIndexes)
                    selectedImage = sharedData.ImageIndexes(image);
                    currImage = sharedData.BulkI{selectedImage};
                    %Reconfigure images so that that they all are of the
                    %same size

                    classType = class(currImage);
                    fullBulkI = zeros(maxRow, maxCol, classType);
                    fullBulkI(1:size(currImage,1), 1:size(currImage,2)) = currImage;
                    app.BulkI{image} = fullBulkI;
                    class(fullBulkI)
                   

                    CC_temp = sharedData.CC{selectedImage};
                    CC_temp.PixelIdxList = CC_temp.PixelIdxList(~cellfun('isempty',CC_temp.PixelIdxList));
                    CC_temp.NumObjects = length(CC_temp.PixelIdxList); 
    
                    app.OldCC{image} = CC_temp; 
    
                    CC_temp.ImageSize = size(fullBulkI);
                    
                    for spher = 1:length(CC_temp.PixelIdxList)
                        [row, col] = ind2sub(size(currImage),CC_temp.PixelIdxList{spher});
                        CC_temp.PixelIdxList{spher} = sub2ind(size(fullBulkI), row, col);
                    end
                    app.CC{image} = CC_temp;
                    bwfinal = logical(labelmatrix(CC_temp));
                    
                    bw_perim = double(bwperim(bwfinal));
                    %Make visible (dilate)
                    bw_perim_dil = imdilate(bw_perim,ones(3,3));


                    displayI = app.BulkI{image};
                    %Normalize raw image to min and max
                    displayI = double(displayI);
                    displayI = (displayI - min(displayI(:)))./(max(displayI(:)) - min(displayI(:)));
        
                    app.ImgMasked{image} = imoverlay(displayI,bw_perim_dil,[0 0 1]);  


                    app.Resize{image} = sharedData.Resize{selectedImage};


                    statsBW_temp = regionprops(CC_temp, 'BoundingBox', 'Centroid');
                    app.BoundingBox{image} = {statsBW_temp.BoundingBox};
                    centers = [statsBW_temp.Centroid];
                    recenters = reshape(centers, 2, length(statsBW_temp))';
                    app.Centers{image} = recenters;
    
                    
                    filenameCell = sharedData.FileNames(selectedImage);
                    app.Filename{image} = filenameCell;
                    app.LeftImageSelectList.Items = cat(2,app.LeftImageSelectList.Items, filenameCell);
                    app.LeftImageSelectList.ItemsData(image) = image;
    
                    app.RightImageSelectList.Items = cat(2,app.LeftImageSelectList.Items, filenameCell);
                    app.RightImageSelectList.ItemsData(image) = image;
                end


                app.LeftImageSelectList.Value = 1;
                app.RightImageSelectList.Value = 2;
                
                displayFirstImage(app, 1)
                displaySecondImage(app, 2)
                %Remove interactivity from axes (reduces lagging)
                disableDefaultInteractivity(app.FirstImageAxes)
                disableDefaultInteractivity(app.SecondImageAxes)
                disableDefaultInteractivity(app.SpheroidMontageAxes)
    
                app.XOffset = zeros(1, length(app.BulkI));
                app.YOffset = zeros(1, length(app.BulkI));
                app.XOffset(1) = 0;
                app.YOffset(1) = 0;
                app.DisplacementFields = cell(1, length(app.BulkI));
                app.TotalTform = cell(1, length(app.BulkI));
                app.AdjustedCenters = app.Centers;
                app.RegisteredPerimeter = cell(1, length(app.BulkI));
                app.RegisteredBulkI = cell(1, length(app.BulkI));
                app.OldRegistered = cell(1, length(app.BulkI));

    
                app.GridLayout.RowHeight = {'1x', 0, 0};
                app.LeftImageSelectList.Enable = 0;
                app.RightImageSelectList.Enable = 0;
                
                app.OrientationGrid.ColumnWidth = {'0.5x', '0.5x'};
                app.LeftGrid.Layout.Column = 1;
                app.LeftGrid.RowHeight = {220, '1x'};
                app.BottomGrid.RowHeight = {'1x', 0};
                app.GroupingTable = sharedData.groupingTable;
    
                app.NumObjects = zeros(1,length(app.BulkI));
                
                for selected = 1:length(app.BulkI)
                    app.NumObjects(selected) = size(app.Centers{selected}, 1);
                end
                if iscell(app.GroupConditions)
                    app.SortingDropDown.Items = app.GroupingTable.Properties.VariableNames;
                    app.SortingDropDown.ItemsData = app.GroupingTable.Properties.VariableNames;
                    
                    
                    app.RegistrationDropDown.Items = ["Rigid (No Rotation)", "Rigid (With Rotation)", "Non-rigid"];
                    app.RegistrationDropDown.ItemsData = ["Rigid", "Rotate", "NonRigid"];
                
                    app.RegisteredBatches = zeros(1,length(app.BatchStarts));
                    
                else
                    app.SortingDropDown.Items = {'NA'};
                    app.SortingDropDown.ItemsData = {'NA'};
                    app.RegistrationDropDown.Items = ["Rigid (No Rotation)", "Rigid (With Rotation)", "Non-rigid", "Manual Translation"];
                    app.RegistrationDropDown.ItemsData = ["Rigid", "Rotate", "NonRigid", "Manual"];
                    app.SortingDropDown.Enable = 0;
                    app.RegisteredBatches = [1];
                    
                end
                
                displaySortingList(app)

            else
                
                %loading saved tracking file
                mb = msgbox('Please wait... loading variables...');
                load(strcat(sharedData.basename,sharedData.filename), 'hiddenProps');
                if isfield(hiddenProps, 'mainSegmentColor')
                    app.MainSegmentColor = hiddenProps.mainSegmentColor;
                else
                    app.MainSegmentColor = [0 0 1];
                end


                %Add saved data into app data
                app.CC = hiddenProps.cc;
                app.BulkI = hiddenProps.bulkI;
                %app.DisplayBulkI = hiddenProps.displayBulkI;
                app.BatchStarts = [1];
                app.Resize = hiddenProps.resize;
                app.MicronsPerPixel = hiddenProps.micronsPerPixel;
                app.BoundingBox = hiddenProps.boundingBox;
                app.Centers = hiddenProps.centers;
                app.MetricsSelected = hiddenProps.metricsSelected;
                app.Filename = hiddenProps.filename;
                app.AdjustedCenters = hiddenProps.adjustedCenters;
                app.MaximumDistance = hiddenProps.maximumDistance;
                app.NumObjects = hiddenProps.numObjects;
                app.Area = hiddenProps.area;
                app.XCoordsAdj = hiddenProps.xCoordsAdj;
                app.YCoordsAdj = hiddenProps.yCoordsAdj;
                app.MatchingID = hiddenProps.matchingID;
                app.ImgMasked = cell(1,length(app.BulkI));
                for newImage = 1:length(app.BulkI)
                    
                    CClabel = labelmatrix(app.CC{newImage});
                    

                    bwMock = logical(CClabel);
                    %bwMock(bwMock > 0) = 1;


                    %Find perimeter
                    bw_perim = double(bwperim(bwMock));
                    %Make visible (dilate)
                    bw_perim_dil = imdilate(bw_perim,ones(3,3));


                    %Normalize raw image to min and max
                    displayI = app.BulkI{newImage};
                    displayI = double(displayI);
                    displayI = (displayI - min(displayI(:)))./(max(displayI(:)) - min(displayI(:)));
                    %Create crop of perimeter and raw image
                    
                    imgMasked = imoverlay(displayI,bw_perim_dil,app.MainSegmentColor);

                    
                    app.ImgMasked{newImage} = imgMasked;
                    
                   
                end
                

                for i = 1:length(app.Filename)
                    app.LeftImageSelectList.Items = cat(2,app.LeftImageSelectList.Items, app.Filename{i});
                    app.LeftImageSelectList.ItemsData(i) = i;
    
                    app.RightImageSelectList.Items = cat(2,app.LeftImageSelectList.Items, app.Filename{i});
                    app.RightImageSelectList.ItemsData(i) = i;
                end

                app.LeftImageSelectList.Value = 1;
                app.RightImageSelectList.Value = 2;
                doneOrientingCallback(app,nan);
                app.DoneTracking = true;
                app.Orienting = false;

                batch = 1;
                maxNum = getMaxMatchingID(app, batch);

                if isfield(hiddenProps, 'goodOrganoids')
                    app.GoodOrganoids{batch} = hiddenProps.goodOrganoids;
                else
                    app.GoodOrganoids{batch} = ones(1, maxNum)*2;
                end


                app.LeftImageBatch = 1;
                updateSpheroidList(app, app.LeftImageBatch)
                
                app.SpheroidListBox.Value = 1;
    
                displayFirstImage(app, 1)
                displaySecondImage(app, 2)
                displaySpheroidMontage(app, 1)
    
                app.Spheroid2EditField.Limits = [0 getMaxMatchingID(app, batch)];


                delete(mb)


            end
            
        end

        % Value changed function: LeftImageSelectList
        function leftImageSelectListCallback(app, event)
            selected = app.LeftImageSelectList.Value;
            rightSelected = app.RightImageSelectList.Value;
            currBatch = find(app.BatchStarts <= selected, 1, 'last');
            app.LeftImageBatch = currBatch(1);
            changeSecond = false;
            if app.RightImageBatch ~= app.LeftImageBatch                
                app.RightImageBatch = app.LeftImageBatch;
                changeSecond = true;
                displaySecondImage(app, app.BatchStarts(app.LeftImageBatch))
                if ~app.Orienting & iscell(app.MatchingID)
                    updateSpheroidList(app, app.LeftImageBatch)
                    displaySpheroidMontage(app, 1)
                end
            end
          
            %During alignment, show overlay of registered perimeter of right image on
            %registered version of left image
            if app.Orienting & ~strcmp(app.RegistrationDropDown.Value, 'Manual')
                %Make sure right and left image batch are the same
                if changeSecond
                    rightSelected = app.BatchStarts(app.LeftImageBatch);
                    
                end
                try 
                    imgMasked = imoverlay(app.OldRegistered{selected}, app.RegisteredPerimeter{rightSelected}, [1,0,1]);
                    axesChild = imshow(imgMasked,[], 'Parent', app.FirstImageAxes);
                    axesChild.ButtonDownFcn = createCallbackFcn(app, @firstImageButtonDown, true);
                    app.FirstImageAxes.Title.String = sprintf("Batch %d: Image %d", app.LeftImageBatch, selected - app.BatchStarts(app.LeftImageBatch) + 1);
                catch
                    displayFirstImage(app, selected)
                end
                    
                
            else
                displayFirstImage(app, selected)
                if app.RightImageBatch ~= app.LeftImageBatch                    
                    app.RightImageBatch = app.LeftImageBatch;
                    displaySecondImage(app, app.BatchStarts(app.LeftImageBatch))
                    updateSpheroidList(app, app.LeftImageBatch)
                    displaySpheroidMontage(app, 1)
                    
                end
            end
            
        end

        % Button pushed function: TrackButton
        function trackImages(app, event)
            answer = "Yes";
            if any(cell2mat(app.GoodOrganoids) ~= 2)
                answer = uiconfirm(app.UIFigure, {['Tracking again will reset manual organoid verification assignments.' ...
                    'Are you sure you want to continue?']}, ...
                    'Track Again', ...
                    'Options', {'Yes', 'No'}, 'DefaultOption', 'No', 'CancelOption', 'No');
            end
            
            if strcmp(answer, "Yes")
                app.MaximumDistance = app.MaximumDistanceEditField.Value;
                app.MatchingID = cell(1, length(app.BulkI));
                
                
                getMetrics(app)
                numNotAssignedPerImage = cell(1, length(app.BulkI) - 1);
                h = waitbar(0,'Please wait... tracking...');
                for batch = 1:length(app.BatchStarts)
                    app.MatchingID{app.BatchStarts(batch)} = 1:size(app.Centers{app.BatchStarts(batch)},1);
                  
                    [startInd, endInd] = getBatchEnds(app, batch, app.BatchStarts, length(app.BulkI));
                    %Start tracking from second image in batch
                    for selected = startInd+1:endInd
                       
                        
                        prev = selected - 1;
                        %Work backwards to match organoids from each image
                        %before the current image
                        while prev >= app.BatchStarts(batch)
                            
                            xs = [app.XCoordsAdj{prev}' app.XCoordsAdj{selected}'];
                            ys = [app.YCoordsAdj{prev}' app.YCoordsAdj{selected}'];
                            areas = [app.Area{prev}' app.Area{selected}'];
            
                            xDist = findDifference(app, xs, selected, prev, false);
                            
                            yDist = findDifference(app, ys, selected, prev, false);
                            
                            areaDist = findDifference(app, areas, selected, prev, true);
                            xyDist = sqrt(xDist.^2+yDist.^2);


                            distMatrix = xyDist;
                            
                            
                            %Filter out organoids that are far away and do
                            %not fall in the allowed size range
                            distMatrix(xyDist > (app.MaximumDistance)) = nan;
                            
                            distMatrix(areaDist < app.MinGrowthEditField.Value | areaDist > app.MaxGrowthEditField.Value) = nan;
                            outOfSizeRange = zeros(size(distMatrix));
                            outOfSizeRange(areaDist < app.MinGrowthEditField.Value | areaDist > app.MaxGrowthEditField.Value) = 1;
                            
                            badOrgsCurrImage = cell(1,size(app.Centers{selected},1));
                            for currOrg = 1:size(distMatrix,2) 
                                
                                badOrgsCurrImage{currOrg} = app.MatchingID{prev}(outOfSizeRange(:,currOrg)'== 1);
                            end


                            prevMatchingID = app.MatchingID{prev};
        
                            %If this is the first iteration backwards, allocate
                            %otherwise, clear out the values that have already been
                            %matched
                            if prev == selected - 1
                                badOrgsTotal = badOrgsCurrImage;
                                matchingID = zeros(1, size(app.Centers{selected},1));
                            else    
                                %Clear out spheroids from this image that have been
                                %matched
                                distMatrix(:, matchingID ~= 0) = nan;
                                alreadyUsedID = ismember(prevMatchingID, matchingID);
                                %Clear out spheroids from previous image that were
                                %matched to this image already
                                distMatrix(alreadyUsedID, :) = nan;
                                for org = 1:length(badOrgsTotal)
                                    badOrgsTotal{org} = [badOrgsTotal{org}, badOrgsCurrImage{org}];
                                end
                            end
                            
                            %Check if all spheroids have been assigned
                            allAssigned = false;
                            if nnz(~isnan(distMatrix)) == 0
                                    allAssigned = true;
                            end
            
                            %Perform assignment for the current iteration of image
                            %and a previous image
                            while ~allAssigned
            
                                temp = distMatrix;
                                %temp(isnan(distMatrix())) = maxVal;
                                
                                %Find index of minimum value in distance matrix
                                [~,X] = min(temp(:));
                                [R,C] = ind2sub(size(distMatrix),X);
                                if ismember(prevMatchingID(R), badOrgsTotal{C})
                                    distMatrix(R,C) = nan;
                                
                                else
                                    %Assign to matching ID, and then clear in current
                                    %and prev so that they cannot be assigned again
                                    matchingID(C) = prevMatchingID(R);
                
                                    distMatrix(R,:) = nan;
                                    distMatrix(:,C) = nan;
                                end


                                if nnz(~isnan(distMatrix)) == 0
                                    allAssigned = true;
                                end
                                
                            end
                            prev = prev - 1;
                        end
        
                        
                        %Give IDs to all spheroids from this current image that were not
                        %assigned 
                        numNotAssigned = 0;
                        nextVal = getMaxMatchingID(app, batch) + 1;
                        for i = 1:length(matchingID)
                            if matchingID(i) == 0
                                numNotAssigned = numNotAssigned + 1;
                                matchingID(i) = nextVal;
                                nextVal = nextVal + 1;
                            end
                        end
                        numNotAssignedPerImage{selected-1} = [numNotAssigned, size(app.Centers{selected},1)];
        
        
                        app.MatchingID{selected} = matchingID;
                        
                        app.DoneTracking = true;
                        
                    end
        
                    %Set up list of spheroids
        
                    maxNum = getMaxMatchingID(app, batch);
                    spheroid_str = cell(1, maxNum);
                    
                    for j = 1:maxNum
                        spheroid_str{j} = num2str(j);   
                    end
        
                    app.GoodOrganoids{batch} = ones(1,maxNum)*2;
                    waitbar(batch/length(app.BatchStarts));
                end
    
                close(h)


                app.SpheroidListBox.ItemsData = 1:maxNum;
                app.SpheroidListBox.Items = spheroid_str;
                
                app.SpheroidListBox.Value = 1;
                app.LeftImageBatch = 1;
                app.RightImageBatch = 1;
    
                displayFirstImage(app, 1)
                displaySecondImage(app, 2)
                displaySpheroidMontage(app, 1)
    
                %app.Spheroid1EditField.Limits = [0 getMaxMatchingID(app, batch)];
                app.Spheroid2EditField.Limits = [0 getMaxMatchingID(app, batch)];
                %app.UnmatchSpheroidEditField.Limits = [0 getMaxMatchingID(app, batch)];
            end


        end

        % Value changed function: SpheroidListBox
        function spheroidSelectList(app, event)
            value = app.SpheroidListBox.Value;
            displaySpheroidMontage(app, value)
        end

        % Button pushed function: RegisterAllButton
        function registerAll(app, event)

            value = app.RegistrationDropDown.Value;
           
            if contains(value, 'Non')
                nonRigidRegister(app, 1:length(app.BatchStarts))
            else
                rigidRegisterFunc(app, 1:length(app.BatchStarts))
            end
            
            cla(app.FirstImageAxes)


            axesChild = imshow(imoverlay(app.OldRegistered{1}, app.RegisteredPerimeter{2}, [1,0,1]),[], 'Parent', app.FirstImageAxes);
            axesChild.ButtonDownFcn = createCallbackFcn(app, @firstImageButtonDown, true);
            app.RightImageSelectList.Enable = 1;
            app.LeftImageSelectList.Enable = 1;
            

        end

        % Value changed function: RightImageSelectList
        function rightImageSelectListCallback(app, event)
            selected = app.RightImageSelectList.Value;   
           
            currBatch = find(app.BatchStarts <= selected, 1, 'last');
            app.RightImageBatch = currBatch(1);
            displaySecondImage(app, selected)
            if app.RightImageBatch ~= app.LeftImageBatch 
                
                app.LeftImageBatch = app.RightImageBatch;
                
                displayFirstImage(app, app.BatchStarts(app.RightImageBatch))
                if ~app.Orienting & iscell(app.MatchingID)
                    updateSpheroidList(app, app.RightImageBatch)
                    displaySpheroidMontage(app, 1)
                end
            end
            if app.Orienting & ~strcmp(app.RegistrationDropDown.Value, 'Manual')
                
                    if selected ~= 1
                        imgMasked = imoverlay(app.OldRegistered{selected-1}, app.RegisteredPerimeter{selected}, [1,0,1]);
                        axesChild = imshow(imgMasked,[], 'Parent', app.FirstImageAxes);
                        axesChild.ButtonDownFcn = createCallbackFcn(app, @firstImageButtonDown, true);
                        app.FirstImageAxes.Title.String = sprintf("Batch %d: Image %d", app.RightImageBatch, selected - app.BatchStarts(app.RightImageBatch));
                        app.LeftImageSelectList.Value = selected-1;
                    else
                        imgMasked = imoverlay(app.OldRegistered{app.BatchStarts(app.RightImageBatch)}, app.RegisteredPerimeter{app.BatchStarts(app.RightImageBatch)}, [1,0,1]);
                        axesChild = imshow(imgMasked,[], 'Parent', app.FirstImageAxes);
                        axesChild.ButtonDownFcn = createCallbackFcn(app, @firstImageButtonDown, true);
                        app.FirstImageAxes.Title.String = sprintf("Batch %d: Image %d", app.RightImageBatch, 1);
                        app.LeftImageSelectList.Value = 1;
                    end


            end
            
        end

        % Button pushed function: DoneRegisteringButton
        function doneOrientingCallback(app, event)
            
            if any(~app.RegisteredBatches)
                answer = questdlg(['You have not registered all batches. ' ...
                    'Any unregistered batch will use the original image orientation for tracking.' ...
                    'Do you want to continue?'], ...
	            'Clear Workspace', ...
	            'Yes','No','No');
                if strcmp(answer, 'Yes')
                    app.Orienting = false;
                    app.LeftImageSelectList.Enable = 1;
                    app.RightImageSelectList.Enable = 1;
                    app.LeftGrid.RowHeight = {0, '1x'};
                    app.GridLayout.RowHeight = {'1.2x', '1x', 40};
                    app.BottomGrid.RowHeight = {0, '1x'};
                    resetBasePoints(app);
                end
            else
                app.Orienting = false;
                app.LeftImageSelectList.Enable = 1;
                app.RightImageSelectList.Enable = 1;
                app.LeftGrid.RowHeight = {0, '1x'};
                app.GridLayout.RowHeight = {'1.2x', '1x', 40};
                app.BottomGrid.RowHeight = {0, '1x'};
                resetBasePoints(app);
            end


        end

        % Button pushed function: MatchSpheroidsButton
        function matchSpheroidsCallback(app, event)
            batch = app.LeftImageBatch;
            matchSpheroids(app, app.SpheroidListBox.Value, app.Spheroid2EditField.Value)
            
            displaySpheroidMontage(app, app.SpheroidListBox.Value)
            app.SpheroidListBox.Value = app.SpheroidListBox.Value;
            app.Spheroid2EditField.Value = 0;

        end

        % Button pushed function: UnmatchSpheroidsButton
        function unmatchSpheroidsCallback(app, event)
            batch = app.LeftImageBatch;
            unmatchSpheroids(app, app.SpheroidListBox.Value, app.FirstWrongImageEditField.Value)
            getMaxMatchingID(app, batch)
            app.Spheroid2EditField.Limits = [0 getMaxMatchingID(app, batch)];

            displaySpheroidMontage(app, app.SpheroidListBox.Value)
            
            app.FirstWrongImageEditField.Value = 0;
        end

        % Button pushed function: ExportDataButton
        function exportData(app, event)
            f = figure('Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
            if isnan(app.MostRecentExportFolder)
                [answer,PathName] = uiputfile({'*.xls','Excel File (*.xls)';'*.xlsx','Excel File (*.xlsx)';'*.mat','MAT File (*.mat)'},'Export Metrics As');
            else
                [answer,PathName] = uiputfile({'*.xls','Excel File (*.xls)';'*.xlsx','Excel File (*.xlsx)';'*.mat','MAT File (*.mat)'},'Export Metrics As', app.MostRecentExportFolder);
                
            end
            delete(f)
            if PathName~=0
                app.MostRecentExportFolder = PathName;
            end
            if answer == 0
                %do nothing
            else
            
                
                metrics = {'Area', 'Perimeter', 'PerimeterToAreaRatio', 'Circularity', 'EquivDiameter',...
                    'Eccentricity', 'MajorAxisLength', 'MinorAxisLength', ...
                    'ConvexArea', 'Extent', 'Orientation',   ...
                    'Solidity',   'Z', 'A', 'Phi', ...
                    'MeanPixelIntensity', 'StdPixelIntensity',...
                    'CVPixelIntensity','Skewness'...
                    'Kurtosis','Contrast','Correlation','Energy','Homogeneity'};
    
                %If switch is set to all metrics, assign all
                %metrics for export
                app.MetricsSelected = app.CallingApp.MetricsSelected;
                if strcmp(app.CallingApp.WhichMetricsSwitch.Value, 'AllMetrics')
                        
                        keepMetrics = ones(1, length(metrics));
                    else
                    %otherwise, determine which metrics to export
                    %based on metric scene
                    
                    keepMetrics = [app.MetricsSelected.Area, app.MetricsSelected.Perimeter, app.MetricsSelected.PerimeterToAreaRatio, app.MetricsSelected.Circularity, ...
                        app.MetricsSelected.EquivDiameter, app.MetricsSelected.Eccentricity, app.MetricsSelected.MajorAxisLength, app.MetricsSelected.MinorAxisLength,  ...
                        app.MetricsSelected.ConvexArea, app.MetricsSelected.Extent, app.MetricsSelected.Orientation, app.MetricsSelected.Solidity, ...
                        app.MetricsSelected.Z, app.MetricsSelected.A, app.MetricsSelected.Phi, app.MetricsSelected.MeanPixelIntensity, app.MetricsSelected.StdPixelIntensity,...
                        app.MetricsSelected.CVPixelIntensity, app.MetricsSelected.Skewness, app.MetricsSelected.Kurtosis, app.MetricsSelected.Contrast,...
                        app.MetricsSelected.Correlation, app.MetricsSelected.Energy, app.MetricsSelected.Homogeneity ];
                    
                    %Get indices of metrics for export
                end
                selectedMetrics = find(keepMetrics);
                metricsString = cell(1,24);
                statsString = cell(1,25);
    
                %Create string of metric names that will be exported
                for i = 1:length(keepMetrics)
                    if keepMetrics(i)
                        metricsString{i} = metrics{i};
    
                    end
                end
                %Select the metrics for export that are calculated
                %with region props
                for i = 1:length(keepMetrics)
                    if keepMetrics(i)
    
                        if ismember([metrics{i}], {'Area', 'MajorAxisLength', 'MinorAxisLength', 'Eccentricity',...
                                'Orientation', 'ConvexArea', 'EquivDiameter', 'Solidity', 'Extent', 'Perimeter'})
                            statsString{i} = metrics{i};
                        end
                    end
                end
                %Also add bounding box to region prop since used
                %for other metrics
                statsString{25} = 'BoundingBox';
                %Eliminate empty strings from cell array
                metricsString = metricsString(~cellfun('isempty',metricsString));
                statsString = statsString(~cellfun('isempty',statsString));

                for batch = 1:length(app.BatchStarts)
                    [startInd, endInd] = getBatchEnds(app, batch, app.BatchStarts, length(app.BulkI));
                    signatureTot = cell(length(startInd:endInd));
                    cc = app.CC;
                    bulkI = app.BulkI;
                    maxVal = getMaxMatchingID(app, batch);
                    
                    h = waitbar(0,'Please wait... compiling metric signature...');
                    for ind = 1:length(startInd:endInd)
                        selected = startInd + ind - 1;
                        
                        image = bulkI{selected};
                        %Create temp CC without removed objects
                        CC_temp = cc{selected};
                        CC_temp.PixelIdxList = CC_temp.PixelIdxList(~cellfun('isempty',CC_temp.PixelIdxList));
                        CC_temp.NumObjects = length(CC_temp.PixelIdxList);
                        bwfinalMetrics = logical(labelmatrix(CC_temp));
        
                        matchingID = app.MatchingID{selected};
                        
        
                        %%%Modified export%%%


                        %Find metrics in string
                        statsBW = regionprops(CC_temp, statsString);
        
                        %Assign metrics to struct to store results
                        for i = 1:length(statsString)
                            metricsResults.(statsString{i}) = rearrangeMetric(app,[statsBW.(statsString{i})], matchingID, maxVal);
        
                        end
                        %Calculate P2A ratio if selected
                        if ismember(3, selectedMetrics)
        
                            metricsResults.PerimeterToAreaRatio = rearrangeMetric(app,[statsBW.Perimeter]./[statsBW.Area], matchingID, maxVal) ;
                        end
        
                        %Calculate circularity if selected (uses region
                        %prop with different form%
                        if ismember(4, selectedMetrics)
                            props = regionprops("table",CC_temp,["Circularity" "Perimeter"]);
                            c = props.Circularity;
                            p = props.Perimeter;
                            r_eq = p/(2*pi) + 0.5;
                            correction = (1 - (0.5./r_eq)).^2;
                            props.Circularity = min(c .* correction, 1);
                            circ = cat(1,props.Circularity);
        
                            metricsResults.Circularity = rearrangeMetric(app,circ', matchingID, maxVal);
                        end
        
        
                        boundingBox = {statsBW.BoundingBox};
        
                        %Calculate Zernike Characteristics if any selected
                        if any(ismember([13, 14, 15], selectedMetrics))
        
                            %Preallocate for Zernike Characteristics
                            Z = zeros(1,length(boundingBox));
                            A = zeros(1,length(boundingBox));
                            Phi = zeros(1,length(boundingBox));
        
        
                            bwfinalIm = bwfinalMetrics;
                            for j = 1:length(boundingBox)
                                %Find zernike moments
        
                                p = imcrop(bwfinalIm,boundingBox{j});
                                if boundingBox{j}(3) > boundingBox{j}(4)
                                    p2 = zeros(boundingBox{j}(3)-boundingBox{j}(4),boundingBox{j}(3)+1);
                                    p = [p2;p];
                                else
                                    p2 = zeros(boundingBox{j}(4)+1,boundingBox{j}(4)-boundingBox{j}(3));
                                    p = [p2 p];
                                end
                                [Z(j),A(j),Phi(j)] = Zernikmoment(p,4,2);
        
        
                            end
                            %Assign zernicke results to results struct
                            metricsResults.Z = rearrangeMetric(app,Z, matchingID, maxVal);
                            metricsResults.A = rearrangeMetric(app,A, matchingID, maxVal);
                            metricsResults.Phi = rearrangeMetric(app,Phi, matchingID, maxVal);
        
                        end
        
                        %Find pixel characteristics if any selected
                        if any(ismember([16, 17, 18, 19, 20], selectedMetrics))
        
                            %Preallocate for Pixel Characteristics
        
                            meanTotalPixels = zeros(1,length(boundingBox));
                            stdTotalPixels = zeros(1,length(boundingBox));
                            skewTotalPixels = zeros(1,length(boundingBox));
                            kurtTotalPixels = zeros(1,length(boundingBox));
        
                            CClabel = labelmatrix(CC_temp);
                            for j = 1:length(boundingBox)
        
        
                                %Find Pixel characteristics
                                %Create mask of spheroid j
        
        
                                %Find pixels of only spheroid, crop to
                                %improve speed
                                bbox = boundingBox{j};
                                imageNew = imcrop(image, bbox);
                                CCNew = imcrop(CClabel, bbox);
                                wholeSpheroidPixels = double(imageNew(CCNew == j));
                                meanTotalPixels(j) = mean(wholeSpheroidPixels);
                                stdTotalPixels(j) = std(wholeSpheroidPixels);
                                skewTotalPixels(j) = skewness(wholeSpheroidPixels);
                                kurtTotalPixels(j) = kurtosis(wholeSpheroidPixels);
                            end
                            %Assign pixel characteristic results to struct
                            metricsResults.MeanPixelIntensity = rearrangeMetric(app,meanTotalPixels, matchingID, maxVal);
                            metricsResults.StdPixelIntensity = rearrangeMetric(app,stdTotalPixels, matchingID, maxVal);
                            metricsResults.Kurtosis = rearrangeMetric(app,kurtTotalPixels, matchingID, maxVal);
                            metricsResults.Skewness = rearrangeMetric(app,skewTotalPixels, matchingID, maxVal);
                            pixelCV = stdTotalPixels./meanTotalPixels;
                            metricsResults.CVPixelIntensity = rearrangeMetric(app,pixelCV, matchingID, maxVal);
                        end
        
                        %Find Haralick features if any selected
                        if any(ismember([21, 22, 23, 24], selectedMetrics))
                            %Calculate Haralick Features
                            %initialize
                            glcmFeatures = zeros(4,CC_temp.NumObjects);
                            %for all spheroids
                          
                            for j = 1:CC_temp.NumObjects
                                
                                %crop image around spheroid
                                croppedIm = imcrop(image,boundingBox{j});
                                
                                %Calculate symmetric graycomatrix
                                glcm = graycomatrix(croppedIm,'Symmetric',true);
                                
                                %Calculate haralick features of glcm
                                glcmFeatures(:,j) = cell2mat(struct2cell(graycoprops(glcm)));
                            end
                            glcmCells = mat2cell(glcmFeatures,ones(1,4));
                            %Assign Haralick features to results struct
                            
                            
                            metricsResults.Contrast = rearrangeMetric(app,glcmCells{1,:}, matchingID, maxVal);
                            metricsResults.Correlation = rearrangeMetric(app,glcmCells{2,:}, matchingID, maxVal);
                            metricsResults.Energy = rearrangeMetric(app,glcmCells{3,:}, matchingID, maxVal);
                            metricsResults.Homogeneity = rearrangeMetric(app,glcmCells{4,:}, matchingID, maxVal);
                        end


                        resizeFactor = app.Resize{selected};
                        micronsPerPixel = app.MicronsPerPixel;
                        %Assign selected values to a cell array, and resize
                        %metrics with units of length
                        signature = cell(24,1);
                        %Won't include bounding box in metrics results
                        for i = 1:length(metricsString)
                            metricName = metricsString{i};
                            signature{i} = metricsResults.(metricName);
                            if (strcmp(metricName, 'Area') || strcmp(metricName, 'ConvexArea'))
                                signature{i} = signature{i}./(resizeFactor^2);
                                signature{i} = signature{i}.*(micronsPerPixel^2);
                            elseif (strcmp(metricName, 'MajorAxisLength')||strcmp(metricName, 'MinorAxisLength')||strcmp(metricName, 'Perimeter')||strcmp(metricName, 'EquivDiameter'))
                                signature{i} = signature{i}./resizeFactor;
                                signature{i} = signature{i}.*micronsPerPixel;
                            elseif (strcmp(metricName, 'PerimeterToAreaRatio'))
        
                                signature{i} = signature{i}.*resizeFactor;
                                signature{i} = signature{i}./micronsPerPixel;
        
                            end
        
                        end
                        %Remove empty cells from signature


                        signatureTot{ind} = signature;
                        
                        
                        waitbar(selected/length(app.CC));
                    end
    
                    %Reorder data so that each cell has the data for one metric
                    %across all images
        
                    signatureByMetrics = cell(1, length(metricsString)+1);
                    for i = 1:length(metricsString)
                        signatureByMetrics{i} = zeros(length(signatureTot), maxVal);
                        for j = 1:length(signatureTot)
                            signatureByMetrics{i}(j,:) = signatureTot{j}{i};
                        end
                            
                    end
    
                    %Add final page to export data that shows what the original
                    %ID is of each spheroid that was matched
                    signatureByMetrics{end} = zeros(length(signatureTot), maxVal);
                    for ind = 1:length(startInd:endInd)
                        j = ind + startInd - 1;
                        
                        matchingID = app.MatchingID{j};
                        correspondingID = zeros(1, maxVal);
                        for id = 1:length(matchingID)
                            idInMatching = matchingID(id);
                            correspondingID(idInMatching) = id;
                        end
    
                        signatureByMetrics{end}(ind,:) = correspondingID;
                    end
    
                    
                    if contains(answer,'.mat') == 0
                        waitbar(1,h,'Please wait...exporting to excel file');
            
                        %initialize
                        for j = 1:length(metricsString)+1
        
                            if j <= length(metricsString)
                                sheet = metricsString{j};   
                            else
                                sheet = 'ID in Original Segmentation';
                            end
        
                            %Replace file initially, then just overwrite sheets
                            if j == 1
                                writemode = 'replacefile';
                            else
                                writemode = 'overwritesheet';
                            end


                            topRow = [{'Spheroid #/ Image #'}, num2cell(1:length(startInd:endInd)), {"Annotation"}];
                            secondRow = [{'Filenames'} app.Filename{startInd:endInd}, {""}];
                            dbl = signatureByMetrics{j}';


                            app.GoodOrganoids{batch} = app.GoodOrganoids{batch}(1:getMaxMatchingID(app, batch));
                            goodOrganoidsString = cell(1,length(app.GoodOrganoids{batch}));
                            for org = 1:length(app.GoodOrganoids{batch})
                                switch app.GoodOrganoids{batch}(org)
                                    case 2
                                        goodOrganoidsString{org} = "None";
                                    case 1
                                        goodOrganoidsString{org} = "c";
                                    case 0
                                        goodOrganoidsString{org} = "x";
                                    
                                end
                            end
                            
                            numbers = [num2cell((1:maxVal)'), num2cell(dbl), goodOrganoidsString'];
                            if strcmp(app.WhichOrganoidsExport.Value, 'RemoveX')
                                goodOrgInd = find(app.GoodOrganoids{batch});
                                numbers = numbers(goodOrgInd, :);
                            elseif strcmp(app.WhichOrganoidsExport.Value, 'KeepOnlyC')
                                goodOrgInd = find(app.GoodOrganoids{batch} == 1);
                                numbers = numbers(goodOrgInd, :);
                            end
                            
                            
                            matrix = [topRow;secondRow;numbers];
                            %Name based on image batch
                            if iscell(app.GroupConditions)
                                conditions = app.GroupConditions{batch}{1,:};
                                conditionsString = strjoin(conditions, "_");
                                splitAnswer = split(answer, ".");

                                outputName = char(strcat(PathName,splitAnswer{1}, "_",conditionsString, ".",splitAnswer{2} ));
                            else
                                outputName = char(strcat(PathName,answer));
                            end


                            try    
                                writecell(matrix,outputName, 'Sheet', sheet, 'WriteMode', writemode);
                            catch
                                mb1 = errordlg('The Excel file you choose to write to may be open. Please close it and attempt the export again');
                                t = timer;
                                t.StartDelay = 2;
                                t.TimerFcn = @(~,~) delete(mb1);
                                start(t)
                                delete(h)
                                return;
                            end

                            
                            waitbar(j/(length(metricsString)+1))
                        end
            
                        %Display message box
                        delete(h);
                        mb = msgbox('Excel export successful!');
                        t = timer;
                        t.StartDelay = 2;
                        t.TimerFcn = @(~,~) delete(mb);
                        start(t)
                    else
    
                        mb = msgbox('Please wait... storing variables...');


                            %%% Data from original app for selected images 
                            % (in selected order if apply to multiple images)
                            
                            bulkI = app.BulkI(startInd:endInd);
                            
                            cc = app.CC(startInd:endInd);                       
                            resize = app.Resize(startInd:endInd);
                            micronsPerPixel = app.MicronsPerPixel;
                            boundingBox = app.BoundingBox(startInd:endInd);
                            
                            centers = app.Centers(startInd:endInd);
                            metricsSelected = app.MetricsSelected;
                            filename = app.Filename(startInd:endInd);                        
                            adjustedCenters = app.AdjustedCenters(startInd:endInd); 
                            
                            maximumDistance = app.MaximumDistance;
                            numObjects = app.NumObjects(startInd:endInd); 
                            area = app.Area(startInd:endInd); 
                    
                            xCoordsAdj = app.XCoordsAdj(startInd:endInd); 
                            yCoordsAdj= app.YCoordsAdj(startInd:endInd); 
                            matchingID = app.MatchingID(startInd:endInd); 
                            goodOrganoids = app.GoodOrganoids{batch};
                            mainSegmentColor = app.MainSegmentColor;


                            hiddenString = {'bulkI', 'cc',...
                                'resize','micronsPerPixel','boundingBox','centers',...
                                'metricsSelected', 'filename','adjustedCenters','maximumDistance', 'numObjects',...
                                'area','xCoordsAdj', 'yCoordsAdj', 'matchingID', 'goodOrganoids', 'mainSegmentColor'};
                           
    
                            hiddenCell = [{bulkI}, {cc},...
                                {resize},{micronsPerPixel},{boundingBox},{centers},...
                                {metricsSelected}, {filename},{adjustedCenters},{maximumDistance}, {numObjects},...
                                {area},{xCoordsAdj}, {yCoordsAdj}, {matchingID}, {goodOrganoids}, {mainSegmentColor}]';
                            
                            
                            %Export by group and name files accordingly
                            hiddenProps = cell2struct(hiddenCell,hiddenString,1);
                            if iscell(app.GroupConditions)
                                conditions = app.GroupConditions{batch}{1,:};
                                conditionsString = strjoin(conditions, "_");
                                splitAnswer = split(answer, ".");

                                outputName = char(strcat(PathName,splitAnswer{1}, "_",conditionsString, ".",splitAnswer{2} ));
                            else
                                outputName = char(strcat(PathName,answer));
                            end

                            s = whos('hiddenProps');           
                        
                            if s.bytes > 2e9
                                save(outputName,'hiddenProps', '-v7.3');
                            else                           
                            
                                save(outputName,'hiddenProps');
                            end
                            
                            delete(mb)
                            delete(h);
                            mb = msgbox('Signature export successful!');
                            t = timer;
                            t.StartDelay = 2;
                            t.TimerFcn = @(~,~) delete(mb);
                            start(t)
    
    
                    end
                end
            end
        end

        % Key press function: UIFigure
        function keyPressTracking(app, event)
            %Allow for enter key to confirm orientation
            batch = app.LeftImageBatch;
            key = event.Key;
            if app.Orienting
                if strcmp(app.RegistrationDropDown.Value, 'Manual')
                    if strcmp(key, 'return')                
                        confirmManualOrientation(app)
                    end
                end
            end


            currSpheroid = app.SpheroidListBox.Value;
            
            if strcmp(key, 'downarrow')
                if currSpheroid < getMaxMatchingID(app, batch)
                   app.SpheroidListBox.Value = currSpheroid + 1;
                   displaySpheroidMontage(app, currSpheroid + 1)


                end
            
            elseif strcmp(key, 'uparrow')
                if currSpheroid > 1
                    app.SpheroidListBox.Value = currSpheroid - 1;
                    displaySpheroidMontage(app, currSpheroid - 1)
                end

            %Add user defined classifications to organoid, where x = 0 is
            %bad, and c = 1 is good, (default = 2)
            elseif strcmp(key, 'x') & app.GoodOrganoids{batch}(currSpheroid) ~=0 
                app.GoodOrganoids{batch}(currSpheroid) = 0;
                app.SpheroidListBox.Items{currSpheroid} = strcat(num2str(currSpheroid), '_x');
            elseif strcmp(key, 'c') & app.GoodOrganoids{batch}(currSpheroid) ~= 1
                app.GoodOrganoids{batch}(currSpheroid) = 1;
                app.SpheroidListBox.Items{currSpheroid} = strcat(num2str(currSpheroid), '_c');
            elseif strcmp(key, 'backspace') & app.GoodOrganoids{batch}(currSpheroid) ~= 2
                app.GoodOrganoids{batch}(currSpheroid) = 2;
                newLabel = split(app.SpheroidListBox.Items{currSpheroid}, '_');
                
                app.SpheroidListBox.Items{currSpheroid} = newLabel{1};
            
            end


        end

        % Menu selected function: FluorescenceMenu
        function fluorescenceFromTracking(app, event)
 
            batch = app.LeftImageBatch;
            [startInd, endInd] = getBatchEnds(app, app.LeftImageBatch, app.BatchStarts, length(app.BulkI));
            shareData = struct;
            shareData.CC = app.CC(startInd:endInd);
            shareData.ImgMasked = app.ImgMasked(startInd:endInd);
            shareData.MainSegmentColor = app.MainSegmentColor;
            
            shareData.FileNames = app.LeftImageSelectList.Items(startInd:endInd);
            shareData.BulkI = app.BulkI(startInd:endInd);
           
            %shareData.DisplayBulkI = app.DisplayBulkI;
            

            shareData.MaxID = getMaxMatchingID(app, batch);
            shareData.Centers = app.Centers(startInd:endInd);
            shareData.Resize = app.Resize(startInd:endInd);
            shareData.MicronsPerPixel = app.MicronsPerPixel;
            shareData.MatchingID = app.MatchingID(startInd:endInd);
            shareData.BoundingBox = app.BoundingBox(startInd:endInd);
            shareData.GoodOrganoids = app.GoodOrganoids{batch};
            shareData.MostRecentExport = app.MostRecentExportFolder;
            shareData.MostRecentSave = app.MostRecentSaveFolder;


            app.FluorescenceScene = fluorescenceAnalysisScene(app, shareData, false, 'Tracking');
        end

        % Value changed function: MaximumDistanceEditField
        function changeMaxDistance(app, event)
            
        end

        % Menu selected function: OpenPreviouslyTrackedMenu
        function saveTrackingResults(app, event)
            
        end

        % Button pushed function: RemoveFromSequenceButton
        function removeFromSequence(app, event)
     
            removeSpheroid(app, app.SpheroidListBox.Value, app.ImagetoRemoveEditField.Value )
            app.ImagetoRemoveEditField.Value = 0;
%             displayFirstImage(app, app.LeftImageSelectList.Value)
%             displaySecondImage(app, app.RightImageSelectList.Value)
            displaySpheroidMontage(app, app.SpheroidListBox.Value)
        end

        % Button pushed function: ApplyButton
        function applySorting(app, event)
            if length(app.SortingListBox.Value) < length(app.SortingListBox.Items)
                errordlg("Please select all items before sorting")
            else
                sortData(app, app.SortingListBox.Value)
            end
        end

        % Button pushed function: AlphabeticalButton
        function alphabeticalSort(app, event)
            [~,I] = sort(app.SortingListBox.Items);
            sortData(app, I)
        end

        % Value changed function: SortingDropDown
        function changeSortingVariable(app, event)
            
           
            displaySortingList(app)
        end

        % Value changed function: RegistrationDropDown
        function changeRegMethod(app, event)
            value = app.RegistrationDropDown.Value;
            
            
            if strcmp(value, "Manual")
                
                app.GridLayout.RowHeight = {'1x', 0, 40};
                app.RegisterAllButton.Enable = 0;
                app.RegisterBatchButton.Enable = 0;
                app.DoneRegisteringButton.Enable = 0;
                app.RegMethodSwitch.Enable = 0;
                
            else    
                
                app.GridLayout.RowHeight = {'1x', 0, 0};
                
            
            end

            if ~strcmp(value, "NonRigid")
                app.MaxIterations101000EditField.Enable = 0;
                app.DegreeofSmoothing053EditField.Enable = 0;
            end
            %Reset/ preallocate
            if strcmp(value, "Manual")
                app.XOffset = zeros(1, length(app.BulkI));
                app.YOffset = zeros(1, length(app.BulkI));
                app.XOffset(1) = 0;
                app.YOffset(1) = 0;
                app.DisplacementFields = cell(1, length(app.BulkI));
                app.TotalTform = cell(1, length(app.BulkI));
                app.AdjustedCenters = cell(1, length(app.BulkI));
                app.RegisteredPerimeter = cell(1, length(app.BulkI));
                app.RegisteredBulkI = cell(1, length(app.BulkI));
                app.OldRegistered = cell(1, length(app.BulkI));
                app.LeftImageSelectList.Value = 1;
                app.RightImageSelectList.Value = 2;
                app.RightImageSelectList.Enable = 0;
                displayFirstImage(app, 1)
                displaySecondImage(app, 2)
                resetBasePoints(app);
            end
        end

        % Button pushed function: RegisterBatchButton
        function reigsterBatch(app, event)
            value = app.RegistrationDropDown.Value;
            app.RightImageSelectList.Enable = 1;
            app.LeftImageSelectList.Enable = 1;
            if contains(value, 'Non')
                nonRigidRegister(app, [app.LeftImageBatch])
            else
                rigidRegisterFunc(app, [app.LeftImageBatch])
            end
            cla(app.FirstImageAxes)
            axesChild = imshow(imoverlay(app.OldRegistered{1}, app.RegisteredPerimeter{2}, [1,0,1]),[], 'Parent', app.FirstImageAxes);
            axesChild.ButtonDownFcn = createCallbackFcn(app, @firstImageButtonDown, true);
        end

        % Value changed function: ShowIDsSwitchLeft
        function showIDLeft(app, event)
            value = app.ShowIDsSwitchLeft.Value;
            
            selected = app.LeftImageSelectList.Value;
            centers_display = app.Centers{selected};
            
          
            if value == 1 & ~app.Orienting
                
                for i = 1:length(app.CC{selected}.PixelIdxList)
                    %%acount for removed spheroids
                    
                    if (~isempty(app.CC{selected}.PixelIdxList{i}))
                        
                        % find if selected point is inside of spheroid                       

                            text(app.FirstImageAxes, centers_display(i,1)-5, centers_display(i,2), num2str(app.MatchingID{selected}(i)), 'color', 'w', 'FontWeight', 'bold');                          
                            
                        
                    end
                end
            else
                displayFirstImage(app, selected)
            end
            
        end

        % Value changed function: ShowIDsSwitchRight
        function ShowIDRight(app, event)
            value = app.ShowIDsSwitchRight.Value;
            
            
            selected = app.RightImageSelectList.Value;
            centers_display = app.Centers{selected};
       
          
            if value == 1 & ~app.Orienting
                
                for i = 1:length(app.CC{selected}.PixelIdxList)
                    %%acount for removed spheroids
                    
                    if (~isempty(app.CC{selected}.PixelIdxList{i}))
                        
                        % find if selected point is inside of spheroid                       

                            text(app.SecondImageAxes, centers_display(i,1)-5, centers_display(i,2), num2str(app.MatchingID{selected}(i)), 'color', 'w', 'FontWeight', 'bold');                          
                            
                        
                    end
                end
            else
                displaySecondImage(app, selected)
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @keyPressTracking, true);
            app.OpenPreviouslyTrackedMenu = uimenu(app.UIFigure);
            app.OpenPreviouslyTrackedMenu.MenuSelectedFcn = createCallbackFcn(app, @saveTrackingResults, true);
            app.OpenPreviouslyTrackedMenu.Enable = 'off';
            app.OpenPreviouslyTrackedMenu.Text = 'Open Previously Tracked';
            app.FluorescenceMenu = uimenu(app.UIFigure);
            app.FluorescenceMenu.MenuSelectedFcn = createCallbackFcn(app, @fluorescenceFromTracking, true);
            app.FluorescenceMenu.Text = 'Fluorescence';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TrackingControlGrid = uigridlayout(app.GridLayout);
            app.SpheroidGrid = uigridlayout(app.GridLayout);
            app.AreaPlotAxes = uiaxes(app.SpheroidGrid);
            title(app.AreaPlotAxes, 'Area Growth Over Time')
            xlabel(app.AreaPlotAxes, 'Image Number')
            ylabel(app.AreaPlotAxes, 'Area')
            zlabel(app.AreaPlotAxes, 'Z')
            app.AreaPlotAxes.XLim = [0 Inf];
            app.AreaPlotAxes.YLim = [0 Inf];
            app.AreaPlotAxes.XLimitMethod = 'padded';
            app.AreaPlotAxes.Visible = 'off';
            app.SpheroidMontageAxes = uiaxes(app.SpheroidGrid);
            app.SpheroidMontageAxes.XLimitMethod = 'tight';
            app.SpheroidMontageAxes.YLimitMethod = 'tight';
            app.SpheroidMontageAxes.ZLimitMethod = 'tight';
            app.SpheroidMontageAxes.Visible = 'off';
            app.SpheroidListBox = uilistbox(app.SpheroidGrid);
            app.SpheroidListBox.Items = {};
            app.SpheroidListBox.ValueChangedFcn = createCallbackFcn(app, @spheroidSelectList, true);
            app.SpheroidListBox.Value = {};
            app.LeftGrid = uigridlayout(app.GridLayout);
            app.ImageListGrids = uigridlayout(app.LeftGrid);
            app.LeftImageLabel = uilabel(app.ImageListGrids);
            app.LeftImageLabel.Text = 'Left Image';
            app.LeftImageSelectList = uilistbox(app.ImageListGrids);
            app.LeftImageSelectList.Items = {};
            app.LeftImageSelectList.ValueChangedFcn = createCallbackFcn(app, @leftImageSelectListCallback, true);
            app.LeftImageSelectList.Value = {};
            app.RightImageLabel = uilabel(app.ImageListGrids);
            app.RightImageLabel.Text = 'Right Image';
            app.RightImageSelectList = uilistbox(app.ImageListGrids);
            app.RightImageSelectList.Items = {};
            app.RightImageSelectList.ValueChangedFcn = createCallbackFcn(app, @rightImageSelectListCallback, true);
            app.RightImageSelectList.Value = {};
            app.TabGroup = uitabgroup(app.LeftGrid);
            app.RegistrationTab = uitab(app.TabGroup);
            app.RegistrationTab.Title = 'Registration';
            app.OrientationGrid = uigridlayout(app.RegistrationTab);
            app.RegisterImagesGrid = uigridlayout(app.OrientationGrid);
            app.NumWorkersEditFieldLabel = uilabel(app.RegisterImagesGrid);
            app.NumWorkersEditFieldLabel.Text = 'Num Workers';
            app.NumWorkersEditField = uieditfield(app.RegisterImagesGrid, 'numeric');
            app.NumWorkersEditField.Limits = [0 Inf];
            app.NumWorkersEditField.RoundFractionalValues = 'on';
            app.DoneRegisteringButton = uibutton(app.OrientationGrid, 'push');
            app.DoneRegisteringButton.ButtonPushedFcn = createCallbackFcn(app, @doneOrientingCallback, true);
            app.DoneRegisteringButton.Text = 'Done Registering';
            app.RegMethodGrid = uigridlayout(app.OrientationGrid);
            app.RegMethodSwitch = uiswitch(app.RegMethodGrid, 'slider');
            app.RegMethodSwitch.Items = {'A', 'B'};
            app.RegMethodSwitch.ItemsData = {'Mask', 'Image'};
            app.RegMethodSwitch.Value = 'Mask';
            app.UseRawImageLabel = uilabel(app.RegMethodGrid);
            app.UseRawImageLabel.Text = 'Use Raw Images';
            app.UseMaskLabel = uilabel(app.RegMethodGrid);
            app.UseMaskLabel.Text = 'Use Segmentation Mask';
            app.DegreeofSmoothing053EditFieldLabel = uilabel(app.OrientationGrid);
            app.DegreeofSmoothing053EditFieldLabel.Text = {'Degree of Smoothing'; '[0.5 - 3]'};
            app.DegreeofSmoothing053EditField = uieditfield(app.OrientationGrid, 'numeric');
            app.DegreeofSmoothing053EditField.Limits = [0.5 3];
            app.DegreeofSmoothing053EditField.Value = 1;
            app.MaxIterations101000EditFieldLabel = uilabel(app.OrientationGrid);
            app.MaxIterations101000EditFieldLabel.Text = {'Max Iterations '; '[10-1000]'};
            app.MaxIterations101000EditField = uieditfield(app.OrientationGrid, 'numeric');
            app.MaxIterations101000EditField.Limits = [10 1000];
            app.MaxIterations101000EditField.RoundFractionalValues = 'on';
            app.MaxIterations101000EditField.Value = 100;
            app.RegistrationDropDownLabel = uilabel(app.OrientationGrid);
            app.RegistrationDropDownLabel.Text = 'Registration';
            app.RegistrationDropDown = uidropdown(app.OrientationGrid);
            app.RegistrationDropDown.Items = {'Rigid (No Rotation)', 'Rigid (With Rotation)', 'Non-rigid', 'Manual Translation'};
            app.RegistrationDropDown.ItemsData = {'"Rigid"', '"Rotate"', '"NonRigid"', '"Manual"'};
            app.RegistrationDropDown.ValueChangedFcn = createCallbackFcn(app, @changeRegMethod, true);
            app.RegistrationDropDown.Value = '"Rigid"';
            app.RegisterBatchButton = uibutton(app.OrientationGrid, 'push');
            app.RegisterBatchButton.ButtonPushedFcn = createCallbackFcn(app, @reigsterBatch, true);
            app.RegisterBatchButton.Text = 'Register Batch';
            app.RegisterAllButton = uibutton(app.OrientationGrid, 'push');
            app.RegisterAllButton.ButtonPushedFcn = createCallbackFcn(app, @registerAll, true);
            app.RegisterAllButton.Text = 'Register All';
            app.SortingTab = uitab(app.TabGroup);
            app.SortingTab.Title = 'Sorting';
            app.SortingGrid = uigridlayout(app.SortingTab);
            app.SortingDropDown = uidropdown(app.SortingGrid);
            app.SortingDropDown.Items = {'NA'};
            app.SortingDropDown.ValueChangedFcn = createCallbackFcn(app, @changeSortingVariable, true);
            app.SortingDropDown.Value = 'NA';
            app.SortingListBox = uilistbox(app.SortingGrid);
            app.SortingListBox.Items = {'NA'};
            app.SortingListBox.Value = 'NA';
            app.ApplyButton = uibutton(app.SortingGrid, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @applySorting, true);
            app.ApplyButton.Text = 'Apply';
            app.AlphabeticalButton = uibutton(app.SortingGrid, 'push');
            app.AlphabeticalButton.ButtonPushedFcn = createCallbackFcn(app, @alphabeticalSort, true);
            app.AlphabeticalButton.Text = 'Alphabetical';
            app.BottomGrid = uigridlayout(app.GridLayout);
            app.MatchGrid = uigridlayout(app.BottomGrid);
            app.Spheroid2Label = uilabel(app.MatchGrid);
            app.Spheroid2Label.Text = 'Spheroid #2';
            app.Spheroid2EditField = uieditfield(app.MatchGrid, 'numeric');
            app.MatchSpheroidsButton = uibutton(app.MatchGrid, 'push');
            app.MatchSpheroidsButton.ButtonPushedFcn = createCallbackFcn(app, @matchSpheroidsCallback, true);
            app.MatchSpheroidsButton.Text = 'Match Spheroids';
            app.UnmatchGrid = uigridlayout(app.BottomGrid);
            app.FirstWrongImageLabel = uilabel(app.UnmatchGrid);
            app.FirstWrongImageLabel.Text = 'First Wrong Image #';
            app.FirstWrongImageEditField = uieditfield(app.UnmatchGrid, 'numeric');
            app.UnmatchSpheroidsButton = uibutton(app.UnmatchGrid, 'push');
            app.UnmatchSpheroidsButton.ButtonPushedFcn = createCallbackFcn(app, @unmatchSpheroidsCallback, true);
            app.UnmatchSpheroidsButton.Text = 'Unmatch Spheroids';
            app.MaximumDistanceEditFieldLabel = uilabel(app.BottomGrid);
            app.MaximumDistanceEditFieldLabel.Text = 'Maximum Distance';
            app.MaximumDistanceEditField = uieditfield(app.BottomGrid, 'numeric');
            app.MaximumDistanceEditField.Limits = [0 Inf];
            app.MaximumDistanceEditField.ValueChangedFcn = createCallbackFcn(app, @changeMaxDistance, true);
            app.MaximumDistanceEditField.Value = 50;
            app.GridLayout2 = uigridlayout(app.BottomGrid);
            app.ExportDataButton = uibutton(app.GridLayout2, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @exportData, true);
            app.ExportDataButton.Text = 'Export Data';
            app.WhichOrganoidsExport = uidropdown(app.GridLayout2);
            app.WhichOrganoidsExport.Items = {'Export All Organoids ', 'Remove "x" ', 'Keep Only "c" '};
            app.WhichOrganoidsExport.ItemsData = {'ExportAll', 'RemoveX', 'KeepOnlyC'};
            app.WhichOrganoidsExport.Value = 'ExportAll';
            app.TrackButton = uibutton(app.GridLayout2, 'push');
            app.TrackButton.ButtonPushedFcn = createCallbackFcn(app, @trackImages, true);
            app.TrackButton.Text = 'Track';
            app.RemoveSpheroidInSequence = uigridlayout(app.BottomGrid);
            app.ImagetoRemoveEditFieldLabel = uilabel(app.RemoveSpheroidInSequence);
            app.ImagetoRemoveEditFieldLabel.Text = 'Image to Remove';
            app.ImagetoRemoveEditField = uieditfield(app.RemoveSpheroidInSequence, 'numeric');
            app.RemoveFromSequenceButton = uibutton(app.RemoveSpheroidInSequence, 'push');
            app.RemoveFromSequenceButton.ButtonPushedFcn = createCallbackFcn(app, @removeFromSequence, true);
            app.RemoveFromSequenceButton.Text = 'Remove From Sequence';
            app.ManualInstructionsLabel = uilabel(app.BottomGrid);
            app.ManualInstructionsLabel.Text = 'Orient image by selecting a base point on the left image, and then selecting the matching point for the image on the right. Choose "Confirm Orientation" when you are satisfied with the alignment for the current image. ';
            app.GridLayout3 = uigridlayout(app.BottomGrid);
            app.MinGrowthEditFieldLabel = uilabel(app.GridLayout3);
            app.MinGrowthEditFieldLabel.Text = 'Min Growth';
            app.MinGrowthEditField = uieditfield(app.GridLayout3, 'numeric');
            app.MinGrowthEditField.Limits = [0 Inf];
            app.MaxGrowthEditFieldLabel = uilabel(app.GridLayout3);
            app.MaxGrowthEditFieldLabel.Text = 'Max Growth';
            app.MaxGrowthEditField = uieditfield(app.GridLayout3, 'numeric');
            app.MaxGrowthEditField.Limits = [0 Inf];
            app.MaxGrowthEditField.Value = Inf;
            app.ImageDisplayGrid = uigridlayout(app.GridLayout);
            app.SecondImageAxes = uiaxes(app.ImageDisplayGrid);
            app.SecondImageAxes.XLimitMethod = 'tight';
            app.SecondImageAxes.YLimitMethod = 'tight';
            app.SecondImageAxes.ZLimitMethod = 'tight';
            app.SecondImageAxes.Visible = 'off';
            app.FirstImageAxes = uiaxes(app.ImageDisplayGrid);
            app.FirstImageAxes.XLimitMethod = 'tight';
            app.FirstImageAxes.YLimitMethod = 'tight';
            app.FirstImageAxes.ZLimitMethod = 'tight';
            app.FirstImageAxes.Visible = 'off';
            app.ShowIDsSwitchLeft = uiswitch(app.ImageDisplayGrid, 'slider');
            app.ShowIDsSwitchLeft.Items = {'Yes', 'No'};
            app.ShowIDsSwitchLeft.ItemsData = [1 0];
            app.ShowIDsSwitchLeft.ValueChangedFcn = createCallbackFcn(app, @showIDLeft, true);
            app.ShowIDsSwitchLeft.Value = 0;
            app.ShowIDsSwitchRight = uiswitch(app.ImageDisplayGrid, 'slider');
            app.ShowIDsSwitchRight.Items = {'Yes', 'No'};
            app.ShowIDsSwitchRight.ItemsData = [1 0];
            app.ShowIDsSwitchRight.ValueChangedFcn = createCallbackFcn(app, @ShowIDRight, true);
            app.ShowIDsSwitchRight.Value = 0;
            app.ShowIDsLabel_2 = uilabel(app.ImageDisplayGrid);
            app.ShowIDsLabel_2.Text = 'Show IDs';
            app.ShowIDsLabel = uilabel(app.ImageDisplayGrid);
            app.ShowIDsLabel.Text = 'Show IDs';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = trackingAnalysisScene(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)trackingStartup(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end